classdef ANLPLG3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ANLPluginUIFigure             matlab.ui.Figure
    GridLayout                    matlab.ui.container.GridLayout
    Panel                         matlab.ui.container.Panel
    GridLayout5                   matlab.ui.container.GridLayout
    NegativeampCheckBox           matlab.ui.control.CheckBox
    BaselineButton                matlab.ui.control.Button
    UBfactorSpinner               matlab.ui.control.Spinner
    UBfactorSpinnerLabel          matlab.ui.control.Label
    LBfactorSpinner               matlab.ui.control.Spinner
    LBfactorSpinnerLabel          matlab.ui.control.Label
    cbBoundfit                    matlab.ui.control.CheckBox
    UpdateonfilechangeCheckBox    matlab.ui.control.CheckBox
    ResidueoffsetSpinner          matlab.ui.control.Spinner
    ResidueoffsetSpinnerLabel     matlab.ui.control.Label
    ResiduefactorSpinner          matlab.ui.control.Spinner
    ResiduefactorSpinnerLabel     matlab.ui.control.Label
    ComputeButton                 matlab.ui.control.Button
    TabGroupFittingMethods        matlab.ui.container.TabGroup
    SETab                         matlab.ui.container.Tab
    GridLayout3                   matlab.ui.container.GridLayout
    Se_SetTimeOffsetButton        matlab.ui.control.Button
    SE_ResetRangeButton           matlab.ui.control.Button
    SE_LastPointSpinner           matlab.ui.control.Spinner
    LastPointSpinnerLabel_3       matlab.ui.control.Label
    SE_FirstPointSpinner          matlab.ui.control.Spinner
    FirstPointSpinnerLabel_3      matlab.ui.control.Label
    SE_TimeOffsetSpinner          matlab.ui.control.Spinner
    TimeOffsetusSpinnerLabel      matlab.ui.control.Label
    SEIRFTab                      matlab.ui.container.Tab
    GridLayout6                   matlab.ui.container.GridLayout
    SEIRF_ResetRangeButton        matlab.ui.control.Button
    SEIRF_SetTimeOffsetButton     matlab.ui.control.Button
    SEIRF_LastPointSpinner        matlab.ui.control.Spinner
    LastPointSpinnerLabel_4       matlab.ui.control.Label
    SEIRF_FirstPointSpinner       matlab.ui.control.Spinner
    FirstPointSpinnerLabel_4      matlab.ui.control.Label
    SEIRF_TimeOffsetSpinner       matlab.ui.control.Spinner
    TimeOffsetusSpinnerLabel_3    matlab.ui.control.Label
    SEIRF_IRFguessSpinner         matlab.ui.control.Spinner
    IRFguessSpinnerLabel          matlab.ui.control.Label
    DETab                         matlab.ui.container.Tab
    GridLayout4                   matlab.ui.container.GridLayout
    DE_T1guessSpinner             matlab.ui.control.Spinner
    T1guessSpinnerLabel           matlab.ui.control.Label
    DE_T2guessSpinner             matlab.ui.control.Spinner
    T2guessSpinnerLabel           matlab.ui.control.Label
    DE_SetTimeOffsetButton        matlab.ui.control.Button
    DE_ResetRangeButton           matlab.ui.control.Button
    DE_LastPointSpinner           matlab.ui.control.Spinner
    LastPointSpinnerLabel_5       matlab.ui.control.Label
    DE_FirstPointSpinner          matlab.ui.control.Spinner
    FirstPointSpinnerLabel_5      matlab.ui.control.Label
    DE_TimeOffsetSpinner          matlab.ui.control.Spinner
    TimeOffsetusSpinnerLabel_2    matlab.ui.control.Label
    DEIRFTab                      matlab.ui.container.Tab
    GridLayout7                   matlab.ui.container.GridLayout
    DEIRF_IRFguessSpinner         matlab.ui.control.Spinner
    IRFguessSpinner_2Label        matlab.ui.control.Label
    DEIRF_T2guessSpinnerLabel     matlab.ui.control.Label
    DEIRF_T1guessSpinnerLabel     matlab.ui.control.Label
    DEIRF_T2guessSpinner          matlab.ui.control.Spinner
    DEIRF_T1guessSpinner          matlab.ui.control.Spinner
    DEIRF_ResetRangeButton        matlab.ui.control.Button
    DEIRF_SetTimeOffsetButton     matlab.ui.control.Button
    DEIRF_LastPointSpinnerLabel   matlab.ui.control.Label
    DEIRF_LastPointSpinner        matlab.ui.control.Spinner
    DEIRF_FirstPointSpinnerLabel  matlab.ui.control.Label
    DEIRF_FirstPointSpinner       matlab.ui.control.Spinner
    DEIRF_TimeOffsetSpinner       matlab.ui.control.Spinner
    TimeOffsetusSpinnerLabel_4    matlab.ui.control.Label
    LightDarkTab                  matlab.ui.container.Tab
    GridLayout2                   matlab.ui.container.GridLayout
    SkewSpinner                   matlab.ui.control.Spinner
    SkewLabel                     matlab.ui.control.Label
    PhaseSpinner                  matlab.ui.control.Spinner
    PhaseLabel                    matlab.ui.control.Label
    tOutput                       matlab.ui.control.TextArea
  end


  properties (Access = public)
    guid % Description
  end

  properties (Access = private)
    KazanViewer % Description
    options
    baseline = []
  end

  methods (Access = public)
    %-----------------------------------------------------------------
    function results = FileUpdate(app)
      results = '';
      app.baseline = [];
      if app.UpdateonfilechangeCheckBox.value
        app.Compute();
      end
    end

    function results = InitApp(app, hh)
      results = [];
      app.Master = hh;
    end
  end
  %-----------------------------------------------------------------

  methods (Access = private)
    function Compute(app)
      src = app.KazanViewer.GetData('source');

      % all in usec and real only
      t = src.ax.x * 1e6;
      y = real(src.y);

      if ~isempty(app.baseline)
        bl = mean(y(app.baseline));
      else 
        bl = 0;
      end

      out = {};
      out{1} = src;
      out{end}.title = 'Source';

      if ~isempty(app.baseline)
        out{end+1} = src;
        out{end}.ax.x = out{end}.ax.x(app.baseline);
        out{end}.y = out{end}.y(app.baseline);
        out{end}.ax.Color = 'k';
        out{end}.ax.Marker = 'o';
        out{end}.title = 'Baseline';
      end

      % Plot residuals
      resi_multi = app.ResiduefactorSpinner.Value; % multiplier for residual plotting
      resi_off = app.ResidueoffsetSpinner.Value; % offset for residual plotting

      lbfactor = app.LBfactorSpinner.Value; LB = [];
      ubfactor = app.UBfactorSpinner.Value; UB = [];

      lbamp = 0.01;
      if app.NegativeampCheckBox.Value, lbamp = -5; end
      ubamp = 5;

      switch app.TabGroupFittingMethods.SelectedTab.Title
        case 'SE'
          % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          % %% Mono-Exponential Decay Fit
          % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

          tfp = app.SE_FirstPointSpinner.Value;
          [~,t_start] = min(abs(t-tfp));
          tlp = app.SE_LastPointSpinner.Value;
          [~,t_end] = min(abs(t-tlp));
          t_fitrange = t(t_start:t_end);% fit only the points in this range

          y_data = real(y(t_start:t_end));
          y_data = y_data-bl; % optional offset correction if the data has a constant offset
          fnorm = max(y_data);

          t0 = app.SE_TimeOffsetSpinner.Value; % time offset correction in microseconds
          t_fitrange = t_fitrange-t0;

          % Define the exponential decay function
          exp_decay = @(params, t) params(1) * exp(-t_fitrange/params(2));

          % Define the objective function (sum of squares error)
          obj_fun = @(params) sum((y_data - exp_decay(params, t_fitrange)).^2);

          % Set initial guesses for the parameters [Amplitude A, time constant tau]
          initial_guess = [max(y_data), max(t_fitrange)/5];

          if app.cbBoundfit.Value
            % Perform the fitting using fminsearchbnd
            search_options = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000,'TolX', 1e-8, 'TolFun', 1e-8);
            LB = [lbamp * max(abs(y_data)), initial_guess(2)] .* [1, lbfactor];
            UB = [ubamp * max(abs(y_data)), initial_guess(2)] .* [1, ubfactor];
            [fit_params, fval] = fminsearchbnd(obj_fun, initial_guess, LB, UB, search_options);
          else
            % Perform the fitting using fminsearch
            search_options = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000,'TolX', 1e-8, 'TolFun', 1e-8);
            [fit_params, fval] = fminsearch(obj_fun, initial_guess, search_options);
          end

          % Generate fitted data for plotting
          y_fit = exp_decay(fit_params, t_fitrange);
          resi = (y_data - y_fit);

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'r';
          out{end}.y = y_fit + bl;
          out{end}.title = 'Fit';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'g';
          out{end}.y = resi_multi*(resi + fnorm*resi_off);
          out{end}.title = 'Residual x3';

          [check_error, check_message] = app.CheckFit(fit_params, LB, UB, {'A', 'T'});
          ShowMessage(app, sprintf('T1 = %.2fus   Amp=%f\nfval=%.4g fval_norm=%.4g\n%s', fit_params(2), fit_params(1),fval, sqrt(fval/length(y_data))/fnorm, check_message), check_error);
        case 'SE + IRF'
          % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          % %% Mono-Exponential Decay Fit with IRF
          % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

          tfp = app.SEIRF_FirstPointSpinner.Value;
          [~,t_start] = min(abs(t-tfp));
          tlp = app.SEIRF_LastPointSpinner.Value;
          [~,t_end] = min(abs(t-tlp));
          t_fitrange = t(t_start:t_end);% fit only the points in this range

          y_data = real(y(t_start:t_end));
          y_data = y_data-bl; % optional offset correction if the data has a constant offset
          fnorm = max(y_data);

          t0 = 0;
          % t0 = app.SEIRF_TimeOffsetSpinner.Value; % time offset correction in microseconds
          % t_fitrange = t_fitrange-t0;

          % Define the exponential decay function
          exp_decay = @(params, t) params(1) * expIRF(t-abs(params(4)), abs(params(2)), abs(params(3))); % amplitude, time vector, time corr, lifetime tau, sigma IRF

          % Define the objective function (sum of squares error)
          obj_fun = @(params) sum((y_data - exp_decay(params, t)).^2);

          % Set initial guesses for the parameters [Amplitude A, time constant tau]
          initial_guess = [max(y_data), max(t_fitrange)/5, app.SEIRF_IRFguessSpinner.Value, app.SEIRF_TimeOffsetSpinner.Value];

          if app.cbBoundfit.Value
            % Perform the fitting using fminsearchbnd
            search_options = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000,'TolX', 1e-4, 'TolFun', 1e-4);
            LB = [lbamp * max(abs(y_data)), initial_guess(2:4)] .* [1, lbfactor, lbfactor, -2];
            UB = [ubamp * max(abs(y_data)), initial_guess(2:4)] .* [1, ubfactor, ubfactor, 2];
            [fit_params, fval] = fminsearchbnd(obj_fun, initial_guess, LB, UB, search_options);
          else
            % Perform the fitting using fminsearch
            search_options = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000,'TolX', 1e-4, 'TolFun', 1e-4);
            [fit_params, fval] = fminsearch(obj_fun, initial_guess, search_options);
          end

          % Generate fitted data for plotting
          y_fit = exp_decay(fit_params, t_fitrange);
          resi = (y_data - y_fit);

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'r';
          out{end}.y = y_fit + bl;
          out{end}.title = 'Fit';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'g';
          out{end}.y = resi_multi*(resi + fnorm*resi_off);
          out{end}.title = 'Residual x3';

          [check_error, check_message] = app.CheckFit(fit_params, LB, UB, {'A1', 'T1', 'IRF','tcorr'});
          ShowMessage(app, sprintf('T1 = %.2fus   Amp=%.3f\nIRF=%.2fus   t_corr=%.3fus\nfval=%.4g fval_norm=%.4g\n%s', fit_params(2), fit_params(1),...
            fit_params(3), fit_params(4), fval, sqrt(fval/length(y_data))/fnorm, check_message), check_error);
        case 'DE'
          % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          % %% Bi-Exponential Decay Fit
          % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

          tfp = app.DE_FirstPointSpinner.Value;
          [~,t_start] = min(abs(t-tfp));
          tlp = app.DE_LastPointSpinner.Value;
          [~,t_end] = min(abs(t-tlp));
          t_fitrange = t(t_start:t_end);% fit only the points in this range

          y_data = real(y(t_start:t_end));
          y_data = y_data-bl; % optional offset correction if the data has a constant offset
          fnorm = max(y_data);

          t0 = app.DE_TimeOffsetSpinner.Value; % time offset correction in microseconds
          t_fitrange = t_fitrange-t0;

          % Define the exponential decay function
          exp_decay_all = @(params, t) params(1) * exp(-t_fitrange/params(2)) + params(3) * exp(-t_fitrange/params(4));
          exp_decay_one = @(params, t) params(1) * exp(-t_fitrange/params(2));
          exp_decay_two = @(params, t) params(3) * exp(-t_fitrange/params(4));

          % Define the objective function (sum of squares error)
          obj_fun = @(params) sum((y_data - exp_decay_all(params, t_fitrange)).^2);

          % Set initial guesses for the parameters [Amplitude A, time constant tau]
          initial_guess = [max(y_data)/2, app.DE_T1guessSpinner.Value, max(y_data)/2, app.DE_T2guessSpinner.Value];

          if app.cbBoundfit.Value
            % Perform the fitting using fminsearchbnd
            opts = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000, 'TolX', 1e-8, 'TolFun', 1e-8);
            LB = [lbamp * max(abs(y_data)), initial_guess(2), lbamp * max(abs(y_data)), initial_guess(4)] .* [1, lbfactor, 1, lbfactor];
            UB = [ubamp * max(abs(y_data)), initial_guess(2), ubamp * max(abs(y_data)), initial_guess(4)] .* [1, ubfactor, 1, ubfactor];
            [fit_params, fval] = fminsearchbnd(obj_fun, initial_guess, LB, UB, opts);
          else
            % Perform the fitting using fminsearch
            opts = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000, 'TolX', 1e-8, 'TolFun', 1e-8);
            [fit_params, fval] = fminsearch(obj_fun, initial_guess, opts);
          end

          % Generate fitted data for plotting
          y_fit = exp_decay_all(fit_params, t_fitrange);
          resi = (y_data - y_fit);

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'r';
          out{end}.y = y_fit + bl;
          out{end}.title = 'Fit';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'g';
          out{end}.y = resi_multi*(resi + fnorm*resi_off);
          out{end}.title = 'Residual x3';

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'm';
          out{end}.ax.LineStyle = '--';
          out{end}.y = exp_decay_one(fit_params, t_fitrange) + bl;
          out{end}.title = 'Exponent1';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'k';
          out{end}.ax.LineStyle = '--';
          out{end}.y = exp_decay_two(fit_params, t_fitrange) + bl;
          out{end}.title = 'Exponent2';          

          [check_error, check_message] = app.CheckFit(fit_params, LB, UB, {'A1', 'T1', 'A2','T2'});
          ShowMessage(app, sprintf('T1 = %.2fus Amp=%f\nT2 = %.2fus Amp=%f\nfval=%.4g fval_norm=%.4g\n%s', fit_params(2), fit_params(1), ...
            fit_params(4), fit_params(3), fval, sqrt(fval/length(y_data))/fnorm, check_message), check_error);
        case 'DE + IRF'
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
          %% Bi-Exponential Decay Fit with IRF
          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

          tfp = app.DEIRF_FirstPointSpinner.Value;
          [~,t_start] = min(abs(t-tfp));
          tlp = app.DEIRF_LastPointSpinner.Value;
          [~,t_end] = min(abs(t-tlp));
          t_fitrange = t(t_start:t_end);% fit only the points in this range

          y_data = real(y(t_start:t_end));
          y_data = y_data-bl; % optional offset correction if the data has a constant offset
          fnorm = max(y_data);

          t0 = 0;
          % app.DEIRF_TimeOffsetSpinner.Value; % time offset correction in microseconds
          % t_fitrange = t_fitrange-t0;

          % Define the exponential decay function
          exp_decay_all = @(params, t) params(1) * expIRF(t-abs(params(4)), abs(params(2)), abs(params(3))) + ...
            params(5) * expIRF(t-abs(params(4)), abs(params(6)), abs(params(3))); %  amplitude, time vector, time corr, lifetime tau, sigma IRF

          exp_decay_one = @(params, t) params(1) * expIRF(t-abs(params(4)), abs(params(2)), abs(params(3)));
          exp_decay_two = @(params, t) params(5) * expIRF(t-abs(params(4)), abs(params(6)), abs(params(3)));

          % Define the objective function (sum of squares error)
          obj_fun = @(params) sum((y_data - exp_decay_all(params, t_fitrange)).^2);

          % Set initial guesses for the parameters [Amplitude A, time constant tau]
          initial_guess = [max(y_data)/2, app.DEIRF_T1guessSpinner.Value, app.DEIRF_IRFguessSpinner.Value, app.DEIRF_TimeOffsetSpinner.Value, max(y_data)/2, app.DE_T2guessSpinner.Value];

          if app.cbBoundfit.Value
            % Perform the fitting using fminsearchbnd
            opts = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000, 'TolX', 1e-8, 'TolFun', 1e-8);
            LB = [lbamp * max(abs(y_data)), initial_guess(2), initial_guess(3), -200, lbamp * max(abs(y_data)), initial_guess(6)] .* [1, lbfactor, lbfactor, 1, 1, lbfactor];
            UB = [ubamp * max(abs(y_data)), initial_guess(2), initial_guess(3), 200, ubamp * max(abs(y_data)), initial_guess(6)] .* [1, ubfactor, ubfactor, 1, 1, ubfactor];
            [fit_params, fval] = fminsearchbnd(obj_fun, initial_guess, LB, UB, opts);
          else
            % Perform the fitting using fminsearch
            opts = optimset('Display', 'notify', 'MaxIter',2000, 'MaxFunEvals', 2000, 'TolX', 1e-8, 'TolFun', 1e-8);
            [fit_params, fval] = fminsearch(obj_fun, initial_guess, opts);
          end

          % Generate fitted data for plotting
          y_fit = exp_decay_all(fit_params, t_fitrange);
          resi = (y_data - y_fit);

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'r';
          out{end}.y = y_fit + bl;
          out{end}.title = 'Fit';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'g';
          out{end}.y = resi_multi*(resi + fnorm*resi_off);
          out{end}.title = 'Residual x3';

          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'm';
          out{end}.ax.LineStyle = '--';
          out{end}.y = exp_decay_one(fit_params, t_fitrange) + bl;
          out{end}.title = 'Exponent1';
          out{end+1} = src;
          out{end}.ax.x = (t0 + t_fitrange)*1e-6;
          out{end}.ax.Color = 'k';
          out{end}.ax.LineStyle = '--';
          out{end}.y = exp_decay_two(fit_params, t_fitrange) + bl;
          out{end}.title = 'Exponent2';          

          [check_error, check_message] = app.CheckFit(fit_params, LB, UB, {'A1', 'T1', 'IRF', 'Tcorr', 'A2','T2'});
          ShowMessage(app, sprintf('T1 = %.2fus Amp=%f\nT2 = %.2fus Amp=%f\nIRF=%.2fus   t_corr=%.3fus\nfval=%.4g fval_norm=%.4g\n%s', fit_params(2), fit_params(1), ...
            fit_params(6), fit_params(5), fit_params(3), fit_params(4), fval, sqrt(fval/length(y_data))/fnorm, check_message), check_error);
      end

      app.KazanViewer.SetData('output', out);
    end

    %-----------------------------------------------------------------
    function [Data, src] = LD(app)
      src = app.KazanViewer.GetData('source');
      Phase = app.PhaseSpinner.Value;
      Skew  = app.SkewSpinner.Value;

      % LD experiment
      k = size(src.y);
      isLD = k(1)==2 && k(2) > 2;

      if isLD
        disp('Light-Dark processing engaged size(y,1)==2.');
        Data = repmat(src.y(1,:),size(src.y, 1),1) - src.y;
        Data = Data(2:end,:);
        for ii=1:size(Data,1)
          Data(ii,:) = real(Data(ii,:)) + 1i*imag(Data(ii,:))*Skew;
          Data(ii,:) = Data(ii,:) * exp(-1i*Phase/180*pi);
        end
        Data = Data';

        src.ax.x = src.ax.y;
        src.ax.xlabel = src.ax.ylabel;
        src.ax.y = 0;
      else
        disp('Phase only processing engaged size(y,1)~=2.');
        Data = src.y;
        Data = real(Data) + 1i * imag(Data) * Skew;
        Data = Data * exp(-1i*Phase/180*pi);
      end
    end

    %-----------------------------------------------------------------
    function results = ShowMessage(app, message, flag)
      results = [];
      app.tOutput.Value = message;
      switch flag
        case 'error'
          app.tOutput.FontColor = [1 0 0];
        case 'upper bound'
          app.tOutput.FontColor = [0 0 1];
        case 'lower bound'
          app.tOutput.FontColor = [0 0 1];
        otherwise
          app.tOutput.FontColor = [0 0 0];
      end
    end
    
    %-----------------------------------------------------------------
    function [results, message] = CheckFit(app, fpars, LB, UB, dsc)
      results = ''; message = '';
      if ~isempty(LB) &&  ~isempty(UB) &&  ~isempty(fpars)
        if any(abs((fpars - UB)./UB) < 0.025)
          results = 'upper bound';
          idx = abs((fpars - UB)./UB) < 0.025;
          message = sprintf('upper bound (parameter %s)', dsc{idx});
        end
        if any(abs((fpars - LB)./LB) < 0.025)
          results = 'lower bound';
          idx =  abs((fpars - LB)./LB) < 0.025;
          message = sprintf('%slower bound (parameter %s)', message, dsc{idx});
        end
      end
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;

      app.options.use_bounds = 1;
    end

    % Close request function: ANLPluginUIFigure
    function ANLPluginUIFigureCloseRequest(app, event)
      app.KazanViewer.MakePlugins('close', app);
      delete(app)

    end

    % Callback function: ComputeButton, ResiduefactorSpinner, 
    % ...and 1 other component
    function ComputeButtonPushed(app, event)
      app.Compute();
    end

    % Size changed function: ANLPluginUIFigure
    function ANLPluginUIFigureSizeChanged(app, event)
      position = app.ANLPluginUIFigure.Position;
      
    end

    % Button pushed function: DEIRF_ResetRangeButton, 
    % ...and 3 other components
    function CommonResetRange(app, event)
      src = app.KazanViewer.GetData('source');
      switch event.Source
        case app.SE_ResetRangeButton
          app.SE_FirstPointSpinner.Value = min(src.ax.x) * 1e6;
          app.SE_LastPointSpinner.Value = max(src.ax.x) * 1e6;
          [~,midx] = max(real(src.y));
          app.SE_TimeOffsetSpinner.Value = src.ax.x(midx)*1e6;
          app.SE_FirstPointSpinner.Value = src.ax.x(midx)*1e6;
        case app.DE_ResetRangeButton
          app.DE_LastPointSpinner.Value = max(src.ax.x) * 1e6;
          app.DE_T1guessSpinner.Value = app.DE_LastPointSpinner.Value/5;
          app.DE_T2guessSpinner.Value = app.DE_LastPointSpinner.Value/2;
          [~,midx] = max(real(src.y));
          app.DE_TimeOffsetSpinner.Value = src.ax.x(midx)*1e6;
          app.DE_FirstPointSpinner.Value = src.ax.x(midx)*1e6;
        case app.SEIRF_ResetRangeButton
          app.SEIRF_FirstPointSpinner.Value = min(src.ax.x) * 1e6;
          app.SEIRF_LastPointSpinner.Value = max(src.ax.x) * 1e6;
          app.SEIRF_IRFguessSpinner.Value = app.SEIRF_LastPointSpinner.Value/100;
          [~,midx] = max(real(src.y));
          app.SEIRF_TimeOffsetSpinner.Value = src.ax.x(midx)*1e6;
        case app.DEIRF_ResetRangeButton
          app.DEIRF_FirstPointSpinner.Value = min(src.ax.x) * 1e6;
          app.DEIRF_LastPointSpinner.Value = max(src.ax.x) * 1e6;
          app.DEIRF_IRFguessSpinner.Value = app.DEIRF_LastPointSpinner.Value/100;
          app.DEIRF_T1guessSpinner.Value = app.DEIRF_LastPointSpinner.Value/5;
          app.DEIRF_T2guessSpinner.Value = app.DEIRF_LastPointSpinner.Value/2;
      end
    end

    % Button pushed function: DEIRF_SetTimeOffsetButton, 
    % ...and 3 other components
    function CommonSetTimeOffset(app, event)
      pos = app.KazanViewer.Ginput(1);
      switch event.Source
        case app.Se_SetTimeOffsetButton
          app.SE_TimeOffsetSpinner.Value = pos(1,1)*1e6;
          app.SE_FirstPointSpinner.Value = pos(1,1)*1e6;
        case app.DE_SetTimeOffsetButton
          app.DE_TimeOffsetSpinner.Value = pos(1,1)*1e6;
          app.DE_FirstPointSpinner.Value = pos(1,1)*1e6;
        case app.SEIRF_SetTimeOffsetButton
      end      
    end

    % Button pushed function: BaselineButton
    function BaselineButtonPushed(app, event)
      pos = app.KazanViewer.Ginput(2);

      if isempty(pos)
        app.baseline = [];
      else
        src = app.KazanViewer.GetData('source');
        app.baseline = src.ax.x >= pos(1,1) & src.ax.x <= pos(2,1);
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.ANLPluginUIFigure = uifigure('Visible', 'off');
      app.ANLPluginUIFigure.Name = 'ANL Plugin';
      app.ANLPluginUIFigure.CloseRequestFcn = createCallbackFcn(app, @ANLPluginUIFigureCloseRequest, true);
      app.ANLPluginUIFigure.SizeChangedFcn = createCallbackFcn(app, @ANLPluginUIFigureSizeChanged, true);
      app.GridLayout = uigridlayout(app.ANLPluginUIFigure);
      app.tOutput = uitextarea(app.GridLayout);
      app.TabGroupFittingMethods = uitabgroup(app.GridLayout);
      app.SETab = uitab(app.TabGroupFittingMethods);
      app.SETab.Title = 'SE';
      app.GridLayout3 = uigridlayout(app.SETab);
      app.TimeOffsetusSpinnerLabel = uilabel(app.GridLayout3);
      app.TimeOffsetusSpinnerLabel.Text = 'Time Offset [us]';
      app.SE_TimeOffsetSpinner = uispinner(app.GridLayout3);
      app.SE_TimeOffsetSpinner.Limits = [0 Inf];
      app.SE_TimeOffsetSpinner.ValueDisplayFormat = '%11.4gus';
      app.FirstPointSpinnerLabel_3 = uilabel(app.GridLayout3);
      app.FirstPointSpinnerLabel_3.Text = 'First Point';
      app.SE_FirstPointSpinner = uispinner(app.GridLayout3);
      app.SE_FirstPointSpinner.Limits = [0 Inf];
      app.SE_FirstPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.LastPointSpinnerLabel_3 = uilabel(app.GridLayout3);
      app.LastPointSpinnerLabel_3.Text = 'Last Point';
      app.SE_LastPointSpinner = uispinner(app.GridLayout3);
      app.SE_LastPointSpinner.Limits = [0 Inf];
      app.SE_LastPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.SE_ResetRangeButton = uibutton(app.GridLayout3, 'push');
      app.SE_ResetRangeButton.ButtonPushedFcn = createCallbackFcn(app, @CommonResetRange, true);
      app.SE_ResetRangeButton.Text = 'Reset Range';
      app.Se_SetTimeOffsetButton = uibutton(app.GridLayout3, 'push');
      app.Se_SetTimeOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @CommonSetTimeOffset, true);
      app.Se_SetTimeOffsetButton.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.Se_SetTimeOffsetButton.Text = '';
      app.SEIRFTab = uitab(app.TabGroupFittingMethods);
      app.SEIRFTab.Title = 'SE + IRF';
      app.GridLayout6 = uigridlayout(app.SEIRFTab);
      app.IRFguessSpinnerLabel = uilabel(app.GridLayout6);
      app.IRFguessSpinnerLabel.Text = 'IRF guess';
      app.SEIRF_IRFguessSpinner = uispinner(app.GridLayout6);
      app.SEIRF_IRFguessSpinner.Limits = [0 Inf];
      app.SEIRF_IRFguessSpinner.ValueDisplayFormat = '%11.4gus';
      app.TimeOffsetusSpinnerLabel_3 = uilabel(app.GridLayout6);
      app.TimeOffsetusSpinnerLabel_3.Text = 'Time Offset [us]';
      app.SEIRF_TimeOffsetSpinner = uispinner(app.GridLayout6);
      app.SEIRF_TimeOffsetSpinner.Limits = [0 Inf];
      app.SEIRF_TimeOffsetSpinner.ValueDisplayFormat = '%11.4gus';
      app.FirstPointSpinnerLabel_4 = uilabel(app.GridLayout6);
      app.FirstPointSpinnerLabel_4.Text = 'First Point';
      app.SEIRF_FirstPointSpinner = uispinner(app.GridLayout6);
      app.SEIRF_FirstPointSpinner.Limits = [0 Inf];
      app.SEIRF_FirstPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.LastPointSpinnerLabel_4 = uilabel(app.GridLayout6);
      app.LastPointSpinnerLabel_4.Text = 'Last Point';
      app.SEIRF_LastPointSpinner = uispinner(app.GridLayout6);
      app.SEIRF_LastPointSpinner.Limits = [0 Inf];
      app.SEIRF_LastPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.SEIRF_SetTimeOffsetButton = uibutton(app.GridLayout6, 'push');
      app.SEIRF_SetTimeOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @CommonSetTimeOffset, true);
      app.SEIRF_SetTimeOffsetButton.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.SEIRF_SetTimeOffsetButton.Text = '';
      app.SEIRF_ResetRangeButton = uibutton(app.GridLayout6, 'push');
      app.SEIRF_ResetRangeButton.ButtonPushedFcn = createCallbackFcn(app, @CommonResetRange, true);
      app.SEIRF_ResetRangeButton.Text = 'Reset Range';
      app.DETab = uitab(app.TabGroupFittingMethods);
      app.DETab.Title = 'DE';
      app.GridLayout4 = uigridlayout(app.DETab);
      app.TimeOffsetusSpinnerLabel_2 = uilabel(app.GridLayout4);
      app.TimeOffsetusSpinnerLabel_2.Text = 'Time Offset [us]';
      app.DE_TimeOffsetSpinner = uispinner(app.GridLayout4);
      app.DE_TimeOffsetSpinner.Limits = [0 Inf];
      app.DE_TimeOffsetSpinner.ValueDisplayFormat = '%11.4gus';
      app.FirstPointSpinnerLabel_5 = uilabel(app.GridLayout4);
      app.FirstPointSpinnerLabel_5.Text = 'First Point';
      app.DE_FirstPointSpinner = uispinner(app.GridLayout4);
      app.DE_FirstPointSpinner.Limits = [0 Inf];
      app.DE_FirstPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.LastPointSpinnerLabel_5 = uilabel(app.GridLayout4);
      app.LastPointSpinnerLabel_5.Text = 'Last Point';
      app.DE_LastPointSpinner = uispinner(app.GridLayout4);
      app.DE_LastPointSpinner.Limits = [0 Inf];
      app.DE_LastPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.DE_ResetRangeButton = uibutton(app.GridLayout4, 'push');
      app.DE_ResetRangeButton.ButtonPushedFcn = createCallbackFcn(app, @CommonResetRange, true);
      app.DE_ResetRangeButton.Text = 'Reset Range';
      app.DE_SetTimeOffsetButton = uibutton(app.GridLayout4, 'push');
      app.DE_SetTimeOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @CommonSetTimeOffset, true);
      app.DE_SetTimeOffsetButton.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.DE_SetTimeOffsetButton.Text = '';
      app.T2guessSpinnerLabel = uilabel(app.GridLayout4);
      app.T2guessSpinnerLabel.Text = 'T2 guess';
      app.DE_T2guessSpinner = uispinner(app.GridLayout4);
      app.DE_T2guessSpinner.Limits = [0 Inf];
      app.DE_T2guessSpinner.ValueDisplayFormat = '%11.4gus';
      app.DE_T2guessSpinner.Value = 100;
      app.T1guessSpinnerLabel = uilabel(app.GridLayout4);
      app.T1guessSpinnerLabel.Text = 'T1 guess';
      app.DE_T1guessSpinner = uispinner(app.GridLayout4);
      app.DE_T1guessSpinner.Limits = [0 Inf];
      app.DE_T1guessSpinner.ValueDisplayFormat = '%11.4gus';
      app.DE_T1guessSpinner.Value = 50;
      app.DEIRFTab = uitab(app.TabGroupFittingMethods);
      app.DEIRFTab.Title = 'DE + IRF';
      app.GridLayout7 = uigridlayout(app.DEIRFTab);
      app.TimeOffsetusSpinnerLabel_4 = uilabel(app.GridLayout7);
      app.TimeOffsetusSpinnerLabel_4.Text = 'Time Offset [us]';
      app.DEIRF_TimeOffsetSpinner = uispinner(app.GridLayout7);
      app.DEIRF_TimeOffsetSpinner.Limits = [0 Inf];
      app.DEIRF_TimeOffsetSpinner.ValueDisplayFormat = '%11.4gus';
      app.DEIRF_FirstPointSpinner = uispinner(app.GridLayout7);
      app.DEIRF_FirstPointSpinner.Limits = [0 Inf];
      app.DEIRF_FirstPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.DEIRF_FirstPointSpinnerLabel = uilabel(app.GridLayout7);
      app.DEIRF_FirstPointSpinnerLabel.Text = 'First Point';
      app.DEIRF_LastPointSpinner = uispinner(app.GridLayout7);
      app.DEIRF_LastPointSpinner.Limits = [0 Inf];
      app.DEIRF_LastPointSpinner.ValueDisplayFormat = '%11.4gus';
      app.DEIRF_LastPointSpinnerLabel = uilabel(app.GridLayout7);
      app.DEIRF_LastPointSpinnerLabel.Text = 'Last Point';
      app.DEIRF_SetTimeOffsetButton = uibutton(app.GridLayout7, 'push');
      app.DEIRF_SetTimeOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @CommonSetTimeOffset, true);
      app.DEIRF_SetTimeOffsetButton.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.DEIRF_SetTimeOffsetButton.Text = '';
      app.DEIRF_ResetRangeButton = uibutton(app.GridLayout7, 'push');
      app.DEIRF_ResetRangeButton.ButtonPushedFcn = createCallbackFcn(app, @CommonResetRange, true);
      app.DEIRF_ResetRangeButton.Text = 'Reset Range';
      app.DEIRF_T1guessSpinner = uispinner(app.GridLayout7);
      app.DEIRF_T1guessSpinner.Limits = [0 Inf];
      app.DEIRF_T1guessSpinner.ValueDisplayFormat = '%11.4gus';
      app.DEIRF_T1guessSpinner.Value = 50;
      app.DEIRF_T2guessSpinner = uispinner(app.GridLayout7);
      app.DEIRF_T2guessSpinner.Limits = [0 Inf];
      app.DEIRF_T2guessSpinner.ValueDisplayFormat = '%11.4gus';
      app.DEIRF_T2guessSpinner.Value = 100;
      app.DEIRF_T1guessSpinnerLabel = uilabel(app.GridLayout7);
      app.DEIRF_T1guessSpinnerLabel.Text = 'T1 guess';
      app.DEIRF_T2guessSpinnerLabel = uilabel(app.GridLayout7);
      app.DEIRF_T2guessSpinnerLabel.Text = 'T2 guess';
      app.IRFguessSpinner_2Label = uilabel(app.GridLayout7);
      app.IRFguessSpinner_2Label.Text = 'IRF guess';
      app.DEIRF_IRFguessSpinner = uispinner(app.GridLayout7);
      app.DEIRF_IRFguessSpinner.Limits = [0 Inf];
      app.DEIRF_IRFguessSpinner.ValueDisplayFormat = '%11.4gus';
      app.LightDarkTab = uitab(app.TabGroupFittingMethods);
      app.LightDarkTab.Title = 'Light-Dark';
      app.GridLayout2 = uigridlayout(app.LightDarkTab);
      app.PhaseLabel = uilabel(app.GridLayout2);
      app.PhaseLabel.Text = 'Phase';
      app.PhaseSpinner = uispinner(app.GridLayout2);
      app.SkewLabel = uilabel(app.GridLayout2);
      app.SkewLabel.Text = 'Skew';
      app.SkewSpinner = uispinner(app.GridLayout2);
      app.Panel = uipanel(app.GridLayout);
      app.GridLayout5 = uigridlayout(app.Panel);
      app.ComputeButton = uibutton(app.GridLayout5, 'push');
      app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
      app.ComputeButton.Text = 'Compute';
      app.ResiduefactorSpinnerLabel = uilabel(app.GridLayout5);
      app.ResiduefactorSpinnerLabel.Text = 'Residue factor';
      app.ResiduefactorSpinner = uispinner(app.GridLayout5);
      app.ResiduefactorSpinner.Step = 0.1;
      app.ResiduefactorSpinner.Limits = [1 50];
      app.ResiduefactorSpinner.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
      app.ResiduefactorSpinner.Value = 3;
      app.ResidueoffsetSpinnerLabel = uilabel(app.GridLayout5);
      app.ResidueoffsetSpinnerLabel.Text = 'Residue offset';
      app.ResidueoffsetSpinner = uispinner(app.GridLayout5);
      app.ResidueoffsetSpinner.Step = 0.1;
      app.ResidueoffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
      app.ResidueoffsetSpinner.Value = -0.5;
      app.UpdateonfilechangeCheckBox = uicheckbox(app.GridLayout5);
      app.UpdateonfilechangeCheckBox.Text = 'Update on file change';
      app.cbBoundfit = uicheckbox(app.GridLayout5);
      app.cbBoundfit.Text = 'Bound fit';
      app.LBfactorSpinnerLabel = uilabel(app.GridLayout5);
      app.LBfactorSpinnerLabel.Text = 'LB factor';
      app.LBfactorSpinner = uispinner(app.GridLayout5);
      app.LBfactorSpinner.Step = 0.1;
      app.LBfactorSpinner.Limits = [-1 1];
      app.LBfactorSpinner.ValueDisplayFormat = 'x%-.2g';
      app.LBfactorSpinner.Value = 0.2;
      app.UBfactorSpinnerLabel = uilabel(app.GridLayout5);
      app.UBfactorSpinnerLabel.Text = 'UB factor';
      app.UBfactorSpinner = uispinner(app.GridLayout5);
      app.UBfactorSpinner.Step = 0.1;
      app.UBfactorSpinner.Limits = [0 10];
      app.UBfactorSpinner.ValueDisplayFormat = 'x%-.2g';
      app.UBfactorSpinner.Value = 2;
      app.BaselineButton = uibutton(app.GridLayout5, 'push');
      app.BaselineButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineButtonPushed, true);
      app.BaselineButton.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.BaselineButton.Text = 'Baseline';
      app.NegativeampCheckBox = uicheckbox(app.GridLayout5);
      app.NegativeampCheckBox.Text = 'Negative amp';
      % Show the figure after all components are created
      app.ANLPluginUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ANLPLG3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ANLPluginUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ANLPluginUIFigure)
    end
  end
end