classdef CWMH3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure             matlab.ui.Figure
    GridLayout           matlab.ui.container.GridLayout
    Reports              matlab.ui.control.TextArea
    btnUsePhase          matlab.ui.control.StateButton
    CalculateButton      matlab.ui.control.Button
    DropDown2            matlab.ui.control.DropDown
    PhaseSpinner         matlab.ui.control.Spinner
    PhaseSpinnerLabel    matlab.ui.control.Label
    GridLayout2          matlab.ui.container.GridLayout
    b0001                matlab.ui.control.StateButton
    b100                 matlab.ui.control.StateButton
    b10                  matlab.ui.control.StateButton
    b1                   matlab.ui.control.StateButton
    b01                  matlab.ui.control.StateButton
    b001                 matlab.ui.control.StateButton
    SourceDropDown       matlab.ui.control.DropDown
    SourceDropDownLabel  matlab.ui.control.Label
  end

  
  properties (Access = public)
    guid % Description
  end

  properties (Access = private)
    KazanViewer  % Description
  end
  
  methods (Access = public)
    
    function results = FileUpdate(app)
      
    end

    function ph = FindLINPhase(app, y1,y2,y3,y4,y5)
      F = @(x)sum(imag(y1*exp(-1j*(x(1))) ).^2) + ...
        sum(imag( y2*exp(-1j*(x(1)+x(2))) ).^2) + ...
        sum(imag( y3*exp(-1j*(x(1)+2*x(2))) ).^2) + ...
        sum(imag( y4*exp(-1j*(x(1)+3*x(2))) ).^2) + ...
        sum(imag( y5*exp(-1j*(x(1)+4*x(2))) ).^2);

      ppp = 0:180;
      for ii=1:length(ppp)
        s(ii) = F([-5,ppp(ii)*pi/180]);
      end
      [~,idx] = min(s);

      ph = fminsearch(F, [0,idx*pi/180]) * 180/pi;
      if ph(1) < 0, ph(1) = ph(1) + 360; end
    end

  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
    end

    % Callback function: CalculateButton, SourceDropDown
    function CalculateButtonPushed(app, event)
      signal = app.KazanViewer.GetData('source');

      switch app.SourceDropDown.Value
        case 'I Channel 1 and 6'
          y1 = signal.ax.raw(:,:,1);
          y2 = signal.ax.raw(:,:,6);
        case 'I Channel 2 and 7'
          y1 = signal.ax.raw(:,:,2);
          y2 = signal.ax.raw(:,:,7);
        case 'I Channel 3 and 8'
          y1 = signal.ax.raw(:,:,3);
          y2 = signal.ax.raw(:,:,8);
        case 'I Channel 4 and 9'
          y1 = signal.ax.raw(:,:,4);
          y2 = signal.ax.raw(:,:,9);
        case 'I Channel 5 and 10'
          y1 = signal.ax.raw(:,:,5);
          y2 = signal.ax.raw(:,:,10);
        case 'I Channel 6'
          y1 = signal.ax.raw(:,:,6);
        case 'I Channel 7'
          y1 = signal.ax.raw(:,:,7);
        case 'I Channel 8'
          y1 = signal.ax.raw(:,:,8);
        case 'I Channel 9'
          y1 = signal.ax.raw(:,:,9);
        case 'I Channel 10'
          y1 = signal.ax.raw(:,:,10);
      end

      if app.btnUsePhase.Value && exist("y2","var")
        phase = app.PhaseSpinner.Value * pi/180;
        y = y1 + 1i*y2;
        y = y*exp(-1j*phase);
      else
        y = y1 + 1i*y2;
      end

      switch app.DropDown2.Value
        case 'As Is'

          out = {};
          out{end+1} = signal;
          out{end}.y = y;
          out{end}.title = 'source';
          out{end}.ax.Color = 'r';

          app.KazanViewer.SetData('output', out);
        case 'Power saturation'
          yprocess = [];
          y = real(y);
          idx = 256:768;
          for ii=1:size(y,2)
            yprocess(ii) = max(y(idx,ii)) - min(y(idx,ii));
          end

          [att, sidx] = sort(-signal.ax.y);
          yprocess = yprocess(sidx);

          ref = 20; % dBm

          out = {};
          out{end+1}.ax.x = att+ref;
          out{end}.ax.xlabel = 'Power, dBm';
          out{end}.ax.y = 0;
          out{end}.y = 20*log10(yprocess(:));
          out{end}.title = 'source';
          out{end}.ax.Color = 'r';

          P = 10.^(((ref + att)-30 )/10);
          CONS = @(x) min(max(x, 0.5), 1.5);
          SAT = @(x) x(1)*sqrt(P)./(1 + (P/x(2))).^CONS(x(3));
          YY = yprocess(:);
          CF = @(x) sqrt(sum((YY - SAT(x)).^2));

          x = fminsearch(CF, [1,0.01,1]);
          
          out{end+1}.ax.x = att+ref;
          out{end}.ax.xlabel = 'Power, dBm';
          out{end}.ax.y = 0;
          out{end}.y = 20*log10(SAT(x));
          out{end}.title = 'fit';
          out{end}.ax.Color = 'g';

          app.Reports.Value = sprintf('Pref=%.1fdBm\nPs=%.1fmW (%.1fdBm) e=%.3f', 20, x(2)*1e3, 10*log10(x(2)*1e3), x(3));

          app.KazanViewer.SetData('output', out);
        case 'Phases'
            y1 = signal.ax.raw(:,:,1) + 1i*signal.ax.raw(:,:,6);
            y2 = signal.ax.raw(:,:,2) + 1i*signal.ax.raw(:,:,7);
            y3 = signal.ax.raw(:,:,3) + 1i*signal.ax.raw(:,:,8);
            y4 = signal.ax.raw(:,:,4) + 1i*signal.ax.raw(:,:,9);
            y5 = signal.ax.raw(:,:,5) + 1i*signal.ax.raw(:,:,10);
            
            if app.btnUsePhase.Value == 1
              phs = app.FindLINPhase(y1,y2,y3,y4,y5);
              fprintf('%.2f*(x-1) + %.2f\n', phs(2), phs(1));
              ph = phs(1) + (0:4)*phs(2);
              ph
            else
              ph = zeros(1,5);
            end

            out = {};
            out{end+1} = signal;
            out{end}.ax.y = 1:5;

            for ii=1:5
              y = signal.ax.raw(:,:,ii) + 1i*signal.ax.raw(:,:,ii+5);
              % for jj=1:ii
              %   idx = [1:10,length(y)-10:length(y)];
              %   p = polyfit(idx,y(idx),1);
              %   y = cumsum(y - (polyval(p,1:length(y)))');
              % end
              out{end}.y(:,ii) = y * exp(-1i*ph(ii)*pi/180);
            end

            app.KazanViewer.SetData('output', out);
      end


    end

    % Value changed function: b0001, b001, b01, b1, b10, b100
    function SetStep(app, event)
      allbuttons = [app.b100, app.b10, app.b1, app.b01, app.b001, app.b0001];
      switch event.Source
        case app.b0001, step = 0.001;
        case app.b001, step = 0.01;
        case app.b01, step = 0.1;
        case app.b10, step = 10;
        case app.b100, step = 100;
        otherwise, step = 1;
      end

      allbuttons = allbuttons(allbuttons ~= event.Source);
      set(allbuttons, 'Value', 0);
      app.PhaseSpinner.Step = step; 
    end

    % Value changed function: PhaseSpinner, btnUsePhase
    function PhaseSpinnerValueChanged(app, event)
        app.CalculateButtonPushed(event);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.GridLayout = uigridlayout(app.UIFigure);
      app.SourceDropDownLabel = uilabel(app.GridLayout);
      app.SourceDropDownLabel.Text = 'Source';
      app.SourceDropDown = uidropdown(app.GridLayout);
      app.SourceDropDown.Items = {'I Channel 1 and 6', 'I Channel 2 and 7', 'I Channel 3 and 8', 'I Channel 4 and 9', 'I Channel 5 and 10', 'I Channel 6', 'I Channel 7', 'I Channel 8', 'I Channel 9', 'I Channel 10'};
      app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
      app.SourceDropDown.Value = 'I Channel 1 and 6';
      app.GridLayout2 = uigridlayout(app.GridLayout);
      app.b001 = uibutton(app.GridLayout2, 'state');
      app.b001.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b001.Text = '0.01';
      app.b01 = uibutton(app.GridLayout2, 'state');
      app.b01.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b01.Text = '0.1';
      app.b1 = uibutton(app.GridLayout2, 'state');
      app.b1.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b1.Text = 'x1';
      app.b10 = uibutton(app.GridLayout2, 'state');
      app.b10.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b10.Text = '10';
      app.b100 = uibutton(app.GridLayout2, 'state');
      app.b100.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b100.Text = '100';
      app.b0001 = uibutton(app.GridLayout2, 'state');
      app.b0001.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b0001.Text = '0.001';
      app.PhaseSpinnerLabel = uilabel(app.GridLayout);
      app.PhaseSpinnerLabel.Text = 'Phase';
      app.PhaseSpinner = uispinner(app.GridLayout);
      app.PhaseSpinner.ValueChangedFcn = createCallbackFcn(app, @PhaseSpinnerValueChanged, true);
      app.DropDown2 = uidropdown(app.GridLayout);
      app.DropDown2.Items = {'As Is', 'Power saturation', 'Phases'};
      app.DropDown2.Value = 'As Is';
      app.CalculateButton = uibutton(app.GridLayout, 'push');
      app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
      app.CalculateButton.Text = 'Calculate';
      app.btnUsePhase = uibutton(app.GridLayout, 'state');
      app.btnUsePhase.ValueChangedFcn = createCallbackFcn(app, @PhaseSpinnerValueChanged, true);
      app.btnUsePhase.Text = 'Button';
      app.Reports = uitextarea(app.GridLayout);
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = CWMH3(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.UIFigure)

        % Execute the startup function
        runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.UIFigure)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end