classdef CWplugin3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1                       matlab.ui.Figure
    GridLayout                    matlab.ui.container.GridLayout
    IntegrationandfittingrangePanel  matlab.ui.container.Panel
    GridLayout7                   matlab.ui.container.GridLayout
    cbFullIntRange                matlab.ui.control.CheckBox
    cbShowIntRange                matlab.ui.control.CheckBox
    eFirstPos                     matlab.ui.control.EditField
    btnGetEvaluationRange         matlab.ui.control.Button
    LinefittingPanel              matlab.ui.container.Panel
    GridLayout8                   matlab.ui.container.GridLayout
    cbFixGauss                    matlab.ui.control.CheckBox
    Guess0autoLabel               matlab.ui.control.Label
    eLV_Lorentz                   matlab.ui.control.NumericEditField
    LorenzLabel                   matlab.ui.control.Label
    eLV_Gauss                     matlab.ui.control.NumericEditField
    GaussLabel                    matlab.ui.control.Label
    eSpinProbe                    matlab.ui.control.DropDown
    pbFitLWpp                     matlab.ui.control.Button
    pbFitLWHH                     matlab.ui.control.Button
    pbFitLW_G                     matlab.ui.control.Button
    cbSquare                      matlab.ui.control.CheckBox
    cbShift                       matlab.ui.control.CheckBox
    pbIntegral                    matlab.ui.control.StateButton
    pbFitLW_L                     matlab.ui.control.Button
    pbFitLW_V                     matlab.ui.control.Button
    pbFitLW_Mod                   matlab.ui.control.Button
    eResInt                       matlab.ui.control.TextArea
    Filterprocessingstep3Panel    matlab.ui.container.Panel
    GridLayout6                   matlab.ui.container.GridLayout
    tbFilter4                     matlab.ui.control.StateButton
    tbFilter3                     matlab.ui.control.StateButton
    tbFilter2                     matlab.ui.control.StateButton
    tbFilter1                     matlab.ui.control.StateButton
    tbFilterOn                    matlab.ui.control.StateButton
    Phaseprocessingstep2Panel     matlab.ui.container.Panel
    GridLayout5                   matlab.ui.container.GridLayout
    cbUseDispersion               matlab.ui.control.CheckBox
    ePhase                        matlab.ui.control.Spinner
    pbPhase                       matlab.ui.control.StateButton
    pbPhase_x10                   matlab.ui.control.StateButton
    pbPhase_x1                    matlab.ui.control.StateButton
    pbPhase_x01                   matlab.ui.control.StateButton
    Baselineprocessingstep1Panel  matlab.ui.container.Panel
    GridLayout9                   matlab.ui.container.GridLayout
    cbApplyLin4Integration        matlab.ui.control.CheckBox
    cbBaselineShow                matlab.ui.control.CheckBox
    ePars                         matlab.ui.control.EditField
    ddStepSize                    matlab.ui.control.DropDown
    pmPars                        matlab.ui.control.Spinner
    btnFit                        matlab.ui.control.Button
    text16                        matlab.ui.control.Label
    btnAddFitRange                matlab.ui.control.Button
    text14                        matlab.ui.control.Label
    btnRemoveFitRange             matlab.ui.control.Button
    text15                        matlab.ui.control.Label
    lbPars                        matlab.ui.control.ListBox
    pbBaseline                    matlab.ui.control.StateButton
    pbBase_0                      matlab.ui.control.StateButton
    pbBase_1                      matlab.ui.control.StateButton
    pbBase_2                      matlab.ui.control.StateButton
    pbBase_3                      matlab.ui.control.StateButton
    pbBase_4                      matlab.ui.control.StateButton
    pbBase_5                      matlab.ui.control.StateButton
    OutputTypePanel               matlab.ui.container.Panel
    GridLayout4                   matlab.ui.container.GridLayout
    tbDestDerivative              matlab.ui.control.StateButton
    tbDestAbsorption              matlab.ui.control.StateButton
    tbDestIntegral                matlab.ui.control.StateButton
    InputTypePanel                matlab.ui.container.Panel
    GridLayout3                   matlab.ui.container.GridLayout
    tbSrcDerivative               matlab.ui.control.StateButton
    tbSrcAbsorption               matlab.ui.control.StateButton
    DataPanel                     matlab.ui.container.Panel
    GridLayout2                   matlab.ui.container.GridLayout
    tbSrcProcSource               matlab.ui.control.StateButton
    tbSrcSource                   matlab.ui.control.StateButton
    tbSrcOutput                   matlab.ui.control.StateButton
  end

  properties (Access = public)
    guid % Description
  end

  properties (Access = private)
    KazanViewer % Description
    areafit % index of area for baseline analysys
    area_evaluate % index for area of linewidth or integration
    baseline % Description
    fit
  end

  methods (Access = private)

    %--------------------------------------------------------------------
    function results = FileUpdate(app)
      results = '';
      %if app.UpdateonfilechangeCheckBox.value
      app.BigProcessingFunction();
      %end
    end

    %--------------------------------------------------------------------
    function FitBaseline(app)

      [ax,yy] = app.GetRawData();

      Script = app.lbPars.Items;
      [NewScript, bline_fit]= kv_autofit(Script, ax.x,  real(yy), app.areafit);
      app.lbPars.Items = NewScript;
      if length(bline_fit) == 1, bline_fit = bline_fit*ones(size(ax.x)); end
      app.baseline = bline_fit;
    end

    %--------------------------------------------------------------------

    %---------------------------------------------------------------------
    function [ax, yy] = GetRawData(app)

      if app.tbSrcSource.Value
        mydata = app.KazanViewer.GetData('source');
      elseif app.tbSrcProcSource.Value
        mydata = app.KazanViewer.GetData('psource');
      else
        mydata = app.KazanViewer.GetData('output');
      end

      ax = mydata.ax;
      yy = mydata.y;
    end

    %---------------------------------------------------------------------
    function [ax, yy] = GetData(app)

      [ax,yy] = GetRawData(app);
      xx = ax.x;

      if length(app.areafit) ~= length(xx)
        app.areafit = true(length(xx),1);
        n = floor(length(xx)/3);
        app.areafit(n:2*n) = false;
      end

      if length(app.baseline) ~= length(xx)
        app.baseline = zeros(length(xx),1);
      end

      if length(app.area_evaluate) ~= length(xx)
        app.area_evaluate = false(length(xx),1);
      end

      if app.tbSrcAbsorption.Value && app.tbDestAbsorption.Value
        yy = app.ChangeBaseline(yy);
        yy = app.ChangePhase(yy);
      elseif app.tbSrcAbsorption.Value && app.tbDestDerivative.Value
        yy = app.ChangeBaseline(yy);
        yy = app.ChangePhase(yy);
        yy = gradient(yy, mean(diff(xx)));
      elseif app.tbSrcAbsorption.Value && app.tbDestIntegral.Value
        yy = app.ChangeBaseline(yy);
        yy = app.ChangePhase(yy);
        yy = cumtrapz(xx,yy);
      elseif app.tbSrcDerivative.Value && app.tbDestAbsorption.Value
        yy = app.ChangeBaseline(yy);
        yy = app.ChangePhase(yy);
        yy = cumtrapz(xx,yy);
        if app.cbApplyLin4Integration.Value
          yy = app.ChangeBaselineLin(yy);
        end
      elseif app.tbSrcDerivative.Value && app.tbDestDerivative.Value
        yy = app.ChangeBaseline(yy);
        yy = app.ChangePhase(yy);
      elseif app.tbSrcDerivative.Value && app.tbDestIntegral.Value
        yy = app.ChangeBaseline(yy);
        yy = app.ChangePhase(yy);
        yy = cumtrapz(xx,yy);
        if app.cbApplyLin4Integration.Value
          yy = app.ChangeBaselineLin(yy);
        end
        yy = cumtrapz(xx,yy);
      end

      if app.tbFilterOn.Value
        if app.tbFilter1.Value, yy = sgolayfilt(yy, 1, 7);
        elseif app.tbFilter2.Value, yy = sgolayfilt(yy, 1, 13);
        elseif app.tbFilter3.Value, yy = sgolayfilt(yy, 1, 35);
        elseif app.tbFilter4.Value, yy = sgolayfilt(yy, 1, 53);
        end
      end
    end

    % --------------------------------------------------------------------
    function f = GetFit(app, handles)
      [ax] = GetRawData(app, handles);
      x = ax.x;
      the_script = get(handles.lbPars, 'string');

      for ii=length(the_script):-1:1
        eval([the_script{ii},';']);
      end
    end

    % --------------------------------------------------------------------
    function evallist(app, handles)

      [viewer_projection, viewer_range] = getslider_kazan(app, handles);
      str = get(handles.lbPars, 'String');
      val = 0;
      hand = guidata(handles.hh);
      FitRng = [];
      eval(['x = hand.src.ax.', viewer_projection, ';']);
      eval(['y = hand.src.y', viewer_range, ';']);
      [~, ind] = max(size(y));
      if ind == 2, x = x.'; end
      for count = 1:size(str, 1)
        if ~strcmp(str{count}(1:3), 'f =')
          eval([str{count}, ';']);
        else
          val = count;
        end
      end

      [handles.range(handles.ActivePage).pnts, handles.range(handles.ActivePage).idx] = getrange(app, FitRng, x);
      handles.range(handles.ActivePage).bracket = FitRng;

      if ~val, disp('EVALLIST: Can not find function'); return; end
      if findstr(str{val}, 'polyfit')
        btnFit_Callback(handles.btnFit, [], handles)
      else
        eval([str{val}, ';']);
        if strcmp(viewer_range, '(:, :)')
          switch viewer_projection
            case 'x'
              eval('handles.sim.y = f(:, ones(size(hand.src.y, 2), 1));');
            case 'y'
              eval('handles.sim.y = f(ones(size(hand.src.y, 1), 1), :);');
          end
        else
          eval(['handles.sim.y', viewer_range, ' = f;']);
        end
        guidata(handles.figure1, handles);
      end
    end

    % --------------------------------------------------------------------
    function [points, FitRng] = getrange(app, FitRngX, x)

      numpoints=length(x);
      pnts = zeros(1,numpoints);
      sz = size(FitRngX);
      allranges = reshape(FitRngX, 1, prod(sz));
      [~,idx] = min(abs(allranges(ones(numpoints,1),:)-x(:,ones(1,prod(sz)))));
      FitRng = reshape(idx, sz);
      if ~isempty(FitRng)
        for i = 1:size(FitRng, 1)
          pnts([min(FitRng(i, 1), end):min(FitRng(i, 2), end)]) = 1;
        end
        points = find(pnts);
      else
        points = [];
      end
    end

    % --------------------------------------------------------------------
    function [prj, sel] = getslider_kazan(app, handles)

      hand = guidata(handles.hh);
      str = {'x', 'y'};
      prj = str{hand.Projection};
      if hand.PlotType ==1
        switch hand.Projection
          case 1
            sel = ['(:, ','min(',  num2str(hand.Selection),', end))'];
          case 2
            sel = ['(min(', num2str(hand.Selection),', end), :)'];
        end
      else
        sel = '(:, :)';
      end
    end

    % --------------------------------------------------------------------
    function y = simple_fit(app, a, xdata, xpatt, pattern, derivative)

      lw = lshape(xpatt, 0, abs(a(3)), derivative, 0);
      y = real(conv2(lw(:,1),pattern,'same'));
      y = a(1) * interp1(xpatt + a(2), y, xdata);
    end

    % --------------------------------------------------------------------
    function INT = CalculateIntegral(app, xx, yy)

      if app.cbSquare.Value,  yy=abs(yy); end
      if app.cbShift.Value
        shift = min(real(yy)) + 1i*min(imag(yy));
        yy = yy - shift;
      end
      
      INT = trapz(xx,yy);
    end

    % --------------------------------------------------------------------
    function yy = ChangePhase(app, yy)
      if app.pbPhase.Value
        if ~app.cbUseDispersion.Value
          yy = real(yy(:));
          % Extend symmetrically to approximate negative frequencies
          yy_extended = [flip(yy); yy];
          % Compute Hilbert transform
          H = imag(hilbert(yy_extended));
          % Extract the second half (positive frequencies)
          yy_imag = H(length(yy)+1:end);

          yy = real(yy) + 1i*yy_imag;
        end
        yy = yy.*exp(-1i*app.ePhase.Value/180*pi);
      end
    end

    % --------------------------------------------------------------------
    function yy = ChangeBaseline(app, yy)
      if app.pbBaseline.Value
        % [funstr, funpar, funval] = kv_autofit(strFunc, cellVar)
        % TODO baseline supports only autofit now

        yy = yy - app.baseline;
      end
    end

    % --------------------------------------------------------------------
    function yy = ChangeBaselineLin(app, yy)
      if app.pbBaseline.Value
        xx1 = 1:length(yy); xx1 = xx1(:);
        yy1 = real(yy);
        P = polyfit(xx1(app.areafit), yy1(app.areafit), 1);
        lbaseline = polyval(P,xx1); 

        yy = yy - lbaseline;
      end
    end

    % --------------------------------------------------------------------
    function yy = ChangeFilter(app, yy)
      % if app.pbBaseline.Value
      % end
    end

    % --------------------------------------------------------------------
    function BigProcessingFunction(app)

      [ax,yy] = app.GetData();
      xx = ax.x;

      if app.pbBaseline.Value && app.cbBaselineShow.Value
        yy = yy + app.baseline;
      end

      out = {};
      out{1}.ax = ax;
      out{1}.ax.x = xx;
      out{1}.y = yy;
      out{1}.title = 'Data';

      if app.cbShowIntRange.Value &&  any(app.area_evaluate)
        out{end+1}.ax.x = xx(app.area_evaluate);
        out{end}.y = yy(app.area_evaluate);
        out{end}.ax.Color = [1, 0, 1]; % always majenta
        out{end}.title = 'Range';
      end

      if app.cbBaselineShow.Value
        out{end+1}.ax.x = xx;
        out{end}.y = app.baseline;
        out{end}.ax.Color = [0, 0, 0]; % always black
        out{end}.ax.LineStyle = ':';
        out{end}.title = 'Baseline';
      end

      if app.pbBaseline.Value && ~all(app.areafit)
        out{end+1}.ax.x = xx(app.areafit);
        out{end}.y = yy(app.areafit);
        out{end}.ax.Color = [0, 0, 0]; % always black
        out{end}.ax.Marker = 'o';
        out{end}.ax.LineStyle = 'none';
        out{end}.title = 'fit area';
      end

      if app.pbBaseline.Value && app.cbBaselineShow.Value
        yy = yy - app.baseline;
      end

      if isfield(app.fit, 'y')
        out{end+1}.ax.x = app.fit.ax.x;
        out{end}.y = app.fit.y;
        out{end}.ax.Color = [1, 0, 0]; % always black
        out{end}.ax.LineStyle = '-';
        out{end}.title = 'Fit';
      end

      app.KazanViewer.SetData('output', out);
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;

      set(app.ddStepSize, 'Items', {'1e-7', '1e-6', '1e-5', '1e-4', '1e-3', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7', '1e8'});
      set(app.ddStepSize, 'ItemsData', [1e-7, 1e-6, 1e-5, 1e-4, 1e-3, 0.01, 0.1, 1, 10, 100, 1000, 1e4, 1e5, 1e6, 1e7, 1e8]);
    end

    % Button pushed function: btnAddFitRange, btnGetEvaluationRange, 
    % ...and 1 other component
    function btnAddFitRange_Callback(app, event)
      disp('Select the beginning and the end of the range');
      ginput = app.KazanViewer.Ginput(2);
      pos = [ginput(1,1), ginput(2,1)];
      pos = sort(pos);

      [ax] = app.GetData();
      xx = ax.x;

      switch event.Source
        case app.btnAddFitRange
          app.areafit(xx > pos(1) & xx < pos(2)) = true;
        case app.btnRemoveFitRange
          app.areafit(xx > pos(1) & xx < pos(2)) = false;
        case app.btnGetEvaluationRange
          app.area_evaluate = false(size(xx));
          app.area_evaluate(xx > pos(1) & xx < pos(2)) = true;
          app.eFirstPos.Value = sprintf('%f  - %f', pos(1), pos(2));
      end

      app.BigProcessingFunction();

    end

    % Button pushed function: btnFit
    function btnFit_Callback(app, event)
      app.FitBaseline();
      app.BigProcessingFunction();
    end

    % Value changed function: eFirstPos
    function eFirstPos_Callback(app, event)
      % --------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [h, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      hand = guidata(handles.hh);
      y = hand.src.y;

      if get(handles.cbFullIntRange,'Value')
        handles.area = 1:size(y,1);
      else
        str1 = get(handles.eFirstPos, 'String');
        str2 = get(handles.eLastPos, 'String');
        p1=str2double(str1);
        p2=str2double(str2);
        if isempty(p1), p1=hand.src.ax.x(1); end
        if isempty(p2), p2=hand.src.ax.x(size(handles.sim.y,1)); end
        [~,idx1]=min(abs(hand.src.ax.x-p1));
        [~,idx2]=min(abs(hand.src.ax.x-p2));
        %   set(handles.eFirstPos, 'String', num2str(hand.src.ax.x(idx1)));
        %   set(handles.eLastPos, 'String', num2str(hand.src.ax.x(idx2)));
        handles.area = idx1:idx2;
      end

      guidata(handles.figure1, handles);
      DrawAll(app, handles);
    end

    % Value changed function: ePars, pmPars
    function ePars_Callback(app, event)
      [name, str1] = strtok(app.lbPars.Value, '=');
      str2 = [strtrim(str1(2:end)), ' '];

      newval = num2str(app.pmPars.Value);

      switch app.pmPars.UserData
        case 'function'
          % set(handles.ePars, 'String', newval);
          %
          % tokens = kv_autofit([name ' = ' newval]);
          %
          % %%%%%%%%%%%%% load script %%%%%%%%%%%%%%%%%%%%%%
          % for k=1:size(Res,1)
          %   if ~strcmp(strtrim(strtok(Res{k},'=')),'f')
          %     try eval([Res{k} ';']);catch;end
          %   end
          % end
          % tempcell = Res;
          % Res = {[name ' = ' newval]};
          % for k = 1: size(tokens, 2)
          %   try var = eval(tokens{k}); catch err, var = 0; end
          %   Res{end+1} = [tokens{k},' = ', num2str(var)];
          % end
          % %%%% find strings with additional parameters %%%
          % for count = 1:length(tempcell)
          %   if length(tempcell{count})>6
          %     if strcmp(tempcell{count}(1:6), 'FitRng')
          %       Res{end+1} = tempcell{count};
          %     end
          %   end
          % end
          % clear tempcell;
        case 'string'
          % set(handles.ePars, 'string', newval);
          % Res{val} = [name ' = ''', newval,''''];
        otherwise
          selectedIdx = find(strcmp(app.lbPars.Value, app.lbPars.Items));
          new_val = [strtrim(name),' = ',num2str(app.pmPars.Value)];
          app.lbPars.Items{selectedIdx} = new_val;
          app.lbPars.Value = new_val;

          app.BigProcessingFunction();
      end
    end

    % Size changed function: figure1
    function figure1_SizeChangedFcn(app, event)
      standard_size = [70   669   370   480];
      fig_size = app.figure1.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      if contains(class(app.KazanViewer), 'kazan3')
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.figure1, 'Type', 'uistatebutton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UITextArea'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIEdit'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIDropDown'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UILabel'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UISpinner'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'uipanel'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIListBox'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIcheckbox'), 'FontSize', font_size);
      end
    end

    % Value changed function: lbPars
    function lbPars_Callback(app, event)

      [name, str1] = strtok(app.lbPars.Value, '=');
      str2 = [strtrim(str1(2:end)), ' '];

      if strcmp(strtrim(name), 'f')
        app.ePars.Value = str2;
        app.pmPars.Enable = false;
        app.pmPars.UserData = 'function';
      elseif str2(1)==''''
        primes = strfind(str2, '''');
        if size(primes, 2) < 2, primes(2) = size(str2, 2); end
        app.ePars.Value = str2(2:(primes(2)-1));
        app.pmPars.Enable = false;
        app.pmPars.UserData = 'string';
      else
        app.pmPars.Value = str2double(str2);
        app.pmPars.Enable = true;
        app.pmPars.UserData = 'value';
      end
    end

    % Button pushed function: pbFitLWHH, pbFitLW_G, pbFitLW_L, 
    % ...and 3 other components
    function pbFitLW_Callback(app, event)

      [ax, yy] = app.GetData();
      xx = ax.x;

      if app.cbFullIntRange.Value
        xxx = xx;
        yyy = real(yy);
      else
        xxx = xx(app.area_evaluate);
        yyy = real(yy(app.area_evaluate));
      end

      lw_estimate = (max(xxx)-min(xxx)) / 5.0;

      switch event.Source
        %===================================================
        case app.pbFitLW_L % Lorenzian
          if app.tbDestDerivative.Value
            predicted = @(a, xdata) abs(a(1))*lshape(xdata, a(2), abs(a(3)), 1, 0); % lorentian
            if app.eLV_Lorentz.Value ~=0
              lw_estimate = app.eLV_Lorentz.Value;
            end

            [~,idxmax] = max(yyy);
            [~,idxmin] = min(yyy);
            x0_estimate = xxx(floor((idxmax+idxmin)/2));
            opts = optimset('Display','off');
            a = [lw_estimate*(max(yyy)-min(yyy)), x0_estimate, lw_estimate];
            [ahat] = lsqcurvefit(predicted,a,xxx,yyy,[],[],opts);
            [ahat] = lsqcurvefit(predicted,ahat,xxx,yyy,[],[],opts);

            app.fit.ax = ax;
            app.fit.ax.x = xxx;
            app.fit.y = predicted(ahat, xxx);
            app.fit.error = std(yyy-app.fit.y);
            app.eResInt.Value = sprintf('LW-L = %5.4f\nSD=%f\nINT=%f', abs(ahat(3)), app.fit.error, abs(a(1)));
            % INTx = linspace(ahat(2)-ahat(3)*10, ahat(2)+ahat(3)*10, 1024);
            % app.CalculateIntegral(INTx, lshape(INTx, ahat(2), ahat(3), 0) <- always one for lshape
          elseif app.tbDestAbsorption.Value
            predicted = @(a, xdata) abs(a(1))*lshape(xdata, a(2), abs(a(3)), 0, 0); % lorentian

            amp_estimate = app.CalculateIntegral(xxx,yyy);
            [~,idxmax] = max(yyy);
            x0_estimate = xxx(idxmax);
            opts = optimset('Display','off');
            a = [amp_estimate, x0_estimate, lw_estimate];
            [ahat] = lsqcurvefit(predicted,a,xxx,yyy,[],[],opts);
            [ahat] = lsqcurvefit(predicted,ahat,xxx,yyy,[],[],opts);

            app.fit.ax = ax;
            app.fit.ax.x = xxx;
            app.fit.y = predicted(ahat, xxx);
            app.fit.error = std(yyy-app.fit.y);
            app.eResInt.Value = sprintf('LW-L = %5.4f\nSD=%f\nINT=%f', abs(ahat(3)), app.fit.error, abs(a(1)));
          end

          %===================================================
        case app.pbFitLW_V  % Vogtian

          lwL_estimate = lw_estimate;
          lwG_estimate = lw_estimate;
          if app.eLV_Lorentz.Value ~=0
            lwL_estimate = app.eLV_Lorentz.Value;
          end
          if app.eLV_Gauss.Value ~=0
            lwG_estimate = app.eLV_Gauss.Value;
          end

          if app.tbDestDerivative.Value
            [~,idxmax] = max(yyy);
            [~,idxmin] = min(yyy);
            x0_estimate = xxx(floor((idxmax+idxmin)/2));

            if app.cbFixGauss.Value
              a = [lw_estimate*(max(yyy)-min(yyy)), x0_estimate, lwL_estimate];
              predicted = @(a, xdata) abs(a(1))*voigtian(xdata,abs(a(2)),abs([lwG_estimate,a(3)]),1);
            else
              a = [lw_estimate*(max(yyy)-min(yyy)), x0_estimate, lwG_estimate, lwL_estimate];
              predicted = @(a, xdata) abs(a(1))*voigtian(xdata,abs(a(2)),abs([a(3),a(4)]),1);
            end
            opts = optimset('Display','off');
            [ahat] = lsqcurvefit(predicted,a,xx,yy,[],[],opts);
            [ahat] = lsqcurvefit(predicted,ahat,xx,yy,[],[],opts);
            ahat = abs(ahat);

            app.fit.ax = ax;
            app.fit.ax.x = xxx;
            app.fit.y = predicted(ahat, xxx);
            app.fit.error = std(yyy-app.fit.y);

            if app.cbFixGauss.Value
              INTy = app.CalculateIntegral(xxx, a(1)*voigtian(xxx, ahat(2), [lwG_estimate,ahat(3)], 0));
              app.eResInt.Value =sprintf('LW-GL = %5.4f / %5.4f\nSD=%5.4f\nINT=%f', lwG_estimate, ahat(3), app.fit.error, INTy);
            else
              INTy = app.CalculateIntegral(xxx, a(1)*voigtian(xxx, ahat(2), [ahat(3),ahat(4)], 0));
              app.eResInt.Value =sprintf('LW-GL = %5.4f / %5.4f\nSD=%5.4f\nINT=%f', ahat(3), ahat(4), app.fit.error, INTy);
            end

          elseif app.tbDestAbsorption.Value

            [~,idxmax] = max(yyy);
            x0_estimate = xxx(idxmax);

            if app.cbFixGauss.Value
              a = [lw_estimate*(max(yyy)-min(yyy)), x0_estimate, lwL_estimate];
              predicted = @(a, xdata) abs(a(1))*voigtian(xdata,abs(a(2)),abs([lwG_estimate,a(3)]),0);
            else
              a = [lw_estimate*(max(yyy)-min(yyy)), x0_estimate, lwG_estimate, lwL_estimate];
              predicted = @(a, xdata) abs(a(1))*voigtian(xdata,abs(a(2)),abs([a(3),a(4)]),0);
            end
            opts = optimset('Display','off');
            [ahat] = lsqcurvefit(predicted,a,xx,yy,[],[],opts);
            [ahat] = lsqcurvefit(predicted,ahat,xx,yy,[],[],opts);
            ahat = abs(ahat);

            app.fit.ax = ax;
            app.fit.ax.x = xxx;
            app.fit.y = predicted(ahat, xxx);
            app.fit.error = std(yyy-app.fit.y);

            INTy = app.CalculateIntegral(xxx, predicted(ahat, xxx));
            if app.cbFixGauss.Value
              app.eResInt.Value =sprintf('LW-GL = %5.4f / %5.4f\nSD=%5.4f\nINT=%f', lwG_estimate, ahat(3), app.fit.error, INTy);
            else
              app.eResInt.Value =sprintf('LW-GL = %5.4f / %5.4f\nSD=%5.4f\nINT=%f', ahat(3), ahat(4), app.fit.error, INTy);
            end
          end

        %===================================================
        case app.pbFitLW_G % Gaussian
          if app.tbDestDerivative.Value
            predicted = @(a, xdata) abs(a(1))*lshape(xdata, abs(a(2)), abs(a(3)), 1, 1); % Gaussian
            if app.eLV_Gauss.Value ~=0
              lw_estimate = app.eLV_Gauss.Value;
            end

            [~,idxmax] = max(yyy);
            [~,idxmin] = min(yyy);
            x0_estimate = xxx(floor((idxmax+idxmin)/2));
            opts = optimset('Display','off');
            a = [lw_estimate*(max(yyy)-min(yyy)), x0_estimate, lw_estimate];
            [ahat] = lsqcurvefit(predicted,a,xxx,yyy,[],[],opts);
            [ahat] = lsqcurvefit(predicted,ahat,xxx,yyy,[],[],opts);
            ahat = abs(ahat);

            app.fit.ax = ax;
            app.fit.ax.x = xxx;
            app.fit.y = predicted(ahat, xxx);
            app.fit.error = std(yyy-app.fit.y);
            INTy = app.CalculateIntegral(xxx, a(1)*lshape(xxx, ahat(2), ahat(3), 0));
            app.eResInt.Value = sprintf('LW-G = %5.4f\nSD=%f\nINT=%f', ahat(3), app.fit.error, INTy);
            % INTx = linspace(ahat(2)-ahat(3)*10, ahat(2)+ahat(3)*10, 1024);
            % app.CalculateIntegral(INTx, lshape(INTx, ahat(2), ahat(3), 0) <- always one for lshape
          elseif app.tbDestAbsorption.Value
            predicted = @(a, xdata) abs(a(1))*lshape(xdata, a(2), a(3), 0, 1); % Gaussian

            amp_estimate = app.CalculateIntegral(xxx,yyy);
            [~,idxmax] = max(yyy);
            x0_estimate = xxx(idxmax);
            opts = optimset('Display','off');
            a = [amp_estimate, x0_estimate, lw_estimate];
            [ahat] = lsqcurvefit(predicted,a,xxx,yyy,[],[],opts);
            [ahat] = lsqcurvefit(predicted,ahat,xxx,yyy,[],[],opts);
            ahat = abs(ahat);

            app.fit.ax = ax;
            app.fit.ax.x = xxx;
            app.fit.y = predicted(ahat, xxx);
            app.fit.error = std(yyy-app.fit.y);
            app.eResInt.Value = sprintf('LW-G = %5.4f\nSD=%f\nINT=%f', ahat(3), app.fit.error, abs(a(1)));
          end

          %===================================================
        case app.pbFitLW_Mod

          % WORK IN PROGRESS
          spin_probe = app.eSpinProbe.Value;
          [~, shf_pars] = cw_shf_model(spin_probe);
          [pattern, resolution] = cw_shf(shf_pars);
          Bshf = (1:length(pattern))' * resolution; Bshf = Bshf - mean(Bshf);
          predicted = @(a, xdata) simple_fit(app, a, xdata, Bshf, pattern, is_src_derivative);

          opts = optimset('Display','off');
          [ahat] = lsqcurvefit(predicted,[max(yyy), x_estimate, lw_estimate/2],xx,yy,[],[],opts);
          [ahat] = lsqcurvefit(predicted,ahat,xxx,yyy,[],[],opts);

          app.fit.ax = ax;
          app.fit.x = ax.x;
          app.fit.y = predicted(ahat, ax.x);
          the_error = std(yyy-handles.fit.y);
          app.eResInt.Value = sprintf('LW-L = %5.4f\nSD=%5.4f', abs(ahat(3)), the_error);


          %===================================================
        case app.pbFitLWHH

          if app.tbDestAbsorption.Value
            % locate maximum
            [mm,pidx] = max(yyy);

            for ii=pidx:-1:1
              if yyy(ii) < mm/2
                lpidx=ii; break;
              end
            end
            for ii=pidx:length(ax.x)
              if yyy(ii) < mm/2
                rpidx=ii; break;
              end
            end

            INTy = app.CalculateIntegral(xxx, yyy);
            app.eResInt.Value = sprintf('LWhh = %5.4f\nINT=%f', xx(rpidx)-xx(lpidx), INTy);
          else
            app.eResInt.Value = 'Not a direct detected spectra';
          end

          %===================================================
        case app.pbFitLWpp

          if app.tbDestDerivative.Value
            % locate maximum
            [~,lpidx] = min(yyy);
            [~,rpidx] = max(yyy);
            INTy = app.CalculateIntegral(xxx, yyy);
            app.eResInt.Value = sprintf('LWpp = %5.4f\nINT=%f', abs(xxx(rpidx)-xxx(lpidx)), INTy);
          else
            app.eResInt.Value = 'Not a derivative.';
          end

      end

      app.BigProcessingFunction();

    end

    % Value changed function: pbIntegral
    function pbIntegral_Callback(app, event)

      [ax, yy] = app.GetData();
      xx = ax.x;

      if app.cbFullIntRange.Value
        xxx = xx;
        yyy = real(yy);
      else
        xxx = xx(app.area_evaluate);
        yyy = real(yy(app.area_evaluate));
      end
      INTy = app.CalculateIntegral(xxx, yyy);
      app.eResInt.Value = sprintf('INT=%f', INTy);
      
    end

    % Value changed function: tbDestAbsorption, tbDestDerivative, 
    % ...and 1 other component
    function tbDest_Callback(app, event)
      switch event.Source
        case app.tbDestAbsorption
          app.tbDestAbsorption.Value = true;
          app.tbDestDerivative.Value = false;
          app.tbDestIntegral.Value = false;
        case app.tbDestDerivative
          app.tbDestAbsorption.Value = false;
          app.tbDestDerivative.Value = true;
          app.tbDestIntegral.Value = false;
        otherwise
          app.tbDestAbsorption.Value = false;
          app.tbDestDerivative.Value = false;
          app.tbDestIntegral.Value = true;
      end

      app.fit = [];
      app.BigProcessingFunction();
    end

    % Value changed function: tbFilter1, tbFilter2, tbFilter3, 
    % ...and 2 other components
    function tbFilterOn_Callback(app, event)
      switch event.Source
        case app.tbFilter1
          app.tbFilter1.Value = true;
          app.tbFilter2.Value = false;
          app.tbFilter3.Value = false;
          app.tbFilter4.Value = false;
        case app.tbFilter2
          app.tbFilter1.Value = false;
          app.tbFilter2.Value = true;
          app.tbFilter3.Value = false;
          app.tbFilter4.Value = false;
        case app.tbFilter3
          app.tbFilter1.Value = false;
          app.tbFilter2.Value = false;
          app.tbFilter3.Value = true;
          app.tbFilter4.Value = false;
        case app.tbFilter4
          app.tbFilter1.Value = false;
          app.tbFilter2.Value = false;
          app.tbFilter3.Value = false;
          app.tbFilter4.Value = true;
      end

      app.BigProcessingFunction();

    end

    % Value changed function: tbSrcOutput, tbSrcProcSource, tbSrcSource
    function tbSrcOutput_Callback(app, event)
      switch event.Source
        case app.tbSrcSource
          app.tbSrcSource.Value = true;
          app.tbSrcProcSource.Value = false;
          app.tbSrcOutput.Value = false;
        case app.tbSrcProcSource
          app.tbSrcSource.Value = false;
          app.tbSrcProcSource.Value = true;
          app.tbSrcOutput.Value = false;
        otherwise
          app.tbSrcSource.Value = false;
          app.tbSrcProcSource.Value = false;
          app.tbSrcOutput.Value = true;
      end

      app.BigProcessingFunction();
    end

    % Value changed function: tbSrcAbsorption, tbSrcDerivative
    function tbSrc_Callback(app, event)
      switch event.Source
        case app.tbSrcAbsorption
          app.tbSrcAbsorption.Value = true;
          app.tbSrcDerivative.Value = false;
        otherwise
          app.tbSrcAbsorption.Value = false;
          app.tbSrcDerivative.Value = true;
      end
      app.BigProcessingFunction();
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      try app.KazanViewer.MakePlugins('close', app); catch; end
      delete(app)
    end

    % Value changed function: cbApplyLin4Integration, cbBaselineShow, 
    % ...and 8 other components
    function MajorUpdateCallback(app, event)
      app.BigProcessingFunction();
    end

    % Value changed function: pbPhase_x01, pbPhase_x1, pbPhase_x10
    function pbPhaseStep_Callback(app, event)
      switch event.Source
        case app.pbPhase_x01
          app.pbPhase_x01.Value = true;
          app.pbPhase_x1.Value = false;
          app.pbPhase_x10.Value = false;
          app.ePhase.Step = 0.01;
        case app.pbPhase_x10
          app.pbPhase_x01.Value = false;
          app.pbPhase_x1.Value = false;
          app.pbPhase_x10.Value = true;
          app.ePhase.Step = 10;
        otherwise
          app.pbPhase_x01.Value = false;
          app.pbPhase_x1.Value = true;
          app.pbPhase_x10.Value = false;
          app.ePhase.Step = 1;
      end
    end

    % Value changed function: pbBase_0, pbBase_1, pbBase_2, pbBase_3, 
    % ...and 2 other components
    function cbSelectBaselineCallback(app, event)
      switch event.Source
        case app.pbBase_1
          app.pbBase_0.Value = false;
          app.pbBase_1.Value = true;
          app.pbBase_2.Value = false;
          app.pbBase_3.Value = false;
          app.pbBase_4.Value = false;
          app.pbBase_4.Value = false;
          app.lbPars.Items = {'f = a', 'a = 0'};
        case app.pbBase_2
          app.pbBase_0.Value = false;
          app.pbBase_1.Value = false;
          app.pbBase_2.Value = true;
          app.pbBase_3.Value = false;
          app.pbBase_4.Value = false;
          app.pbBase_4.Value = false;
          app.lbPars.Items = {'f = a*x + b', 'a = 0', 'b = 0 '};
        case app.pbBase_3
          app.pbBase_0.Value = false;
          app.pbBase_1.Value = false;
          app.pbBase_2.Value = false;
          app.pbBase_3.Value = true;
          app.pbBase_4.Value = false;
          app.pbBase_4.Value = false;
        case app.pbBase_4
          app.pbBase_0.Value = false;
          app.pbBase_1.Value = false;
          app.pbBase_2.Value = false;
          app.pbBase_3.Value = false;
          app.pbBase_4.Value = true;
          app.pbBase_4.Value = false;
        case app.pbBase_5
          app.pbBase_0.Value = true;
          app.pbBase_1.Value = false;
          app.pbBase_2.Value = false;
          app.pbBase_3.Value = false;
          app.pbBase_4.Value = false;
          app.pbBase_4.Value = true;
        otherwise
          app.pbBase_0.Value = true;
          app.pbBase_1.Value = false;
          app.pbBase_2.Value = false;
          app.pbBase_3.Value = false;
          app.pbBase_4.Value = false;
          app.pbBase_4.Value = false;
          app.lbPars.Items = {};
      end

    end

    % Value changed function: ddStepSize
    function ddStepSizeValueChanged(app, event)
      app.pmPars.Step = app.ddStepSize.Value;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Name = 'cwplugin';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1_SizeChangedFcn, true);
      app.figure1.Tag = 'figure1';
      app.GridLayout = uigridlayout(app.figure1);
      app.DataPanel = uipanel(app.GridLayout);
      app.DataPanel.Title = 'Data';
      app.GridLayout2 = uigridlayout(app.DataPanel);
      app.tbSrcOutput = uibutton(app.GridLayout2, 'state');
      app.tbSrcOutput.ValueChangedFcn = createCallbackFcn(app, @tbSrcOutput_Callback, true);
      app.tbSrcOutput.Tag = 'tbSrcOutput';
      app.tbSrcOutput.Text = 'Output';
      app.tbSrcSource = uibutton(app.GridLayout2, 'state');
      app.tbSrcSource.ValueChangedFcn = createCallbackFcn(app, @tbSrcOutput_Callback, true);
      app.tbSrcSource.Tag = 'tbSrcSource';
      app.tbSrcSource.Text = 'Source';
      app.tbSrcSource.Value = true;
      app.tbSrcProcSource = uibutton(app.GridLayout2, 'state');
      app.tbSrcProcSource.ValueChangedFcn = createCallbackFcn(app, @tbSrcOutput_Callback, true);
      app.tbSrcProcSource.Text = 'Proc. Source';
      app.tbSrcProcSource.Value = true;
      app.InputTypePanel = uipanel(app.GridLayout);
      app.InputTypePanel.Title = 'Input Type';
      app.GridLayout3 = uigridlayout(app.InputTypePanel);
      app.tbSrcAbsorption = uibutton(app.GridLayout3, 'state');
      app.tbSrcAbsorption.ValueChangedFcn = createCallbackFcn(app, @tbSrc_Callback, true);
      app.tbSrcAbsorption.Tag = 'tbSrcAb';
      app.tbSrcAbsorption.Text = 'Absorption';
      app.tbSrcAbsorption.Value = true;
      app.tbSrcDerivative = uibutton(app.GridLayout3, 'state');
      app.tbSrcDerivative.ValueChangedFcn = createCallbackFcn(app, @tbSrc_Callback, true);
      app.tbSrcDerivative.Tag = 'tbSrcDer';
      app.tbSrcDerivative.Text = 'Derivative';
      app.OutputTypePanel = uipanel(app.GridLayout);
      app.OutputTypePanel.Title = 'Output Type';
      app.GridLayout4 = uigridlayout(app.OutputTypePanel);
      app.tbDestIntegral = uibutton(app.GridLayout4, 'state');
      app.tbDestIntegral.ValueChangedFcn = createCallbackFcn(app, @tbDest_Callback, true);
      app.tbDestIntegral.Tag = 'tbDestInt';
      app.tbDestIntegral.Text = 'Integral';
      app.tbDestAbsorption = uibutton(app.GridLayout4, 'state');
      app.tbDestAbsorption.ValueChangedFcn = createCallbackFcn(app, @tbDest_Callback, true);
      app.tbDestAbsorption.Tag = 'tbDestAb';
      app.tbDestAbsorption.Text = 'Absorption';
      app.tbDestAbsorption.Value = true;
      app.tbDestDerivative = uibutton(app.GridLayout4, 'state');
      app.tbDestDerivative.ValueChangedFcn = createCallbackFcn(app, @tbDest_Callback, true);
      app.tbDestDerivative.Tag = 'tbDestDer';
      app.tbDestDerivative.Text = 'Derivative';
      app.Baselineprocessingstep1Panel = uipanel(app.GridLayout);
      app.Baselineprocessingstep1Panel.Title = 'Baseline (processing step 1)';
      app.GridLayout9 = uigridlayout(app.Baselineprocessingstep1Panel);
      app.pbBase_5 = uibutton(app.GridLayout9, 'state');
      app.pbBase_5.ValueChangedFcn = createCallbackFcn(app, @cbSelectBaselineCallback, true);
      app.pbBase_5.Tag = 'pb5';
      app.pbBase_5.Text = '5';
      app.pbBase_4 = uibutton(app.GridLayout9, 'state');
      app.pbBase_4.ValueChangedFcn = createCallbackFcn(app, @cbSelectBaselineCallback, true);
      app.pbBase_4.Tag = 'pb4';
      app.pbBase_4.Text = '4';
      app.pbBase_3 = uibutton(app.GridLayout9, 'state');
      app.pbBase_3.ValueChangedFcn = createCallbackFcn(app, @cbSelectBaselineCallback, true);
      app.pbBase_3.Tag = 'pb3';
      app.pbBase_3.Text = '3';
      app.pbBase_2 = uibutton(app.GridLayout9, 'state');
      app.pbBase_2.ValueChangedFcn = createCallbackFcn(app, @cbSelectBaselineCallback, true);
      app.pbBase_2.Tag = 'pb2';
      app.pbBase_2.Text = '2';
      app.pbBase_1 = uibutton(app.GridLayout9, 'state');
      app.pbBase_1.ValueChangedFcn = createCallbackFcn(app, @cbSelectBaselineCallback, true);
      app.pbBase_1.Tag = 'pb1';
      app.pbBase_1.Text = '1';
      app.pbBase_0 = uibutton(app.GridLayout9, 'state');
      app.pbBase_0.ValueChangedFcn = createCallbackFcn(app, @cbSelectBaselineCallback, true);
      app.pbBase_0.Tag = 'pb0';
      app.pbBase_0.Text = '-';
      app.pbBaseline = uibutton(app.GridLayout9, 'state');
      app.pbBaseline.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.pbBaseline.Tag = 'pbBaseline';
      app.pbBaseline.Text = 'Use';
      app.lbPars = uilistbox(app.GridLayout9);
      app.lbPars.Items = {'f = a*x + b', 'a = 0', 'b = 0 '};
      app.lbPars.ValueChangedFcn = createCallbackFcn(app, @lbPars_Callback, true);
      app.lbPars.Tag = 'lbPars';
      app.lbPars.Value = 'f = a*x + b';
      app.text15 = uilabel(app.GridLayout9);
      app.text15.Tag = 'text15';
      app.text15.Text = 'Remove';
      app.btnRemoveFitRange = uibutton(app.GridLayout9, 'push');
      app.btnRemoveFitRange.ButtonPushedFcn = createCallbackFcn(app, @btnAddFitRange_Callback, true);
      app.btnRemoveFitRange.Tag = 'btnRemoveFitRange';
      app.btnRemoveFitRange.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.btnRemoveFitRange.Text = '';
      app.text14 = uilabel(app.GridLayout9);
      app.text14.Tag = 'text14';
      app.text14.Text = 'Add';
      app.btnAddFitRange = uibutton(app.GridLayout9, 'push');
      app.btnAddFitRange.ButtonPushedFcn = createCallbackFcn(app, @btnAddFitRange_Callback, true);
      app.btnAddFitRange.Tag = 'btnAddFitRange';
      app.btnAddFitRange.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.btnAddFitRange.Text = '';
      app.text16 = uilabel(app.GridLayout9);
      app.text16.Tag = 'text16';
      app.text16.Text = 'Range';
      app.btnFit = uibutton(app.GridLayout9, 'push');
      app.btnFit.ButtonPushedFcn = createCallbackFcn(app, @btnFit_Callback, true);
      app.btnFit.Tag = 'btnFit';
      app.btnFit.Text = 'Fit';
      app.pmPars = uispinner(app.GridLayout9);
      app.pmPars.ValueChangedFcn = createCallbackFcn(app, @ePars_Callback, true);
      app.ddStepSize = uidropdown(app.GridLayout9);
      app.ddStepSize.Items = {'1e-6', '1e-5', '1e-4', '1e-3', '1e-2', '0.1', '1', '10', '100', '1e+3', '1e+4', '1e+5', '1e+6'};
      app.ddStepSize.ItemsData = {'1e-6', '1e-5', '1e-4', '1e-3', '1e-2', '0.1', '1', '10', '100', '1e+3', '1e+4', '1e+5', '1e+6'};
      app.ddStepSize.ValueChangedFcn = createCallbackFcn(app, @ddStepSizeValueChanged, true);
      app.ddStepSize.Value = '0.1';
      app.ePars = uieditfield(app.GridLayout9, 'text');
      app.ePars.ValueChangedFcn = createCallbackFcn(app, @ePars_Callback, true);
      app.ePars.Editable = 'off';
      app.ePars.Enable = 'off';
      app.cbBaselineShow = uicheckbox(app.GridLayout9);
      app.cbBaselineShow.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbBaselineShow.Text = 'Show';
      app.cbApplyLin4Integration = uicheckbox(app.GridLayout9);
      app.cbApplyLin4Integration.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbApplyLin4Integration.Tooltip = {'Apply linear fit second time if integration is used'};
      app.cbApplyLin4Integration.Text = 'Apply linear b-line before integration';
      app.Phaseprocessingstep2Panel = uipanel(app.GridLayout);
      app.Phaseprocessingstep2Panel.Title = 'Phase (processing step 2)';
      app.GridLayout5 = uigridlayout(app.Phaseprocessingstep2Panel);
      app.pbPhase_x01 = uibutton(app.GridLayout5, 'state');
      app.pbPhase_x01.ValueChangedFcn = createCallbackFcn(app, @pbPhaseStep_Callback, true);
      app.pbPhase_x01.Tag = 'pb01';
      app.pbPhase_x01.Text = 'x0.1';
      app.pbPhase_x1 = uibutton(app.GridLayout5, 'state');
      app.pbPhase_x1.ValueChangedFcn = createCallbackFcn(app, @pbPhaseStep_Callback, true);
      app.pbPhase_x1.Text = 'x1';
      app.pbPhase_x10 = uibutton(app.GridLayout5, 'state');
      app.pbPhase_x10.ValueChangedFcn = createCallbackFcn(app, @pbPhaseStep_Callback, true);
      app.pbPhase_x10.Tag = 'pb10';
      app.pbPhase_x10.Text = 'x10';
      app.pbPhase = uibutton(app.GridLayout5, 'state');
      app.pbPhase.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.pbPhase.Tag = 'pbPhase';
      app.pbPhase.Text = 'Phase';
      app.ePhase = uispinner(app.GridLayout5);
      app.ePhase.Limits = [-360 360];
      app.ePhase.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbUseDispersion = uicheckbox(app.GridLayout5);
      app.cbUseDispersion.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbUseDispersion.Text = 'Use Dispersion';
      app.cbUseDispersion.Value = true;
      app.Filterprocessingstep3Panel = uipanel(app.GridLayout);
      app.Filterprocessingstep3Panel.Title = 'Filter (processing step 3)';
      app.GridLayout6 = uigridlayout(app.Filterprocessingstep3Panel);
      app.tbFilterOn = uibutton(app.GridLayout6, 'state');
      app.tbFilterOn.ValueChangedFcn = createCallbackFcn(app, @tbFilterOn_Callback, true);
      app.tbFilterOn.Tag = 'tbFilterOn';
      app.tbFilterOn.Text = 'Filter';
      app.tbFilter1 = uibutton(app.GridLayout6, 'state');
      app.tbFilter1.ValueChangedFcn = createCallbackFcn(app, @tbFilterOn_Callback, true);
      app.tbFilter1.Tag = 'tbFilter1';
      app.tbFilter1.Text = '1';
      app.tbFilter2 = uibutton(app.GridLayout6, 'state');
      app.tbFilter2.ValueChangedFcn = createCallbackFcn(app, @tbFilterOn_Callback, true);
      app.tbFilter2.Tag = 'tbFilter2';
      app.tbFilter2.Text = '2';
      app.tbFilter3 = uibutton(app.GridLayout6, 'state');
      app.tbFilter3.ValueChangedFcn = createCallbackFcn(app, @tbFilterOn_Callback, true);
      app.tbFilter3.Tag = 'tbFilter3';
      app.tbFilter3.Text = '3';
      app.tbFilter4 = uibutton(app.GridLayout6, 'state');
      app.tbFilter4.ValueChangedFcn = createCallbackFcn(app, @tbFilterOn_Callback, true);
      app.tbFilter4.Tag = 'tbFilter4';
      app.tbFilter4.Text = '4';
      app.LinefittingPanel = uipanel(app.GridLayout);
      app.LinefittingPanel.Title = 'Line fitting';
      app.GridLayout8 = uigridlayout(app.LinefittingPanel);
      app.eResInt = uitextarea(app.GridLayout8);
      app.eResInt.Tag = 'eResInt';
      app.eResInt.Value = {'Edit Text'};
      app.pbFitLW_Mod = uibutton(app.GridLayout8, 'push');
      app.pbFitLW_Mod.ButtonPushedFcn = createCallbackFcn(app, @pbFitLW_Callback, true);
      app.pbFitLW_Mod.Tag = 'pbFitLW_Mod';
      app.pbFitLW_Mod.Tooltip = 'Model based';
      app.pbFitLW_Mod.Text = 'LW-Md';
      app.pbFitLW_V = uibutton(app.GridLayout8, 'push');
      app.pbFitLW_V.ButtonPushedFcn = createCallbackFcn(app, @pbFitLW_Callback, true);
      app.pbFitLW_V.Tag = 'pbFitLW_V';
      app.pbFitLW_V.Tooltip = 'Voightian';
      app.pbFitLW_V.Text = 'LW-V';
      app.pbFitLW_L = uibutton(app.GridLayout8, 'push');
      app.pbFitLW_L.ButtonPushedFcn = createCallbackFcn(app, @pbFitLW_Callback, true);
      app.pbFitLW_L.Tag = 'pbFitLW_L';
      app.pbFitLW_L.Tooltip = 'Lorentzian';
      app.pbFitLW_L.Text = 'LW-L';
      app.pbIntegral = uibutton(app.GridLayout8, 'state');
      app.pbIntegral.ValueChangedFcn = createCallbackFcn(app, @pbIntegral_Callback, true);
      app.pbIntegral.Tag = 'pbIntegral';
      app.pbIntegral.Text = 'Integral';
      app.cbShift = uicheckbox(app.GridLayout8);
      app.cbShift.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbShift.Tag = 'cbShift';
      app.cbShift.Text = 'Shift 2 min';
      app.cbSquare = uicheckbox(app.GridLayout8);
      app.cbSquare.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbSquare.Tag = 'cbSquare';
      app.cbSquare.Text = 'Magnitude';
      app.pbFitLW_G = uibutton(app.GridLayout8, 'push');
      app.pbFitLW_G.ButtonPushedFcn = createCallbackFcn(app, @pbFitLW_Callback, true);
      app.pbFitLW_G.Tag = 'pbFitLW_G';
      app.pbFitLW_G.Tooltip = 'Gaussian';
      app.pbFitLW_G.Text = 'LW-G';
      app.pbFitLWHH = uibutton(app.GridLayout8, 'push');
      app.pbFitLWHH.ButtonPushedFcn = createCallbackFcn(app, @pbFitLW_Callback, true);
      app.pbFitLWHH.Tag = 'pbFitLWHH';
      app.pbFitLWHH.Tooltip = 'FWHH';
      app.pbFitLWHH.Text = 'LWhh';
      app.pbFitLWpp = uibutton(app.GridLayout8, 'push');
      app.pbFitLWpp.ButtonPushedFcn = createCallbackFcn(app, @pbFitLW_Callback, true);
      app.pbFitLWpp.Tag = 'pbFitLWpp';
      app.pbFitLWpp.Tooltip = 'peak-peak';
      app.pbFitLWpp.Text = 'LWpp';
      app.eSpinProbe = uidropdown(app.GridLayout8);
      app.eSpinProbe.Items = {'OX063', 'OX071'};
      app.eSpinProbe.Tag = 'eSpinProbe';
      app.eSpinProbe.Value = 'OX063';
      app.GaussLabel = uilabel(app.GridLayout8);
      app.GaussLabel.Text = 'Gauss';
      app.eLV_Gauss = uieditfield(app.GridLayout8, 'numeric');
      app.eLV_Gauss.Limits = [0 Inf];
      app.LorenzLabel = uilabel(app.GridLayout8);
      app.LorenzLabel.Text = 'Lorenz';
      app.eLV_Lorentz = uieditfield(app.GridLayout8, 'numeric');
      app.eLV_Lorentz.Limits = [0 Inf];
      app.Guess0autoLabel = uilabel(app.GridLayout8);
      app.Guess0autoLabel.Text = {'Guess'; '(0 - auto)'};
      app.cbFixGauss = uicheckbox(app.GridLayout8);
      app.cbFixGauss.Text = 'Fix';
      app.IntegrationandfittingrangePanel = uipanel(app.GridLayout);
      app.IntegrationandfittingrangePanel.Title = 'Integration and fitting range';
      app.GridLayout7 = uigridlayout(app.IntegrationandfittingrangePanel);
      app.btnGetEvaluationRange = uibutton(app.GridLayout7, 'push');
      app.btnGetEvaluationRange.ButtonPushedFcn = createCallbackFcn(app, @btnAddFitRange_Callback, true);
      app.btnGetEvaluationRange.Tag = 'btnGetMinRange';
      app.btnGetEvaluationRange.Icon = fullfile(pathToMLAPP, 'pbCross.png');
      app.btnGetEvaluationRange.Text = '';
      app.eFirstPos = uieditfield(app.GridLayout7, 'text');
      app.eFirstPos.ValueChangedFcn = createCallbackFcn(app, @eFirstPos_Callback, true);
      app.eFirstPos.Tag = 'eFirstPos';
      app.eFirstPos.Value = '-';
      app.cbShowIntRange = uicheckbox(app.GridLayout7);
      app.cbShowIntRange.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbShowIntRange.Tag = 'cbShowIntRange';
      app.cbShowIntRange.Text = 'Show Range';
      app.cbFullIntRange = uicheckbox(app.GridLayout7);
      app.cbFullIntRange.ValueChangedFcn = createCallbackFcn(app, @MajorUpdateCallback, true);
      app.cbFullIntRange.Tag = 'cbFullIntRange';
      app.cbFullIntRange.Text = 'Full Range';
      app.cbFullIntRange.Value = true;
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = CWplugin3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end