classdef CompBox3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1          matlab.ui.Figure
    mProces          matlab.ui.container.Menu
    mFittAllToFirst  matlab.ui.container.Menu
    mNormSlices      matlab.ui.container.Menu
    mNormalize       matlab.ui.container.Menu
    mPreserveZero    matlab.ui.container.Menu
    mWaterfall       matlab.ui.container.Menu
    mWaterfall2D     matlab.ui.container.Menu
    mFittAddToOut    matlab.ui.container.Menu
    m1Dto2D          matlab.ui.container.Menu
    m2Dto1D          matlab.ui.container.Menu
    mSortIndex       matlab.ui.container.Menu
    mProcAddAll      matlab.ui.container.Menu
    uimenu1          matlab.ui.container.Menu
    mProcessSource   matlab.ui.container.Menu
    GridLayout       matlab.ui.container.GridLayout
    txtColor         matlab.ui.control.Label
    eZoomY           matlab.ui.control.Spinner
    eZoomX           matlab.ui.control.Spinner
    eShiftY          matlab.ui.control.Spinner
    eShiftX          matlab.ui.control.Spinner
    bDeleteAll       matlab.ui.control.Button
    bDelete          matlab.ui.control.Button
    bInvertY         matlab.ui.control.Button
    pbChange         matlab.ui.control.Button
    bAdd             matlab.ui.control.Button
    eSecondDim       matlab.ui.control.EditField
    text9            matlab.ui.control.Label
    bClearZoom       matlab.ui.control.Button
    ddUnits          matlab.ui.control.DropDown
    text3            matlab.ui.control.Label
    text2            matlab.ui.control.Label
    ddZoomX          matlab.ui.control.DropDown
    ddZoomY          matlab.ui.control.DropDown
    lblShiftX        matlab.ui.control.Label
    lblShiftY        matlab.ui.control.Label
    bClearShift      matlab.ui.control.Button
    pbNorm           matlab.ui.control.Button
    pbToFirst        matlab.ui.control.Button
    ddShiftY         matlab.ui.control.DropDown
    ddShiftX         matlab.ui.control.DropDown
    pbColor          matlab.ui.control.Button
    pbTitle          matlab.ui.control.Button
    ddTraces         matlab.ui.control.DropDown
  end


  properties (Access = private)
    KazanViewer % Description
    trace % Description
    Color % Description
  end

  properties (Access = public)
    guid % Description
  end

  methods (Access = public)
    function results = FileUpdate(app)
    end
  end
  
  %----------------------------------------------------------------------
  methods (Access = private)
    function PlotThis(app)
      out = {};

      for kk = 1:length(app.trace)
        the_trace = app.trace{kk};
        if app.mProcessSource.Checked
          %           [the_trace.Data.y, the_trace.Data.ax] = processing(the_trace.Data.y, the_trace.Data.ax);
        else
          the_trace.Data.ax.filt = '';
          the_trace.Data.ax.diff = '';
        end
        out{kk}.ax = the_trace.Data.ax;
        out{kk}.ax.x = the_trace.Data.ax.x*the_trace.ZoomX;
        switch app.ddUnits.Value
          case 'linear'
            zoom = the_trace.ZoomY;
          case 'dB'
            zoom = 10^(the_trace.ZoomY/10);
        end
        if safeget(the_trace, 'change2D', 0)
          sh = safeget(the_trace, 'ShiftY2D', 0);
          zm = safeget(the_trace, 'ZoomY2D', 1);
          out{kk}.y = (the_trace.Data.y*zoom*the_trace.YSign - sh).*zm;
        else
          out{kk}.y = the_trace.Data.y*zoom*the_trace.YSign;
        end

        out{kk}.ax.s = the_trace.ShiftY;
        out{kk}.ax.dx= the_trace.ShiftX;
        out{kk}.title = ['Trace', num2str(kk)];
      end
      app.KazanViewer.SetData('output', out);
    end

    %----------------------------------------------------------------------
    function UpdateInterface(app)
      if isempty(app.trace)
        app.eShiftX.Value = 0;
        app.eShiftY.Value = 0;
        app.eZoomX.Value = 1;
        app.eZoomY.Value = 1;
        app.txtColor.BackgroundColor = [0.75,0.75,0.75];
        app.eSecondDim.Value = '-';
      else
        num = app.ddTraces.Value;
        app.eShiftX.Value = (app.trace{num}.ShiftX);
        app.eShiftY.Value = (app.trace{num}.ShiftY);
        app.eZoomX.Value = (app.trace{num}.ZoomX);
        app.eZoomY.Value = (app.trace{num}.ZoomY);
        app.txtColor.BackgroundColor = app.trace{num}.Data.ax.Color;
        app.eSecondDim.Value = num2str(app.trace{num}.Data.ax.y');
      end
    end

    %----------------------------------------------------------------------
    function UpdateList(app)
      if ~isempty(app.trace)
        Str = {};
        for ii=1:length(app.trace)
          Str{ii} = [num2str(ii),':',app.trace{ii}.Name];
        end
        app.ddTraces.Items = Str;
        app.ddTraces.ItemsData = 1:length(app.ddTraces.Items);
      else
        app.ddTraces.Items = {'-------'};
      end
    end

    %----------------------------------------------------------------------
    function mDisChanges_Callback(app)
      for kk = 1:length(app.trace)
        app.trace{kk}.ShiftY2D = 0;
        app.trace{kk}.ZoomY2D = 1;
        app.trace{kk}.ShiftX2D = 0;
        app.trace{kk}.ZoomX2D = 1;
      end

      if ~isempty(safeget(app, 'mDisChandes', []))
        set(app.mDisChandes, 'Visible', 'off');
      end
      app.PlotThis();
    end

    %----------------------------------------------------------------------
    function [xx, yy] = preprocess(app, trace)

      ZoomUnits = app.ddUnits.Value;
      if contains(app.mProcessSource, 'on')
        [yy, ax] = processing(trace.Data.y, trace.Data.ax);
        xx = ax.x;
      else
        yy = trace.Data.y;
        xx = trace.Data.ax.x;
      end
      xx = xx + trace.ShiftX;

      switch ZoomUnits
        case 'linear'
          zoom = trace.ZoomY;
        case 'dB'
          zoom = 10^(trace.ZoomY/10);
      end
      if safeget(trace, 'change2D', 0)
        sh = safeget(trace, 'ShiftY2D', 0);
        zm = safeget(trace, 'ZoomY2D', 1);
        yy = (yy*zoom*trace.YSign - sh).*zm;
      else
        yy = yy*zoom*trace.YSign;
      end

      yy = yy + trace.ShiftY;
    end

    % Update components that require runtime configuration
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;

      app.Color = [0, 0, 1;...
        1, 0, 0;...
        0, 1, 0;...
        0, 0, 0;...
        1, 0, 1;...
        0, 1, 1;...
        1, 1, 0];

      app.ddShiftX.ItemsData = [10e-7, 10e-6, 10e-5, 10e-4, 0.001, 0.01, 0.1, 1, 10, 100, 1000, 1e4, 1e5, 1e6, 1e7, 1e8];
      app.ddShiftX.Value = 0.1;
      app.ddShiftY.ItemsData = [10e-7, 10e-6, 10e-5, 10e-4, 0.001, 0.01, 0.1, 1, 10, 100, 1000, 1e4, 1e5, 1e6, 1e7, 1e8];
      app.ddShiftY.Value = 0.1;

      app.ddZoomX.ItemsData = [10e-6, 10e-5, 10e-4, 0.001, 0.01, 0.1, 1, 10, 100, 1000, 1e4, 1e5, 1e6];
      app.ddZoomX.Value = 0.1;
      app.ddZoomY.ItemsData = [10e-6, 10e-5, 10e-4, 0.001, 0.01, 0.1, 1, 10, 100, 1000, 1e4, 1e5, 1e6];
      app.ddZoomY.Value = 0.1;

      app.eShiftX.Step = app.ddShiftX.Value;
      app.eShiftY.Step = app.ddShiftY.Value;
      app.eZoomX.Step = app.ddZoomX.Value;
      app.eZoomY.Step = app.ddZoomY.Value;

    end

    % Button pushed function: bAdd
    function bAdd_Callback(app, event)
      src = app.KazanViewer.GetData('source');

      % if app.process, [src.y, src.ax] = processing(src.y, src.ax);
      % else
      %     src.ax.filt = '';
      %     src.ax.diff = '';
      % end

      counter = length(app.trace);
      newtrace.Name = safeget(src, 'filename', safeget(src, 'title', ''));
      newtrace.Data = src;
      newtrace.Data.ax.Color = app.Color(mod(counter, 7)+1,:);
      if length(src.ax.y)==1 && src.ax.y(1)==0
        newtrace.Data.ax.y=counter;
      end
      newtrace.ShiftX = 0;
      newtrace.ShiftY = 0;
      newtrace.ZoomX = 1;
      newtrace.ZoomY = 1;
      newtrace.YSign = 1;
      app.trace{end+1} = newtrace;

      app.UpdateList();
      app.ddTraces.Value = length(app.ddTraces.ItemsData);

      app.UpdateInterface();
      app.PlotThis();
    end

    % Button pushed function: pbChange
    function pbChange_Callback(app, event)

      if isempty(app.trace), app.bAdd_Callback(event);
        return
      end

      src = app.KazanViewer.GetData('source');

      Num = app.ddTraces.Value;
      app.trace{Num}.Data = src;
      app.trace{Num}.Name = src.filename;
      app.trace{Num}.Data.ax.Color = app.Color(mod(Num, 7)+1,:);

      app.UpdateList();
    end

    % Button pushed function: bDelete
    function bDelete_Callback(app, event)
      if isempty(app.trace), return; end

      num = app.ddTraces.Value;
      endnum = length(app.trace);

      if endnum == 1
        app.ddTraces.Items = {'-------'};
        app.trace = {};
        return;
      end

      app.trace(num) = [];
      app.UpdateList();

      app.UpdateInterface();
      app.PlotThis();
    end

    % Button pushed function: bDeleteAll
    function bDeleteAllButtonPushed(app, event)

      app.trace = {};
      app.UpdateList();

    end

    % Menu selected function: m1Dto2D
    function m1Dto2D_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool

      Num = length(app.trace);
      if Num == 0, return; end

      Out = [];
      dim2 = [];
      xx = app.trace{1}.Data.ax.x*app.trace{1}.ZoomX + app.trace{1}.ShiftX;
      minax = xx(1);
      maxax = xx(end);
      npax = length(xx);
      axdx  = (maxax - minax)/length(app.trace{1}.Data.ax.x);
      notsameax = 0;
      for kk = 2:Num
        xx = app.trace{kk}.Data.ax.x*app.trace{kk}.ZoomX + app.trace{kk}.ShiftX;
        notsameax = notsameax + minax-xx(1) + maxax-xx(end) + npax - length(xx);
        if xx(1)   > minax, minax = xx(1); end
        if xx(end) < maxax, maxax = xx(end); end
      end
      npoints = floor((maxax - minax)/axdx + 0.5);

      if notsameax
        newax = linspace(minax, maxax, npoints)';
      else
        newax = xx(:);
      end
      for kk = 1:Num
        if app.process, [app.trace{kk}.Data.y, app.trace{kk}.Data.ax] = ...
            processing(app.trace{kk}.Data.y, app.trace{kk}.Data.ax);
        else
          app.trace{kk}.Data.ax.filt = '';
          app.trace{kk}.Data.ax.diff = '';
        end
        x = app.trace{kk}.Data.ax.x*app.trace{kk}.ZoomX + app.trace{kk}.ShiftX;
        y = app.trace{kk}.Data.y*app.trace{kk}.ZoomY*app.trace{kk}.YSign + app.trace{kk}.ShiftY;
        %   if notsameax
        Out(:, kk) = spline(x, y, newax);
        %   else
        %       Out(:, kk) = y;
        %   end
        dim2(end+1,1) = app.trace{kk}.Data.ax.y;
      end

      ax = app.trace{1}.Data.ax;
      new_trace.Data = {};
      new_trace.Data.ax = ax;
      new_trace.Data.ax.x = newax;
      new_trace.Data.ax.y = dim2;
      new_trace.Data.ax.ylabel = 'trace,';
      new_trace.Data.ax.c = 'b';
      new_trace.Data.ax.s = 0;
      new_trace.Data.ax.dx = 0;
      new_trace.Data.ax.filt = '';
      new_trace.Data.ax.diff = '';
      new_trace.Data.ax.Color = app.Color(1,:);

      new_trace.Data.y = Out;
      new_trace.Data.title = 'Out';
      new_trace.ShiftX = 0;
      new_trace.ShiftY = 0;
      new_trace.ZoomX = 1;
      new_trace.ZoomY = 1;
      new_trace.YSign = 1;
      new_trace.Name = safeget(app.trace{1}, 'Name', '');
      app.trace = {new_trace};

      set(app.ddTraces, 'String', 'Group', 'Value', 1);

      app.UpdateInterface();
      app.PlotThis();
    end

    % Menu selected function: m2Dto1D
    function m2Dto1D_Callback(app, event)


      if length(app.trace) < 1, return; end

      num = app.ddTraces.Value;
      the_trace = app.trace{num};
      DelCurr_Callback(hObject, eventdata, app);

      counter  = size(the_trace.Data.y,2);
      for ii=1:counter
        app.trace{end+1}.Data.ax = the_trace.Data.ax;
        app.trace{end}.Data.y  = the_trace.Data.y(:,ii);
        app.trace{end}.Data.ax.y = the_trace.Data.ax.y(ii);
        app.trace{end}.Data.title = [the_trace.Data.ax.title,'::',num2str(ii)];
        app.trace{end}.Data.ax.Color = app.Color(mod(ii,7)+1,:);
        app.trace{end}.ShiftX = the_trace.ShiftX;
        app.trace{end}.ShiftY = the_trace.ShiftY;
        app.trace{end}.ZoomX  = the_trace.ZoomX;
        app.trace{end}.ZoomY  = the_trace.ZoomY;
        app.trace{end}.YSign  = the_trace.YSign;
        app.trace{end}.Name   = [num2str(endnum+ii),':',the_trace.Data.ax.title,'::',num2str(ii)];
      end

      app.UpdateList();
      app.UpdateInterface();
      app.PlotThis();
      return
    end

    % Menu selected function: mFittAddToOut
    function mFittAddToOut_Callback(app, event)

      kk = get(app.ddTraces, 'Value');
      the_trace = app.trace{kk};
      if app.process, [the_trace.Data.y, the_trace.Data.ax] = app.processing(the_trace.Data.y, the_trace.Data.ax);
      else
        the_trace.Data.ax.filt = '';
        the_trace.Data.ax.diff = '';
      end
      out = {};
      out{end+1}.ax = the_trace.Data.ax;
      out{end}.ax.x = the_trace.Data.ax.x*the_trace.ZoomX;
      switch app.ddUnits.Value
        case 'Norm'
          zoom = the_trace.ZoomY;
        case 'dB'
          zoom = 10^(the_trace.ZoomY/10);
      end
      out{end}.y = the_trace.Data.y*zoom*the_trace.YSign;
      out{end}.ax.s = the_trace.ShiftY;
      out{end}.ax.dx= the_trace.ShiftX;
      out{end}.title = ['Trace', num2str(kk)];

      app.KazanViewer.SetData('output', out);
    end

    % Menu selected function: mNormSlices
    function mNormSlices_Callback(app, event)


      num = app.ddTraces.Value;
      parentmenu = get(h, 'Parent');
      if isempty(safeget(app, 'mDisChandes', []))
        app.mDisChandes = uimenu(parentmenu, 'Label', 'Discard ..2D', 'Tag', 'mDisChandes', ...
          'callback', 'compbox(''mDisChanges_Callback'', guidata(gcbo))');
      else
        set(app.mDisChandes, 'Visible', 'on');
      end
      app.trace{num}.ZoomY2D = app.trace{num}.Data.y*0;
      app.trace{num}.ShiftY2D = app.trace{num}.Data.y*0;
      for slice=1:size(app.trace{num}.Data.y,2)
        maxy = max(real(app.trace{num}.Data.y(:,slice)));
        miny = min(real(app.trace{num}.Data.y(:,slice)));

        if strcmp(get(app.mPreserveZero, 'Checked'),'on')
          if maxy > 0 && miny > 0, miny=0;
          elseif maxy < 0 && miny < 0, maxy=0;
          end
        end

        switch app.ddUnits.Value
          case 'linear'
            app.trace{num}.ZoomY = 1;
          case 'dB'
            app.trace{num}.ZoomY = 0;
        end
        app.trace{num}.ShiftY = 0;

        zmy = 1/(maxy-miny);
        %app.trace{num}.Data.y(:,slice) = (app.trace{num}.Data.y(:,slice) - miny) * zmy;
        app.trace{num}.ZoomY2D(:, slice) = zmy(ones(size(app.trace{num}.Data.y, 1), 1), 1);
        app.trace{num}.ShiftY2D(:, slice) = miny(ones(size(app.trace{num}.Data.y, 1), 1), 1);
        app.trace{num}.change2D = 1;
      end

      app.UpdateInterface();
      app.PlotThis();
    end

    % Menu selected function: mNormalize
    function mNormalize_Callback(app, event)


      for num=1:length(app.trace)
        maxy = max(max(real(app.trace{num}.Data.y)));
        miny = min(min(real(app.trace{num}.Data.y)));

        zmy = 1/(maxy-miny);
        if strcmp(get(app.mPreserveZero, 'Checked'),'on')
          app.trace{num}.ShiftY = 0;
        else
          app.trace{num}.ShiftY = -miny*zmy;
        end

        switch app.ddUnits.Value
          case 'linear'
            app.trace{num}.ZoomY = zmy;
          case 'dB'
            app.trace{num}.ZoomY = log10(zmy)*10;
        end
      end

      app.UpdateInterface();
      app.PlotThis();
    end

    % Menu selected function: mProcAddAll
    function mProcAddAll_Callback(app, event)

      Num = length(app.trace);

      if Num < 2, return; end

      [xx,yy]=preprocess(app, app.trace{1});

      for kk = 2:Num
        [xx1,yy1]=preprocess(app, app.trace{kk});

        yy2 = interp1(xx1, yy1, xx);
        yy = yy + yy2;
      end

      out = {};
      out{1}.ax.x = xx;
      out{1}.y= yy;
      out{1}.title = ['Trace', num2str(kk)];

      app.KazanViewer.SetData('output', out);
    end

    % Menu selected function: mSortIndex
    function mSortIndex_Callback(app, event)

      if length(app.trace) < 2, return; end

      yy = [];
      for kk=1:length(app.trace), yy(end+1)=app.trace{kk}.Data.ax.y; end
      [~,idx] = sort(yy);

      oldtrace = app.trace;
      for kk=1:length(app.trace)
        app.trace{kk} = oldtrace{idx(kk)};
      end

      app.UpdateList();
      app.UpdateInterface();
      app.PlotThis();
    end

    % Menu selected function: mWaterfall, mWaterfall2D
    function mWaterfall_Callback(app, event)


      if isempty(app.trace), return; end

      wfc = inputdlg('wf','Enter the Waterfall parameter',1,{'1'});
      wfc = str2num(wfc{1});

      Num = length(app.trace);

      maxy = -1E19; miny = 1E19;

      for kk = 1:Num
        maxy = max([maxy,max(max(real(app.trace{kk}.Data.y.*app.trace{kk}.ZoomY+app.trace{kk}.ShiftY)))]);
        miny = min([miny,min(min(real(app.trace{kk}.Data.y.*app.trace{kk}.ZoomY+app.trace{kk}.ShiftY)))]);
      end

      wf = (maxy-miny)*wfc;

      switch event.Source
        case app.mWaterfall
          for kk = 1:Num
            app.trace{kk}.ShiftY = app.trace{kk}.ShiftY - (kk-1)*wf;
          end
        case app.mWaterfall2D
          for kk = 1:Num
            [sz1,sz2] = size(app.trace{kk}.Data.y);
            sh = [1:sz2]-1;
            %h.trace{kk}.Data.y = app.trace{kk}.Data.y - sh(ones(sz1,1),:)*wf;
            app.trace{kk}.ShiftY2D = sh(ones(sz1,1),:)*wf;
            app.trace{kk}.change2D = 1;
          end
      end

      app.UpdateInterface();
      app.PlotThis();
    end

    % Button pushed function: pbNorm
    function pbNorm_Callback(app, event)

      num = app.ddTraces.Value;

      maxy = max(max(real(app.trace{num}.Data.y)));
      miny = min(min(real(app.trace{num}.Data.y)));

      zmy = 1/(maxy-miny);
      if app.mPreserveZero.Checked
        app.trace{num}.ShiftY = 0;
      else
        app.trace{num}.ShiftY = -miny*zmy;
      end

      switch app.ddUnits.Value
        case 'linear'
          app.trace{num}.ZoomY = zmy;
        case 'dB'
          app.trace{num}.ZoomY = log10(zmy)*10;
      end

      app.UpdateInterface();
      app.PlotThis();
    end

    % Callback function: mFittAllToFirst, mPreserveZero, mProcessSource, 
    % ...and 3 other components
    function MenuPressed(app, event)

      num = app.ddTraces.Value;

      switch event.Source
        case app.pbTitle
          newtitle = inputdlg('Change Data Title', 'Data Title', 1, {app.trace{num}.Name});
          if ~isempty(newtitle)
            app.trace{num}.Name = newtitle{1};
            app.UpdateList();
          end

        case app.pbColor
          if isempty(app.trace), return; end

          Col = uisetcolor(app.trace{num}.Data.ax.Color);
          if length(Col)== 1, return; end

          app.trace{num}.Data.ax.Color = Col;

        case app.pbToFirst

          if num==1 || length(app.trace) < 2, return; end

          app.trace{num}.ZoomX = 1;
          app.trace{num}.ShiftX = 0;
          y  = app.trace{1}.Data.y;
          y1 = app.trace{num}.Data.y;
          zmy = (max(real(y))-min(real(y)))/(max(real(y1))-min(real(y1)));
          yprime = y1*zmy;
          app.trace{num}.ShiftY = min(real(y))-min(real(yprime));

          switch app.ddUnits.Value
            case 'linear'
              app.trace{num}.ZoomY = zmy;
            case 'dB'
              app.trace{num}.ZoomY = log10(zmy)*10;
          end

        case app.mFittAllToFirst

          if length(app.trace) < 2, return; end

          y  = app.trace{1}.Data.y;

          for num=2:length(app.trace)
            app.trace{num}.ZoomX = 1;
            app.trace{num}.ShiftX = 0;
            y1 = app.trace{num}.Data.y;
            if strcmp(get(app.mPreserveZero, 'Checked'),'on')
              zmy = (max(real(y))-min(real(y)))/(max(real(y1))-min(real(y1)));
              app.trace{num}.ShiftY = 0;
            else
              zmy = max(real(y))/max(real(y1));
              yprime = y1*zmy;
              app.trace{num}.ShiftY = min(real(y))-min(real(yprime));
            end

            switch app.ddUnits.Value
              case 'linear'
                app.trace{num}.ZoomY = zmy;
              case 'dB'
                app.trace{num}.ZoomY = log10(zmy)*10;
            end
          end
        case app.mProcessSource
          app.mProcessSource.Checked = ~app.mProcessSource.Checked;
        case app.mPreserveZero
          app.mPreserveZero.Checked = ~app.mPreserveZero.Checked;
      end

      app.UpdateInterface();
      app.PlotThis();

    end

    % Value changed function: ddUnits
    function ddUnits_Callback(app, event)

      for num=1:length(app.trace)
        switch app.ddUnits.Value
          case 'linear'
            app.trace{num}.ZoomY = 10^(app.trace{num}.ZoomY/10);
          case 'dB'
            app.trace{num}.ZoomY = log10(app.trace{num}.ZoomY)*10;
        end
      end

      app.UpdateInterface();
      app.PlotThis();

    end

    % Value changed function: ddTraces
    function ddTraces_Callback(app, event)
      app.UpdateInterface();
    end

    % Size changed function: figure1
    function figure1SizeChanged(app, event)
      standard_size = [70   669   370   480];
      fig_size = app.figure1.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      if ishandle(app.KazanViewer)
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.figure1, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UITextArea'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIEdit'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIDropDown'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UILabel'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UISpinner'), 'FontSize', font_size);
      end

    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      try app.KazanViewer.MakePlugins('close', app); catch; end
      delete(app);
    end

    % Value changed function: ddShiftX, ddShiftY, ddZoomX, ddZoomY
    function StepChanged(app, event)
      switch event.Source
        case app.ddShiftX
          app.eShiftX.Step = app.ddShiftX.Value;
        case app.ddShiftY
          app.eShiftY.Step = app.ddShiftY.Value;
        case app.ddZoomX
          app.eZoomX.Step = app.ddZoomX.Value;
        case app.ddZoomY
          app.eZoomY.Step = app.ddZoomY.Value;
      end

    end

    % Callback function: bClearShift, bClearZoom, bInvertY, eShiftX, 
    % ...and 3 other components
    function ValueChanged(app, event)
      if isempty(app.trace), return; end
      num = app.ddTraces.Value;

      switch event.Source
        case app.eShiftX
          app.trace{num}.ShiftX = app.eShiftX.Value;
        case app.eShiftY
          app.trace{num}.ShiftY = app.eShiftY.Value;
        case app.eZoomX
          app.trace{num}.ZoomX = app.eZoomX.Value;
        case app.eZoomY
          app.trace{num}.ZoomY = app.eZoomY.Value;
        case app.bClearShift
          app.trace{num}.ShiftX = 0;
          app.trace{num}.ShiftY = 0;
        case app.bClearZoom
          app.trace{num}.ZoomX = 1;
          app.trace{num}.ZoomY = 1;
        case app.bInvertY
          app.trace{num}.YSign = -app.trace{num}.YSign;
      end
      %       app.trace{num}.Data.ax.y = str2num(get(app.eSecondDim, 'String'))';
      app.UpdateInterface();
      app.PlotThis();
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Name = 'Comp. Box';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
      app.figure1.Tag = 'figure1';
      app.mProces = uimenu(app.figure1);
      app.mProces.Text = 'Processing';
      app.mProces.Tag = 'mProces';
      app.mFittAllToFirst = uimenu(app.mProces);
      app.mFittAllToFirst.MenuSelectedFcn = createCallbackFcn(app, @MenuPressed, true);
      app.mFittAllToFirst.Text = 'Fit All to 1st';
      app.mFittAllToFirst.Tag = 'mFittAllFst';
      app.mNormSlices = uimenu(app.mProces);
      app.mNormSlices.MenuSelectedFcn = createCallbackFcn(app, @mNormSlices_Callback, true);
      app.mNormSlices.Text = 'Normalize Current 2D';
      app.mNormSlices.Tag = 'mNormSlices';
      app.mNormalize = uimenu(app.mProces);
      app.mNormalize.MenuSelectedFcn = createCallbackFcn(app, @mNormalize_Callback, true);
      app.mNormalize.Text = 'Normalize All Traces';
      app.mNormalize.Tag = 'mNormalize';
      app.mPreserveZero = uimenu(app.mProces);
      app.mPreserveZero.MenuSelectedFcn = createCallbackFcn(app, @MenuPressed, true);
      app.mPreserveZero.Separator = 'on';
      app.mPreserveZero.Text = 'Preserve Zero';
      app.mPreserveZero.Tag = 'mPreserveZero';
      app.mWaterfall = uimenu(app.mProces);
      app.mWaterfall.MenuSelectedFcn = createCallbackFcn(app, @mWaterfall_Callback, true);
      app.mWaterfall.Separator = 'on';
      app.mWaterfall.Text = 'Waterfall';
      app.mWaterfall.Tag = 'mWaterfall';
      app.mWaterfall2D = uimenu(app.mProces);
      app.mWaterfall2D.MenuSelectedFcn = createCallbackFcn(app, @mWaterfall_Callback, true);
      app.mWaterfall2D.Text = 'Waterfall 2D';
      app.mWaterfall2D.Tag = 'mWaterfall2D';
      app.mFittAddToOut = uimenu(app.mProces);
      app.mFittAddToOut.MenuSelectedFcn = createCallbackFcn(app, @mFittAddToOut_Callback, true);
      app.mFittAddToOut.Separator = 'on';
      app.mFittAddToOut.Text = 'Add Current To Output';
      app.mFittAddToOut.Tag = 'mFittAddToOut';
      app.m1Dto2D = uimenu(app.mProces);
      app.m1Dto2D.MenuSelectedFcn = createCallbackFcn(app, @m1Dto2D_Callback, true);
      app.m1Dto2D.Text = 'Join Traces';
      app.m1Dto2D.Tag = 'm1Dto2D';
      app.m2Dto1D = uimenu(app.mProces);
      app.m2Dto1D.MenuSelectedFcn = createCallbackFcn(app, @m2Dto1D_Callback, true);
      app.m2Dto1D.Text = 'Ungroup Data';
      app.m2Dto1D.Tag = 'm2Dto1D';
      app.mSortIndex = uimenu(app.mProces);
      app.mSortIndex.MenuSelectedFcn = createCallbackFcn(app, @mSortIndex_Callback, true);
      app.mSortIndex.Text = 'Sort By Y Value';
      app.mSortIndex.Tag = 'mSortIndex';
      app.mProcAddAll = uimenu(app.mProces);
      app.mProcAddAll.MenuSelectedFcn = createCallbackFcn(app, @mProcAddAll_Callback, true);
      app.mProcAddAll.Text = 'Add All';
      app.mProcAddAll.Tag = 'mProcAddAll';
      app.uimenu1 = uimenu(app.figure1);
      app.uimenu1.Text = 'Load Options';
      app.uimenu1.Tag = 'uimenu1';
      app.mProcessSource = uimenu(app.uimenu1);
      app.mProcessSource.MenuSelectedFcn = createCallbackFcn(app, @MenuPressed, true);
      app.mProcessSource.Text = 'Source processing';
      app.mProcessSource.Tag = 'mPrAgr';
      app.GridLayout = uigridlayout(app.figure1);
      app.ddTraces = uidropdown(app.GridLayout);
      app.ddTraces.Items = {'--------------'};
      app.ddTraces.ValueChangedFcn = createCallbackFcn(app, @ddTraces_Callback, true);
      app.ddTraces.Tag = 'popfiles';
      app.ddTraces.Value = '--------------';
      app.pbTitle = uibutton(app.GridLayout, 'push');
      app.pbTitle.ButtonPushedFcn = createCallbackFcn(app, @MenuPressed, true);
      app.pbTitle.Tag = 'pbTitle';
      app.pbTitle.Text = 'Title';
      app.pbColor = uibutton(app.GridLayout, 'push');
      app.pbColor.ButtonPushedFcn = createCallbackFcn(app, @MenuPressed, true);
      app.pbColor.Tag = 'CollCur';
      app.pbColor.Text = 'Color';
      app.ddShiftX = uidropdown(app.GridLayout);
      app.ddShiftX.Items = {'1e-7', '1e-6', '1e-5', '1e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7', '1e8'};
      app.ddShiftX.ItemsData = {'1e-7', '1e-6', '1e-5', '1e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7', '1e8'};
      app.ddShiftX.ValueChangedFcn = createCallbackFcn(app, @StepChanged, true);
      app.ddShiftX.Tag = 'SXdim';
      app.ddShiftX.Value = '0.1';
      app.ddShiftY = uidropdown(app.GridLayout);
      app.ddShiftY.Items = {'1e-7', '1e-6', '1e-5', '1e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7'};
      app.ddShiftY.ItemsData = {'1e-7', '1e-6', '1e-5', '1e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7', '1e8'};
      app.ddShiftY.ValueChangedFcn = createCallbackFcn(app, @StepChanged, true);
      app.ddShiftY.Tag = 'SYdim';
      app.ddShiftY.Value = '0.1';
      app.pbToFirst = uibutton(app.GridLayout, 'push');
      app.pbToFirst.ButtonPushedFcn = createCallbackFcn(app, @MenuPressed, true);
      app.pbToFirst.Tag = 'pbToFirst';
      app.pbToFirst.Text = '-> 1st';
      app.pbNorm = uibutton(app.GridLayout, 'push');
      app.pbNorm.ButtonPushedFcn = createCallbackFcn(app, @pbNorm_Callback, true);
      app.pbNorm.Tag = 'pbNorm';
      app.pbNorm.Tooltip = {'Set p-p to 1'};
      app.pbNorm.Text = 'Norm';
      app.bClearShift = uibutton(app.GridLayout, 'push');
      app.bClearShift.ButtonPushedFcn = createCallbackFcn(app, @ValueChanged, true);
      app.bClearShift.Tag = 'ClearX';
      app.bClearShift.Text = 'Clear';
      app.lblShiftY = uilabel(app.GridLayout);
      app.lblShiftY.Tag = 'text4';
      app.lblShiftY.Text = 'Shift Y';
      app.lblShiftX = uilabel(app.GridLayout);
      app.lblShiftX.Tag = 'text1';
      app.lblShiftX.Text = 'Shift X';
      app.ddZoomY = uidropdown(app.GridLayout);
      app.ddZoomY.Items = {'10e-6', '10e-5', '10e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6'};
      app.ddZoomY.ItemsData = {'10e-6', '10e-5', '10e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6'};
      app.ddZoomY.ValueChangedFcn = createCallbackFcn(app, @StepChanged, true);
      app.ddZoomY.Tag = 'ZYdim';
      app.ddZoomY.Value = '1';
      app.ddZoomX = uidropdown(app.GridLayout);
      app.ddZoomX.Items = {'10e-6', '10e-5', '10e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6'};
      app.ddZoomX.ItemsData = {'10e-6', '10e-5', '10e-4', '0.001', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6'};
      app.ddZoomX.ValueChangedFcn = createCallbackFcn(app, @StepChanged, true);
      app.ddZoomX.Tag = 'ZXdim';
      app.ddZoomX.Value = '1';
      app.text2 = uilabel(app.GridLayout);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Zoom X';
      app.text3 = uilabel(app.GridLayout);
      app.text3.Tag = 'text3';
      app.text3.Text = 'Zoom Y';
      app.ddUnits = uidropdown(app.GridLayout);
      app.ddUnits.Items = {'linear', 'dB'};
      app.ddUnits.ValueChangedFcn = createCallbackFcn(app, @ddUnits_Callback, true);
      app.ddUnits.Tag = 'popUnits';
      app.ddUnits.Value = 'linear';
      app.bClearZoom = uibutton(app.GridLayout, 'push');
      app.bClearZoom.ButtonPushedFcn = createCallbackFcn(app, @ValueChanged, true);
      app.bClearZoom.Tag = 'ClearY';
      app.bClearZoom.Text = 'Clear';
      app.text9 = uilabel(app.GridLayout);
      app.text9.Tag = 'text9';
      app.text9.Text = 'Y Units';
      app.eSecondDim = uieditfield(app.GridLayout, 'text');
      app.eSecondDim.Tag = 'eSecondDim';
      app.bAdd = uibutton(app.GridLayout, 'push');
      app.bAdd.ButtonPushedFcn = createCallbackFcn(app, @bAdd_Callback, true);
      app.bAdd.Tag = 'AddButt';
      app.bAdd.Text = 'Add';
      app.pbChange = uibutton(app.GridLayout, 'push');
      app.pbChange.ButtonPushedFcn = createCallbackFcn(app, @pbChange_Callback, true);
      app.pbChange.Tag = 'pbChange';
      app.pbChange.Text = 'Change';
      app.bInvertY = uibutton(app.GridLayout, 'push');
      app.bInvertY.ButtonPushedFcn = createCallbackFcn(app, @ValueChanged, true);
      app.bInvertY.Tag = 'pushbutton8';
      app.bInvertY.Text = 'Inverse Y';
      app.bDelete = uibutton(app.GridLayout, 'push');
      app.bDelete.ButtonPushedFcn = createCallbackFcn(app, @bDelete_Callback, true);
      app.bDelete.Tag = 'DelCurr';
      app.bDelete.Text = 'Delete Current';
      app.bDeleteAll = uibutton(app.GridLayout, 'push');
      app.bDeleteAll.ButtonPushedFcn = createCallbackFcn(app, @bDeleteAllButtonPushed, true);
      app.bDeleteAll.Tag = 'Delete';
      app.bDeleteAll.Text = 'Delete All';
      app.eShiftX = uispinner(app.GridLayout);
      app.eShiftX.Step = 0.1;
      app.eShiftX.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
      app.eShiftY = uispinner(app.GridLayout);
      app.eShiftY.Step = 0.1;
      app.eShiftY.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
      app.eZoomX = uispinner(app.GridLayout);
      app.eZoomX.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
      app.eZoomY = uispinner(app.GridLayout);
      app.eZoomY.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
      app.txtColor = uilabel(app.GridLayout);
      app.txtColor.Tag = 'text6';
      app.txtColor.Text = '';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = CompBox3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end