classdef Description3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure        matlab.ui.Figure
    GridLayout      matlab.ui.container.GridLayout
    SequenceButton  matlab.ui.control.Button
    txtDescription  matlab.ui.control.TextArea
  end


  properties (Access = private)
    KazanViewer % Description
  end

  properties (Access = public)
    guid % Description
  end

  methods (Access = public)

    %----------------------------------------------------------------------
    function FileUpdate(app)

      str = {};
      src = app.KazanViewer.GetData('source');
      names = fieldnames(src.dsc);
      for k = 1:length(names)
        try
          val = src.dsc.(names{k});
          if isstruct(val)
            s_names = fieldnames(val);
            for jj = 1:size(s_names)
              s_val = val.(s_names{jj});
              str{end+1}=sprintf('%-16s   ',[names{k} '-' s_names{jj}], s_val);
            end
          else
            str{end+1}=sprintf('%-16s   ',[names{k} ':'], val);
          end
        catch
        end
      end
      if isempty(str), str = {}''; end
      app.txtDescription.Value = str;

    end
  end

  methods (Access = private)

    %----------------------------------------------------------------------
    function val = getsequencestring(~, dsc, n)
      str = safeget(dsc, ['Psd', num2str(n)], '');

      pos = strfind(str, '}');
      if pos ~= 0
        str = str(pos+1 : end);
      end

      val = str2num(str);

      val = reshape(val, length(val)/4, 4);
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
      app.FileUpdate();
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      try app.KazanViewer.MakePlugins('close', app); catch; end
      delete(app);
    end

    % Button pushed function: SequenceButton
    function SequenceButtonPushed(app, event)
      src = app.KazanViewer.GetData('source');
      dsc = src.dsc;

      channelnames = {'1','2','TWT','4','detect',...
        '+X','7','8','9','10',...
        '11','12','13','14','15',...
        '16','17','10','19','RFGate',...
        '21','22','23','24','25',...
        '26','27','28','29','RF1',...
        '31','32','33','34','35'};
      istep = 1;

      if isfield(dsc, 'Psd1')
        figure; % (10); clf
        hold on
        val = app.getsequencestring(dsc, 5); % detection channel
        maxval = max(val(:,1)+val(:,2))+50;

        for ii=1:36
          val = app.getsequencestring(dsc, ii);
          if any(val(:))
            last_point = 0;
            for j=1:size(val, 1)
              if val(j, 1) > 0 && val(j, 2) > 0
                plot([last_point val(j, 1)], istep+[0 0]);       % _
                plot(val(j,1)*[1,1], istep+[0 .5]);              %  up
                plot(val(j,1)+[0, val(j,2)], istep+[0.5 0.5]);   %   -
                plot((val(j,1)+val(j,2))*[1,1], istep+[0.5 0]);  %    dn
                text(val(j,1),istep+0.7,[num2str(val(j,2)),'ns'])
                text(10,istep+0.3,channelnames{ii})
                last_point = val(j,1)+val(j,2);
              end
            end
            if last_point > 0
              plot([last_point maxval], istep+[0 0]);
              %         text(20, -2*i+.3, num2str(i))
            end
            istep = istep + 1;
          end
        end
        xlabel('Sequence, ns');
        ylabel('Channels');
        axis tight
      elseif isfield(dsc, 'XPD1')
        figure; hold on
        for ii=1:16
          if isfield(dsc, ['XPD',num2str(ii)])
            start = 0;
            val = dsc.(['XPD' num2str(ii)]);
            val1 = str2num(val);
            if length(val1)>4
              ppulse=floor((length(val1)+3)/4)-1;
              val1(end+1:end+4) = 0;
              for j=1:ppulse
                start1 = val1(1+j*4)*8;
                plot([start,start1], ii+[0 0]);
                text(start+10,ii+0.2,[num2str(start1-start),'ns'])
                start = start1;
                plot([start,start], ii+[0 .5]);
                start1 = 8*(val1(2+j*4)-1)+start;
                plot([start,start1], ii+[0.5 0.5]);
                text(start,ii+0.7,[num2str(start1-start),'ns'])
                plot([start1 start1], ii+[0.5 0]);
                start=start1;
              end
            end
          end
        end
        xlabel('Sequence, ns');
        ylabel('Channels');
        title(src.filename);
        axis tight
      end
    end

    % Size changed function: UIFigure
    function UIFigureSizeChanged(app, event)
      standard_size = [70   669   370   480];
      fig_size = app.UIFigure.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      if isfield(app, 'KazanViewer')
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.UIFigure, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.UIFigure, 'Type', 'UITextArea'), 'FontSize', font_size);
      end

    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.txtDescription = uitextarea(app.GridLayout);
      app.SequenceButton = uibutton(app.GridLayout, 'push');
      app.SequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SequenceButtonPushed, true);
      app.SequenceButton.Text = 'Sequence';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Description3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end