classdef Noise3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1         matlab.ui.Figure
    GridLayout      matlab.ui.container.GridLayout
    ddRangeOptions  matlab.ui.control.DropDown
    RangeLabel      matlab.ui.control.Label
    TextArea        matlab.ui.control.TextArea
    NoiseLabel      matlab.ui.control.Label
    SignalLabel     matlab.ui.control.Label
    ddNoiseOptions  matlab.ui.control.DropDown
    ddFitOptions    matlab.ui.control.DropDown
    ClearRefButton  matlab.ui.control.Button
    pbSetReference  matlab.ui.control.Button
    pbCalculate     matlab.ui.control.Button
  end

  properties (Access = public)
    guid % Description
  end

  properties (Access = private)
    KazanViewer  % Description
    Reference    % Description
  end
  
  methods (Access = public)
    
    function results = FileUpdate(app)
      pbCalculate_Callback(app, []);
    end
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
    end

    % Button pushed function: pbSetReference
    function pbSetReference_Callback(app, event)
      src = app.KazanViewer.GetData('source');
      app.Reference = src;
      app.tOutput.Text = 'Reference is added';
    end

    % Button pushed function: pbCalculate
    function pbCalculate_Callback(app, event)
      noise = app.KazanViewer.GetData('source');

      isref = ~isempty(app.Reference) && isequal(size(app.Reference.y),size(noise.y));
      signalfitoptions = app.ddFitOptions.Value;
      noisefitoptions = app.ddNoiseOptions.Value;
      rangefitoptions = app.ddRangeOptions.Value;

      if isref
        signal = app.Reference;
        noise.y = noise.y - signal.y;
      else
        signal = noise;
      end

      n = length(safeget(signal.ax, 'data', [])) + 2;
      stat = cell(n,1);
      stat{1}.data = real(signal.y);
      stat{2}.data = imag(signal.y);
      stat{1}.noisedata = real(noise.y);
      stat{2}.noisedata = imag(noise.y);
      for ii=1:(n-2)
        stat{2+ii}.data = real(signal.ax.data{ii});
        stat{2+ii}.noisedata = real(signal.ax.data{ii});
      end
      
      for ii=1:n
        % signal processing options
        switch signalfitoptions
          case 'no_fit' % no signal modifications
          case 'running_average_7'
            y = movmean(stat{ii}.data, 7);
            stat{ii}.noisedata = stat{ii}.data- y;
            stat{ii}.data = y;
          case 'running_average_11'
            y = movmean(stat{ii}.data, 11);
            stat{ii}.noisedata = stat{ii}.data- y;
            stat{ii}.data = y;
          case 'running_average_21'
            y = movmean(stat{ii}.data, 21);
            stat{ii}.noisedata = stat{ii}.data- y;
            stat{ii}.data = y;
          case 'running_average_31'
            y = movmean(stat{ii}.data, 31);
            stat{ii}.noisedata = stat{ii}.data- y;
            stat{ii}.data = y;
          case 'running_average_51'
            y = movmean(stat{ii}.data, 51);
            stat{ii}.noisedata = stat{ii}.data- y;
            stat{ii}.data = y;
          case 'sinus_fit'
            % Define the objective function for SSE (sum of squared errors)
            fitfunc = @(p, x) p(1)*sin(p(2)*x + p(3)) + p(4);
            objectiveFunc = @(params,x,y) sum((y - fitfunc(params, x)).^2);
            xx = (1:size(signal.y,1))';

            % Initial guess for the parameters [amplitude, frequency, phase shift, offset]
            initialGuess = [max(max(real(signal.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
            initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,2)));
            initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,2)));

            initialGuessQ = [max(max(imag(signal.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
            initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,2)));
            initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,2)));

            for ii=1:size(noise.y,2)
              % Use fminsearch to find the best-fit parameters
              bestParams = fminsearch(objectiveFunc, initialGuess, [], xx, real(signal.y(:,ii)));
              bestParamsQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(signal.y(:,ii)));

              signal.y(:,ii) = signal.y(:,ii) - fitfunc(bestParams, xx) - 1i*fitfunc(bestParamsQ, xx);
            end
        end

        % signal processing options
        switch noisefitoptions
          case 'sinus_fit'
            % Define the objective function for SSE (sum of squared errors)
            fitfunc = @(p, x) p(1)*sin(p(2)*x + p(3)) + p(4);
            objectiveFunc = @(params,x,y) sum((y - fitfunc(params, x)).^2);
            xx = (1:size(noise.y,1))';

            % Initial guess for the parameters [amplitude, frequency, phase shift, offset]
            initialGuess = [max(max(real(noise.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
            initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(noise.y(:,2)));
            initialGuess = fminsearch(objectiveFunc, initialGuess, [], xx, real(noise.y(:,2)));

            initialGuessQ = [max(max(imag(noise.y))), 10*pi/length(xx), pi, 0]; % Adjust based on your dataset
            initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(noise.y(:,2)));
            initialGuessQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(noise.y(:,2)));

            for ii=1:size(noise.y,2)
              % Use fminsearch to find the best-fit parameters
              bestParams = fminsearch(objectiveFunc, initialGuess, [], xx, real(noise.y(:,ii)));
              bestParamsQ = fminsearch(objectiveFunc, initialGuessQ, [], xx, imag(noise.y(:,ii)));

              noise.y(:,ii) = noise.y(:,ii) - fitfunc(bestParams, xx) - 1i*fitfunc(bestParamsQ, xx);
            end
          case 'minus_trace2'
            for jj=1:size(noise.y,2)
              noise.y(:,jj) = noise.y(:,jj) - noise.y(:,2);
            end
          case 'filter_LP_0p5'
            for jj=1:length(stat)
              t = fft(stat{jj}.noisedata);
              sz = length(t);
              cut_off_idx = floor(size(t,1)*0.875 / 2);
              t((sz/2 - cut_off_idx) : (sz/2 + cut_off_idx)) = 0;
              stat{jj}.noisedata = ifft(t);
            end
          case 'filter_HP_0p75'
            for jj=1:length(stat)
              t = fft(stat{jj}.noisedata);
              sz = length(t);
              cut_off_idx = floor(size(t,1)*0.25 / 2);
              t([(1:cut_off_idx), ((sz - cut_off_idx) : end)]) = 0;
              stat{jj}.noisedata = real(ifft(t));
            end
        end
      end

      % range processing options
      for ii=1:n
        switch rangefitoptions
          case 'last_95', stat{ii}.stat_idx = floor(length(stat{ii}.data)*0.05) : length(stat{ii}.data);
          case 'last_90', stat{ii}.stat_idx = floor(length(stat{ii}.data)*0.1) : length(stat{ii}.data);
          case 'last_80', stat{ii}.stat_idx = floor(length(stat{ii}.data)*0.2) : length(stat{ii}.data);
          case 'last_20', stat{ii}.stat_idx = floor(length(stat{ii}.data)*0.8) : length(stat{ii}.data);
          otherwise
            stat{ii}.stat_idx = 1:length(stat{ii}.data);
        end
      end

      for ii=1:n
        stat{ii}.signal = max(stat{ii}.data, [], 1) - min(stat{ii}.data, [], 1);
        stat{ii}.noise = std(stat{ii}.noisedata(stat{ii}.stat_idx));
        stat{ii}.snr = stat{ii}.signal / stat{ii}.noise ;
      end

      message = []; for ii=1:n, message = [message , sprintf(' %.3f |', stat{ii}.signal*1000)]; end 
      text_message = ['signal_mV=', message];
      message = []; for ii=1:n, message = [message , sprintf(' %.3f |', stat{ii}.noise*1E6)]; end 
      text_message = [text_message, newline, 'noise_uV=', message];
      message = []; for ii=1:n, message = [message , sprintf(' %.2f |', stat{ii}.snr)]; end 
      text_message = [text_message, newline, 'snr=', message];

      out = {};

      out{end+1} = signal;
      out{end}.y = stat{1}.data + 1i*stat{2}.data;
      out{end}.title = 'signal';
      out{end}.ax.Color = 'b';

      out{end+1} = noise;
      out{end}.y = stat{1}.noisedata(stat{ii}.stat_idx) + 1i*stat{2}.noisedata(stat{ii}.stat_idx);
      out{end}.ax.x = out{end}.ax.x(stat{ii}.stat_idx);
      out{end}.title = 'noise';
      out{end}.ax.Color = 'r';

      % cc = ['r','b','g','m','k','b','r','b','g','m','k','b'];
      % for ii=3:n
      %   out{end+1} = signal;
      %   out{end}.y = stat{ii}.data;
      %   out{end}.title = ['signal',num2str(ii)];
      %   out{end}.ax.Color = cc(ii);
      % end

      app.KazanViewer.SetData('output', out);

      app.TextArea.Value = text_message;

    end

    % Button pushed function: ClearRefButton
    function ClearRefButtonPushed(app, event)
      app.Reference = [];
      app.tOutput.Text = 'Reference is cleared';
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      app.KazanViewer.MakePlugins('close', app);
      delete(app)      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      app.figure1.Name = 'noiseplugin';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.Tag = 'figure1';
      app.GridLayout = uigridlayout(app.figure1);
      app.pbCalculate = uibutton(app.GridLayout, 'push');
      app.pbCalculate.ButtonPushedFcn = createCallbackFcn(app, @pbCalculate_Callback, true);
      app.pbCalculate.Tag = 'pushbutton1';
      app.pbCalculate.Text = 'Calculate';
      app.pbSetReference = uibutton(app.GridLayout, 'push');
      app.pbSetReference.ButtonPushedFcn = createCallbackFcn(app, @pbSetReference_Callback, true);
      app.pbSetReference.Tag = 'pbSetReference';
      app.pbSetReference.Text = 'Set Reference';
      app.ClearRefButton = uibutton(app.GridLayout, 'push');
      app.ClearRefButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRefButtonPushed, true);
      app.ClearRefButton.Text = 'Clear Ref';
      app.ddFitOptions = uidropdown(app.GridLayout);
      app.ddFitOptions.Items = {'No fit', 'Sinus fit', 'Mov.average x7', 'Mov.average x11', 'Mov.average x21', 'Mov.average x31', 'Mov.average x51'};
      app.ddFitOptions.ItemsData = {'no_fit', 'sinus_fit', 'running_average_11', 'running_average_7', 'running_average_21', 'running_average_31', 'running_average_51'};
      app.ddFitOptions.Placeholder = 'No fit';
      app.ddFitOptions.Value = 'no_fit';
      app.ddNoiseOptions = uidropdown(app.GridLayout);
      app.ddNoiseOptions.Items = {'As is / Ref', 'Sinus fit', '- Trace2', 'LP Filter 0.5', 'HP Filter 0.75'};
      app.ddNoiseOptions.ItemsData = {'as_is', 'sinus_fit', 'minus_trace2', 'filter_LP_0p5', 'filter_HP_0p75'};
      app.ddNoiseOptions.Value = 'as_is';
      app.SignalLabel = uilabel(app.GridLayout);
      app.SignalLabel.Text = 'Signal';
      app.NoiseLabel = uilabel(app.GridLayout);
      app.NoiseLabel.Text = 'Noise';
      app.TextArea = uitextarea(app.GridLayout);
      app.TextArea.Editable = 'off';
      app.RangeLabel = uilabel(app.GridLayout);
      app.RangeLabel.Text = 'Range';
      app.ddRangeOptions = uidropdown(app.GridLayout);
      app.ddRangeOptions.Items = {'As is ', 'Last 95%', 'Last 90%', 'Last 80%', 'Last 20%'};
      app.ddRangeOptions.ItemsData = {'as_is', 'last_95', 'last_90', 'last_80', 'last_20'};
      app.ddRangeOptions.Value = 'as_is';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Noise3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end