classdef RapidScan3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1                matlab.ui.Figure
    GridLayout4            matlab.ui.container.GridLayout
    VisualMode             matlab.ui.control.DropDown
    TabGroup               matlab.ui.container.TabGroup
    SinusoidalTab          matlab.ui.container.Tab
    GridLayout             matlab.ui.container.GridLayout
    LinearTab              matlab.ui.container.Tab
    GridLayout3            matlab.ui.container.GridLayout
    edb                    matlab.ui.control.EditField
    text2                  matlab.ui.control.Label
    text4                  matlab.ui.control.Label
    eNharm                 matlab.ui.control.EditField
    pbBaseline             matlab.ui.control.StateButton
    CompensationTab        matlab.ui.container.Tab
    GridLayout5            matlab.ui.container.GridLayout
    spinBsweep             matlab.ui.control.Spinner
    ScanfactorLabel        matlab.ui.control.Label
    Tab                    matlab.ui.container.Tab
    TextArea               matlab.ui.control.TextArea
    uipanel1               matlab.ui.container.ButtonGroup
    rb3                    matlab.ui.control.RadioButton
    rb2                    matlab.ui.control.RadioButton
    rb1                    matlab.ui.control.RadioButton
    pbShowScript           matlab.ui.control.Button
    pbDeconvolute          matlab.ui.control.StateButton
    cbFieldMapping         matlab.ui.control.CheckBox
    pmScanDir              matlab.ui.control.DropDown
    cbAutoPhase            matlab.ui.control.CheckBox
    spinScanPhase          matlab.ui.control.Spinner
    ScanphasedegLabel      matlab.ui.control.Label
    GridLayout2            matlab.ui.container.GridLayout
    FactorLabel            matlab.ui.control.Label
    pb1                    matlab.ui.control.StateButton
    pb10                   matlab.ui.control.StateButton
    pb01                   matlab.ui.control.StateButton
    pb0001                 matlab.ui.control.StateButton
    pb00001                matlab.ui.control.StateButton
    spinPhase              matlab.ui.control.Spinner
    PhasedegLabel          matlab.ui.control.Label
    pbPhase                matlab.ui.control.StateButton
    spinCutOff             matlab.ui.control.Spinner
    CutOffMHzSpinnerLabel  matlab.ui.control.Label
    pbFilter               matlab.ui.control.StateButton
  end


  properties (Access = private)
    KazanViewer % Description
    Magnifier = 1.0
  end

  properties (Access = public)
    guid % Description
  end

  methods (Access = public)
    function results = FileUpdate(app)
    end
  end

  methods (Access = private)
    function [h, A, B, fp_corr_total, rs_out, bg_out, out] = Process1D(app, yy, pars)
      % --------------------------------------------------------------------
      par.hm = pars.ScanWidth; % [G] Peak-to-peak modulation amplitude
      par.Vm = pars.Freq;      % [Hz] Modulation frequency
      par.rs = yy;             % Experimantal Rapid scan signal, must have at least one full cycle
      par.dt = pars.sampling;  %  dt -  time base (sampling period) for signal stored in array [s]
      par.up = iff(pars.Direction == 1, 1, -1);   % up must be '1' if
      % the first half-scan has up direction, '-1' for down-scan.
      par.ph = pars.Phase;  %  RF (MW) phase correction [degrees]

      if  pars.AutoPhase
        par.fp = 0;
        par.N_iter = 4;
      else
        par.fp = pars.FP;
        par.N_iter = 1;
        % par.fp     First point of the cycle to be deconvolved in units of degrees [0 360]
        %            For experimental data fp has to be adjusted,so that A and B (spectra for up and down scans) coinside.
        % par.bw;    Currently is not used
        %            [Hz] Expected signal BW for pre-filtering, should be slighty
        %            larger than the resonator BW
        % dPh = eval(get(ha.ecph, 'String'));
        % np = size(y_RS, 1);
        % y_RS = circshift(y_RS, round(np*dPh/360));
        % Rs=y_RS+flipud(y_RS);
        % Brange = (max(x_RS) - min(x_RS));
      end

      par.fwhm = pars.ScanWidth / 25;
      % par.fwhm;  [G] Estimated full width at the half height of the narrowest line in the spectrum.
      %            It is used for spectrum filtering with a gaussain profile with width =par.fwhm/10

      par.msg = 1;  %  '1' to show, '0' switches off warning messages to speed up data processing
      par.fig = 0;  % '1' is used for adjustment of par.fp to match up and down spectra;
      %            '0' does not refresh the figure and is used to speed up the
      %             deconvolution program for automated data processing.

      par.method='fast';  % default is 'fast', for very sensetive experiments you may try  'slow'

      [h, A, B, fp_corr_total, rs_out, bg_out, out]=sinDecoBG(par);
    end

    function ProcessData(app)
      % --------------------------------------------------------------------


      h = guidata(ha.figure1);
      handles = guidata(h.hh);

      dsc = safeget(handles.src, 'dsc', []);

      y_TD = handles.src.y;

      if 1
        y_TD = real(y_TD) - 1i*imag(y_TD);
      end

      if get(ha.pbFilter, 'Value')
        CutOff = eval(get(ha.eCutOff, 'String'));
        Wn = CutOff*1E6 / (1/2/mean(diff(handles.src.ax.x)));
        if Wn < 0.99
          [b,a]=butter(2,Wn);
          y_TD = filter(b,a,y_TD, [], 1);
        end
      end

      % determine what modality is used
      sampling = mean(diff(handles.src.ax.x));
      dPh = eval(get(ha.ecph, 'String'));
      dtype = get(ha.pmScanType, 'Value');
      if dtype == 1
        % linear scan
        Freq = kvgetvalue(safeget(dsc, 'SCAN_Frequency', '500 Hz'));
        ScanWidth = kvgetvalue(safeget(dsc, 'SCAN_ScanWidth', '500 Hz'));

        [x_RS,y_RS]=rs_deconvolve(y_TD, ScanWidth, Freq, sampling);

        if get(ha.pbPhase, 'Value')
          phase = str2num(get(ha.ePhase, 'String'));
          y_RS = y_RS*exp(-1i*phase/180*pi);
        end

        dPh = eval(get(ha.ecph, 'String'));
        np = size(y_RS, 1);
        y_RS = circshift(y_RS, round(np*dPh/360));
        Rs=y_RS+flipud(y_RS);
        Brange = (max(x_RS) - min(x_RS));

        if ~get(ha.pbCyclicPhase, 'Value') && get(ha.pbBaseline, 'Value')
          dB = eval(get(ha.edb, 'String'));
          nH = str2double(get(ha.eNharm, 'String'));
          [y_RS, BG] = rs_baseline_sawtooth(x_RS, y_RS, dB, nH);
          x_RS = x_RS(1:np/2);
          y_RS = y_RS(1:np/2, :);
          BG = BG(1:np/2, :);
        end

        handles.out = {};
        handles.out{1}.ax = handles.src.ax;
        handles.out{1}.ax.x = x_RS(:);
        handles.out{1}.y = y_RS;
        handles.out{1}.dsc = dsc;

        if get(ha.pbCyclicPhase, 'Value')
          handles.out{2}.ax = handles.src.ax;
          handles.out{2}.ax.x = x_RS(:);
          handles.out{2}.ax.Color = 'g';
          y_fcRS = flipud(y_RS);
          handles.out{2}.y = y_RS+y_fcRS;
          handles.out{2}.dsc = dsc;

          handles.out{3}.ax = handles.src.ax;
          handles.out{3}.ax.x = x_RS(:);
          handles.out{3}.ax.Color = 'r';
          handles.out{3}.y = y_RS-y_fcRS;
          handles.out{3}.dsc = dsc;
        elseif  get(ha.pbBaseline, 'Value')
          %   nprj2 = size(Rs, 2);
          %   idx1 = 1:2:nprj2;    % indexes for dn field projections
          %   idx2 = 2:2:nprj2;    % indexes for up field projections
          %   shiftsize = round(np*dB/Brange/2);
          %
          %   R1=Rs;
          %   R1(np/2+1:end,idx1)=Rs(np/2+1:end,idx2); %     step2; rearranging
          %   R1(np/2+1:end,idx2)=Rs(np/2+1:end,idx1); %
          %   a0=R1(:,idx2);
          %   b0=R1(:,idx1);
          %   a=circshift(a0,-shiftsize);       % step 3; circularly shifting
          %   b=circshift(b0,shiftsize);

          %   handles.out{2}.ax = handles.src.ax;
          %   handles.out{2}.ax.x = x_RS(:);
          %   handles.out{2}.ax.Color = 'g';
          %   handles.out{2}.y = BG;
          %   handles.out{2}.dsc = dsc;
          %
          %   handles.out{3}.ax = handles.src.ax;
          %   handles.out{3}.ax.x = x_RS(:);
          %   handles.out{3}.ax.Color = 'r';
          %   handles.out{3}.y = a(1:np/2,:)-b(1:np/2,:);
          %   handles.out{3}.dsc = dsc;
        end
      else % sinusoidal
        Freq = kvgetvalue(safeget(handles.src.dsc, 'aliases_RSfrequency', 1e3));
        ScanWidth = kvgetvalue(safeget(dsc, 'aliases_RSwidth', '1 G'));
        ScanWidth = ScanWidth * app.spinBsweep.Value;

        if get(ha.pbPhase, 'Value')
          phase = str2double(get(ha.ePhase, 'String'));
          y_TD = y_TD*exp(-1i*phase/180*pi);
        end

        pars.field_scan_phase = dPh;
        pars.display = 'off';
        pars.data_phase = 0;

        if get(ha.pmScanDir, 'Value') == 2
          pars.up_down = 'down_up';
        else
          pars.up_down = 'up_down';
        end

        auto_phase = get(ha.cbAutoPhase, 'value');
        if auto_phase
          [x_RS,y_RS,pars.field_scan_phase]=rs_sscan_phase(y_TD, ScanWidth, Freq, sampling, pars);
          set(ha.ecph, 'string', sprintf('%5.3f', pars.field_scan_phase));
        else
          pars.N_iter=1;
          [~,y_RS_r]=rs_sdeconvolve(y_TD, ScanWidth, Freq, sampling, pars);
          pars2 = pars;
          pars2.data_phase = pars.data_phase + 90;
          [x_RS,y_RS_i]=rs_sdeconvolve(y_TD, ScanWidth, Freq, sampling, pars2);
          y_RS = y_RS_r + 1i * y_RS_i;
        end

        handles.out = {};
        handles.out{1}.ax = handles.src.ax;
        handles.out{1}.ax.x = x_RS(:);
        handles.out{1}.y = y_RS;
        handles.out{1}.dsc = dsc;

        if get(ha.pbCyclicPhase, 'Value')
          handles.out{2}.ax = handles.src.ax;
          handles.out{2}.ax.x = x_RS(:);
          handles.out{2}.ax.Color = 'g';
          y_fcRS = flipud(y_RS);
          handles.out{2}.y = y_RS+y_fcRS;
          handles.out{2}.dsc = dsc;

          handles.out{3}.ax = handles.src.ax;
          handles.out{3}.ax.x = x_RS(:);
          handles.out{3}.ax.Color = 'r';
          handles.out{3}.y = y_RS-y_fcRS;
          handles.out{3}.dsc = dsc;
        end
      end

      guidata(h.hh, handles);
      [~,name] = fileparts(get(h.hh, 'FileName'));
      eval([name '(''SetDataSource'', 2, handles)']);
    end

    % --------------------------------------------------------------------
    function ProcessDataDenver(app)

      [y_TD, pars, dsc] = get_data(app);
      src = app.KazanViewer.GetData('source');

      if app.pbFilter.Value
        CutOff = eval(get(ha.eCutOff, 'String'));
        Wn = CutOff*1E6 / (1/2/mean(pars.sampling));
        if Wn < 0.99
          [b,a]=butter(2,Wn);
          y_TD = filter(b,a,y_TD, [], 1);
        end
      end

      % determine what modality is used
      scan_phase = app.spinScanPhase.Value;
      switch app.TabGroup.SelectedTab.Title
        case 'Sinusoidal'

          if app.pbPhase.Value
            phase = app.spinPhase.Value;
            y_TD = y_TD*exp(-1i*phase/180*pi);
          end

          pars.field_scan_phase = scan_phase;
          pars.display = 'off';
          pars.data_phase = 0;

          switch app.pmScanDir.Value
            case '\/', pars.up_down = 'down_up';
            case '/\', pars.up_down = 'up_down';
          end

          auto_phase = app.cbAutoPhase.Value;
          if auto_phase
            [x_RS,y_RS,pars.field_scan_phase]=rs_sscan_phase(y_TD, pars.ScanWidth, pars.Freq, pars.sampling, pars);
            set(ha.ecph, 'string', sprintf('%5.3f', pars.field_scan_phase));
          else
            pars.N_iter=1;
            [~, y_RS_r, A_r, B_r]=rs_sdeconvolve(y_TD, pars.ScanWidth, pars.Freq, pars.sampling, pars);
            pars2 = pars;
            pars2.data_phase = pars.data_phase + 90;
            [x_RS,y_RS_i, A_i, B_i]=rs_sdeconvolve(y_TD, pars.ScanWidth, pars.Freq, pars.sampling, pars2);
            y_RS = y_RS_r + 1i * y_RS_i;
            A = A_r + 1i * A_i;
            B = B_r + 1i * B_i;
          end

          out = {};
          out{1}.ax = [];
          out{1}.ax.x = x_RS(:);
          out{1}.y = y_RS;
          out{1}.dsc = dsc;
          out{1}.title = 'A+B';

          if app.pbShowSumDiff.Value
            out{end+1}.ax = [];
            out{end}.ax.x = x_RS(:);
            out{end}.ax.Color = 'g';
            out{end}.title = 'A';
            out{end}.y = A;
            out{end}.dsc = dsc;

            out{end+1}.ax = src.ax;
            out{end}.ax.x = x_RS(:);
            out{end}.ax.Color = 'r';
            out{end}.title = 'B';
            out{end}.y = B;
            out{end}.dsc = dsc;
          end

        case 'Linear'

          %
          % % linear scan
          % Freq = kvgetvalue(safeget(dsc, 'SCAN_Frequency', '500 Hz'));
          % ScanWidth = kvgetvalue(safeget(dsc, 'SCAN_ScanWidth', '500 Hz'));
          %
          % [x_RS,y_RS]=rs_deconvolve(y_TD, ScanWidth, Freq, sampling);
          %
          % if get(ha.pbPhase, 'Value')
          %   phase = str2num(get(ha.ePhase, 'String'));
          %   y_RS = y_RS*exp(-1i*phase/180*pi);
          % end
          %
          % dPh = eval(get(ha.ecph, 'String'));
          % np = size(y_RS, 1);
          % y_RS = circshift(y_RS, round(np*dPh/360));
          % Rs=y_RS+flipud(y_RS);
          % Brange = (max(x_RS) - min(x_RS));
          %
          % if ~get(ha.pbCyclicPhase, 'Value') && get(ha.pbBaseline, 'Value')
          %   dB = eval(get(ha.edb, 'String'));
          %   nH = str2double(get(ha.eNharm, 'String'));
          %   [y_RS, BG] = rs_baseline_sawtooth(x_RS, y_RS, dB, nH);
          %   x_RS = x_RS(1:np/2);
          %   y_RS = y_RS(1:np/2, :);
          %   BG = BG(1:np/2, :);
          % end
          %
          % handles.out = {};
          % handles.out{1}.ax = handles.src.ax;
          % handles.out{1}.ax.x = x_RS(:);
          % handles.out{1}.y = y_RS;
          % handles.out{1}.dsc = dsc;
          %
          % if get(ha.pbCyclicPhase, 'Value')
          %   handles.out{2}.ax = handles.src.ax;
          %   handles.out{2}.ax.x = x_RS(:);
          %   handles.out{2}.ax.Color = 'g';
          %   y_fcRS = flipud(y_RS);
          %   handles.out{2}.y = y_RS+y_fcRS;
          %   handles.out{2}.dsc = dsc;
          %
          %   handles.out{3}.ax = handles.src.ax;
          %   handles.out{3}.ax.x = x_RS(:);
          %   handles.out{3}.ax.Color = 'r';
          %   handles.out{3}.y = y_RS-y_fcRS;
          %   handles.out{3}.dsc = dsc;
          % elseif  get(ha.pbBaseline, 'Value')
          %   %   nprj2 = size(Rs, 2);
          %   %   idx1 = 1:2:nprj2;    % indexes for dn field projections
          %   %   idx2 = 2:2:nprj2;    % indexes for up field projections
          %   %   shiftsize = round(np*dB/Brange/2);
          %   %
          %   %   R1=Rs;
          %   %   R1(np/2+1:end,idx1)=Rs(np/2+1:end,idx2); %     step2; rearranging
          %   %   R1(np/2+1:end,idx2)=Rs(np/2+1:end,idx1); %
          %   %   a0=R1(:,idx2);
          %   %   b0=R1(:,idx1);
          %   %   a=circshift(a0,-shiftsize);       % step 3; circularly shifting
          %   %   b=circshift(b0,shiftsize);
          %
          %   %   handles.out{2}.ax = handles.src.ax;
          %   %   handles.out{2}.ax.x = x_RS(:);
          %   %   handles.out{2}.ax.Color = 'g';
          %   %   handles.out{2}.y = BG;
          %   %   handles.out{2}.dsc = dsc;
          %   %
          %   %   handles.out{3}.ax = handles.src.ax;
          %   %   handles.out{3}.ax.x = x_RS(:);
          %   %   handles.out{3}.ax.Color = 'r';
          %   %   handles.out{3}.y = a(1:np/2,:)-b(1:np/2,:);
          %   %   handles.out{3}.dsc = dsc;
          % end


      end

      app.KazanViewer.SetData('output', out);
    end

    % --------------------------------------------------------------------
    function ProcessDataDenverNew(app)

      [y_TD, pars, dsc] = get_data(app);

      switch app.pmScanDir.Value
        case '/\', pars.Direction = 1;
        case '\/', pars.Direction = 2;
      end

      pars.Phase = iff(app.pbPhase.Value, app.spinPhase.Value, 0);
      pars.AutoPhase = app.cbAutoPhase.Value;
      pars.FP = app.spinScanPhase.Value;

      sz = size(y_TD);
      % y_TD = -imag(y_TD) + 1i*real(y_TD);

      app.TextArea.Value = sprintf('Process1D:\nFreq %.1fkHz, ScanWidth %.1fkG, sampling %.1fns\nFP %i,Phase %.0fdeg',pars.Freq*1e-3,pars.ScanWidth,pars.sampling*1e9,pars.FP, pars.Phase);  

      for ii=1:sz(2)
        [h, A, B, fp_corr_total, ~, ~, outdata] = Process1D(app, y_TD(:,ii), pars);
        if ii==1
          AA = A(:);
          BB = B(:);
        else
          AA(:,ii) = A(:);
          BB(:,ii) = B(:);
        end
      end

      % x_RS = linspace(-par.hm/2, par.hm/2, length(A));

      if  app.cbAutoPhase.Value
        app.spinScanPhase.Value = fp_corr_total;
      end

      out = {};

     switch app.VisualMode.Value
       case 'Sum'
        out{1}.ax = [];
        out{1}.ax.x = h(:) + pars.CentralField;
        out{1}.y = AA+BB;
        out{1}.dsc = dsc;
        out{1}.title = 'A+B';
       case 'A and B'
        out{1}.ax = [];
        out{1}.ax.x = h(:) + pars.CentralField;
        out{1}.y = AA;
        out{1}.dsc = dsc;
        out{1}.ax.Color = 'r';
        out{1}.title = 'A';

        out{2}.ax = [];
        out{2}.ax.x = h(:) + pars.CentralField;
        out{2}.y = BB;
        out{2}.dsc = dsc;
        out{2}.ax.Color = 'g';
        out{2}.title = 'B';
       case 'Interleave-1'
        out{1}.ax = [];
        out{1}.ax.x = 1:length(outdata.rs_i);
        out{1}.y = outdata.rs_i(:);
        out{1}.dsc = dsc;
        out{1}.ax.Color = 'b';
        out{1}.title = 'rs_i';
       case 'Interleave-2'
        out{1}.ax = [];
        out{1}.ax.x = 1:length(outdata.rs_ii);
        out{1}.y = outdata.rs_ii(:);
        out{1}.dsc = dsc;
        out{1}.ax.Color = 'b';
        out{1}.title = 'rs_ii';
       otherwise
        out{1}.ax = [];
        out{1}.ax.x = h(:) + pars.CentralField;
        out{1}.y = AA+BB;
        out{1}.dsc = dsc;
        out{1}.title = 'A+B';
      end

      app.KazanViewer.SetData('output', out);
    end

    % --------------------------------------------------------------------
    function ProcessDataJIVA(app)


      [y_TD, pars, dsc] = get_data(app, ha);
      % y_TD = -imag(y_TD) + 1i*real(y_TD);

      pars.ScanWidth = 15;
      pars.Freq = 18300;

      par.hm = pars.ScanWidth; % [G] Peak-to-peak modulation amplitude
      par.Vm = pars.Freq;      % [Hz] Modulation frequency
      par.rs = y_TD;           % Experimantal Rapid scan signal, must have at least one full cycle
      par.dt = pars.sampling;  %  dt -  time base (sampling period) for signal stored in array [s]
      par.up = iff(ha.pmScanDir.Value == 1, 1, -1);   % up must be '1' if
      % the first half-scan has up direction, '-1' for down-scan.
      par.ph = iff(ha.pbPhase.Value, str2double(ha.ePhase.String), 0);  %  RF (MW) phase correction [degrees]

      if  ha.cbAutoPhase.Value
        par.fp = 0;
        par.N_iter = 4;
      else
        par.fp = eval(ha.ecph.String);
        par.N_iter = 1;
        % par.fp     First point of the cycle to be deconvolved in units of degrees [0 360]
        %            For experimental data fp has to be adjusted,so that A and B (spectra for up and down scans) coinside.
        % par.bw;    Currently is not used
        %            [Hz] Expected signal BW for pre-filtering, should be slighty
        %            larger than the resonator BW
        % dPh = eval(get(ha.ecph, 'String'));
        % np = size(y_RS, 1);
        % y_RS = circshift(y_RS, round(np*dPh/360));
        % Rs=y_RS+flipud(y_RS);
        % Brange = (max(x_RS) - min(x_RS));
      end

      par.fwhm = pars.ScanWidth / 25;
      % par.fwhm;  [G] Estimated full width at the half height of the narrowest line in the spectrum.
      %            It is used for spectrum filtering with a gaussain profile with width =par.fwhm/10

      par.msg = 1;  %  '1' to show, '0' switches off warning messages to speed up data processing
      par.fig = 0;  % '1' is used for adjustment of par.fp to match up and down spectra;
      %            '0' does not refresh the figure and is used to speed up the
      %             deconvolution program for automated data processing.

      par.method='fast';  % default is 'fast', for very sensetive experiments you may try  'slow'

      [h, A, B, fp_corr_total, rs_out, bg_out]=sinDecoBG(par);
      % x_RS = linspace(-par.hm/2, par.hm/2, length(A));

      if  app.cbAutoPhase.Value
        ha.ecph.String = num2str(fp_corr_total);
      end

      out = {};
      out{1}.ax = [];
      out{1}.ax.x = h(:);
      out{1}.y = A(:) + B(:);
      out{1}.dsc = dsc;

      if get(app.pbShowSumDiff, 'Value')
        out = {};
        out{1}.ax = [];
        out{1}.ax.x = h(:);
        out{1}.y = A(:);
        out{1}.dsc = dsc;
        out{1}.ax.Color = 'r';
        out{1}.title = 'A';

        out{2}.ax = [];
        out{2}.ax.x = h(:);
        out{2}.y = B(:);
        out{2}.dsc = dsc;
        out{2}.ax.Color = 'g';
        out{2}.title = 'B';
      else
        out = {};
        out{1}.ax = [];
        out{1}.ax.x = h(:);
        out{1}.y = A(:) + B(:);
        out{1}.dsc = dsc;
        out{1}.title = 'A+B';
      end

      app.KazanViewer.SetData('output', out);
    end

    % --------------------------------------------------------------------
    function ProcessDataMain(app)
      if get(app.rb3, 'Value')
        ProcessDataDenverNew(app)
      elseif get(app.rb2, 'Value')
        ProcessDataDenver(app)
        % else
        %   ProcessDataJIVA(handles)
      else
        ShowData(app);
      end
    end

    % --------------------------------------------------------------------
    function ShowData(app)
      [y, pars, dsc] = get_data(app);
      src = app.KazanViewer.GetData('source');

      dt = pars.sampling;
      x = (0:size(y,1)-1) * dt;

      phase = app.spinPhase.Value;
      sphase = app.spinScanPhase.Value;

      if app.pbFilter.Value
        CutOff = app.spinCutOff.Value;
        Wn = CutOff*1E6 / (1/2/mean(diff(x)));
        if Wn < 0.99
          [b,a]=butter(2,Wn);
          y = filter(b,a,y, [], 1);
        end
      end

      % Average projections
      Npt  = size(y, 1);           % trace length
      Ntr  = size(y, 2);           % number of traces
      P  =  1/pars.Freq;           % Scan period
      N  = fix(Npt*dt/P);          % number of cycles in the trace

      t = dt * (0:Npt-1);
      t = t(t < P);
      M = length(t);
      x_ss = linspace(-pars.ScanWidth/2, pars.ScanWidth/2, M);

      for jj=1:Ntr
        rsi1 = y(1:M, jj);
        rsi1 = circshift(rsi1, round(sphase/360*M));

        if app.pbPhase.Value
          rsi1 = rsi1*exp(-1i*phase/180*pi);
        end

        n = length(x_ss);
        if app.cbFieldMapping.Value
          xx = pars.ScanWidth/2 * -cos((0:n-1) / (n-1) * 2 * pi);
          idx = 1:n/2;
        else
          xx = x_ss(:);
          idx = 1:n;
        end

        rsi(:,jj) = rsi1;
      end

      frsi = flipud(rsi);

      out = {};
      out{1}.ax = src.ax;
      out{end}.title = 'sum';
      out{end}.ax.x = xx(idx);
      out{end}.ax.Color = 'r';
      out{end}.y = rsi(idx,:)+frsi(idx,:);
      out{end}.dsc = dsc;

      switch app.VisualMode.Value
        case 'A and B'
          out{end+1}.ax = src.ax;
          out{end}.ax.x = xx(idx);
          out{end}.y = rsi(idx,:);
          out{end}.ax.Color = 'b';
          out{end}.title = 'rsi';
          out{end}.dsc = dsc;

          out{end+1}.ax = src.ax;
          out{end}.title = 'flip';
          out{end}.ax.x = xx(idx);
          out{end}.ax.Color = 'g';
          out{end}.y = frsi(idx,:);
          out{end}.dsc = dsc;

          % out{end+1}.ax = src.ax;
          % out{end}.title = 'diff';
          % out{end}.ax.x = xx(idx);
          % out{end}.ax.Color = 'm';
          % out{end}.y = rsi-flipud(rsi);
          % out{end}.dsc = dsc;
      end

      app.KazanViewer.SetData('output', out);
    end

    % --------------------------------------------------------------------
    function ShowDataA(app)

      ha = guidata(handles.hh);
      [y, pars, dsc] = get_data(app, handles);

      dt = pars.sampling;
      x = (0:size(y,1)-1) * dt;

      phase = str2num(get(handles.ePhase, 'String'));
      sphase = str2num(get(handles.ecph, 'String'));

      dtype = get(handles.pmScanType, 'Value');

      if get(handles.pbFilter, 'Value')
        CutOff = eval(get(handles.eCutOff, 'String'));
        Wn = CutOff*1E6 / (1/2/mean(diff(x)));
        if Wn < 0.99
          [b,a]=butter(2,Wn);
          y = filter(b,a,y, [], 1);
        end
      end

      % Average projections
      Npt  = size(y, 1);           % trace length
      Ntr  = size(y, 2);           % number of traces
      P  =  1/pars.Freq;           % Scan period
      N  = fix(Npt*dt/P);          % number of cycles in the trace

      M=2^16;
      t = dt * (0:Npt-1);
      x_ss = (0:(M-1))'/M * pars.ScanWidth*2;

      rsi = zeros(M, Ntr);
      for ii=1:Ntr
        for jj=1:N
          pIdx = t >= ((jj-1) * P - 2*dt) & t < (jj * P + 2*dt);

          x1 = t(pIdx)';
          x2 = linspace((jj-1) * P, jj * P - dt, M)';
          rsi_trace = interp1(x1,y(pIdx,ii),x2);
          rsi_trace(isnan(rsi_trace)) = 0;
          rsi(:,ii)=rsi(:,ii)+rsi_trace;
        end
      end
      rsi=rsi / N;

      rsi = circshift(rsi, round(sphase/360*M));

      if dtype == 2
        rss = zeros(size(rsi));
        x_ss1 = pars.ScanWidth*cos(linspace(0,2*pi, M+1)+pi); x_ss1 = x_ss1(1:end-1);
        x_i   = pars.ScanWidth*linspace(-1,1,M/2+1); x_i = x_i(1:end-1);
        rss(1:M/2,:) = interp1(x_ss1(1:M/2), rsi(1:M/2,:), x_i);
        rss(M/2+1:end,:) = interp1(x_ss1(M/2+1:end), rsi(M/2+1:end,:), -x_i);
        %   figure; plot(x_i, rsi(1:M/2,:), x_i, rss(1:M/2,:));
        rsi = rss;
      end

      if get(handles.pbPhase, 'Value')
        rsi = rsi*exp(-1i*phase/180*pi);
      end

      out = {};
      out{1}.ax = ha.src.ax;
      out{1}.ax.x = x_ss(:);
      out{1}.y = rsi;
      out{1}.dsc = dsc;

      if get(handles.pbCyclicPhase, 'Value')
        out{2}.ax = ha.src.ax;
        out{2}.ax.x = x_ss(:);
        out{2}.ax.Color = 'r';
        out{2}.y = rsi-flipud(rsi);
        out{2}.dsc = dsc;
      end

      app.KazanViewer.SetData('output', out);
    end

    function new_phase = constrain_phase(app, phase)
      % --------------------------------------------------------------------


      if phase > 180, new_phase = phase - 360;
      elseif phase < -360, new_phase = phase + 180;
      else
        new_phase = phase;
      end
    end

    % --------------------------------------------------------------------
    function r = fit(app, complex_data, phase)

      data = real(complex_data * exp(-1i*phase/180*pi));
      r    = abs(max(data) + min(data));
    end

    % --------------------------------------------------------------------
    function [y_TD, pars, dsc] = get_data(app)

      src = app.KazanViewer.GetData('source');

      dsc = safeget(src, 'dsc', []);

      y_TD = src.y;
      pars.CentralField = 0;

      if isfield(src.ax, 'RapidScan_Freq')
        pars.origin = 'specman';
        pars.Freq = src.ax.RapidScan_Freq; % Hz
        pars.ScanWidth = src.ax.RapidScan_ScanWidth;
        pars.sampling = src.ax.RapidScan_Sampling;
        pars.CentralField = src.ax.RapidScan_CenterField;
        y_TD = real(y_TD) - 1i*imag(y_TD);
      elseif contains(dsc.general_dconfig, 'JIVA25RapidScan')
        pars.origin = 'specman';
        pars.Freq = kvgetvalue(safeget(dsc, 'SCAN_Frequency1', '1')); % Hz
        pars.ScanWidth = kvgetvalue(safeget(dsc, 'SCAN_AmplitudePP1', '1')); % G
        pars.sampling = kvgetvalue(safeget(dsc, 'DG_Sampling', '100e-9'));
        % y_TD = real(y_TD) - 1i*imag(y_TD);
      elseif contains(dsc.general_dconfig, 'JIVA25')
        pars.origin = 'specman';
        pars.Freq = kvgetvalue(safeget(dsc, 'SCAN_Frequency1', '1')); % Hz
        pars.ScanWidth = kvgetvalue(safeget(dsc, 'SCAN_AmplitudePP1', '1')) * 20.25; % G
        pars.sampling = 100e-9;
        y_TD = real(y_TD) - 1i*imag(y_TD);
      elseif isfield(dsc,'aliases_RSfrequency')
        pars.origin = 'specman';
        y_TD = real(y_TD) - 1i*imag(y_TD);
        pars.Freq = kvgetvalue(safeget(dsc, 'aliases_RSfrequency', '500 Hz'));
        pars.ScanWidth = kvgetvalue(safeget(dsc, 'aliases_RSwidth', '500 Hz'));
        pars.sampling = mean(diff(ha.src.ax.x));
      else
        pars.origin = 'denver';
        pars.Freq = str2double(safeget(dsc, 'ScanFrequency', '1'))*1E3; % Hz
        pars.ScanWidth = str2double(safeget(dsc, 'ScanWidth', '7'))*10; % G
        pars.sampling = mean(diff(ha.src.ax.x))*1e-9;
      end

      pars.ScanWidth = pars.ScanWidth * app.spinBsweep.Value;
    end

    % --------------------------------------------------------------------
    function ret = get_max_data_phase(app, y_TD, pars)
      tic
      [~,y_RS_r]=rs_sdeconvolve(y_TD, pars.ScanWidth, pars.Freq, pars.sampling, pars);
      [~,y_RS_i]=rs_sdeconvolve(y_TD*exp(-1i*pi/2), pars.ScanWidth, pars.Freq, pars.sampling, pars);

      y_RS = sgolayfilt(diff(real(y_RS_r)), 1, 21) + ...
        1i*sgolayfilt(diff(real(y_RS_i)), 1, 21);

      fun = @(x) fit(app, y_RS, x);

      ret = fminbnd(fun,-180,180);
      toc
    end

    function ret = get_max_scan_angle(app, y_TD, pars, angles)
      % --------------------------------------------------------------------

      tic
      idx = zeros(size(angles));
      for ii=1:length(angles)
        pars.field_scan_phase = angles(ii);
        [~,y_RS_r]=rs_sdeconvolve(y_TD, pars.ScanWidth, pars.Freq, pars.sampling, pars);
        ff = sgolayfilt(diff(real(y_RS_r)), 1, 13);
        idx(ii)=max(ff);
      end
      [~,iii] = max(idx);
      ret = angles(iii);
      figure(1); clf; plot(angles, idx); hold on; plot(ret,idx(iii),'o')
      toc
    end

  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
    end

    % Value changed function: eNharm
    function eNharm_Callback(app, event)
      % --------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      ProcessDataMain(app, handles);
    end

    % Value changed function: edb
    function edb_Callback(app, event)
      % --------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      ProcessDataMain(app, handles);
    end

    % Callback function
    function pbAutoSearch_Callback(app, event)
      % --------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


      [y_TD, pars] = get_data(app, handles);

      pars.N_iter=1;
      pars.display = 'off';
      pars.data_phase = 0;


      a0 = str2double(handles.ecph.String);
      if handles.pb10.Value
        span0 = 360;
      elseif handles.pb01.Value
        span0 = 20;
      elseif handles.pb0001.Value
        span0 = 10;
      elseif handles.pb00001.Value
        span0 = 5;
      else
        span0 = 40;
      end

      a1 = str2double(handles.ePhase.String);
      for ii=1:3
        a0 = get_max_scan_angle(app, y_TD*exp(-1i*a1/180*pi), pars, linspace(a0-span0/2, a0+span0/2, 50));
        pars.field_scan_phase = a0;
        span0 = span0 / 5;
        a1 = get_max_data_phase(app, y_TD, pars);
      end

      handles.ePhase.String = num2str(a1);
      handles.ecph.String = num2str(a0);
      fprintf('Optimization is finished.\n');

      ProcessDataMain(app, handles);
    end

    % Value changed function: pbBaseline
    function pbBaseline_Callback(app, event)
      % --------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      ProcessDataMain(app, handles);
    end

    % Value changed function: pbFilter
    function pbFilter_Callback(app, event)
      % --------------------------------------------------------------------

      % Create GUIDE-style callback args - Added by Migration Tool
      [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      ProcessDataMain(app, handles);
    end

    % Button pushed function: pbShowScript
    function pbShowScript_Callback(app, event)
      % --------------------------------------------------------------------

      [y_TD, pars, dsc] = get_data(app);
      phase = app.spinPhase.Value;
      sphase = app.spinScanPhase.Value;

      fprintf('--------------\n')
      fprintf('y_TD = load();\n')

      % if get(ha.pbFilter, 'Value')
      %   CutOff = eval(get(ha.eCutOff, 'String'));
      %   Wn = CutOff*1E6 / (1/2/mean(pars.sampling));
      %   if Wn < 0.99
      %     fprintf('[b,a]=butter(2,%5.3f)\n', Wn);
      %     fprintf('y_TD = filter(b,a,y_TD, [], 1\n');
      %   end
      % end

      % determine what modality is used
      switch app.TabGroup.SelectedTab.Title
        case 'Linear'
          % linear scan
          Freq = kvgetvalue(safeget(dsc, 'SCAN_Frequency', '500 Hz'));
          ScanWidth = kvgetvalue(safeget(dsc, 'SCAN_ScanWidth', '500 Hz'));

          [x_RS,y_RS]=rs_deconvolve(y_TD, ScanWidth, Freq, sampling);

          if get(ha.pbPhase, 'Value')
            phase = str2num(get(ha.ePhase, 'String'));
            y_RS = y_RS*exp(-1i*phase/180*pi);
          end

          dPh = eval(get(ha.ecph, 'String'));
          np = size(y_RS, 1);
          y_RS = circshift(y_RS, round(np*dPh/360));
          Rs=y_RS+flipud(y_RS);
          Brange = (max(x_RS) - min(x_RS));

          if ~get(ha.pbCyclicPhase, 'Value') && get(ha.pbBaseline, 'Value')
            dB = eval(get(ha.edb, 'String'));
            nH = str2double(get(ha.eNharm, 'String'));
            [y_RS, BG] = rs_baseline_sawtooth(x_RS, y_RS, dB, nH);
            x_RS = x_RS(1:np/2);
            y_RS = y_RS(1:np/2, :);
            BG = BG(1:np/2, :);
          end

          handles.out = {};
          handles.out{1}.ax = handles.src.ax;
          handles.out{1}.ax.x = x_RS(:);
          handles.out{1}.y = y_RS;
          handles.out{1}.dsc = dsc;

          if get(ha.pbCyclicPhase, 'Value')
            handles.out{2}.ax = handles.src.ax;
            handles.out{2}.ax.x = x_RS(:);
            handles.out{2}.ax.Color = 'g';
            y_fcRS = flipud(y_RS);
            handles.out{2}.y = y_RS+y_fcRS;
            handles.out{2}.dsc = dsc;

            handles.out{3}.ax = handles.src.ax;
            handles.out{3}.ax.x = x_RS(:);
            handles.out{3}.ax.Color = 'r';
            handles.out{3}.y = y_RS-y_fcRS;
            handles.out{3}.dsc = dsc;
          elseif  get(ha.pbBaseline, 'Value')
            %   nprj2 = size(Rs, 2);
            %   idx1 = 1:2:nprj2;    % indexes for dn field projections
            %   idx2 = 2:2:nprj2;    % indexes for up field projections
            %   shiftsize = round(np*dB/Brange/2);
            %
            %   R1=Rs;
            %   R1(np/2+1:end,idx1)=Rs(np/2+1:end,idx2); %     step2; rearranging
            %   R1(np/2+1:end,idx2)=Rs(np/2+1:end,idx1); %
            %   a0=R1(:,idx2);
            %   b0=R1(:,idx1);
            %   a=circshift(a0,-shiftsize);       % step 3; circularly shifting
            %   b=circshift(b0,shiftsize);

            %   handles.out{2}.ax = handles.src.ax;
            %   handles.out{2}.ax.x = x_RS(:);
            %   handles.out{2}.ax.Color = 'g';
            %   handles.out{2}.y = BG;
            %   handles.out{2}.dsc = dsc;
            %
            %   handles.out{3}.ax = handles.src.ax;
            %   handles.out{3}.ax.x = x_RS(:);
            %   handles.out{3}.ax.Color = 'r';
            %   handles.out{3}.y = a(1:np/2,:)-b(1:np/2,:);
            %   handles.out{3}.dsc = dsc;
          end
        case 'Sinusoidal' % sinusoidal

          if app.pbPhase.Value
            fprintf('y_TD = y_TD*exp(-1i*%5.3f/180*pi);\n', phase);
          end

          fprintf('pars.field_scan_phase = %5.3f;\n', sphase);
          fprintf('pars.display = ''off'';\n');
          fprintf('pars.data_phase = 0;\n');

          fprintf('pars.ScanWidth = %5.3f;\n', pars.ScanWidth);
          fprintf('pars.Freq = %6.1f;\n', pars.Freq);
          fprintf('pars.sampling = %6.1fE-9;\n', pars.sampling*1e9);


          if app.pmScanDir == 2
            fprintf('pars.up_down = ''down_up'';\n');
          else
            fprintf('pars.up_down = ''up_down'';\n');
          end

          fprintf('pars.N_iter=1;\n');
          fprintf('[x_RS,y_RS_r]=rs_sdeconvolve(y_TD, pars.ScanWidth, pars.Freq, pars.sampling, pars);\n');
      end
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      app.KazanViewer.MakePlugins('close', app);
      delete(app)
    end

    % Value changed function: cbFieldMapping, pbDeconvolute, pbPhase, 
    % ...and 3 other components
    function ProcessDataMainEvent(app, event)
      ProcessDataMain(app);
    end

    % Value changed function: pb00001, pb0001, pb01, pb1, pb10
    function MagnifierChangeEvent(app, event)
      hh = [app.pb00001, app.pb0001, app.pb01, app.pb10];
      set(hh(hh~=event.Source.Value), 'Value', 0);
      set(event.Source, 'Value', 1);
      if event.Source.Value == 1
        switch event.Source
          case app.pb00001
            app.Magnifier = 0.001;
          case app.pb0001
            app.Magnifier = 0.01;
          case app.pb01
            app.Magnifier = 0.1;
          case app.pb10
            app.Magnifier = 10;
          case app.pb1
            app.Magnifier = 1.0;
          otherwise
            app.Magnifier = 1.0;
        end
      else
        app.Magnifier = 1.0;
      end

      app.spinPhase.Step = app.Magnifier;
      app.spinScanPhase.Step = app.Magnifier;
      app.spinBsweep.Step = app.Magnifier;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Name = 'RapidScanPLG';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.Tag = 'figure1';
      app.GridLayout4 = uigridlayout(app.figure1);
      app.pbFilter = uibutton(app.GridLayout4, 'state');
      app.pbFilter.ValueChangedFcn = createCallbackFcn(app, @pbFilter_Callback, true);
      app.pbFilter.Tag = 'pbFilter';
      app.pbFilter.Text = 'Use filter';
      app.CutOffMHzSpinnerLabel = uilabel(app.GridLayout4);
      app.CutOffMHzSpinnerLabel.Text = 'CutOff [MHz]';
      app.spinCutOff = uispinner(app.GridLayout4);
      app.spinCutOff.Step = 0.1;
      app.spinCutOff.Limits = [0 100];
      app.spinCutOff.ValueDisplayFormat = '%5.2f';
      app.spinCutOff.Value = 1;
      app.pbPhase = uibutton(app.GridLayout4, 'state');
      app.pbPhase.ValueChangedFcn = createCallbackFcn(app, @ProcessDataMainEvent, true);
      app.pbPhase.Tag = 'pbPhase';
      app.pbPhase.Text = 'Use phase';
      app.PhasedegLabel = uilabel(app.GridLayout4);
      app.PhasedegLabel.Text = 'Phase [deg]';
      app.spinPhase = uispinner(app.GridLayout4);
      app.spinPhase.Limits = [-360 360];
      app.spinPhase.ValueDisplayFormat = '%.4f';
      app.spinPhase.ValueChangedFcn = createCallbackFcn(app, @ProcessDataMainEvent, true);
      app.GridLayout2 = uigridlayout(app.GridLayout4);
      app.pb00001 = uibutton(app.GridLayout2, 'state');
      app.pb00001.ValueChangedFcn = createCallbackFcn(app, @MagnifierChangeEvent, true);
      app.pb00001.Tag = 'pb00001';
      app.pb00001.Text = 'x0.001';
      app.pb0001 = uibutton(app.GridLayout2, 'state');
      app.pb0001.ValueChangedFcn = createCallbackFcn(app, @MagnifierChangeEvent, true);
      app.pb0001.Tag = 'pb0001';
      app.pb0001.Text = 'x0.01';
      app.pb01 = uibutton(app.GridLayout2, 'state');
      app.pb01.ValueChangedFcn = createCallbackFcn(app, @MagnifierChangeEvent, true);
      app.pb01.Tag = 'pb01';
      app.pb01.Text = 'x0.1';
      app.pb10 = uibutton(app.GridLayout2, 'state');
      app.pb10.ValueChangedFcn = createCallbackFcn(app, @MagnifierChangeEvent, true);
      app.pb10.Tag = 'pb10';
      app.pb10.Text = 'x10';
      app.pb1 = uibutton(app.GridLayout2, 'state');
      app.pb1.ValueChangedFcn = createCallbackFcn(app, @MagnifierChangeEvent, true);
      app.pb1.Text = 'x1';
      app.pb1.Value = true;
      app.FactorLabel = uilabel(app.GridLayout2);
      app.FactorLabel.Text = 'Factor';
      app.ScanphasedegLabel = uilabel(app.GridLayout4);
      app.ScanphasedegLabel.Text = 'Scan phase [deg]';
      app.spinScanPhase = uispinner(app.GridLayout4);
      app.spinScanPhase.Limits = [-360 360];
      app.spinScanPhase.ValueDisplayFormat = '%.4f';
      app.spinScanPhase.ValueChangedFcn = createCallbackFcn(app, @ProcessDataMainEvent, true);
      app.cbAutoPhase = uicheckbox(app.GridLayout4);
      app.cbAutoPhase.Tag = 'cbAutoPhase';
      app.cbAutoPhase.Text = 'auto';
      app.pmScanDir = uidropdown(app.GridLayout4);
      app.pmScanDir.Items = {'/\', '\/'};
      app.pmScanDir.ValueChangedFcn = createCallbackFcn(app, @ProcessDataMainEvent, true);
      app.pmScanDir.Tag = 'pmScanDir';
      app.pmScanDir.Value = '/\';
      app.cbFieldMapping = uicheckbox(app.GridLayout4);
      app.cbFieldMapping.ValueChangedFcn = createCallbackFcn(app, @ProcessDataMainEvent, true);
      app.cbFieldMapping.Text = 'B mapping';
      app.pbDeconvolute = uibutton(app.GridLayout4, 'state');
      app.pbDeconvolute.ValueChangedFcn = createCallbackFcn(app, @ProcessDataMainEvent, true);
      app.pbDeconvolute.Tag = 'pbDeconvolute';
      app.pbDeconvolute.Text = 'Go!';
      app.pbShowScript = uibutton(app.GridLayout4, 'push');
      app.pbShowScript.ButtonPushedFcn = createCallbackFcn(app, @pbShowScript_Callback, true);
      app.pbShowScript.Tag = 'pbShowScript';
      app.pbShowScript.Text = 'Script';
      app.uipanel1 = uibuttongroup(app.GridLayout4);
      app.uipanel1.Title = 'Processing';
      app.uipanel1.Tag = 'uipanel1';
      app.rb1 = uiradiobutton(app.uipanel1);
      app.rb1.Tag = 'rb1';
      app.rb1.Text = 'As is';
      app.rb2 = uiradiobutton(app.uipanel1);
      app.rb2.Tag = 'rb2';
      app.rb2.Text = 'Deconvolve (old)';
      app.rb3 = uiradiobutton(app.uipanel1);
      app.rb3.Tag = 'rb3';
      app.rb3.Text = 'Deconvolve (new)';
      app.rb3.Value = true;
      app.TabGroup = uitabgroup(app.GridLayout4);
      app.SinusoidalTab = uitab(app.TabGroup);
      app.SinusoidalTab.Title = 'Sinusoidal';
      app.GridLayout = uigridlayout(app.SinusoidalTab);
      app.LinearTab = uitab(app.TabGroup);
      app.LinearTab.Title = 'Linear';
      app.GridLayout3 = uigridlayout(app.LinearTab);
      app.pbBaseline = uibutton(app.GridLayout3, 'state');
      app.pbBaseline.ValueChangedFcn = createCallbackFcn(app, @pbBaseline_Callback, true);
      app.pbBaseline.Tag = 'pbBaseline';
      app.pbBaseline.Text = 'BL';
      app.eNharm = uieditfield(app.GridLayout3, 'text');
      app.eNharm.ValueChangedFcn = createCallbackFcn(app, @eNharm_Callback, true);
      app.eNharm.Tag = 'eNharm';
      app.eNharm.Value = '4';
      app.text4 = uilabel(app.GridLayout3);
      app.text4.Tag = 'text4';
      app.text4.Text = 'Nharm';
      app.text2 = uilabel(app.GridLayout3);
      app.text2.Tag = 'text2';
      app.text2.Text = 'dB [G]';
      app.edb = uieditfield(app.GridLayout3, 'text');
      app.edb.ValueChangedFcn = createCallbackFcn(app, @edb_Callback, true);
      app.edb.Tag = 'edb';
      app.edb.Value = '1';
      app.CompensationTab = uitab(app.TabGroup);
      app.CompensationTab.Title = 'Compensation';
      app.GridLayout5 = uigridlayout(app.CompensationTab);
      app.ScanfactorLabel = uilabel(app.GridLayout5);
      app.ScanfactorLabel.Text = 'Scan factor';
      app.spinBsweep = uispinner(app.GridLayout5);
      app.spinBsweep.Limits = [-100 100];
      app.spinBsweep.Value = 1;
      app.Tab = uitab(app.TabGroup);
      app.Tab.Title = 'Tab';
      app.TextArea = uitextarea(app.Tab);
      app.VisualMode = uidropdown(app.GridLayout4);
      app.VisualMode.Items = {'Sum', 'A and B', 'Interleave-1', 'Interleave-2', 'Interleave-3'};
      app.VisualMode.Value = 'Sum';
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = RapidScan3(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.figure1)

        % Execute the startup function
        runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.figure1)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end