classdef Symmetrize < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                    matlab.ui.Figure
    GridLayout                  matlab.ui.container.GridLayout
    multiplysymmetricspectrumby1Label  matlab.ui.control.Label
    greenLabel                  matlab.ui.control.Label
    blueLabel                   matlab.ui.control.Label
    redLabel                    matlab.ui.control.Label
    cbInvY                      matlab.ui.control.CheckBox
    GridLayout2                 matlab.ui.container.GridLayout
    b0001                       matlab.ui.control.StateButton
    b100                        matlab.ui.control.StateButton
    b10                         matlab.ui.control.StateButton
    b1                          matlab.ui.control.StateButton
    b01                         matlab.ui.control.StateButton
    b001                        matlab.ui.control.StateButton
    eCenter                     matlab.ui.control.Spinner
    SymmetrycenterSpinnerLabel  matlab.ui.control.Label
    cbSource                    matlab.ui.control.CheckBox
    cbFlip                      matlab.ui.control.CheckBox
    cbSum                       matlab.ui.control.CheckBox
    pbCalculate                 matlab.ui.control.Button
  end

  
  properties (Access = public)
    guid % Description
  end

  properties (Access = private)
    KazanViewer  % Description
  end
  
  methods (Access = public)
    
    function results = FileUpdate(app)
      pbCalculate_Callback(app, []);
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
    end

    % Callback function: cbFlip, cbInvY, cbSource, cbSum, eCenter, 
    % ...and 1 other component
    function pbCalculateButtonPushed(app, event)
      signal = app.KazanViewer.GetData('source');
      
      out = {};
      x0 = app.eCenter.Value;
      if app.cbSource.Value
        out{end+1} = signal;
        out{end}.title = 'source';
        out{end}.ax.Color = 'r';
      end
      
      x = signal.ax.x;
      xflipped = 2*x0 - x;

      flipped = interp1(x, signal.y, xflipped);
       
      if app.cbInvY.Value
        flipped = -flipped;
      end

      if app.cbFlip.Value
        out{end+1} = signal;
        out{end}.y = flipped;
        out{end}.title = 'signal';
        out{end}.ax.Color = 'b';
      end

      if app.cbSum.Value
        out{end+1} = signal;
        out{end}.y = (flipped + signal.y)/2;
        out{end}.title = 'signal';
        out{end}.ax.Color = 'g';
      end
     
      app.KazanViewer.SetData('output', out);      
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      app.KazanViewer.MakePlugins('close', app);
      delete(app)   
    end

    % Value changed function: b0001, b001, b01, b1, b10, b100
    function SetStep(app, event)
      allbuttons = [app.b100, app.b10, app.b1, app.b01, app.b001, app.b0001];
      switch event.Source
        case app.b0001, step = 0.001;
        case app.b001, step = 0.01;
        case app.b01, step = 0.1;
        case app.b10, step = 10;
        case app.b100, step = 100;
        otherwise, step = 1;
      end

      allbuttons = allbuttons(allbuttons ~= event.Source);
      set(allbuttons, 'Value', 0);
      app.eCenter.Step = step;      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.GridLayout = uigridlayout(app.UIFigure);
      app.pbCalculate = uibutton(app.GridLayout, 'push');
      app.pbCalculate.ButtonPushedFcn = createCallbackFcn(app, @pbCalculateButtonPushed, true);
      app.pbCalculate.Text = 'Calculate';
      app.cbSum = uicheckbox(app.GridLayout);
      app.cbSum.ValueChangedFcn = createCallbackFcn(app, @pbCalculateButtonPushed, true);
      app.cbSum.Text = 'Show Sum';
      app.cbSum.Value = true;
      app.cbFlip = uicheckbox(app.GridLayout);
      app.cbFlip.ValueChangedFcn = createCallbackFcn(app, @pbCalculateButtonPushed, true);
      app.cbFlip.Text = 'Show flipped';
      app.cbSource = uicheckbox(app.GridLayout);
      app.cbSource.ValueChangedFcn = createCallbackFcn(app, @pbCalculateButtonPushed, true);
      app.cbSource.Text = 'Show Source';
      app.cbSource.Value = true;
      app.SymmetrycenterSpinnerLabel = uilabel(app.GridLayout);
      app.SymmetrycenterSpinnerLabel.Text = 'Symmetry center';
      app.eCenter = uispinner(app.GridLayout);
      app.eCenter.ValueChangedFcn = createCallbackFcn(app, @pbCalculateButtonPushed, true);
      app.GridLayout2 = uigridlayout(app.GridLayout);
      app.b001 = uibutton(app.GridLayout2, 'state');
      app.b001.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b001.Text = '0.01';
      app.b01 = uibutton(app.GridLayout2, 'state');
      app.b01.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b01.Text = '0.1';
      app.b1 = uibutton(app.GridLayout2, 'state');
      app.b1.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b1.Text = 'x1';
      app.b10 = uibutton(app.GridLayout2, 'state');
      app.b10.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b10.Text = '10';
      app.b100 = uibutton(app.GridLayout2, 'state');
      app.b100.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b100.Text = '100';
      app.b0001 = uibutton(app.GridLayout2, 'state');
      app.b0001.ValueChangedFcn = createCallbackFcn(app, @SetStep, true);
      app.b0001.Text = '0.001';
      app.cbInvY = uicheckbox(app.GridLayout);
      app.cbInvY.ValueChangedFcn = createCallbackFcn(app, @pbCalculateButtonPushed, true);
      app.cbInvY.Text = 'Inv Y';
      app.redLabel = uilabel(app.GridLayout);
      app.redLabel.Text = '(red)';
      app.blueLabel = uilabel(app.GridLayout);
      app.blueLabel.Text = '(blue)';
      app.greenLabel = uilabel(app.GridLayout);
      app.greenLabel.Text = '(green)';
      app.multiplysymmetricspectrumby1Label = uilabel(app.GridLayout);
      app.multiplysymmetricspectrumby1Label.Text = 'multiply symmetric spectrum by -1';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Symmetrize(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end