classdef kazan3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    FileMenu                      matlab.ui.container.Menu
    OpenDirectoryMenu             matlab.ui.container.Menu
    mFavoriteDirectoriesMain      matlab.ui.container.Menu
    SaveSourceMenu                matlab.ui.container.Menu
    SaveSourcewithProcessingMenu  matlab.ui.container.Menu
    ConvertFoldertoASCIIMenu      matlab.ui.container.Menu
    ExportSrcToWorkspaceMenu      matlab.ui.container.Menu
    ExportOuttoWorkspaceMenu      matlab.ui.container.Menu
    LoadSrcfromWorkspaceMenu      matlab.ui.container.Menu
    ViewMenu                      matlab.ui.container.Menu
    FontMenu                      matlab.ui.container.Menu
    FontM2Menu                    matlab.ui.container.Menu
    FontM1Menu                    matlab.ui.container.Menu
    Font0Menu                     matlab.ui.container.Menu
    Font1Menu                     matlab.ui.container.Menu
    Font2Menu                     matlab.ui.container.Menu
    Font3Menu                     matlab.ui.container.Menu
    Font4Menu                     matlab.ui.container.Menu
    AxisTightMenu                 matlab.ui.container.Menu
    AxisAutoMenu                  matlab.ui.container.Menu
    AxisManualMenu                matlab.ui.container.Menu
    SelectViewAreaMenu            matlab.ui.container.Menu
    ZoomMenu                      matlab.ui.container.Menu
    ShowGridMenu                  matlab.ui.container.Menu
    ShowMinorGridMenu             matlab.ui.container.Menu
    AspectNormalMenu              matlab.ui.container.Menu
    AspectSquareMenu              matlab.ui.container.Menu
    AxisEqualMenu                 matlab.ui.container.Menu
    NextFileMenu                  matlab.ui.container.Menu
    PreviousFileMenu              matlab.ui.container.Menu
    NextSliceMenu                 matlab.ui.container.Menu
    PreviousSlideMenu             matlab.ui.container.Menu
    ToolsMenu                     matlab.ui.container.Menu
    mClearSource                  matlab.ui.container.Menu
    mFixSourceProcessing          matlab.ui.container.Menu
    mCutSlice                     matlab.ui.container.Menu
    mShowLoadScript               matlab.ui.container.Menu
    mPluginsMain                  matlab.ui.container.Menu
    mWindow                       matlab.ui.container.Menu
    mNewWindow                    matlab.ui.container.Menu
    mShowinWindow                 matlab.ui.container.Menu
    mPlot1DMenu                   matlab.ui.container.Menu
    mOpenInFigure                 matlab.ui.container.Menu
    mEmptyFigure                  matlab.ui.container.Menu
    mAxis1Of12                    matlab.ui.container.Menu
    mAxis2Of12                    matlab.ui.container.Menu
    mAxis1Of21                    matlab.ui.container.Menu
    mAxis2Of21                    matlab.ui.container.Menu
    mPlot2D                       matlab.ui.container.Menu
    m2DSurf                       matlab.ui.container.Menu
    m2DScatter                    matlab.ui.container.Menu
    m2DSurface                    matlab.ui.container.Menu
    HelpMenu                      matlab.ui.container.Menu
    HowtoStartMenu                matlab.ui.container.Menu
    mDataLoadRoutines             matlab.ui.container.Menu
    mAxisDataStructure            matlab.ui.container.Menu
    mPluginsMenu                  matlab.ui.container.Menu
    HowtoPrintMenu                matlab.ui.container.Menu
    AboutKazanViewerMenu          matlab.ui.container.Menu
    GridMain                      matlab.ui.container.GridLayout
    GridLeftPanel                 matlab.ui.container.GridLayout
    ResultsPanel                  matlab.ui.container.Panel
    GridLayout                    matlab.ui.container.GridLayout
    TextArea                      matlab.ui.control.TextArea
    ddLoadOption1                 matlab.ui.control.DropDown
    ddLoadOption2                 matlab.ui.control.DropDown
    ddDataType                    matlab.ui.control.DropDown
    ddExt                         matlab.ui.control.DropDown
    lbDirlist                     matlab.ui.control.ListBox
    bUp                           matlab.ui.control.Button
    eLoadOption                   matlab.ui.control.EditField
    ePath                         matlab.ui.control.EditField
    b2D                           matlab.ui.control.Button
    GridButtonsRow2               matlab.ui.container.GridLayout
    eParameter                    matlab.ui.control.EditField
    bZoom                         matlab.ui.control.Button
    bMeasure                      matlab.ui.control.Button
    bXhalf                        matlab.ui.control.Button
    bXtwice                       matlab.ui.control.Button
    bYhalf                        matlab.ui.control.Button
    bYtwice                       matlab.ui.control.Button
    ddSelParameter                matlab.ui.control.DropDown
    pnlAxis                       matlab.ui.container.Panel
    haImag                        matlab.ui.control.UIAxes
    haReal                        matlab.ui.control.UIAxes
    sl2Dplot                      matlab.ui.control.Slider
    Label_2                       matlab.ui.control.Label
    GridButtonsRow1               matlab.ui.container.GridLayout
    bDensity                      matlab.ui.control.StateButton
    bContour                      matlab.ui.control.StateButton
    bStack                        matlab.ui.control.StateButton
    b1D                           matlab.ui.control.StateButton
    bOutput                       matlab.ui.control.StateButton
    bSource                       matlab.ui.control.StateButton
    bMagnitude                    matlab.ui.control.StateButton
    bImag                         matlab.ui.control.StateButton
    bReal                         matlab.ui.control.StateButton
    bTransfer                     matlab.ui.control.Button
    ContextMenu                   matlab.ui.container.ContextMenu
    NormalMenu                    matlab.ui.container.Menu
    PSmodMenu                     matlab.ui.container.Menu
    IntegrateMenu                 matlab.ui.container.Menu
    NotFilteredMenu               matlab.ui.container.Menu
    RCMenu                        matlab.ui.container.Menu
    MaverMenu                     matlab.ui.container.Menu
    SavgolMenu                    matlab.ui.container.Menu
    ApplytoMenu                   matlab.ui.container.Menu
    AutoMenu                      matlab.ui.container.Menu
    FirstDimMenu                  matlab.ui.container.Menu
    SecondDimMenu                 matlab.ui.container.Menu
    BothMenu                      matlab.ui.container.Menu
    xyMenu                        matlab.ui.container.Menu
    XMenu                         matlab.ui.container.Menu
    XAxisAsIsMenu                 matlab.ui.container.Menu
    XpntsMenu                     matlab.ui.container.Menu
    ReverseXMenu                  matlab.ui.container.Menu
    XLogMenu                      matlab.ui.container.Menu
    Xgspacefreq1Menu              matlab.ui.container.Menu
    XppmreferenceMenu             matlab.ui.container.Menu
    Xppm1referenceMenu            matlab.ui.container.Menu
    YMenu                         matlab.ui.container.Menu
    YAxisAsIsMenu                 matlab.ui.container.Menu
    YpntsMenu                     matlab.ui.container.Menu
    ReverseYMenu                  matlab.ui.container.Menu
    YLogMenu                      matlab.ui.container.Menu
    ContextMenu2                  matlab.ui.container.ContextMenu
    cmReload                      matlab.ui.container.Menu
    cmReladDir                    matlab.ui.container.Menu
    cmConvert                     matlab.ui.container.Menu
    cmSelectSource                matlab.ui.container.Menu
  end


  properties (Access = private)
    ini % Configuration file
    LastLoadingScript % Description
    View % View options
    DataParameters % Definition of parameters loaded from files
    Plugins % List of plugins
    src % Description
    out % Description
    LoadScriptOptions % Description
    ParameterReader % Description
    ScriptLoad % Description
    FavoriteDirectories % List of favorite directories
    Message % Description
    MessageBlocker % Description
    gamma = 1.3996e6 % e gyromagnetic ratio MHz/G
  end

  methods (Access = private)

    function dir_path=LoadDirectory(app, dir_path)
      if length(dir_path) == 1
        dir_path = [upper(dir_path), ':\'];
      end

      app.ini.KazanViewer.last_path = dir_path;
      dir_struct = dir(dir_path);
      if ~isempty(dir_struct)
        dir_dir_struct = dir_struct([dir_struct.isdir]);

        [sorted_dir_names] = sortrows({dir_dir_struct.name}');

        final_names = {};
        for ii=1:length(sorted_dir_names)
          if ~isequal(sorted_dir_names{ii},'.') &&  ~isequal(sorted_dir_names{ii},'..')
            final_names{end+1} = ['[',sorted_dir_names{ii},']'];
          end
        end

        extension = app.ddExt.Value;
        dir_struct = dir(fullfile(dir_path, extension));
        if ~isempty(dir_struct)
          file_dir_struct = dir_struct(~[dir_struct.isdir]);
          [sorted_file_names] = sortrows({file_dir_struct.name}');
          for ii=1:length(sorted_file_names)
            final_names{end+1} = sorted_file_names{ii};
          end

        end
        app.lbDirlist.Items = final_names;
        %         app.lbDirlist.Value = ''; % deselect item

      end
    end

    %----------------------------------------------------------------------
    function ShowError(~, err, message)
      if nargin > 2
        fprintf('\nKAZAN-%s error:', message);
      else
        fprintf('\nKAZAN-intercepted error: ');
      end
      fprintf(2,'%s\n', err.message);
      for ii=length(err.stack):-1:1
        try ref = which(err.stack(ii).name); catch, ref='????'; end
        fprintf('Stack <a href="matlab:opentoline(''%s'',%i)">%s: line %i</a>\n', ref, err.stack(ii).line, err.stack(ii).name, err.stack(ii).line);
      end
    end

    %----------------------------------------------------------------------
    function LoadFile(app, file_name)
      % Prevent loading of too long files
      fileinfo = dir(file_name);
      if isempty(fileinfo), return; end
      if fileinfo.bytes >= 2^20
        button = questdlg('Do you want to continue reading?',...
          ['File size ' num2str(fileinfo.bytes/2^20, '%10.2f'), 'Mb'],...
          'Yes','No','Yes');
        if strcmp(button, 'No'), return; end
      end

      %       handles.LoadingScript = {};
      %       if get(handles.tb1D, 'Value')
      %         cmContexMenu2(handles.tb1D, [], handles);
      %       end

      ax = []; y = []; dsc = []; % just to avoid warnings in the text
      app.ini.KazanViewer.last_file = file_name;
      try
        options = cell(3,1);
        if length(app.LoadScriptOptions) >= 1
          options{1} = app.ddLoadOption1.Value;
        end
        if length(app.LoadScriptOptions) >= 2
          options{2} = app.ddLoadOption2.Value;
        end
        if length(app.LoadScriptOptions) >= 3
          options{3} = app.eLoadOption.Value;
        end

        app.LastLoadingScript = app.ScriptLoad('script', [app.ddExt.Value, app.ddDataType.Value], file_name, options);

        % load script, creat src, ax and dsc variables
        for k = 1:size(app.LastLoadingScript, 2)
          eval(app.LastLoadingScript{k});
        end
        app.LastLoadingScript{end+1} = '% plot(ax.x,real(y),ax.x,imag(y))';
      catch err
        app.ShowError(err);
        error(['LoadFile: Data load error of type ',app.ddDataType.Value, ' (', app.ddExt.Value, ').']);
      end

      % this will convert data into 2 dimensional variant
      sz = size(y);
      if size(sz,2) > 2
        warning('LoadFile: Data dimension is reduced.');
        y = reshape(y, [sz(1), prod(sz(2:end))]);
        ax.y = (1:prod(sz(2:end)))';
        sz = size(y);
      end

      str = {'X', 'Y'};
      if sz(2) == 1
        app.View.MaxDim = 1;
        app.View.AbscissaXorY = 1;
        app.b2D.Text = str{app.View.AbscissaXorY};
      else
        app.View.MaxDim = 2;
        app.b2D.Text = str{app.View.AbscissaXorY};
      end

      % Convert DSC parameters in AX parameters
      app.src.ax = app.ParameterReader('load', ax, dsc);
      % app.src.ax = app.MakeParameters('from_editor', ax);
      app.src.ax.dx = 0;
      app.src.y   = y;
      app.src.dsc = dsc;

      app.src.filename = file_name;
      app.src.ax.Datatype = [app.ddExt.Value, app.ddDataType.Value];
      app.src.Script = app.LastLoadingScript(1);

      app.src = app.Verify(app.src);

      %       app.src.ax.ext = handles.ext;
      %       app.src.ax.filt = handles.filter;
      %       app.src.ax.diff = handles.process;
      app.SrcControlUpdate();
    end

    %----------------------------------------------------------------------
    function SrcControlUpdate(app)

      % load parameters
      app.MakeParameters('load', app.src.ax);

      app.UpdateSlider();

      if isfield(app.src.ax, 'title')
        app.UIFigure.Name = ['KAZAN3 - ',app.src.filename, ' [',app.src.ax.title,']'];
      else
        app.UIFigure.Name = ['KAZAN3 - ',app.src.filename, ' [ ]'];
        %         set(app.MainFigure, 'Name', ['KAZAN - ',filename, ' [',handles.process,']'])
      end

      app.ProcessMenuChanged([]);
      app.MakePlugins('onload');
    end

    %----------------------------------------------------------------------
    function UpdateSlider(app)
      n = 1; % number of points
      switch app.View.DataSrcOut
        case 1
          n = size(app.src.y, app.View.OrdinateXorY);
        case 2
          for jj=1:length(app.out)
            n1 = size(app.out{jj}.y, app.View.OrdinateXorY);
            if n1>n, n=n1; end
          end
        otherwise
          n = size(handles.src.y, app.View.OrdinateXorY);
          for jj=1:length(app.out)
            n1 = size(handles.out{jj}.y, app.View.OrdinateXorY);
            if n1>n, n=n1; end
          end
      end
      if n > 1
        app.sl2Dplot.Visible = 1;
        app.b2D.Visible = 1;
        app.pnlAxis.Layout.Column = 2;
        nold = app.sl2Dplot.Limits(2);
        if nold ~= n
          app.sl2Dplot.Limits=[1,n];
          app.sl2Dplot.Value=1;
          %           , 'Min', 1, 'Max', n, 'Value', 1, 'SliderStep', [1,1]/(n-1), 'Enable', 'on');
        else
          app.sl2Dplot.Enable = 'on';
        end
        slider_step = 1;
        while n/slider_step > 40, slider_step = slider_step * 2; end
        app.sl2Dplot.MajorTicks = 1:slider_step:n;
        app.sl2Dplot.MinorTicks = [];
      else
        app.sl2Dplot.Visible = 0;
        app.b2D.Visible = 0;
        app.pnlAxis.Layout.Column = [2,4];
        app.sl2Dplot.Limits=[1,2];
        %         set(handles.sl2Dplot, 'Min', 1, 'Max', 2, 'Value', 1, 'SliderStep', [1,1], 'Enable', 'off');
      end
      app.sl2DplotValueChanged();
    end

    %----------------------------------------------------------------------
    function PlotData(app, varargin)
      try
        % this part is used to redirect output to different plots
        if nargin == 1
          raxis = app.haReal;
          iaxis = app.haImag;
          plots = [raxis, iaxis];
          %  clear all plots
          ch = allchild(raxis);
          delete(ch);
          ch = allchild(iaxis);
          delete(ch);
        elseif nargin == 2
          raxis = varargin{1};
          iaxis = [];
          plots = raxis;
        elseif nargin == 3
          raxis = varargin{1};
          iaxis = varargin{2};
          plots = [raxis, iaxis];
        end

        raxis.NextPlot = 'add';
        if ~isempty(iaxis), iaxis.NextPlot = 'add'; end

        if strcmp(kv3_safeget(app.src.ax, 'type', 'data'), 'image') && (handles.DataSrcOut==1 || handles.DataSrcOut==3)
          %           image(handles.src.y, 'Parent', haReal);
          %           set(haReal, 'YDir', 'reverse')
          %           axis(haReal, 'image');
        else
          % protecting axis against upside down etc
          %           axis(haReal, 'normal');
          %           axis(haImag, 'normal');
          %           set(haReal, 'XDir', 'normal')
          %           set(haReal, 'YDir', 'normal')

          for ii = app.GetPlotNumber()
            % data selection
            if app.View.PlotType~=1
              proj = 3;
            else
              proj = app.View.AbscissaXorY;
            end
            if ii~=0
              Data = app.Process(app.out{ii});
              % UserData = kv3_safeget(handles.out{ii}.ax,'UserData',1);
            else
              Data = app.Process(app.src);
              % UserData = 1;
            end
            if isempty(Data.y), continue; end
            tt  = app.GetSlice(Data, proj, app.View.Position);
            ty = tt.y;
            b = whos('ty');
            clear ty;
            if ~strcmp(b.class, 'uint8')
              [y, ax] = kv3_processing(tt.y, tt.ax);
            else
              y = tt.y;
              ax = tt.ax;
            end
            newax = app.getplotaxis(app.View.XType, app.View.YType, ax);

            % data printing
            switch app.View.PlotType
              case {1,2} % 1D and Stack
                switch app.View.RealImagMagn
                  case 1
                    hhh = app.SuperPlot(raxis, app.View.XType, app.View.YType, newax, newax.x, real(y));
                    xlabel(raxis, newax.xlabel);
                    if ~isempty(iaxis), ylabel(iaxis, newax.ylabel); end
                    %                     set(hhh,'UserData',UserData)
                  case 2
                    hhh = app.SuperPlot(raxis, app.View.XType, app.View.YType, newax, newax.x, real(y));
                    %                     set(hhh,'UserData',UserData)
                    hhh = app.SuperPlot(iaxis, app.View.XType, app.View.YType, newax, newax.x, imag(y));
                    %                     set(hhh,'UserData',UserData)
                    %                     axis(app.haImag, handles.axisset);
                    xlabel(raxis, '');
                    xlabel(iaxis, newax.xlabel);
                    ylabel(raxis, newax.ylabel);
                    ylabel(iaxis, newax.ylabel);
                  case 3
                    hhh = app.SuperPlot(raxis, app.View.XType, app.View.YType, newax, newax.x, abs(y));
                    xlabel(raxis, newax.xlabel);
                    ylabel(iaxis, newax.ylabel);
                    %                     set(hhh,'UserData',UserData)
                end

                %                 if handles.PlotType==2 && isfield(newax,'yslicelabel') && ~isempty(newax.yslicelabel)
                %                   xmin = min(newax.x) + kv3_safeget(newax, 'yslicelabeldx',0);
                %                   xmax = max(newax.x) + kv3_safeget(newax, 'yslicelabeldx',0);
                %                   ymax = y(end,:) + kv3_safeget(newax, 'yslicelabeldy',0);
                %                   for jj=1:length(newax.y)
                %                     text((xmax-xmin)*.9+xmin, ymax(jj), sprintf(newax.yslicelabel, newax.y(jj)), 'Parent', haReal);
                %                   end
                %                 end
              case 3 % contour
                if isfield(ax, 'contour'), cc = ax.contour;
                else, cc = .1:.1:.6;
                end

                switch app.View.RealImagMagn
                  case 1
                    maxyy=max(max(real(y))); minyy=min(min(real(y)));
                    if maxyy < 0, sh = minyy; else, sh = 0; end
                    contour(raxis, newax.x, newax.y, real(y.'), cc*maxyy+sh);
                  case 2
                    maxyy=max(max(real(y))); minyy=min(min(real(y)));
                    if maxyy < 0, sh = minyy; else, sh = 0; end
                    contour(raxis, newax.x, newax.y, real(y.'), cc*(maxyy-minyy)+sh);
                    maxyy=max(max(imag(y))); minyy=min(min(imag(y)));
                    if maxyy < 0, sh = minyy; else, sh = 0; end
                    contour(iaxis, newax.x, newax.y, imag(y.'), cc*(maxyy-minyy)+sh);
                    %                     axis(haImag, handles.axisset);
                  case 3
                    maxyy=max(max(abs(y)));
                    contour(raxis, newax.x, newax.y, abs(y.'), cc*maxyy);
                end
              case 4 % density
                if isfield(ax, 'contour'), cc = ax.contour;
                else, cc = .1:.1:.6;
                end
                maxyy=max(max(y));
                minyy=min(min(y));
                if maxyy < 0, sh = minyy;
                else, sh = 0;
                end

                switch app.View.RealImagMagn
                  case 1
                    clim = cc*real(maxyy)+real(sh);
                    imagesc(app.haReal, newax.x, newax.y, real(y.'), [min(clim), max(clim)]);
                  case 2
                    clim1 = cc*real(maxyy-minyy)+real(sh);
                    imagesc(newax.x, newax.y, real(y.'), [min(clim1), max(clim1)]);
                    clim2 = cc*imag(maxyy-minyy)+imag(sh);
                    imagesc(app.haReal, newax.x,newax.y, imag(y.'), [min(clim2), max(clim2)]);
                    axis(app.haImag, handles.axisset);
                  case 3
                    clim = cc*(max(max(abs(y)))-min(min(abs(y))));
                    imagesc(app.haReal, newax.x, newax.y, abs(y.'), [min(clim), max(clim)]);
                end
              case 5 % 3d plot
                switch handles.DataPart
                  case 1
                    h = figure; hold on
                    for jj=1:size(y,2)
                      plot3(app.haReal, ax.x, ax.y(jj)*ones(length(ax.x),1), real(y(:,jj))); hold on
                    end
                    cameratoolbar(h);
                    rotate3d(h,'on')
                    view(45,25)              ;
                  case 2
                    %               h = figure; hold on
                    for jj=1:size(y,2)
                      plot3(app.haReal, ax.x, ax.y(jj)*ones(length(ax.x),1), imag(y(:,jj))); hold on
                    end
                    cameratoolbar(h);
                    rotate3d(h,'on')
                    view(45,25)              ;
                  case 3
                    h = figure; hold on
                    for jj=1:size(y,2)
                      plot3(app.haReal, ax.x, ax.y(jj)*ones(length(ax.x),1), abs(y(:,jj)))
                    end
                    cameratoolbar(h);
                    rotate3d(h,'on')
                    view(45,25)              ;
                end
              case 6 % for hyscorebox and image2data <<< AlSi 30.03.05 <<<
                if ii==0||strcmp(kv3_safeget(handles.out{ii}, 'forceplot', 'contour'), 'image')  % input data
                  if isfield(ax, 'contour'), cc = ax.contour;
                  else, cc = .1:.1:.6;
                  end
                  maxyy=max(max(y));
                  minyy=min(min(y));
                  if maxyy < 0, sh = minyy;
                  else, sh = 0;
                  end

                  if ~isempty(y)
                    b = whos('y');
                    if strcmp(b.class, 'uint8')
                      image(newax.x, newax.y, y, 'Parent', app.haReal);
                      %                         set(haReal, 'YDir', 'reverse')
                      %                         axis(haReal, 'image');
                    else
                      switch handles.DataPart
                        case 1
                          clim = cc*real(maxyy-minyy)+real(sh);
                          imagesc(newax.x, newax.y, real(y).', [min(clim), max(clim)]);
                          %               surf(ax.x, ax.y, real(y.'), 'LineStyle', 'none');
                        case 2
                          clim1 = cc*real(maxyy-minyy)+real(sh);
                          imagesc(newax.x, newax.y, real(y).', [min(clim1), max(clim1)]);
                          %                surf(ax.x, ax.y, real(y.'), 'LineStyle', 'none');
                          clim2 = cc*imag(maxyy-minyy)+imag(sh);
                          imagesc(newax.x, newax.y, imag(y).', [min(clim2), max(clim2)]);
                          %                surf(ax.x, ax.y, imag(y.'), 'LineStyle', 'none');
                          axis(app.haImag, handles.axisset);
                        case 3
                          clim = cc*(max(max(abs(y)))-min(min(abs(y))));
                          imagesc(newax.x, newax.y, abs(y).', [min(clim), max(clim)]);
                          %                surf(ax.x, ax.y, abs(y.'), 'LineStyle', 'none');
                      end
                    end
                  end
                else
                  switch handles.DataPart
                    case 1
                      app.SuperPlot(app.haReal, handles.xrepresent, handles.yrepresent, ax, ax.x, ax.y);
                    case 2
                      app.SuperPlot(app.haReal, handles.xrepresent, handles.yrepresent, ax, ax.x, ax.y);
                      app.SuperPlot(app.haImag, handles.xrepresent, handles.yrepresent, ax, ax.x, ax.y);
                      axis(app.haImag, handles.axisset);
                    case 3
                      app.SuperPlot(app.haReal, handles.xrepresent, handles.yrepresent, ax, ax.x, ax.y);
                  end
                end
            end
          end
        end

        range = kv3_safeget(app.View, 'Range', 'auto'); % tight/auto/manual
        aspect = kv3_safeget(app.View, 'Aspect', 'normal'); % normal/square
        aspect1 = kv3_safeget(app.View, 'Aspect', ''); % equal

        if contains(aspect1, 'equal')
          axis(plots, 'equal');
        elseif contains(aspect, 'square')
          axis(plots, 'square');
        else
          axis(plots, 'normal');
        end
        if ~contains(range, 'manual')
          axis(plots, range);
        end

        grid(plots, kv3_safeget(app.View, 'AxisGrid', 'off'));
        set(plots, 'XMinorGrid', kv3_safeget(app.View, 'MinAxisGrid', 'off'));

        % set label
        %         set(handles.tUnits, 'String', newax.xlabel);

        %         % put axis menu, select View
        %         if handles.PlotType ~= 5, set(haReal, 'View',[0 90]); set(haImag, 'View',[0 90]); end
        %         axtoolbar(haReal, 'default');

      catch err, app.ShowError(err);
      end
    end

    %----------------------------------------------------------------------
    function res = GetPlotNumber(app)
      if app.View.DataSrcOut ~= 1
        nplots = length(app.out);
      else
        nplots = 0;
      end
      % if DataSrcOut 1 or 3 print source
      if app.View.DataSrcOut~=2
        fplot = 0;
      else
        fplot = 1;
      end
      res = fplot:nplots;
    end

    %----------------------------------------------------------------------
    function rs = GetSlice(~, src, dim, sel)
      src.ax.y = kv3_safeget(src.ax,'y',0);
      rs = src;
      switch dim
        case 1 % X
          rs.y    = src.y(:,min(sel,end));
          rs.ax.y = src.ax.y(min(sel,end));
        case 2 % Y
          rs.y    = src.y(sel,:)';
          rs.ax.x = src.ax.y;
          rs.ax.y = src.ax.x(min(sel,end));
          rs.ax.xlabel = kv3_safeget(src.ax,'ylabel', 'a.u.');
          rs.ax.ylabel = kv3_safeget(src.ax,'xlabel', 'a.u.');
        otherwise
      end
    end

    %----------------------------------------------------------------------
    function newax = getplotaxis(app, xfname, yfname, ax)
      newax = ax;

      dx = kv3_safeget(ax, 'dx', 0); newax.dx=0;
      dy = kv3_safeget(ax, 'dy', 0); newax.dy=0;
      gref = kv3_safeget(ax, 'freq1', 34E9)/app.gamma;
      if gref == 0.0, gref = 1.; end
      ref = kv3_safeget(ax, 'reference', 34E9);
      if ref == 0.0, ref = 1.; end

      xlabel = kv3_safeget(ax, 'xlabel', '');
      pos = findstr(xlabel, ',');
      if isempty(pos), xvar='a.u.'; else, xvar = xlabel(1:min(pos)); end
      ylabel = kv3_safeget(ax, 'ylabel', '');
      pos = findstr(ylabel, ',');
      if isempty(pos), yvar='a.u.'; else, yvar = ylabel(1:min(pos)); end

      if contains(xfname, 'gspace')
        newax.x = gref./(ax.x+dx);
        newax.xlabel = 'g-factor,';
      elseif contains(xfname, 'ppm-1')
        newax.x = ((ax.x+dx)./ref - 1)*1E6;
        newax.xlabel = [xlabel,'ppm'];
      elseif contains(xfname, 'ppm')
        newax.x = (ax.x+dx)./ref*1E6;
        newax.xlabel = [xvar,'ppm'];
      elseif contains(xfname, 'pnt')
        newax.x = (1:size(ax.x,1)).';
        newax.xlabel = [xvar,'pts'];
      else
        newax.x = ax.x+dx;
        newax.xlabel = xlabel;
      end

      if contains(yfname, 'gspace')
        newax.y = gref./(ax.y+dy);
        newax.ylabel = 'g-factor,';
      elseif contains(yfname, 'ppm-1')
        newax.y = ((ax.y+dy)./ref - 1)*1E6;
        newax.ylabel = [yvar,'ppm'];
      elseif contains(yfname, 'ppm')
        newax.y = (ax.y+dy)./ref*1E6;
        newax.ylabel = [yvar,'ppm'];
      elseif contains(yfname, 'pnt')
        newax.y = (1:size(ax.y,1))';
        newax.ylabel = [yvar,'pts'];
      else
        newax.y = ax.y+dy;
        newax.ylabel = ylabel;
      end
    end

    %----------------------------------------------------------------------
    function res = SuperPlot(~, hax, xrepresent, yrepresent, style, x, y)
      c = kv3_safeget(style, 'Color', 'b');
      l = kv3_safeget(style, 'LineStyle', '-');
      lw = kv3_safeget(style, 'LineWidth', 1); % protection
      if ischar(lw), lw = str2double(lw); end
      m = kv3_safeget(style, 'Marker', 'none');

      res = plot(hax, x, y, 'Color', c, 'LineStyle', l, 'LineWidth', lw, 'Marker', m, 'HitTest', 'off');

      if strcmp(xrepresent, 'semilog'), set(hax, 'XScale','log', 'XDir', 'normal');
      else
        set(hax, 'XScale','linear');
        if strcmp(xrepresent, 'reverse') || strcmp(xrepresent, 'gspace')
          set(hax, 'XDir','reverse');
        else, set(hax, 'XDir','normal');
        end
      end
      if strcmp(yrepresent, 'semilog'), set(hax, 'YScale','log', 'YDir', 'normal');
      else
        set(hax, 'YScale','linear');
        if strcmp(yrepresent, 'reverse'), set(hax, 'YDir','reverse');
        else, set(hax, 'YDir','normal');
        end
      end
    end

    % everything related to parameters
    %----------------------------------------------------------------------
    function data = MakeParameters(app, mode, data)

      switch mode
        case 'load' % load parameters from data structure ---------------------------------------------------
          fields = fieldnames(data);

          for k=1:length(fields)
            fname = fields{k};
            idx = find(strcmp( cellfun( @(a) a.title, app.View.Par, 'UniformOutput', false ), {fname}), 1);
            if ~isempty(idx)
              switch app.View.Par{idx}.type
                case 's',  app.View.Par{idx}.value = data.(fname);
                otherwise,  app.View.Par{idx}.value = num2str(data.(fname));
              end
            end
          end
          for k=1:length(fields)
            fname = fields{k};
            idx = find(strcmp( cellfun( @(a) a.title, app.DataParameters, 'UniformOutput', false ), {fname}), 1);
            if ~isempty(idx)
              switch app.DataParameters{idx}.type
                case 's',  app.DataParameters{idx}.value = data.(fname);
                otherwise,  app.DataParameters{idx}.value = num2str(data.(fname));
              end
            end
          end

          app.MakeParameters('update_list');
          app.MakeParameters('update_editor');

        case 'update_list' %-----------------------------------------------------------------------------------
          pars = [app.View.Par, app.DataParameters];
          titles = cellfun(@(a) [a.prefix,a.title], pars, 'UniformOutput', false);
          app.ddSelParameter.Items = titles;

        case 'update_editor' %-----------------------------------------------------------------------------------
          pars = [app.View.Par, app.DataParameters];
          idx = find(strcmp( cellfun( @(a) [a.prefix,a.title], pars, 'UniformOutput', false ), {app.ddSelParameter.Value} ), 1);
          if isempty(idx)
            idx = 1;
            app.ddSelParameter.Value = [pars{1}.prefix, pars{1}.title];
          else
            idx = find(strcmp( cellfun( @(a) [a.prefix,a.title], pars, 'UniformOutput', false ), {app.ddSelParameter.Value} ));
          end
          switch pars{idx}.type
            case 's',  app.eParameter.Value = pars{idx}.value;
            otherwise,  app.eParameter.Value = num2str(pars{idx}.value);
          end
          app.eParameter.Tooltip = kv3_safeget(pars{idx}, 'about', '');
          app.ddSelParameter.Tooltip = kv3_safeget(pars{idx}, 'about', '');

        case 'from_editor' %-----------------------------------------------------------------------------------
          idx = find(strcmp( cellfun( @(a) [a.prefix,a.title], app.View.Par, 'UniformOutput', false ), {app.ddSelParameter.Value} ), 1);
          if ~isempty(idx)
            switch app.View.Par{idx}.type
              case 's',  app.View.Par{idx}.value = app.eParameter.Value;
              otherwise,  app.View.Par{idx}.value = str2double(app.eParameter.Value);
            end
          else
            idx = find(strcmp( cellfun( @(a) [a.prefix,a.title], app.DataParameters, 'UniformOutput', false ), {app.ddSelParameter.Value} ), 1);
            if ~isempty(idx)
              switch app.DataParameters{idx}.type
                case 's',  app.DataParameters{idx}.value = app.eParameter.Value;
                otherwise,  app.DataParameters{idx}.value = str2double(app.eParameter.Value);
              end
            end
          end

          pars = [app.View.Par, app.DataParameters];
          for k=1:length(pars)
            data.(pars{k}.title) = pars{k}.value;
          end
      end
    end

    %----------------------------------------------------------------------
    function OnPluginSelected(app, event)
      switch event.Source.Label
        case 'Arrange plugins'
        case 'Reload plugins'
          app.MakePlugins('search')
        otherwise
          switch event.Source.Parent.Label
            case 'Add to Favorites'
              plugin_name = upper(event.Source.UserData);
              app.ini.(plugin_name).favorite = '1';
              inifilename = fullfile(userpath, 'kazan3.ini');
              try kv3_inimanage(inifilename, app.ini); catch, end
              app.MakePlugins('search')
            case 'Remove from Favorites'
              plugin_name = upper(event.Source.UserData);
              if isfield(app.ini, plugin_name)
                app.ini.(plugin_name).favorite = '0';
                inifilename = fullfile(userpath, 'kazan3.ini');
                try kv3_inimanage(inifilename, app.ini); catch, end
                app.MakePlugins('search')
              end
            otherwise
              [~,guid] = fileparts(tempname);
              runstring = sprintf('%s(app, ''%s'')', event.Source.UserData, guid);
              app.Plugins{end+1} = eval(runstring);
              fprintf('Plugin is added. Overall %i\n', length(app.Plugins))
          end
      end
    end

    %----------------------------------------------------------------------
    function OnFavoriteSelected(app, event)
      switch event.Source.Label
        case 'Add Current', app.MakeFavorites('addcurrent');
        case 'Remove Current', app.MakeFavorites('removecurrent');
        case 'Remove All', app.MakeFavorites('removeall');
        otherwise
          if exist(event.Source.Label, 'file')
            app.ePath.Value = event.Source.Label;
            app.ePathValueChanged();
          end
      end
    end

    %----------------------------------------------------------------------
    % Verify data structure, create necessary fields if missing
    function Data = Verify(~, Data)
      if isfield(Data, 'y')
        if find(size(Data.y) ~= 1) == 1
          Data.y = Data.y(:);
        end
      end

      Data.ax = kv3_safeget(Data, 'ax', []);
      if isfield(Data.ax, 'x')
        Data.ax.x = Data.ax.x(:);
      end

      if isfield(Data.ax, 'y')
        Data.ax.y = Data.ax.y(:);
      end

      Data.filename = kv3_safeget(Data, 'filename', '');
      sz = size(Data.y);

      Data.Script = kv3_safeget(Data, 'Script', '');

      if ~isfield(Data.ax, 'x'), Data.ax.x = (1:sz(1))'; end
      if ~isfield(Data.ax, 'xlabel'), Data.ax.xlabel = 'unknown, pnt'; end
      if ~isfield(Data.ax, 'y'), Data.ax.y = (1:sz(2))'; end
      if ~isfield(Data.ax, 'ylabel'), Data.ax.ylabel = 'unknown, pnt'; end

      Data.ax.filt = kv3_safeget(Data.ax, 'filt', 'none');
      Data.ax.diff = kv3_safeget(Data.ax, 'diff', 'normal');
      Data.ax.filtpar1 = kv3_safeget(Data.ax, 'filtpar1', 'auto');

    end

    %----------------------------------------------------------------------
    function Data = Process(app, Data)
      if isempty(Data.y), return; end

      filtx = 0; filty = 0;
      switch kv3_safeget(Data.ax, 'filt', '')
        case 'rc'
          cc = kv3_safeget(Data.ax, 'tc', 5);
          Data.y = rcfilt(Data.y, 1, cc).';
        case 'mvgaver'
          cc = kv3_safeget(Data.ax, 'tc', 5);
          fpar = kv3_safeget(Data.ax, 'filtpar1', 'auto');
          if strcmp(fpar,'auto')
            filtx = 1;
            if size(Data.y,2) > 5, filty = 1; end
          else
            if contains(fpar, '1D'), filtx = 1; end
            if contains(fpar, '2D'), filty = 1; end
          end
          if filtx, Data.y = kv_mvgavg(Data.y, cc, 'binom'); end
          if filty, Data.y = kv_mvgavg(Data.y.', cc, 'binom').'; end
        case 'savgol'
          cc   = kv3_safeget(Data.ax, 'tc', 5);
          cc1  = kv3_safeget(Data.ax, 'pol', 2);
          fpar = kv3_safeget(Data.ax, 'filtpar1', 'auto');
          if strcmp(fpar,'auto')
            filtx = 1;
            if size(Data.y,2) > 5, filty = 1; end
          else
            if contains(fpar, '1D'), filtx = 1; end
            if contains(fpar, '2D'), filty = 1; end
          end
          if filtx, Data.y = kv_mvgavg(Data.y, cc, 'savgol', cc1); end
          if filty, Data.y = kv_mvgavg(Data.y.', cc, 'savgol', cc1).'; end
        otherwise
      end
      switch kv3_safeget(Data.ax, 'diff', '')
        case 'psmod'
          cc = kv3_safeget(Data.ax, 'ps_mod', 5);
          sizey = size(Data.y,1);
          bl = sum(Data.y([1:4,end-3:end],:))/8;
          for k = 1:size(Data.y,2)
            Data.y(:,k) = fieldmod((1:sizey)', real(Data.y(:,k)-bl(1,k)).', cc).';
          end
        case 'integrate'
          % alsi 07.01.2004
          sizey = size(Data.y,1);

          % boep for cw is better:
          bl = mean(Data.y);
          %     bl = sum(y([1:4,end-3:end],:))/8;

          sizey = size(Data.y, 2);
          % a bit rough but enougth for viewing
          for k = 1:sizey
            Data.y(:, k) = cumsum(Data.y(:, k)-bl(1,k));
          end
          Data.y = Data.y .* (Data.ax.x(2) - Data.ax.x(1))/2;
        otherwise
      end
      Data.ax.filt = '';
      Data.ax.diff = '';
      shift = kv3_safeget(Data.ax,'s', 0);
      Data.y = Data.y + shift;
    end
  end

  %----------------------------------------------------------------------
  %----------------------------------------------------------------------
  %----------------------------------------------------------------------
  methods (Access = public)

    %----------------------------------------------------------------------
    function results = GetData(app, mode)
      switch mode
        case 'source', results = app.src; results = app.Verify(results);
        case 'psource', results = app.Process(app.src); results = app.Verify(results);
        case 'output', results = app.out; 
      end
    end

    %----------------------------------------------------------------------
    function SetData(app, mode, arg)
      switch mode
        case 'source', app.src = arg;
          app.SrcControlUpdate();
        case 'output', app.out = arg;
          app.UpdateSlider();
      end
      app.ProcessMenuChanged([]);
      app.View.DataSrcOut = 2;
      app.bSource.Value = 0;
      app.bOutput.Value = 1;
      app.PlotData();
    end

    %----------------------------------------------------------------------
    function results = Get(app, mode)
      switch mode
        case 'font', results = app.View.Font;
        case 'slice', results = min(app.View.Position, size(app.src.y,2));
        otherwise, results = [];
      end
    end

    %----------------------------------------------------------------------
    function results = Set(app, mode, value)
      switch mode
        case 'font', app.View.Font=value;
        case 'slice'
          app.View.Position=value;
          app.sl2Dplot.Value = value;
          app.PlotData();
        case 'cursor'
          title(app.haReal, value);
        case 'message'
          app.MessageBlocker = tic;
          title(app.haReal, value);
        case 'log'
          if length(app.TextArea.Value) > 4, app.TextArea.Value = ''; end
          app.TextArea.Value = [value; app.TextArea.Value];
        otherwise, results = [];
      end
    end

    %----------------------------------------------------------------------
    function MakeFavorites(app, mode)
      switch mode
        case 'update'
          delete(app.mFavoriteDirectoriesMain.Children);

          for ii=1:length(app.FavoriteDirectories)
            if ~isempty(app.FavoriteDirectories{ii})
              hmenu = uimenu(app.mFavoriteDirectoriesMain, 'Label', app.FavoriteDirectories{ii});
              hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnFavoriteSelected, true);
            end
            app.ini.KazanViewer.(['Favorite',num2str(ii)]) = app.FavoriteDirectories{ii};
          end
          hmenu = uimenu(app.mFavoriteDirectoriesMain, 'Label', 'Add Current', 'Separator', 1);
          hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnFavoriteSelected, true);
          hmenu = uimenu(app.mFavoriteDirectoriesMain, 'Label', 'Remove Current');
          hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnFavoriteSelected, true);
          hmenu = uimenu(app.mFavoriteDirectoriesMain, 'Label', 'Remove All');
          hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnFavoriteSelected, true);

        case 'addcurrent'
          the_dir = app.ePath.Value;
          idx = find(strcmp(app.FavoriteDirectories, the_dir));
          if ~isempty(idx)
            app.FavoriteDirectories(idx) = [];
            app.FavoriteDirectories{5} = '';
            for ii=4:-1:1, app.FavoriteDirectories{ii+1}=app.FavoriteDirectories{ii}; end
          elseif idx == 1, return;
          else
            for ii=4:-1:1, app.FavoriteDirectories{ii+1}=app.FavoriteDirectories{ii}; end
          end
          app.FavoriteDirectories{1}=the_dir;
          app.MakeFavorites('update');
        case 'removecurrent'
          the_dir = app.ePath.Value;
          idx = find(strcmp(app.FavoriteDirectories, the_dir));
          if ~isempty(idx)
            app.FavoriteDirectories(idx) = [];
            app.FavoriteDirectories{5} = '';
            app.MakeFavorites('update');
          end
        case 'removeall'
          for ii=1:5, app.FavoriteDirectories{ii} = ''; end
          app.MakeFavorites('update');
      end
    end

    %----------------------------------------------------------------------
    function MakePlugins(app, mode, arg1)
      switch mode
        case 'close'
          GUID = arg1.guid;
          idx = strcmp( cellfun( @(a) a.guid, app.Plugins, 'UniformOutput', false ), {GUID});
          app.Plugins = app.Plugins(~idx);
          fprintf('Plugin removed. %i left.\n', length(app.Plugins))
        case 'closeall'
          for ii=1:length(app.Plugins), try delete(app.Plugins{ii}); catch, end; end
        case 'onload'
          for ii=1:length(app.Plugins)
            try app.Plugins{ii}.FileUpdate(); catch, fprintf('FileUpdate error guid ''%s''\n', app.Plugins{ii}.guid); end
          end
        case 'search'
          plugins = dir(fullfile(app.ini.KazanViewer.plugin_path, '*.mlapp'));
          [sorted_plugin_names] = sortrows({plugins.name}');
          sorted_plugin_names(strcmpi(sorted_plugin_names,'kazan3.mlapp')) = [];

          delete(app.mPluginsMain.Children);

          for ii=1:length(sorted_plugin_names)
            plugin_name = upper(sorted_plugin_names{ii}(1:end-6));
            plugin_ini = kv3_safeget(app.ini, plugin_name, []);
            plugin_ini.favorite = kv3_safeget(plugin_ini, 'favorite', '0');
            app.ini.(plugin_name) = plugin_ini;
            favorite = str2double(plugin_ini.favorite);

            if favorite
              hmenu = uimenu(app.mPluginsMain, 'Label',plugin_name);
              hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnPluginSelected, true);
              hmenu.UserData = sorted_plugin_names{ii}(1:end-6);
            end
          end

          hmenuOther = uimenu(app.mPluginsMain, 'Label', 'Other plugins');
          hmenuAddFavorites = uimenu(app.mPluginsMain, 'Label', 'Add to Favorites', 'Separator', 1);
          hmenuRemoveFavorites = uimenu(app.mPluginsMain, 'Label', 'Remove from Favorites');
          for ii=1:length(sorted_plugin_names)
            plugin_name = upper(sorted_plugin_names{ii}(1:end-6));
            plugin_ini.favorite = kv3_safeget(app.ini.(plugin_name), 'favorite', '0');
            favorite = str2double(plugin_ini.favorite);

            if ~favorite
              hmenu = uimenu(hmenuOther, 'Label',plugin_name);
              hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnPluginSelected, true);
              hmenu.UserData = sorted_plugin_names{ii}(1:end-6);
              hmenu = uimenu(hmenuAddFavorites, 'Label',plugin_name);
              hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnPluginSelected, true);
              hmenu.UserData = plugin_name;
            else
              hmenu = uimenu(hmenuRemoveFavorites, 'Label',plugin_name);
              hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnPluginSelected, true);
              hmenu.UserData = plugin_name;
            end
          end

          hmenu = uimenu(app.mPluginsMain, 'Label', 'Arrange plugins', 'Separator', 1);
          hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnPluginSelected, true);
          hmenu = uimenu(app.mPluginsMain, 'Label', 'Reload plugins');
          hmenu.MenuSelectedFcn = createCallbackFcn(app, @OnPluginSelected, true);
      end
    end

    %----------------------------------------------------------------------
    function points = Ginput(app, num)
      points = [];

      Crossbar = app.View.Crossbar;
      app.View.Crossbar = true;
      app.View.CrossbarClick = {};
      app.View.CrossbarWait = num;
      app.UIFigure.focus();

      uiwait(app.UIFigure);
      app.View.CrossbarWait = 0;
      delete(findall(app.haReal, 'Tag', 'CrosshairReal'));
      app.View.Crossbar = Crossbar;
      
      for ii=1:length(app.View.CrossbarClick)
        p1 = app.View.CrossbarClick{ii}.CurrentPoint;
        points(ii,:) = p1(1,:);
      end
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      app.MessageBlocker = tic;

      % connect local version of function
      if exist('kv3_ParameterReader_local', 'file') == 2
        app.ParameterReader = @kv3_ParameterReader_local;
        disp(['Using local: ', which('kv3_ParameterReader_local')]);
      else
        app.ParameterReader = @kv3_ParameterReader;
      end
      if exist('kv3_ScriptLoad_local', 'file') == 2
        app.ScriptLoad = @kv3_ScriptLoad_local;
        disp(['Using local: ', which('kv3_ScriptLoad_local')]);
      else
        app.ScriptLoad = @kv3_ScriptLoad;
      end
      
      % Data extensions
      app.ddExt.Items = app.ScriptLoad('extension');

      inifilename = fullfile(userpath, 'kazan3.ini');
      app.ini = kv3_inimanage(inifilename);
      app.ini.KazanViewer = kv3_safeget(app.ini, 'KazanViewer', []);

      % Load last saved position
      app.ini.KazanViewer.last_path = kv3_safeget(app.ini.KazanViewer, 'last_path', pwd);
      if isempty(app.ini.KazanViewer.last_path), app.ini.KazanViewer.last_path = pwd; end
      last_extension = kv3_safeget(app.ini.KazanViewer, 'last_extension', '');
      if any(strcmp(app.ddExt.Items, last_extension))
        app.ddExt.Value = last_extension;
      end
      app.ePath.Value = app.ini.KazanViewer.last_path;

      % Load list of favorite directories
      for ii=1:5
        app.FavoriteDirectories{ii} = kv3_safeget(app.ini.KazanViewer, ['Favorite',num2str(ii)], '');
        app.ini.KazanViewer.(['Favorite',num2str(ii)]) = app.FavoriteDirectories{ii};
      end

      Font = str2double(kv3_safeget(app.ini.KazanViewer, 'Font', '12'));
      idx = [app.Font0Menu,app.Font1Menu,app.Font2Menu,app.Font3Menu,app.Font4Menu,app.FontM1Menu,app.FontM2Menu];
      set(idx, 'Checked', 'off');
      for ii=1:length(idx), if str2double(get(idx(ii), 'Tag')) == Font - 12, set(idx(ii), 'Checked', 'on'); break; end; end

      % define directories
      app.ini.KazanViewer.plugin_path = kv3_safeget(app.ini.KazanViewer, 'plugin_path', fileparts(which('kazan3')));

      app.View.DataSrcOut = 1;
      app.View.PlotType = 1;     % plot types 1/2/3/4/5
      app.View.XType = 'plot';   % plot
      app.View.YType = 'plot';   % plot
      app.View.RealImagMagn = 1;
      app.View.AbscissaXorY = 1;
      app.View.OrdinateXorY = 2;
      app.View.MaxDim = 1;
      app.View.Position = 1;
      app.View.Range = 'tight';
      app.View.AxisGrid = 'off';
      app.View.MinAxisGrid = 'off';
      app.View.DefaultFont = 12;
      app.View.Font = Font;
      app.View.Crossbar = false;
      app.View.CrossbarClick = {};
      app.View.ZoomDirection = 'Z';
      app.View.CrossbarWait = 0;

      app.View.Par = {};
      app.View.Par{end+1} = struct('title', 'xlabel', 'prefix', '', 'value', '', 'type', 's', 'about', '');
      app.View.Par{end+1} = struct('title', 'ylabel', 'prefix', '', 'value', '', 'type', 's', 'about', '');
      app.View.Par{end+1} = struct('title', 'Color', 'prefix', '', 'value', 'b', 'type', 's', 'about', '');
      app.View.Par{end+1} = struct('title', 'LineStyle', 'prefix', '', 'value', '-', 'type', 's', 'about', '');
      app.View.Par{end+1} = struct('title', 'LineWidth', 'prefix', '', 'value', 1, 'type', 'f', 'about', '');
      app.View.Par{end+1} = struct('title', 'Marker', 'prefix', '', 'value', 'none', 'type', 's', 'about', '');
      app.View.Par{end+1} = struct('title', 'dx', 'prefix', '', 'value', 0, 'type', 'f', 'about', 'X axis correction');
      app.View.Par{end+1} = struct('title', 's', 'prefix', '', 'value', 0, 'type', 'f', 'about', '');
      app.View.Par{end+1} = struct('title', 'ps_mod', 'prefix', '', 'value', 1, 'type', 'f', 'about', '');
      app.View.Par{end+1} = struct('title', 'tc', 'prefix', '', 'value', 2, 'type', 'f', 'about', '');
      app.View.Par{end+1} = struct('title', 'contour', 'prefix', '', 'value', '.2:.1:.6', 'type', 'f', 'about', '');
      app.View.Par{end+1} = struct('title', 'yslicelabel', 'prefix', '', 'value', '', 'type', 's', 'about', '');

      app.DataParameters = app.ParameterReader('options');

      %   KAZAN VIEWER logo
      app.src.ax.xlabel = 'KAZAN viewer logo,';
      app.src.ax.type = 'data';
      sz = 0.3;
      kx = [-.15 0 0 0 1 0 1]*1.3; ky = [1 1 0 .5 1 .5 0];
      ax = [0 .5 .75 .25 .75 1]; ay = [0 1 .5 .5 .5 0];
      zx = [0 1 0  0  0 1 0 1 1 1]; zy = [0 1 1 .92 1 1  0 0 .08 0];
      nx = [0 0 1 1 1+.15]*1.4; ny = [0 1 0  1  1];
      app.src.ax.x = [8,8,-2,-2, kx-.3,ax+1.2,zx+2.4,ax+3.6,nx+4.8, ...
        8,8,6.2,8,8,-2,-2,-0.3,-2,-2]'*sz;
      app.src.ax.y = 1;
      app.src.y = [1,2,2,1,ky,ay,zy,ay,ny, ...
        1,0,0,0,-1,-1,0,0,0,1]';
      app.src = app.Verify(app.src);
      app.SrcControlUpdate();

      app.Message = '';
      app.MakeParameters('update_list');
      app.ddExtValueChanged([]);
      app.PlotData();

      app.MakePlugins('search');
      app.MakeFavorites('update');
      app.Set('message', 'Welcome !')

    end

    % Callback function: cmReladDir, ePath
    function ePathValueChanged(app, event)
      app.ePath.Value = app.LoadDirectory(app.ePath.Value);

    end

    % Value changed function: ddExt
    function ddExtValueChanged(app, event)
      app.ddDataType.Items = app.ScriptLoad('subtype', app.ddExt.Value);
      app.ddDataType.Value =  app.ddDataType.Items{1};
      app.ddDataTypeValueChanged([]);

      %       handles.ext = str{sel};
      %       handles = pmDatatype_Callback(h, eventdata, handles, varargin);
      app.LoadDirectory(app.ePath.Value);

    end

    % Value changed function: ddDataType
    function ddDataTypeValueChanged(app, event)
      app.LoadScriptOptions = app.ScriptLoad('options', [app.ddExt.Value, app.ddDataType.Value]);
      app.ini.KazanViewer.last_extension = app.ddExt.Value;

      % generate contols
      if length(app.LoadScriptOptions) >= 1
        app.ddLoadOption1.Items = app.LoadScriptOptions{1}.String;
        app.ddLoadOption1.ItemsData = app.LoadScriptOptions{1}.Data;
        app.ddLoadOption1.Value = app.ddLoadOption1.ItemsData{ app.LoadScriptOptions{1}.Default};
        app.ddLoadOption1.Visible = true;
      else
        app.ddLoadOption1.Visible = false;
      end
      if length(app.LoadScriptOptions) >= 2
        app.ddLoadOption2.Items = app.LoadScriptOptions{2}.String;
        app.ddLoadOption2.ItemsData = app.LoadScriptOptions{2}.Data;
        app.ddLoadOption2.Value = app.ddLoadOption2.ItemsData{ app.LoadScriptOptions{2}.Default};
        app.ddLoadOption2.Visible = true;
      else
        app.ddLoadOption2.Visible = false;
      end
      if length(app.LoadScriptOptions) >= 3
        app.eLoadOption.Visible = true;
      else
        app.eLoadOption.Visible = false;
      end

      if ~app.eLoadOption.Visible && ~app.ddLoadOption1.Visible && ~app.ddLoadOption2.Visible
        app.lbDirlist.Layout.Row = [3,5];
      elseif ~app.ddLoadOption1.Visible && ~app.ddLoadOption2.Visible
        app.lbDirlist.Layout.Row = [3,4];
      else
        app.lbDirlist.Layout.Row = 3;
      end
    end

    % Callback function: lbDirlist, lbDirlist
    function lbDirlistValueChanged(app, event)
      selected = app.lbDirlist.Value;

      if isempty(selected)
        % do nothing
      elseif contains(selected, '[')
        cd(fullfile(app.ePath.Value, selected(2:end-1)));
        app.ePath.Value = pwd;
        app.LoadDirectory(app.ePath.Value);
      else
        file_name = fullfile(app.ePath.Value, selected);
        if app.cmSelectSource.Checked
          app.View.DataSrcOut = 1;
          app.bSource.Value = 1;
          app.bOutput.Value = 0;
        end
        app.LoadFile(file_name);
      end
      %       file_list = app.lbDirlist.String;

      %       if isempty(index_selected), return; end
      %       filename = file_list{index_selected}; % Item selected in list box
      %       if handles.is_dir(handles.sorted_index(index_selected)) % If directory
      %         %strip directory brackets
      %         filename = filename(2:end-1);
      %         % Load list box with new directory when double clicked
      %         seltype = get(handles.MainFigure, 'SelectionType');
      %         if strcmp(seltype, 'open'), handles = LoadDirectory(filename, handles); end
      %       else
      %         handles.src.ax = [];
      %         handles.src.y = [];
      %
      %         handles=LoadFile(fullfile(handles.dir_path, filename), handles);
      %
      %         % call update and handles repair for all plugins
      %         if ~isempty(handles.PluginHandle)
      %           for k=1:length(handles.PluginHandle)
      %             if ~isvalid(handles.PluginHandle{k})
      %               % delete plugin
      %               DeletePlugins(handles.PluginHandle{k}, handles.MainFigure);
      %               handles = guidata(handles.MainFigure);
      %             else
      %               try
      %                 if contains(class(handles.PluginHandle{k}), 'Figure')
      %                   [~,name] = fileparts(get(handles.PluginHandle{k}, 'FileName'));
      %                   str = [name, '(''FileUpdate'',handles.PluginHandle{k},guidata(handles.PluginHandle{k}));'];
      %                   try
      %                     eval(str);
      %                     handles = guidata(handles.MainFigure);
      %                   catch err, ShowError(err);
      %                   end
      %                 else
      %                   handles.PluginHandle{k}.FileUpdate();
      %                   handles = guidata(handles.MainFigure);
      %                 end
      %               catch err, ShowError(err);
      %               end
      %             end
      %           end
      %         end
      %
      %         smSelPar_Callback([], [], handles);
      %         guidata(handles.MainFigure,handles);
      %
      %         handles = guidata(handles.MainFigure);
      %
      %         source = handles.DataSrcOut;
      %         guidata(handles.MainFigure, handles);
      %         %   if source ~=1, source = 3; end;
      %         SetDataSrcOut(source, handles);

    end

    % Value changed function: sl2Dplot
    function sl2DplotValueChanged(app, event)
      app.View.Position = floor(app.sl2Dplot.Value + 0.5);
      app.sl2Dplot.Value = app.View.Position;
      app.sl2Dplot.Tooltip = num2str(app.View.Position);

      %
      %       handles.Selection = floor(get(handles.sl2Dplot, 'Value')+0.5);
      %       guidata(h, handles);
      %       str = {'X', 'Y'};
      %       data = kv3_safeget(handles.src.ax, lower(str{handles.AntiProjection}), []);
      %       if isempty(data), data = 0; else data = data(min(handles.Selection,length(data))); end
      %       label = kv3_safeget(handles.src.ax, [lower(str{handles.AntiProjection}), 'label'], '?');
      %       str1 = [str{handles.AntiProjection}, ' (', label,'): ' num2str(data), ' [', num2str(handles.Selection),']'];
      %
      %       set(handles.stCoordinates, 'String', str1);
      app.PlotData();
      %
      %       % call update and handles repair for all plugins
      %
      %       par.Selection = handles.Selection;
      %       par.Dim = lower(str{handles.AntiProjection});
      %       for k=1:length(handles.PluginHandle)
      %         if contains(class(handles.PluginHandle{k}), 'Figure')
      %           try
      %             [~,name] = fileparts(get(handles.PluginHandle{k}, 'FileName'));
      %             str = [name, '(''SelectionUpdate'',handles.PluginHandle(k), par, guidata(handles.PluginHandle{k}));'];
      %             %             try eval(str); catch; end
      %           catch  err
      %             ShowError(err);
      %             DeletePlugins(handles.PluginHandle{k}, handles.MainFigure);
      %           end
      %         end
      %       end

    end

    % Value changed function: bImag, bMagnitude, bReal
    function SetDataPartChanged(app, event)
      hdl = [app.bReal, app.bImag, app.bMagnitude];
      %       hdl1 = [handles.mViewReal, handles.mViewImag, handles.mViewMagnitude];

      event.Source.Value = 1;
      set(hdl(hdl ~= event.Source),'Value',0);
      app.View.RealImagMagn = find(hdl == event.Source);

      app.pnlAxisSizeChanged([]);
      app.PlotData();
    end

    % Size changed function: pnlAxis
    function pnlAxisSizeChanged(app, event)
      position = app.pnlAxis.Position;

      switch kv3_safeget(app.View, 'RealImagMagn', 1)
        case 2
          app.haReal.Position = [20, 10+position(4)/2, position(3) - 40, position(4)/2-30];
          app.haImag.Position = [20, 20, position(3) - 40, position(4)/2-30];
          app.haImag.Visible = true;
        otherwise
          app.haReal.Position = [20, 20, position(3) - 40, position(4)-40];
          app.haImag.Visible = false;
      end

    end

    % Menu selected function: AboutKazanViewerMenu, HowtoPrintMenu, 
    % ...and 4 other components
    function KazanViewerHelp(app, event)
      switch event.Source
        case app.AboutKazanViewerMenu
          msgbox({'KAZAN data viewer and plugins';'Version 3.0 beta (11/15/2024)'; 'by Boris Epel, 2003-2023'}, ...
            'About', 'help')
        case app.mAxisDataStructure
          msgbox({'Parameters(fields) that affect visualization:'; ...
            'title     - data title'; ...
            'x/y       - 1D axis array (column!)   REQUIRED'; ...
            'x/ylabel  - axis labels'; ...
            'Color     - color of the trace b/g/r/c/m/y/k or [r g b], rgb=0..1';...
            'LineStyle - style of the line -/:/-./--'; ...
            'LineWidth - width of the line 0..inf'; ...
            'Marker    - style of dots none/./o/x/+/*/s/d/v/^/</>/p/h'; ...
            'contour   - for 2D plots - contour level';''; ...
            'Plot type related:'; ...
            'reference - for PPM plot'; ...
            'freq1     - for g-plot';''; ...
            'Data processing:'; ...
            'dx, s          - shift of abscissa/ordinate(2D)'; ...
            'diff, ps_mod    - pseudo modulation parameters diff/integr'; ...
            'filt, tc, pol   - filtering parameters rc/mvgaver/savgol, 1..inf, 1..inf';''; ...
            'Fields of ax structure are accessible throught the';...
            'combobox and edit controls at the bottom of the main window'},...
            'Axis Data Structure (ax)', 'help')
        case app.HowtoStartMenu
          msgbox({'1: Load data : Select file extension -> Select file type -> Change parameters (if present)';...
            '2: Adjust the view: Right click on the plot, select options';...
            '3: Process the data: Start a plugin from Plugins menu';...
            '4: Copy processed data from Output to Source (<<)';...
            '5: Print plot: Select Tools/Show in Window'},...
            'How To Start', 'help')
        case app.mDataLoadRoutines
          ScriptLoad_info = functions(app.ScriptLoad);
          ParameterReader_info = functions(app.ParameterReader);
          msgbox({'The association of loading routines is located in kv3_ScriptLoad.'; ...
            'The parameter extraction is located in kv3_ParameterReader.'; ...
            "To redefine, create local versions: kv3_ScriptLoad_local, kv3_ParameterReader_local"; ...
            "Local versions will not be uploaded to GitHub";"";...
            ['Active ScriptLoad:  ', ScriptLoad_info.file]; ...
            ['Active ParameterReader:  ', ParameterReader_info.file];"";...
            'Developer''s corner:'; ...
            'ListOfExtensions = kv3_ScriptLoad(''extension'')';...
            'ListOfSubTypes = kv3_ScriptLoad(''subtype'', extension)'; ...
            'ListOfControls = kv3_ScriptLoad(''options'', file_type)'; ...
            'LoadingScript = kv3_ScriptLoad(''script'', file_type, file_name, options)';'';...
            },...
            'Data Load Routines', 'help')
        case app.mPluginsMenu
          msgbox({'Plugins are pop-up GUIs, which have KAZAN GUI data access';...
            'Plugins must have:';...
            'function startupFcn(app, caller, guid) for initialization';...
            'property KazanViewer to store kazan viewer handle'
            'property guid !public! to be identified by kazan viewer'
            'function FileUpdate(app) !public! for update when data are loaded'; ...
            'function UIFigureCloseRequest(app, event) to inform of plugin closing';...
            'function UIFigureSizeChanged(app, event) to update interface on resizing';...
            'Plugins use:'; ...
            'src = app.KazanViewer.GetData(''source''); to retrive data';...
            'src = app.KazanViewer.GetData(''psource''); to retrive processed data';...
            'app.KazanViewer.SetData(''output'', out); to set output data';...
            'Font = app.KazanViewer.Get(''font''); to retrive font size';...
            'app.KazanViewer.Set(''slice'', slice); to change current slice';...
            },...
            'Plugins Programming', 'help')
        case app.HowtoPrintMenu
          msgbox({['Select ''Tools/Show in Separate Figure'' menu.',...
            'Then the standard Matlab figure will appear.'];...
            'Optionally the plot can be conditioned using Export Image utility';...
            'or Profile Manager (see corresponding menus)';'';...
            'To create multiple axis on the figure use Show in Axis menu.';...
            ''},...
            'How to print', 'help')
        otherwise, msgbox('No help found');
      end
    end

    % Button pushed function: bUp
    function bUpButtonPushed(app, event)
      cd(fullfile(app.ePath.Value, '..'));
      app.ePath.Value = pwd;
      app.ePath.Value=app.LoadDirectory(app.ePath.Value);
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      inifilename = fullfile(userpath, 'kazan3.ini');
      try kv3_inimanage(inifilename, app.ini); catch, end
      app.MakePlugins('closeall');
      delete(app);
    end

    % Value changed function: ddSelParameter
    function ddSelParameterValueChanged(app, event)
      app.MakeParameters('update_editor');
      app.PlotData();
    end

    % Value changed function: eParameter
    function eParameterValueChanged(app, event)
      app.src.ax = app.MakeParameters('from_editor', app.src.ax);
      app.PlotData();
    end

    % Button pushed function: b2D
    function b2DValueChanged(app, event)
      if app.View.MaxDim == 1
        app.View.AbscissaXorY = 1; app.View.OrdinateXorY = 2;
      else
        if app.View.AbscissaXorY == 1, app.View.AbscissaXorY = 2; app.View.OrdinateXorY = 1;
        else, app.View.AbscissaXorY = 1; app.View.OrdinateXorY = 2;
        end
      end
      str = {'X', 'Y'};
      app.b2D.Text = str{app.View.AbscissaXorY};
      app.UpdateSlider();
    end

    % Value changed function: b1D, bContour, bDensity, bStack
    function SetPlotTypeChanged(app, event)

      hdl = [app.b1D,  app.bStack, app.bContour, app.bDensity];
      %       hdl1 = [handles.mView1D, handles.mViewStacked, handles.mViewContour, handles.mViewDensity];

      %       isScroller = handles.isScroller;
      %       handles.isScroller = (type == 1);

      event.Source.Value = 1;
      set(hdl(hdl ~= event.Source),'Value',0);
      app.View.PlotType = find(hdl == event.Source);

      %       if isScroller ~= handles.isScroller
      %         str = {'off', 'on'};
      %         MainFigure_ResizeFcn([], [], handles);
      %         set(handles.pb2DSelect, 'Visible', str{handles.isScroller + 1});
      %         set(handles.sl2Dplot, 'Visible', str{handles.isScroller + 1});
      %       end

      %       % incorrect ...
      %       if handles.isScroller
      %         dim = size(handles.src.y);
      %         if dim(2) > 1
      %           set(handles.pb2DSelect, 'String', 'X');
      %           set(handles.sl2Dplot, 'Min', 1, 'Max', dim(2), 'Value', 1,...
      %             'SliderStep', [1 10]*1/dim(2));
      %           handles = sl2Dplot_Callback(handles.sl2Dplot, [], handles);
      %         end
      %       else
      %         set(handles.stCoordinates, 'String', '');
      %       end

      app.PlotData();
    end

    % Menu selected function: ConvertFoldertoASCIIMenu, 
    % ...and 7 other components
    function ExportSaveConvert(app, event)
      switch event.Source
        case app.OpenDirectoryMenu
          directory_name = uigetdir(pwd,'Select directory');
          if ~directory_name, return; end
          app.ePath.Value = directory_name;
          app.LoadDirectory(directory_name);
        case {app.SaveSourceMenu, app.SaveSourcewithProcessingMenu, app.cmConvert}
          curdir = pwd;
          cd(app.ini.KazanViewer.last_path);
          [pname,fname,sel] = uiputfile({'*.txt', 'ASCII files (*.txt)'; '*.dat', 'ASCII files (*.dat)'; '*.d01', 'SpecMan files (*.d01)';'*.dsc', ...
            'Bruker files (new, *.dsc)'; '*.par', 'Bruker files (old, *.par)'; '*.lmb', 'WinSIM files (*.lmb)'}, ...
            'Select file format', kv3_safeget(app.src, 'filename', ''));
          cd(curdir);

          if pname == 0, return; end

          data = app.src;
          data.ax.x = data.ax.x + safeget(data.ax, 'dx', 0);
          data.ax.dx = 0;
          if event.Source == app.SaveSourcewithProcessingMenu
            data = app.Process(data);
            %             src.ax = getplotaxis(handles.xrepresent, handles.yrepresent, src.ax);
            %             switch handles.DataPart
            %               case 1, src.y = real(src.y);
            %               case 2, src.y = real(src.y);
            %               case 4, src.y = abs(src.y);
            %             end
          end

          file_name = fullfile(fname, pname);
          switch sel
            case 1
              kv3_asciiwrite(file_name, data, 'usefooter', 1, 'origin', data.filename, 'parameters', app.DataParameters);
              fprintf('%s successfully saved in format "txt"\n', file_name);
            case 2
              kv3_asciiwrite(file_name, data, 'usefooter', 1, 'origin', data.filename, 'parameters', app.DataParameters);
              fprintf('%s successfully saved in format "dat"\n', file_name);
            case 3
              kv_d01write(file_name, data);
              fprintf('%s successfully saved in format "d01/exp"\n', file_name);
            case 4
              kv_brukerwrite(file_name, data, 'DSC');
              fprintf('%s successfully saved in format "dsc/dta"\n', file_name);
            case 5
              kv_brukerwrite(file_name, data, 'PAR');
              fprintf('%s successfully saved in format "par/spc"\n', file_name);
            case 6
              kv_LMBwrite(file_name, data);
              fprintf('%s successfully saved in format "LMB"\n', file_name);
          end
        case app.ConvertFoldertoASCIIMenu
          button = questdlg('Converting all files to ASCII?', 'ASCII file conversion',...
            'Yes (dat)', 'Yes (txt)', 'No', 'No');
          if ~contains(button, 'No')
            extension = app.ddExt.Value;
            folder = app.ini.KazanViewer.last_path;
            dir_struct = dir(fullfile(folder, extension));
            file_dir_struct = dir_struct(~[dir_struct.isdir]);
            if strcmp(button, 'Yes (dat)'), ext = '.dat'; else, ext = '.txt'; end

            for kk=1:length(file_dir_struct)
              file_name = fullfile(folder,file_dir_struct(kk).name);
              ExpLoadingScript = app.ScriptLoad('script', [app.ddExt.Value, app.ddDataType.Value], file_name, []);
              for k = 1:size(ExpLoadingScript, 2)
                eval(ExpLoadingScript{k});
              end
              res = struct('y',y,'ax',ax,'dsc',dsc,'filename',file_name);
              res.Script = ExpLoadingScript;

              res.ax = app.ParameterReader('load', res.ax, res.dsc);
              fname = fullfile(folder, [file_dir_struct(kk).name, ext]);

              kv3_asciiwrite(fname, app.Verify(res), 'usefooter', 1, 'origin', file_dir_struct(kk).name, 'parameters', app.DataParameters);
              fprintf('%s successfully saved in format %s\n', file_dir_struct(kk).name, ext);
            end
          end
        case app.ExportSrcToWorkspaceMenu
          disp('Variable ''exp_src'' is created in the workspace');
          assignin('base', 'exp_src', app.src);
          disp(app.src);
        case app.ExportOuttoWorkspaceMenu
          disp('Variable ''exp_out'' is created in the workspace');
          assignin('base', 'exp_out', app.out);
          for ii=1:length(app.out), disp(app.out{ii}); end
        case app.LoadSrcfromWorkspaceMenu
          tt = evalin('base', 'exist(''exp_src'', ''var'')');
          if tt == false
            disp('Variable ''exp_src'' was not found in the workspace');
          else
            tt = evalin('base', 'exp_src');
            app.src = app.Verify(tt);
            disp('Variable ''exp_src'' was loaded from workspace');
            app.SrcControlUpdate();
            try app.PlotData(); catch, end
          end
      end
    end

    % Callback function: bOutput, bSource, bTransfer
    function SrcOutTransferChanged(app, event)
      switch event.Source
        case app.bTransfer

          cols = {}; titles = {};
          for k = 1:size(app.out, 2)
            cols{end+1, 1} =  kv3_safeget(app.out{k}.ax, 'Color', [0;0;1]);
            titles{end+1, 1} = kv3_safeget(app.out{k}, 'title', '');
          end
          n = kv_seltrace(cols, titles);
          if n
            app.src = app.out{n};
            app.bSource.Value = 1;
            app.bOutput.Value = 0;
            app.View.DataSrcOut = 1;
          end
        case app.bOutput
          app.bSource.Value = 0;
          app.bOutput.Value = 1;
          app.View.DataSrcOut = 2;
        case app.bSource
          app.bSource.Value = 1;
          app.bOutput.Value = 0;
          app.View.DataSrcOut = 1;
      end
      app.ProcessMenuChanged([]);
      app.PlotData();
    end

    % Menu selected function: AspectNormalMenu, AspectSquareMenu, 
    % ...and 8 other components
    function ViewValueChanged(app, event)
      switch event.Source
        case app.AxisTightMenu
          app.AxisTightMenu.Checked = 'on';
          set([app.AxisManualMenu,app.AxisAutoMenu], 'Checked', 'off');
          app.View.Range = 'tight';
        case app.AxisAutoMenu
          app.AxisAutoMenu.Checked = 'on';
          set([app.AxisTightMenu,app.AxisManualMenu], 'Checked', 'off');
          app.View.Range = 'auto';
        case app.AxisManualMenu
          app.AxisManualMenu.Checked = 'on';
          set([app.AxisTightMenu,app.AxisAutoMenu], 'Checked', 'off');
          app.View.Range = 'manual';
        case app.AspectNormalMenu
          app.AspectNormalMenu.Checked = 'on';
          set([app.AspectSquareMenu,app.AxisEqualMenu], 'Checked', 'off');
          app.View.Aspect = 'normal';
          app.View.Aspect1 = 'normal';
        case app.AspectSquareMenu
          app.AspectSquareMenu.Checked = 'on';
          set([app.AspectNormalMenu,app.AxisEqualMenu], 'Checked', 'off');
          app.View.Aspect = 'square';
          app.View.Aspect1 = 'normal';
        case app.AxisEqualMenu
          app.AxisEqualMenu.Checked = 'on';
          set([app.AspectNormalMenu,app.AspectSquareMenu], 'Checked', 'off');
          app.View.Aspect = 'normal';
          app.View.Aspect1 = 'equal';
        case app.ShowGridMenu
          if contains(app.View.AxisGrid, 'on')
            app.View.AxisGrid = 'off';
            app.ShowGridMenu.Checked = 'off';
          else
            app.View.AxisGrid = 'on';
            app.ShowGridMenu.Checked = 'on';
          end
        case app.ShowMinorGridMenu
          if contains(app.View.MinAxisGrid, 'on')
            app.View.MinAxisGrid = 'off';
            app.ShowMinorGridMenu.Checked = 'off';
          else
            app.View.MinAxisGrid = 'on';
            app.ShowMinorGridMenu.Checked = 'on';
          end
        case app.ZoomMenu
          zoom(app.UIFigure)
          return;
      end
      app.PlotData();
    end

    % Size changed function: UIFigure
    function UIFigureSizeChanged(app, event)
      standard_size = [70   669   847   581];
      fig_size = app.UIFigure.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      Font = kv3_safeget(app.View, 'Font', 12);
      font_size = Font * scaling_factor;
      set(findall(app.UIFigure, 'Type', 'UIButton'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'UIStateButton'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'axes'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'UIEditField'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'UIDropDown'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'UIListBox'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'UILabel'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'UITextArea'), 'FontSize', font_size);
      set(findall(app.UIFigure, 'Type', 'UIPanel'), 'FontSize', font_size);
      % how to scale menu ???
      % set(findall(app.UIFigure, 'Type', 'UIMenu'), 'FontSize', font_size);

    end

    % Menu selected function: Font0Menu, Font1Menu, Font2Menu, 
    % ...and 4 other components
    function Font0MenuSelected(app, event)
      idx = [app.Font0Menu,app.Font1Menu,app.Font2Menu,app.Font3Menu,app.Font4Menu,app.FontM1Menu,app.FontM2Menu];

      event.Source.Checked = 'on';
      set(idx(idx ~= event.Source), 'Checked', 'off');

      app.View.Font = app.View.DefaultFont + str2double(event.Source.Tag);
      app.ini.KazanViewer.Font = num2str(app.View.Font);
      app.UIFigureSizeChanged([]);

    end

    % Menu selected function: NextFileMenu, PreviousFileMenu
    function NextFileMenuSelected(app, event)
      selected = app.lbDirlist.Value;
      [~,idx] = ismember(selected, app.lbDirlist.Items);
      switch event.Source
        case app.NextFileMenu
          if idx+1 <= length(app.lbDirlist.Items)
            app.lbDirlist.Value = app.lbDirlist.Items{idx+1};
          end
        case app.PreviousFileMenu
          if idx > 1
            app.lbDirlist.Value = app.lbDirlist.Items{idx-1};
          end
      end
      app.lbDirlistValueChanged([]);
    end

    % Menu selected function: AutoMenu, BothMenu, FirstDimMenu, 
    % ...and 20 other components
    function ProcessMenuChanged(app, event)
      if ~isempty(event) && ~(app.View.DataSrcOut == 2 && isempty(app.out))
        switch event.Source
          case app.NormalMenu
            if app.View.DataSrcOut == 1, app.src.ax.diff = 'normal'; else, app.out{1}.ax.diff = 'normal'; end
          case app.PSmodMenu
            if app.View.DataSrcOut == 1, app.src.ax.diff = 'psmod'; else, app.out{1}.ax.diff = 'psmod'; end
          case app.IntegrateMenu
            if app.View.DataSrcOut == 1, app.src.ax.diff = 'integrate'; else, app.out{1}.ax.diff = 'integrate'; end
          case app.NotFilteredMenu
            if app.View.DataSrcOut == 1, app.src.ax.filt = 'none'; else, app.out{1}.ax.filt = 'none'; end
          case app.RCMenu
            if app.View.DataSrcOut == 1, app.src.ax.filt = 'rc'; else, app.out{1}.ax.filt = 'rc'; end
          case app.MaverMenu
            if app.View.DataSrcOut == 1, app.src.ax.filt = 'mvgaver'; else, app.out{1}.ax.filt = 'mvgaver'; end
          case app.SavgolMenu
            if app.View.DataSrcOut == 1, app.src.ax.filt = 'savgol'; else, app.out{1}.ax.filt = 'savgol'; end
          case app.AutoMenu
            if app.View.DataSrcOut == 1, app.src.ax.filtpar1 = 'auto'; else, app.out{1}.ax.filtpar1 = 'auto'; end
          case app.FirstDimMenu
            if app.View.DataSrcOut == 1, app.src.ax.filtpar1 = '1D'; else, app.out{1}.ax.filtpar1 = '1D'; end
          case app.SecondDimMenu
            if app.View.DataSrcOut == 1, app.src.ax.filtpar1 = '2D'; else, app.out{1}.ax.filtpar1 = '2D'; end
          case app.BothMenu
            if app.View.DataSrcOut == 1, app.src.ax.filtpar1 = 'both'; else, app.out{1}.ax.filtpar1 = 'both'; end
          case app.xyMenu
            app.View.XType = 'plot'; app.View.YType = 'plot';
          case app.XAxisAsIsMenu        % X
            app.View.XType = 'plot';
          case app.XLogMenu
            app.View.XType = 'semilog';
          case app.ReverseXMenu
            app.View.XType = 'reverse';
          case app.XpntsMenu
            app.View.XType = 'pnt';
          case app.Xgspacefreq1Menu
            app.View.XType = 'gspace';
          case app.XppmreferenceMenu
            app.View.XType = 'ppm';
          case app.Xppm1referenceMenu
            app.View.XType = 'ppm-1';
          case app.YAxisAsIsMenu        % Y
            app.View.YType = 'plot';
          case app.YpntsMenu
            app.View.YType = 'pnt';
          case app.ReverseYMenu
            app.View.YType = 'reverse';
          case app.YLogMenu
            app.View.YType = 'semilog';
        end
      end

      % Update menu items
      if app.View.DataSrcOut == 1 || (app.View.DataSrcOut == 2 && isempty(app.out))
        ax = app.src.ax;
      else
        ax = app.out{1}.ax;
      end
      idx = [app.NormalMenu, app.PSmodMenu, app.IntegrateMenu];
      val = {'normal', 'psmod', 'integrate'};
      diffidx = contains(val, kv3_safeget(ax, 'diff', 'normal'));
      set(idx(diffidx), 'Checked', 'on');
      set(idx(~diffidx), 'Checked', 'off');
      idx = [app.NotFilteredMenu, app.RCMenu, app.MaverMenu, app.SavgolMenu];
      val = {'none', 'rc', 'mvgaver', 'savgol'};
      diffidx = contains(val, kv3_safeget(ax, 'filt', 'none'));
      set(idx(diffidx), 'Checked', 'on');
      set(idx(~diffidx), 'Checked', 'off');
      idx = [app.AutoMenu, app.FirstDimMenu, app.SecondDimMenu, app.BothMenu];
      val = {'auto', '1D', '2D', 'both'};
      diffidx = contains(val, kv3_safeget(ax, 'filtpar1', 'auto'));
      set(idx(diffidx), 'Checked', 'on');
      set(idx(~diffidx), 'Checked', 'off');
      if contains(app.View.XType, 'plot') && contains(app.View.YType, 'plot'), app.xyMenu.Checked = 'on'; else, app.xyMenu.Checked = 'off'; end
      idx = [app.XAxisAsIsMenu, app.XpntsMenu, app.XLogMenu, app.ReverseXMenu, app.Xgspacefreq1Menu, app.XppmreferenceMenu, app.Xppm1referenceMenu];
      val = {'plot', 'pnt', 'semilog', 'reverse', 'gspace', 'ppm', 'ppm-1'};
      diffidx = contains(val, app.View.XType);
      set(idx(diffidx), 'Checked', 'on');
      set(idx(~diffidx), 'Checked', 'off');
      idx = [app.YAxisAsIsMenu, app.YpntsMenu, app.YLogMenu, app.ReverseYMenu];
      val = {'plot', 'pnt', 'semilog','reverse'};
      diffidx = contains(val, app.View.YType);
      set(idx(diffidx), 'Checked', 'on');
      set(idx(~diffidx), 'Checked', 'off');

      app.PlotData();
    end

    % Menu selected function: cmReload, cmSelectSource
    function FileContextMenuChanged(app, event)
      switch event.Source
        case app.cmReload
          app.lbDirlistValueChanged([]);
        case app.cmSelectSource
          app.cmSelectSource.Checked = ~app.cmSelectSource.Checked;
      end
    end

    % Window button motion function: UIFigure
    function UIFigureWindowButtonMotion(app, event)
      if isempty(app.Message) && toc(app.MessageBlocker) > 5
        cursorPoint = get(app.haReal, 'CurrentPoint');
        unit = kv3_safeget(app.src.ax, 'unit', ''); 
        xinfo = '';
        if unit == 'G'
          gref = kv3_safeget(app.src.ax, 'freq1', 0)/app.gamma;
          if gref ~= 0
            xinfo = sprintf(' g=%5.4f', gref(1) / (cursorPoint(1,1) + kv3_safeget(app.src.ax, 'dx', 0)));
          end
        end
        show_dim = '';
        if app.sl2Dplot.Visible, show_dim = sprintf(' (%i)', app.sl2Dplot.Value); end
        switch length(app.View.CrossbarClick)
          case 0, app.Set('cursor', sprintf('x:%6.3f %s y:%6.3f%s', cursorPoint(1,1), xinfo, cursorPoint(1,2),show_dim));
          case 1, f = app.View.CrossbarClick{1}.CurrentPoint; d = cursorPoint; app.Set('cursor', sprintf('dx= %g,    dy= %f%s',d(2,1)-f(1,1),d(2,2)-f(1,2), show_dim));  
          case 2,  app.Set('cursor', sprintf('ratio x:%6.3f %s y:%6.3f%s', cursorPoint(1,1), xinfo, cursorPoint(1,2), show_dim));  
          case 3
              f1 = app.View.CrossbarClick{1}.CurrentPoint;
              f2 = app.View.CrossbarClick{2}.CurrentPoint;
              f3 = app.View.CrossbarClick{3}.CurrentPoint;
              f4 = cursorPoint;
              app.Set('cursor', sprintf('ratio x:%6.3f %s r  atio y:%6.3f%s', (f4(1,1)-f3(1,1))/(f2(1,1)-f1(1,1)), xinfo, (f4(1,2)-f3(1,2))/(f2(1,2)-f1(1,2)), show_dim));
          otherwise, app.View.CrossbarClick = {};
        end

        if app.View.Crossbar
          x = cursorPoint(1,1);
          y = cursorPoint(1,2);
  
          % Get axis limits
          xLimits = xlim(app.haReal);
          yLimits = ylim(app.haReal);
          if x < xLimits(1) || x > xLimits(2); return; end
          if y < yLimits(1) || y > yLimits(2); return; end

          % Clear old crosshair (if needed)
          delete(findall(app.haReal, 'Tag', 'CrosshairReal'));

          % Plot crosshair lines
          hold(app.haReal, 'on');
          plot(app.haReal, [x x], yLimits, 'r--', 'Tag', 'CrosshairReal', 'HitTest', 'off');
          plot(app.haReal, xLimits, [y y], 'r--', 'Tag', 'CrosshairReal', 'HitTest', 'off');

          switch length(app.View.CrossbarClick)
            case 1
              f = app.View.CrossbarClick{1}.CurrentPoint;  x = f(1,1); y = f(1,2);
              plot(app.haReal, [x x], yLimits, 'g--', 'Tag', 'CrosshairReal', 'HitTest', 'off');
              plot(app.haReal, xLimits, [y y], 'g--', 'Tag', 'CrosshairReal', 'HitTest', 'off');
            case {2,3}
              f = app.View.CrossbarClick{1}.CurrentPoint;  x1 = f(1,1); y1 = f(1,2);
              f = app.View.CrossbarClick{2}.CurrentPoint;  x2 = f(1,1); y2 = f(1,2);
              plot(app.haReal, [x1 x2 x2 x1 x1], [y1 y1 y2 y2 y1], 'g--', 'Tag', 'CrosshairReal', 'HitTest', 'off');
          end

          hold(app.haReal, 'off');  
        end
      end
    end

    % Button pushed function: bMeasure, bXhalf, bXtwice, bYhalf, 
    % ...and 2 other components
    function ZoomMeasureButtonChanged(app, event)
      switch event.Source
        case app.bMeasure
          app.View.Crossbar = ~app.View.Crossbar;
          app.haReal.PickableParts = 'all';
          app.View.CrossbarClick = {};
          if app.View.Crossbar == false
            % Clear old crosshair (if needed)
            delete(findall(app.haReal, 'Tag', 'CrosshairReal'));
          end
         app.UIFigure.focus();
        case app.bXhalf, limx = app.haReal.XLim; dx = diff(limx); limx = limx + dx * [1, -1] / 4; set([app.haReal, app.haImag], 'XLim', limx);
        case app.bXtwice, limx = app.haReal.XLim; dx = diff(limx); limx = limx - dx * [1, -1] / 2; set([app.haReal, app.haImag], 'XLim', limx);
        case app.bYhalf, limy = app.haReal.YLim; dx = diff(limy); limx = limy + dx * [1, -1] / 4; set([app.haReal], 'YLim', limx);
        case app.bYtwice, limy = app.haReal.YLim; dx = diff(limy); limx = limy - dx * [1, -1] / 2; set([app.haReal], 'YLim', limx);
        case app.bZoom
          zoom(app.UIFigure)
      end
    end

    % Menu selected function: ToolsMenu, mClearSource, mCutSlice, 
    % ...and 2 other components
    function ToolsMenuSelected(app, event)
      switch event.Source
        case app.mClearSource
          app.src.y=[];
          app.src.ax.x=[];
          app.src.ax.y=[];
          app.src.dsc=[];
          app.PlotData();
        case app.mFixSourceProcessing
          app.src=app.Process(app.src);
          app.src.ax.filter   = '';
          app.src.ax.filtpar1 = '';
          %           handles=cmContexMenu1(handles.cmNormal,[],handles);
          %           handles=cmContexMenu2(handles.cmxy,[],handles);
          %           handles=Filter_CallBack(handles.cmNofilter,[],handles);
          app.PlotData();
        case app.mShowLoadScript
          f = '';
          dim = size(app.LastLoadingScript, 2);
          for i=1:dim, f = [f, newline, app.LastLoadingScript{1,i}]; end
          disp('Data load script is copied to the clipboard.');
          clipboard('copy',f)
          disp(f);
        case app.mCutSlice
          app.src = app.GetSlice(app.src, app.View.AbscissaXorY, app.View.Position);
          app.UpdateSlider();
          app.PlotData();
      end
    end

    % Menu selected function: m2DScatter, m2DSurf, m2DSurface, 
    % ...and 8 other components
    function WindowsMenuSelected(app, event)
      switch event.Source
        case app.mNewWindow
          kazan3;
        case app.mShowinWindow
          a = figure;
          app.PlotData(cla);
          %           set(a,'UserData', struct('MainFigure',handles.MainFigure));
          %           pMenu = uimenu('Parent', a, 'Label', 'KV_Tools');
          %           uimenu('Parent', pMenu, 'Label', 'Export Image', 'Callback', 'kv_exportimage(gcf, ''gui'')');  % alsi 12.01.05
          %           uimenu('Parent', pMenu, 'Label', 'Profile Manager', 'Callback', 'kv_printprofile');  % alsi 12.01.05
          %           uimenu('Parent', pMenu, 'Label', 'Associate',...
          %             'Callback', ['ud = get(gcf ,''UserData''); hand = getfield(ud,''MainFigure''); gdata = guidata(hand);',...
          %             'eval(''kazan(''''AssociateFigure'''',hand, gcf)'');']);
        case app.m2DSurf
          figure; hold on;
          [X,Y] = meshgrid(app.src.ax.y,app.src.ax.x);
          s = surf(X,Y,app.src.y);
          s.EdgeColor = 'none';
          xlabel(app.src.ax.xlabel)
          ylabel(app.src.ax.ylabel)
          colormap jet;
          view(30,14)
        case app.m2DScatter
          figure; hold on;
          for ii=1:size(app.src.y,2)
            x = app.src.ax.x;
            y = app.src.y(:,ii);
            z = app.src.ax.y(ii)*ones(size(x));
            scatter3(x,z,y,0.25,y,'filled');
          end
          xlabel(app.src.ax.xlabel)
          ylabel(app.src.ax.ylabel)
          colormap jet;
          view(-55,14)
        case app.m2DSurface
          figure; hold on;
          for ii=1:size(app.src.y,2)
            x = app.src.ax.x';
            y = app.src.y(:,ii)';
            z = app.src.ax.y(ii)*ones(size(x));
            surface([x;x],[z;z],[y;y],[y;y],...
              'FaceColor','none',...
              'EdgeColor','interp');
          end
          xlabel(app.src.ax.xlabel)
          ylabel(app.src.ax.ylabel)
          colormap jet;
          view(-55,14)
        case app.mEmptyFigure
          figure;
        case app.mOpenInFigure
          figure;
          if app.View.RealImagMagn == 2
            subplot(2,1,1); rAxis = gca;
            subplot(2,1,2); iAxis = gca;
            app.PlotData(rAxis, iAxis);
          else
            rAxis = gca;
            app.PlotData(rAxis);
          end
        case app.mAxis1Of12
          subplot(1,2,1); rAxis = gca;
          app.PlotData(rAxis);
        case app.mAxis2Of12
          subplot(1,2,2); rAxis = gca;
          app.PlotData(rAxis);
        case app.mAxis1Of21
          subplot(2,1,1); rAxis = gca;
          app.PlotData(rAxis);
        case app.mAxis2Of21
          subplot(2,1,2); rAxis = gca;
          app.PlotData(rAxis);
      end
    end

    % Menu selected function: NextSliceMenu, PreviousSlideMenu
    function NextSliceMenuSelected(app, event)
      switch event.Source
        case app.NextSliceMenu
          if app.sl2Dplot.Value+1 <= app.sl2Dplot.Limits(2)
            app.sl2Dplot.Value = floor(app.sl2Dplot.Value + 1.5);
            app.sl2DplotValueChanged([]);
          end
        case app.PreviousSlideMenu
          if app.sl2Dplot.Value-1 >= app.sl2Dplot.Limits(1)
            app.sl2Dplot.Value = floor(app.sl2Dplot.Value - 1 + 0.5);
            app.sl2DplotValueChanged([]);
          end
      end
    end

    % Key press function: UIFigure
    function UIFigureKeyPress(app, event)
      if app.View.Crossbar
        result = '';
        if contains(class(event), 'matlab.ui.eventdata.KeyData'), result = event.Key; 
        elseif contains(class(event), 'matlab.graphics.eventdata.Hit')
          if event.Button == 1, result = 'space'; end
        else, return; % protection
        end
        
        switch result
          case {'escape', 'return'}
            delete(findall(app.haReal, 'Tag', 'CrosshairReal'));
            app.View.CrossbarClick = {};
              if app.View.CrossbarWait ~= 0, uiresume(app.UIFigure); end
            app.View.CrossbarWait = 0; 
          case 'space'  
            app.View.CrossbarClick{end+1} = struct('CurrentPoint', app.haReal.CurrentPoint);
            cursorPoint = get(app.haReal, 'CurrentPoint');
            xLimits = xlim(app.haReal); yLimits = ylim(app.haReal); dx = diff(xLimits)/100; dy = diff(yLimits)/100;
            hold(app.haReal, 'on'); plot(app.haReal, cursorPoint(1,1) + [-dx,dx,dx,-dx,-dx], cursorPoint(1,2) + [-dy,-dy,dy,dy,-dy], 'r', 'HitTest', 'off', 'Tag', 'CrosshairReal'); hold(app.haReal, 'off');
            switch length(app.View.CrossbarClick)  
              case 1, f = app.View.CrossbarClick{1}.CurrentPoint; app.Set('log', sprintf('x:%6.3f y:%6.3f', f(1,1), f(1,2))); 
              case 2, f = app.View.CrossbarClick{1}.CurrentPoint; d = app.View.CrossbarClick{2}.CurrentPoint;  app.Set('log', sprintf('dx= %g,    dy= %f',d(1,1)-f(1,1),d(1,2)-f(1,2)));
              case 3 
              case 4
                f1 = app.View.CrossbarClick{1}.CurrentPoint; f2 = app.View.CrossbarClick{2}.CurrentPoint;
                f3 = app.View.CrossbarClick{3}.CurrentPoint; f4 = app.View.CrossbarClick{4}.CurrentPoint;
                 app.Set('log', sprintf('x-ratio= %g, y-ratio= %f\n',(f4(1,1)-f3(1,1))/(f2(1,1)-f1(1,1)), (f4(1,2)-f3(1,2))/(f2(1,2)-f1(1,2))));
              otherwise, app.View.CrossbarClick = {};
            end
            app.View.CrossbarWait = app.View.CrossbarWait-1;
            if app.View.CrossbarWait == 0, uiresume(app.UIFigure); end
        end
      end
    end

    % Button down function: haReal
    function haRealButtonDown(app, event)
      UIFigureKeyPress(app, event);
    end

    % Window scroll wheel function: UIFigure
    function UIFigureWindowScrollWheel(app, event)
      % if event.VerticalScrollCount > 0, cc = 0.05; else  , cc = -0.05; end
      if app.sl2Dplot.Visible && app.View.ZoomDirection == 'Z'
        v = app.sl2Dplot.Value - event.VerticalScrollCount;
        v = min(app.sl2Dplot.Limits(2), v);
        app.sl2Dplot.Value = max(app.sl2Dplot.Limits(1), v);
        app.UpdateSlider();
        app.PlotData();
      end
      % if app.View.ZoomDirection == 'X'
      %   limx = app.haReal.XLim; dx = diff(limx); limx = limx + dx * [1, -1] * cc; set([app.haReal, app.haImag], 'XLim', limx);
      % else
      %   limy = app.haReal.YLim; dx = diff(limy); limy = limy + dx * [1, -1] * cc; set([app.haReal, app.haImag], 'YLim', limy);
      % end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
      app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
      app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
      app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
      app.FileMenu = uimenu(app.UIFigure);
      app.FileMenu.Text = 'File';
      app.OpenDirectoryMenu = uimenu(app.FileMenu);
      app.OpenDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.OpenDirectoryMenu.Text = 'Open Directory';
      app.mFavoriteDirectoriesMain = uimenu(app.FileMenu);
      app.mFavoriteDirectoriesMain.Text = 'Favorite Directories';
      app.SaveSourceMenu = uimenu(app.FileMenu);
      app.SaveSourceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.SaveSourceMenu.Separator = 'on';
      app.SaveSourceMenu.Accelerator = 'S';
      app.SaveSourceMenu.Text = 'Save Source';
      app.SaveSourcewithProcessingMenu = uimenu(app.FileMenu);
      app.SaveSourcewithProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.SaveSourcewithProcessingMenu.Text = 'Save Source with Processing';
      app.ConvertFoldertoASCIIMenu = uimenu(app.FileMenu);
      app.ConvertFoldertoASCIIMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.ConvertFoldertoASCIIMenu.Text = 'Convert Folder to ASCII';
      app.ExportSrcToWorkspaceMenu = uimenu(app.FileMenu);
      app.ExportSrcToWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.ExportSrcToWorkspaceMenu.Separator = 'on';
      app.ExportSrcToWorkspaceMenu.Text = 'Export Src to Workspace';
      app.ExportOuttoWorkspaceMenu = uimenu(app.FileMenu);
      app.ExportOuttoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.ExportOuttoWorkspaceMenu.Text = 'Export Out to Workspace';
      app.LoadSrcfromWorkspaceMenu = uimenu(app.FileMenu);
      app.LoadSrcfromWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.LoadSrcfromWorkspaceMenu.Text = 'Load Src from Workspace';
      app.ViewMenu = uimenu(app.UIFigure);
      app.ViewMenu.Text = 'View';
      app.FontMenu = uimenu(app.ViewMenu);
      app.FontMenu.Text = 'Font';
      app.FontM2Menu = uimenu(app.FontMenu);
      app.FontM2Menu.MenuSelectedFcn = createCallbackFcn(app, @Font0MenuSelected, true);
      app.FontM2Menu.Text = '-2';
      app.FontM2Menu.Tag = '-2';
      app.FontM1Menu = uimenu(app.FontMenu);
      app.FontM1Menu.MenuSelectedFcn = createCallbackFcn(app, @Font0MenuSelected, true);
      app.FontM1Menu.Text = '-1';
      app.FontM1Menu.Tag = '-1';
      app.Font0Menu = uimenu(app.FontMenu);
      app.Font0Menu.MenuSelectedFcn = createCallbackFcn(app, @Font0MenuSelected, true);
      app.Font0Menu.Checked = 'on';
      app.Font0Menu.Text = 'Default';
      app.Font0Menu.Tag = '0';
      app.Font1Menu = uimenu(app.FontMenu);
      app.Font1Menu.MenuSelectedFcn = createCallbackFcn(app, @Font0MenuSelected, true);
      app.Font1Menu.Text = '+1';
      app.Font1Menu.Tag = '1';
      app.Font2Menu = uimenu(app.FontMenu);
      app.Font2Menu.MenuSelectedFcn = createCallbackFcn(app, @Font0MenuSelected, true);
      app.Font2Menu.Text = '+2';
      app.Font2Menu.Tag = '2';
      app.Font3Menu = uimenu(app.FontMenu);
      app.Font3Menu.MenuSelectedFcn = createCallbackFcn(app, @Font0MenuSelected, true);
      app.Font3Menu.Text = '+3';
      app.Font3Menu.Tag = '3';
      app.Font4Menu = uimenu(app.FontMenu);
      app.Font4Menu.MenuSelectedFcn = createCallbackFcn(app, @Font0MenuSelected, true);
      app.Font4Menu.Text = '+4';
      app.Font4Menu.Tag = '4';
      app.AxisTightMenu = uimenu(app.ViewMenu);
      app.AxisTightMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.AxisTightMenu.Separator = 'on';
      app.AxisTightMenu.Accelerator = 'T';
      app.AxisTightMenu.Checked = 'on';
      app.AxisTightMenu.Text = 'Axis Tight';
      app.AxisAutoMenu = uimenu(app.ViewMenu);
      app.AxisAutoMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.AxisAutoMenu.Accelerator = 'A';
      app.AxisAutoMenu.Text = 'Axis Auto';
      app.AxisManualMenu = uimenu(app.ViewMenu);
      app.AxisManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.AxisManualMenu.Separator = 'on';
      app.AxisManualMenu.Text = 'Axis Manual';
      app.SelectViewAreaMenu = uimenu(app.ViewMenu);
      app.SelectViewAreaMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.SelectViewAreaMenu.Text = 'Select View Area';
      app.ZoomMenu = uimenu(app.ViewMenu);
      app.ZoomMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.ZoomMenu.Accelerator = 'Z';
      app.ZoomMenu.Text = 'Zoom';
      app.ShowGridMenu = uimenu(app.ViewMenu);
      app.ShowGridMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.ShowGridMenu.Separator = 'on';
      app.ShowGridMenu.Accelerator = 'G';
      app.ShowGridMenu.Text = 'Show Grid';
      app.ShowMinorGridMenu = uimenu(app.ViewMenu);
      app.ShowMinorGridMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.ShowMinorGridMenu.Text = 'Show Minor Grid';
      app.AspectNormalMenu = uimenu(app.ViewMenu);
      app.AspectNormalMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.AspectNormalMenu.Separator = 'on';
      app.AspectNormalMenu.Checked = 'on';
      app.AspectNormalMenu.Text = 'Aspect Normal';
      app.AspectSquareMenu = uimenu(app.ViewMenu);
      app.AspectSquareMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.AspectSquareMenu.Text = 'Aspect Square';
      app.AxisEqualMenu = uimenu(app.ViewMenu);
      app.AxisEqualMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewValueChanged, true);
      app.AxisEqualMenu.Text = 'Axis Equal';
      app.NextFileMenu = uimenu(app.ViewMenu);
      app.NextFileMenu.MenuSelectedFcn = createCallbackFcn(app, @NextFileMenuSelected, true);
      app.NextFileMenu.Separator = 'on';
      app.NextFileMenu.Accelerator = 'N';
      app.NextFileMenu.Text = 'Next File';
      app.PreviousFileMenu = uimenu(app.ViewMenu);
      app.PreviousFileMenu.MenuSelectedFcn = createCallbackFcn(app, @NextFileMenuSelected, true);
      app.PreviousFileMenu.Accelerator = 'B';
      app.PreviousFileMenu.Text = 'Previous File';
      app.NextSliceMenu = uimenu(app.ViewMenu);
      app.NextSliceMenu.MenuSelectedFcn = createCallbackFcn(app, @NextSliceMenuSelected, true);
      app.NextSliceMenu.Separator = 'on';
      app.NextSliceMenu.Accelerator = 'U';
      app.NextSliceMenu.Text = 'Next Slice';
      app.PreviousSlideMenu = uimenu(app.ViewMenu);
      app.PreviousSlideMenu.MenuSelectedFcn = createCallbackFcn(app, @NextSliceMenuSelected, true);
      app.PreviousSlideMenu.Accelerator = 'D';
      app.PreviousSlideMenu.Text = 'Previous Slide';
      app.ToolsMenu = uimenu(app.UIFigure);
      app.ToolsMenu.MenuSelectedFcn = createCallbackFcn(app, @ToolsMenuSelected, true);
      app.ToolsMenu.Text = 'Tools';
      app.mClearSource = uimenu(app.ToolsMenu);
      app.mClearSource.MenuSelectedFcn = createCallbackFcn(app, @ToolsMenuSelected, true);
      app.mClearSource.Text = 'Clear Source';
      app.mFixSourceProcessing = uimenu(app.ToolsMenu);
      app.mFixSourceProcessing.MenuSelectedFcn = createCallbackFcn(app, @ToolsMenuSelected, true);
      app.mFixSourceProcessing.Text = 'Fix Source Processing';
      app.mCutSlice = uimenu(app.ToolsMenu);
      app.mCutSlice.MenuSelectedFcn = createCallbackFcn(app, @ToolsMenuSelected, true);
      app.mCutSlice.Text = 'Cut Slice';
      app.mShowLoadScript = uimenu(app.ToolsMenu);
      app.mShowLoadScript.MenuSelectedFcn = createCallbackFcn(app, @ToolsMenuSelected, true);
      app.mShowLoadScript.Separator = 'on';
      app.mShowLoadScript.Text = 'Show Load Script';
      app.mPluginsMain = uimenu(app.UIFigure);
      app.mPluginsMain.Text = 'Plugins';
      app.mWindow = uimenu(app.UIFigure);
      app.mWindow.Text = 'Window';
      app.mNewWindow = uimenu(app.mWindow);
      app.mNewWindow.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mNewWindow.Accelerator = 'K';
      app.mNewWindow.Text = 'New Window';
      app.mShowinWindow = uimenu(app.mWindow);
      app.mShowinWindow.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mShowinWindow.Text = 'Show in Window';
      app.mPlot1DMenu = uimenu(app.mWindow);
      app.mPlot1DMenu.Separator = 'on';
      app.mPlot1DMenu.Text = 'Plot 1D';
      app.mOpenInFigure = uimenu(app.mPlot1DMenu);
      app.mOpenInFigure.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mOpenInFigure.Text = 'Open figure';
      app.mEmptyFigure = uimenu(app.mPlot1DMenu);
      app.mEmptyFigure.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mEmptyFigure.Text = 'Empty figure';
      app.mAxis1Of12 = uimenu(app.mPlot1DMenu);
      app.mAxis1Of12.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mAxis1Of12.Text = 'Axis 1 / [1,2]';
      app.mAxis2Of12 = uimenu(app.mPlot1DMenu);
      app.mAxis2Of12.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mAxis2Of12.Text = 'Axis 2 / [1,2]';
      app.mAxis1Of21 = uimenu(app.mPlot1DMenu);
      app.mAxis1Of21.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mAxis1Of21.Text = 'Axis 1 / [2,1]';
      app.mAxis2Of21 = uimenu(app.mPlot1DMenu);
      app.mAxis2Of21.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.mAxis2Of21.Text = 'Axis 2 / [2,1]';
      app.mPlot2D = uimenu(app.mWindow);
      app.mPlot2D.Text = 'Plot 2D';
      app.m2DSurf = uimenu(app.mPlot2D);
      app.m2DSurf.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.m2DSurf.Text = 'Surf';
      app.m2DScatter = uimenu(app.mPlot2D);
      app.m2DScatter.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.m2DScatter.Text = 'Scatter3';
      app.m2DSurface = uimenu(app.mPlot2D);
      app.m2DSurface.MenuSelectedFcn = createCallbackFcn(app, @WindowsMenuSelected, true);
      app.m2DSurface.Text = 'Surface';
      app.HelpMenu = uimenu(app.UIFigure);
      app.HelpMenu.Text = 'Help';
      app.HowtoStartMenu = uimenu(app.HelpMenu);
      app.HowtoStartMenu.MenuSelectedFcn = createCallbackFcn(app, @KazanViewerHelp, true);
      app.HowtoStartMenu.Text = 'How to Start';
      app.mDataLoadRoutines = uimenu(app.HelpMenu);
      app.mDataLoadRoutines.MenuSelectedFcn = createCallbackFcn(app, @KazanViewerHelp, true);
      app.mDataLoadRoutines.Text = 'Data Load Routines';
      app.mAxisDataStructure = uimenu(app.HelpMenu);
      app.mAxisDataStructure.MenuSelectedFcn = createCallbackFcn(app, @KazanViewerHelp, true);
      app.mAxisDataStructure.Text = 'Axis Data Structure';
      app.mPluginsMenu = uimenu(app.HelpMenu);
      app.mPluginsMenu.MenuSelectedFcn = createCallbackFcn(app, @KazanViewerHelp, true);
      app.mPluginsMenu.Text = 'Plugins';
      app.HowtoPrintMenu = uimenu(app.HelpMenu);
      app.HowtoPrintMenu.MenuSelectedFcn = createCallbackFcn(app, @KazanViewerHelp, true);
      app.HowtoPrintMenu.Text = 'How to Print';
      app.AboutKazanViewerMenu = uimenu(app.HelpMenu);
      app.AboutKazanViewerMenu.MenuSelectedFcn = createCallbackFcn(app, @KazanViewerHelp, true);
      app.AboutKazanViewerMenu.Separator = 'on';
      app.AboutKazanViewerMenu.Text = 'About Kazan Viewer';
      app.GridMain = uigridlayout(app.UIFigure);
      app.GridButtonsRow1 = uigridlayout(app.GridMain);
      app.bTransfer = uibutton(app.GridButtonsRow1, 'push');
      app.bTransfer.ButtonPushedFcn = createCallbackFcn(app, @SrcOutTransferChanged, true);
      app.bTransfer.Text = '<<';
      app.bReal = uibutton(app.GridButtonsRow1, 'state');
      app.bReal.ValueChangedFcn = createCallbackFcn(app, @SetDataPartChanged, true);
      app.bReal.Text = 'Real';
      app.bReal.Value = true;
      app.bImag = uibutton(app.GridButtonsRow1, 'state');
      app.bImag.ValueChangedFcn = createCallbackFcn(app, @SetDataPartChanged, true);
      app.bImag.Text = 'Re/Im';
      app.bMagnitude = uibutton(app.GridButtonsRow1, 'state');
      app.bMagnitude.ValueChangedFcn = createCallbackFcn(app, @SetDataPartChanged, true);
      app.bMagnitude.Text = 'Magn';
      app.bSource = uibutton(app.GridButtonsRow1, 'state');
      app.bSource.ValueChangedFcn = createCallbackFcn(app, @SrcOutTransferChanged, true);
      app.bSource.Text = 'Source';
      app.bSource.Value = true;
      app.bOutput = uibutton(app.GridButtonsRow1, 'state');
      app.bOutput.ValueChangedFcn = createCallbackFcn(app, @SrcOutTransferChanged, true);
      app.bOutput.Text = 'Output';
      app.b1D = uibutton(app.GridButtonsRow1, 'state');
      app.b1D.ValueChangedFcn = createCallbackFcn(app, @SetPlotTypeChanged, true);
      app.b1D.Text = {'1D'; ''};
      app.b1D.Value = true;
      app.bStack = uibutton(app.GridButtonsRow1, 'state');
      app.bStack.ValueChangedFcn = createCallbackFcn(app, @SetPlotTypeChanged, true);
      app.bStack.Text = {'Stack'; ''};
      app.bContour = uibutton(app.GridButtonsRow1, 'state');
      app.bContour.ValueChangedFcn = createCallbackFcn(app, @SetPlotTypeChanged, true);
      app.bContour.Text = 'Contour';
      app.bDensity = uibutton(app.GridButtonsRow1, 'state');
      app.bDensity.ValueChangedFcn = createCallbackFcn(app, @SetPlotTypeChanged, true);
      app.bDensity.Text = {'Density'; ''};
      app.Label_2 = uilabel(app.GridMain);
      app.Label_2.Text = '';
      app.sl2Dplot = uislider(app.GridMain);
      app.sl2Dplot.ValueChangedFcn = createCallbackFcn(app, @sl2DplotValueChanged, true);
      app.sl2Dplot.Value = 23.4608898689843;
      app.pnlAxis = uipanel(app.GridMain);
      app.pnlAxis.SizeChangedFcn = createCallbackFcn(app, @pnlAxisSizeChanged, true);
      app.haReal = uiaxes(app.pnlAxis);
      xlabel(app.haReal, 'X')
      ylabel(app.haReal, 'Y')
      zlabel(app.haReal, 'Z')
      app.haReal.ButtonDownFcn = createCallbackFcn(app, @haRealButtonDown, true);
      app.haImag = uiaxes(app.pnlAxis);
      xlabel(app.haImag, 'X')
      ylabel(app.haImag, 'Y')
      zlabel(app.haImag, 'Z')
      app.GridButtonsRow2 = uigridlayout(app.GridMain);
      app.ddSelParameter = uidropdown(app.GridButtonsRow2);
      app.ddSelParameter.ValueChangedFcn = createCallbackFcn(app, @ddSelParameterValueChanged, true);
      app.bYtwice = uibutton(app.GridButtonsRow2, 'push');
      app.bYtwice.ButtonPushedFcn = createCallbackFcn(app, @ZoomMeasureButtonChanged, true);
      app.bYtwice.Icon = 'pbYCollapse.png';
      app.bYtwice.Tooltip = {'Expand Y'};
      app.bYtwice.Text = '';
      app.bYhalf = uibutton(app.GridButtonsRow2, 'push');
      app.bYhalf.ButtonPushedFcn = createCallbackFcn(app, @ZoomMeasureButtonChanged, true);
      app.bYhalf.Icon = 'pbYExpand.png';
      app.bYhalf.Tooltip = {'Zoom on Y'};
      app.bYhalf.Text = '';
      app.bXtwice = uibutton(app.GridButtonsRow2, 'push');
      app.bXtwice.ButtonPushedFcn = createCallbackFcn(app, @ZoomMeasureButtonChanged, true);
      app.bXtwice.Icon = 'pbXCollapse.png';
      app.bXtwice.Tooltip = {'Expand X'};
      app.bXtwice.Text = '';
      app.bXhalf = uibutton(app.GridButtonsRow2, 'push');
      app.bXhalf.ButtonPushedFcn = createCallbackFcn(app, @ZoomMeasureButtonChanged, true);
      app.bXhalf.Icon = 'pbXExpand.png';
      app.bXhalf.Tooltip = {'Zoom on X'};
      app.bXhalf.Text = '';
      app.bMeasure = uibutton(app.GridButtonsRow2, 'push');
      app.bMeasure.ButtonPushedFcn = createCallbackFcn(app, @ZoomMeasureButtonChanged, true);
      app.bMeasure.Icon = 'pbCross.png';
      app.bMeasure.Tooltip = {'Measure'};
      app.bMeasure.Text = '';
      app.bZoom = uibutton(app.GridButtonsRow2, 'push');
      app.bZoom.ButtonPushedFcn = createCallbackFcn(app, @ZoomMeasureButtonChanged, true);
      app.bZoom.Icon = 'pbZoom.png';
      app.bZoom.Tooltip = {'Zoom'};
      app.bZoom.Text = '';
      app.eParameter = uieditfield(app.GridButtonsRow2, 'text');
      app.eParameter.ValueChangedFcn = createCallbackFcn(app, @eParameterValueChanged, true);
      app.b2D = uibutton(app.GridMain, 'push');
      app.b2D.ButtonPushedFcn = createCallbackFcn(app, @b2DValueChanged, true);
      app.b2D.Text = 'X';
      app.GridLeftPanel = uigridlayout(app.GridMain);
      app.ePath = uieditfield(app.GridLeftPanel, 'text');
      app.ePath.ValueChangedFcn = createCallbackFcn(app, @ePathValueChanged, true);
      app.eLoadOption = uieditfield(app.GridLeftPanel, 'text');
      app.bUp = uibutton(app.GridLeftPanel, 'push');
      app.bUp.ButtonPushedFcn = createCallbackFcn(app, @bUpButtonPushed, true);
      app.bUp.Text = 'Up';
      app.lbDirlist = uilistbox(app.GridLeftPanel);
      app.lbDirlist.ValueChangedFcn = createCallbackFcn(app, @lbDirlistValueChanged, true);
      app.lbDirlist.DoubleClickedFcn = createCallbackFcn(app, @lbDirlistValueChanged, true);
      app.ddExt = uidropdown(app.GridLeftPanel);
      app.ddExt.ValueChangedFcn = createCallbackFcn(app, @ddExtValueChanged, true);
      app.ddDataType = uidropdown(app.GridLeftPanel);
      app.ddDataType.ValueChangedFcn = createCallbackFcn(app, @ddDataTypeValueChanged, true);
      app.ddLoadOption2 = uidropdown(app.GridLeftPanel);
      app.ddLoadOption1 = uidropdown(app.GridLeftPanel);
      app.ResultsPanel = uipanel(app.GridLeftPanel);
      app.ResultsPanel.Title = 'Results';
      app.GridLayout = uigridlayout(app.ResultsPanel);
      app.TextArea = uitextarea(app.GridLayout);
      app.ContextMenu = uicontextmenu(app.UIFigure);
      app.NormalMenu = uimenu(app.ContextMenu);
      app.NormalMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.NormalMenu.Text = 'Normal';
      app.PSmodMenu = uimenu(app.ContextMenu);
      app.PSmodMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.PSmodMenu.Text = 'PS,mod';
      app.IntegrateMenu = uimenu(app.ContextMenu);
      app.IntegrateMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.IntegrateMenu.Text = 'Integrate';
      app.NotFilteredMenu = uimenu(app.ContextMenu);
      app.NotFilteredMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.NotFilteredMenu.Separator = 'on';
      app.NotFilteredMenu.Text = 'Not Filtered';
      app.RCMenu = uimenu(app.ContextMenu);
      app.RCMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.RCMenu.Text = 'RC';
      app.MaverMenu = uimenu(app.ContextMenu);
      app.MaverMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.MaverMenu.Text = 'M.aver.';
      app.SavgolMenu = uimenu(app.ContextMenu);
      app.SavgolMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.SavgolMenu.Text = 'Sav.gol.';
      app.ApplytoMenu = uimenu(app.ContextMenu);
      app.ApplytoMenu.Text = 'Apply to';
      app.AutoMenu = uimenu(app.ApplytoMenu);
      app.AutoMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.AutoMenu.Text = 'Auto';
      app.FirstDimMenu = uimenu(app.ApplytoMenu);
      app.FirstDimMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.FirstDimMenu.Text = 'First Dim';
      app.SecondDimMenu = uimenu(app.ApplytoMenu);
      app.SecondDimMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.SecondDimMenu.Text = 'Second Dim';
      app.BothMenu = uimenu(app.ApplytoMenu);
      app.BothMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.BothMenu.Text = 'Both';
      app.xyMenu = uimenu(app.ContextMenu);
      app.xyMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.xyMenu.Separator = 'on';
      app.xyMenu.Text = 'x-y';
      app.XMenu = uimenu(app.ContextMenu);
      app.XMenu.Text = 'X';
      app.XAxisAsIsMenu = uimenu(app.XMenu);
      app.XAxisAsIsMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.XAxisAsIsMenu.Text = 'X';
      app.XpntsMenu = uimenu(app.XMenu);
      app.XpntsMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.XpntsMenu.Text = 'X pnts';
      app.ReverseXMenu = uimenu(app.XMenu);
      app.ReverseXMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.ReverseXMenu.Text = 'Reverse X';
      app.XLogMenu = uimenu(app.XMenu);
      app.XLogMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.XLogMenu.Text = 'X Log';
      app.Xgspacefreq1Menu = uimenu(app.XMenu);
      app.Xgspacefreq1Menu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.Xgspacefreq1Menu.Text = 'X g-space (freq1)';
      app.XppmreferenceMenu = uimenu(app.XMenu);
      app.XppmreferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.XppmreferenceMenu.Text = 'X ppm (reference)';
      app.Xppm1referenceMenu = uimenu(app.XMenu);
      app.Xppm1referenceMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.Xppm1referenceMenu.Text = 'X ppm - 1 (reference)';
      app.YMenu = uimenu(app.ContextMenu);
      app.YMenu.Text = 'Y';
      app.YAxisAsIsMenu = uimenu(app.YMenu);
      app.YAxisAsIsMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.YAxisAsIsMenu.Text = 'Y';
      app.YpntsMenu = uimenu(app.YMenu);
      app.YpntsMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.YpntsMenu.Text = 'Y pnts';
      app.ReverseYMenu = uimenu(app.YMenu);
      app.ReverseYMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.ReverseYMenu.Text = 'Reverse Y';
      app.YLogMenu = uimenu(app.YMenu);
      app.YLogMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessMenuChanged, true);
      app.YLogMenu.Text = 'Y Log';
      % Assign app.ContextMenu
      app.pnlAxis.ContextMenu = app.ContextMenu;
      app.haReal.ContextMenu = app.ContextMenu;
      app.haImag.ContextMenu = app.ContextMenu;
      app.ContextMenu2 = uicontextmenu(app.UIFigure);
      app.cmReload = uimenu(app.ContextMenu2);
      app.cmReload.MenuSelectedFcn = createCallbackFcn(app, @FileContextMenuChanged, true);
      app.cmReload.Accelerator = 'R';
      app.cmReload.Text = 'Reload Data';
      app.cmReladDir = uimenu(app.ContextMenu2);
      app.cmReladDir.MenuSelectedFcn = createCallbackFcn(app, @ePathValueChanged, true);
      app.cmReladDir.Text = 'Reload Folder';
      app.cmConvert = uimenu(app.ContextMenu2);
      app.cmConvert.MenuSelectedFcn = createCallbackFcn(app, @ExportSaveConvert, true);
      app.cmConvert.Text = 'Convert';
      app.cmSelectSource = uimenu(app.ContextMenu2);
      app.cmSelectSource.MenuSelectedFcn = createCallbackFcn(app, @FileContextMenuChanged, true);
      app.cmSelectSource.Checked = 'on';
      app.cmSelectSource.Text = 'Select Source';
      % Assign app.ContextMenu2
      app.lbDirlist.ContextMenu = app.ContextMenu2;
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = kazan3

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end