classdef simplugin3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    figure1                 matlab.ui.Figure
    uimenu1                 matlab.ui.container.Menu
    mSimLoadFreq            matlab.ui.container.Menu
    mSimLoadRange           matlab.ui.container.Menu
    UnitsMenu               matlab.ui.container.Menu
    mUnitsAutomatic         matlab.ui.container.Menu
    mUnitsGauss             matlab.ui.container.Menu
    mUnitsmT                matlab.ui.container.Menu
    mUnitsHz                matlab.ui.container.Menu
    mSimLarm                matlab.ui.container.Menu
    mApplySourceProcessing  matlab.ui.container.Menu
    mSimAutoBaseline        matlab.ui.container.Menu
    mFileBaseline           matlab.ui.container.Menu
    mSimSubBl               matlab.ui.container.Menu
    mEdit                   matlab.ui.container.Menu
    mScriptEditor           matlab.ui.container.Menu
    mCopy2Clipboard         matlab.ui.container.Menu
    SortMenu                matlab.ui.container.Menu
    mAddScript              matlab.ui.container.Menu
    mLoadScript             matlab.ui.container.Menu
    mReloadScript           matlab.ui.container.Menu
    mSaveScript             matlab.ui.container.Menu
    mDuplicateScript        matlab.ui.container.Menu
    mDeleteScript           matlab.ui.container.Menu
    mDeleteAllScripts       matlab.ui.container.Menu
    mLoadAll                matlab.ui.container.Menu
    mSaveAll                matlab.ui.container.Menu
    mOptions                matlab.ui.container.Menu
    mShowSource             matlab.ui.container.Menu
    mShsim                  matlab.ui.container.Menu
    mShsumm                 matlab.ui.container.Menu
    mShdiff                 matlab.ui.container.Menu
    mColSum                 matlab.ui.container.Menu
    mColDiff                matlab.ui.container.Menu
    mSimChi                 matlab.ui.container.Menu
    mRefresh                matlab.ui.container.Menu
    mAboutSimPlugin         matlab.ui.container.Menu
    mAbout                  matlab.ui.container.Menu
    mAboutHelp              matlab.ui.container.Menu
    GridLayout              matlab.ui.container.GridLayout
    eParsNumber             matlab.ui.control.Spinner
    eParsString             matlab.ui.control.EditField
    UpdCheckBox             matlab.ui.control.CheckBox
    bCalculate              matlab.ui.control.Button
    pbReCalc                matlab.ui.control.Button
    pbCalcAll               matlab.ui.control.Button
    lbOpt                   matlab.ui.control.ListBox
    ddOptMethod             matlab.ui.control.DropDown
    ddStepSize              matlab.ui.control.DropDown
    lScript                 matlab.ui.control.ListBox
    butCol                  matlab.ui.control.Button
    ddScriptNumber          matlab.ui.control.DropDown
    mContext                matlab.ui.container.ContextMenu
    mContOpt                matlab.ui.container.Menu
    mContNotOpt             matlab.ui.container.Menu
  end


  properties (Access = private)
    Color % Description
    KazanViewer % Description
    Sim % Description
    EditorMode % Description
    ActiveParameter
    CurrentDirectory % Description
    SimSum % Description
    SumColor % Description
    DiffColor
    Options
  end

  properties (Access = public)
    guid % Description
  end

  methods (Access = public)
    %----------------------------------------------------------------------
    function FileUpdate(app)
    end
  end

  methods (Access = private)
    %----------------------------------------------------------------------
    function varargout = LoadScript(app, fullname)
      fid = fopen(fullname, 'r');
      [~,fname] = fileparts(fullname);
      if fid == -1, disp('File not found'); return; end
      str = {};
      while feof(fid) < 1
        st = fgetl(fid);
        if ~isempty(st) && st(1) ~= '%'
          str{end+1} = ['l-',st];
        end
      end
      fclose(fid);

      if nargout > 0
        varargout = {fname , str.'};
      end
    end

    %----------------------------------------------------------------------
    function SaveScript(app, fullname, Scr)
      fid = fopen(fullname, 'w');
      if fid > 0
        for kk = 1:length(Scr)
          fprintf(fid, '%s\r\n', Scr{kk}(3:end));
        end
        fclose(fid);
      else
        error(['Can''t write to file ''',fullname,'''']);
      end
    end
    %----------------------------------------------------------------------

    function Str1 = UpdatePars(app, Str, par, val)
      % find if parameter is in the list
      %       idx = find(contains(Str, par));

      Str2=[];
      if ischar(val)
        Str2{end+1}=[par,' = ',val];
      else
        if length(val)==1, Str2{end+1}=[par,' = ',num2str(val)];
        else
          for kk=1:size(val,1)
            for ll=1:size(val,2)
              if size(val,1)==1
                Str2{end+1}=[par,'(',num2str(ll),') = ',num2str(val(kk,ll))];
              else
                Str2{end+1}=[par,'(',num2str(kk),', ',num2str(ll),') = ',num2str(val(kk,ll))];
              end
            end
          end
        end
      end
      max_insert = length(Str2);
      cur_ins=1;

      Str1=[];
      for kk=1:length(Str)
        if isempty(strfind(Str{kk},par))
          Str1{end+1}=Str{kk};
        else
          stat = Str{kk}(1:2);
          if cur_ins<=max_insert
            Str1{end+1}=[stat,Str2{cur_ins}];
            cur_ins=cur_ins+1;
          end
        end
      end

      for kk=cur_ins:max_insert, Str1{end+1}=['l-',Str2{kk}]; end
    end

    %----------------------------------------------------------------------
    function result = FormField(app, name, value)
      if ischar(value)
      elseif length(value) == 1
        result = ['i-',name, ' = ', num2str(value)];
      else
        result = ['i-',name, ' = [', num2str(value), ']'];
      end
    end

    %----------------------------------------------------------------------
    function varargout = mFileSave(app, h, eventdata, handles, varargin)
      handles = guidata(handles.figure1);
      pathstr = fileparts(get(handles.figure1, 'FileName'));
      handles.currdir = safeget(handles, 'currdir', pathstr);
      [fname,pname] = uiputfile([handles.currdir, '*.*'],'Save Script');
      handles.currdir = pname;
      if fname == 0, return; end

      y = real(handles.y);
      x = handles.ax.x + handles.ax.dx;

      p = [x,y];
      save([pname, fname], 'p', '-ascii')
      guidata(handles.figure1, handles);
    end

    %----------------------------------------------------------------------
    function PlotThis(app)
      if app.mApplySourceProcessing.Checked
        src = app.KazanViewer.GetData('psource');
      else
        src = app.KazanViewer.GetData('source');
      end

      out = {};

      if app.mShowSource.Checked
        out{1} = src;
        out{1}.title = 'Src';
        out{1}.ax.Color = [0,0,1];
      end

      if app.mShsim.Checked
        for ii=1:length(app.Sim)
          if isfield(app.Sim{ii}, 'x')
            out{end+1}.y = app.Sim{ii}.res * app.Sim{ii}.Scale + app.Sim{ii}.Shift;
            out{end}.ax.x = app.Sim{ii}.x;
            out{end}.ax.y = 1;
            out{end}.ax.Color = app.Color(ii,:);
            out{end}.title = 'Sim';
          end
        end
      end

      if app.mShsumm.Checked && isfield(app.SimSum, 'x')
        out{end+1}.y = app.SimSum.res * app.SimSum.Scale + app.SimSum.Shift;
        out{end}.ax.x = app.SimSum.x;
        out{end}.ax.y = 1;
        out{end}.ax.Color = app.SumColor;
        out{end}.title = 'Sim';
      end

      %       bsum = app.mShsumm.Checked;
      %       bdiff = app.mShdiff.Checked;
      %       bbasln = app.mSimSubBl.Checked;
      %       autobasln = app.mSimAutoBaseline.Checked;
      %       plotsim  = app.mShsim.Checked;
      %       x_sum = [];  y_sum = [];
      %       errr = 0;
      %
      %       Str = app.lbOpt.Items;
      %       OptRng = [];
      %       for k=1:length(Str)
      %         try eval([Str{k}(3:end) ';']); catch; end
      %       end
      %
      %       wf = safeget(Opm, 'wf', 0);
      %       if ~isempty(src.y)
      %         exp_dat = app.AutoBaselineCorrection(y, app.mSimAutoBaseline.Checked);
      %         out{1}.ax = ax;
      %         if handles.Larmfreq && isfield(handles, 'Larmor')
      %           [out{1}.ax.x, out{1}.y] = ShiftFreq(app, ax.x, exp_dat, handles.Larmor);
      %         else
      %           out{1}.y = exp_dat;
      %         end
      %         sh = wf*(0:size(y,2)-1);
      %         out{1}.y = out{1}.y+sh(ones(size(y, 1), 1), :);
      %         out{1}.ax.Color = [0, 0, 1]; % always blue
      %         out{1}.ax.s = 0;
      %         out{1}.xlabel = safeget(ax,'xlabel','?,');
      %         out{1}.title = 'Src';
      %         out{1}.ax.s = 0; % AlSi
      %         firstsimidx = 2;
      %       else
      %         firstsimidx = 1;
      %       end
      %
      %       bline = zeros(size(ax,1), 1);
      %
      %       for kk = 1:handles.num
      %         if isempty(app.Sim{kk}.x), continue; end
      %
      %         Shift = struct('x',0,'s',0,'Scale',1);
      %         if strcmp(app.Sim{kk}.stype, 'sim') || ~bbasln
      %           % load script
      %           str = app.Sim{kk}.Script;
      %           for k=1:size(str,1)
      %             if ~isempty(strfind(str{k},'Shift.'))
      %               try eval([str{k}(3:end) ';']);catch, end
      %             end
      %           end
      %           if strcmp(app.Sim{kk}.stype, 'sim')
      %             data_mult = exp(Opm.Amp/10);
      %           else
      %             data_mult = 1;
      %           end
      %           xTemp = app.Sim{kk}.x;
      %           yTemp = app.Sim{kk}.y;
      %           [sz1,sz2]=size(yTemp);
      %           if handles.Larmfreq && isfield(handles, 'Larmor')
      %             [xTemp, yTemp] = ShiftFreq(app, xTemp, yTemp, handles.Larmor);
      %           end
      %
      %           sh=(0:sz2-1)*wf;
      %           dx = safeget(Shift, 'dx', 0.0);
      %
      %           Scale = safeget(Shift, 'Scale', 1).*data_mult';
      %           if handles.is2D
      %             Scale(end+1:length(app.Sim{kk}.x1),1)=Scale(1,1);
      %             hand.out{end}.ax.s = 0 ;                 % Shift in relative units
      %           else
      %             Scale(end+1:app.Sim{kk}.MultSize,1)=Scale(1,1);
      %           end
      %           if sz2~=length(Scale)
      %             adDim = [1:sz2,ones(1,length(Scale)-sz2)];
      %             yTemp = yTemp(:,adDim).*Scale(:,ones(sz1, 1))';
      %             sh    = sh(adDim);
      %             app.Sim{kk}.x1 = app.Sim{kk}.x1(adDim);
      %             [sz1,sz2]=size(yTemp);
      %           else
      %             yTemp = yTemp.*Scale(:,ones(sz1, 1))';
      %           end
      %
      %           if plotsim
      %             out{end + 1}.ax = ax;
      %             fldnam = fieldnames(Shift);
      %             for ll=1:length(fldnam)
      %               hand.out{end}.ax=setfield(hand.out{end}.ax, fldnam{ll},getfield(Shift,fldnam{ll}));
      %             end
      %             out{end}.ax.dx = dx;
      %
      %             out{end}.ax.y = app.Sim{kk}.x1;
      %             out{end}.y = yTemp + sh(ones(sz1, 1), :);
      %             out{end}.ax.x = xTemp;
      %             out{end}.ax.Color = safeget(Shift, 'Color', app.Color(kk, :));
      %             out{end}.ax.s = Shift.s*max(data_mult) ; % Shift in relative units
      %             out{end}.title = ['Out', num2str(kk)];
      %             out{end}.ax.freq1 = app.Sim{kk}.freq1;
      %             %% Save scripts
      %             for ci = 1:length(app.Sim{kk}.Script)
      %               out{end}.Script{ci} = app.Sim{kk}.Script{ci}(3:end);
      %             end
      %             %% Add scripts as DSC
      %             if app.mOptAddScripts.Checked
      %               if ~isfield(hand.out{end},'dsc') hand.out{end}.dsc = []; end
      %               for ci = 1:length(app.Sim{kk}.Script)
      %                 out{end}.dsc = setfield(out{end}.dsc, ['script',num2str(ci)],...
      %                   app.Sim{kk}.Script{ci}(3:end));
      %               end
      %             end
      %           end
      %           if (bsum || bdiff)
      %             if isempty(y_sum) || isempty(x_sum)
      %               y_sum = yTemp; x_sum = xTemp + dx;
      %             else
      %               for kk=1:sz2
      %                 spl = spline(xTemp + dx, yTemp(:,kk), x_sum);
      %                 y_sum(:,kk) = y_sum(:,kk) + spl(:);
      %               end
      %             end
      %           end
      %         else
      %           bline = bline + app.Sim{kk}.y;
      %         end
      %       end
      %
      %       if (~errr)&&(bbasln)
      %         hand.out{1}.y = hand.out{1}.y - bline;
      %       end
      %       sh=[0:sz2-1]*wf;
      %       % Add sum
      %       sz = size(y_sum, 2);
      %       if (~errr) && bsum && max(y_sum)~=min(y_sum)
      %         out{end + 1}.ax = hand.out{1}.ax;
      %         out{end}.y = y_sum+sh(ones(size(yTemp, 1), 1), :);
      %         out{end}.ax.x = x_sum;
      %         out{end}.ax.dx = 0;
      %         out{end}.ax.Color = handles.SumCol;
      %         out{end}.ax.s = 0;
      %         out{end}.title = 'Sum';
      %         %     uses the last shift
      %         out{end}.ax.s = Opm.dys;
      %       end
      %       % Add diff
      %       if (~errr) && bdiff && firstsimidx==2 && max(y_sum)~=min(y_sum)
      %         out{end + 1}.ax = hand.out{1}.ax;
      %         for kk=1:sz
      %           yy(:,kk)= exp_dat(:,kk)-spline(x_sum,y_sum(:,kk),hand.out{end}.ax.x);
      %         end
      %         out{end}.y = yy + sh(ones(size(exp_dat, 1), 1), :);
      %         out{end}.ax.Color = handles.DiffCol;
      %         out{end}.ax.s = 0;
      %         out{end}.title = 'Diff';
      %       end
      %       script = lscript(app, handles);

      app.KazanViewer.SetData('output', out);
    end

    % Update components that require runtime configuration

    %----------------------------------------------------------------------
    function result = ScriptFunctions(app, script, mode, arg1)
      switch mode
        case {'Sys','Exp','Shift', 'Opt'}
          eval([mode, '= [];']);
          for ii=1:length(script)
            if contains(script{ii}, [mode,'.'])
              try eval([script{ii}(3:end), ';']); catch; end
            end
          end
          result = eval([mode, ';']);
        case 'Vary'
          Sys = [];
          for ii=1:length(script)
            if contains(script{ii}, 'Sys.')
              prefix = script{ii}(1:2);
              pos = strfind(script{ii}, '=');
              lvalue = script{ii}(3:pos);
              %               rvalue = script{ii}(pos+1:end);
              if contains(prefix, '+')
                try eval([script{ii}(3:end), ';']); catch; end
              else
                try eval([lvalue, '0;']); catch; end
              end
            end
          end
          result = Sys;
        case 'GetSys'
          result = script;
          for ii=1:length(result)
            Sys = arg1;  % used in eval
            if contains(result{ii}, 'Sys.')
              prefix = result{ii}(1:2);
              if contains(prefix, '+')
                pos1 = strfind(result{ii}, 'Sys.');
                pos2 = strfind(result{ii}, '=');
                lvalue = result{ii}(pos1:pos2);
                try
                  rvalue = eval(lvalue(1:end-1));
                  result{ii} = [prefix, lvalue, ' ', num2str(rvalue)];
                catch
                end
              end
            end
          end
        case 'listitems'
          result = script;
        case 'field'
          [arg_name] = strtok(arg1, '=');
          arg_name = arg_name(3:end);
          is_found = false;
          for ii=1:length(script)
            if contains(script{ii}, arg_name)
              script{ii} = arg1;
              is_found = true;
              break;
            end
          end
          if ~is_found
            script{end+1} = arg1;
          end
          result = script;
        case 'sort'
          result = sort(script);
        otherwise
          result = [];
      end
    end

    %----------------------------------------------------------------------
    function UpdateScriptList(app)
      str = cell(length(app.Sim), 1);
      for ii=1:length(app.Sim)
        str{ii} = safeget(app.Sim{ii}, 'ScriptName', num2str(ii));
      end
      app.ddScriptNumber.Items = str;
      app.ddScriptNumber.ItemsData = 1:length(app.ddScriptNumber.Items);
    end

    %----------------------------------------------------------------------
    function UpdateScriptDisplay(app)
      n = app.ddScriptNumber.Value;
      app.lScript.Items = app.ScriptFunctions(app.Sim{n}.Script, 'listitems');
      app.lScript.ItemsData = 1:length(app.lScript.Items);
    end

    %----------------------------------------------------------------------
    function ESFIT(app)
      tic
      num = app.ddScriptNumber.Value;
      Sys = app.ScriptFunctions(app.Sim{num}.Script, 'Sys');
      Opt = app.ScriptFunctions(app.Sim{num}.Script, 'Opt');
      switch safeget(Opt, 'Sim', 'pepper')
        case 'pepper'
          % Calculation of field-swept and frequency-swept solid-state cw EPR spectra for powders and crystals
          src = app.KazanViewer.GetData('source');
          F = safeget(src.ax, 'freq1', 0);
          if app.mSimLoadFreq.Checked && F ~= 0
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.mwFreq', F*1E-9));
            app.UpdateScriptDisplay();
          end

          % Always update range
          range = [min(src.ax.x), max(src.ax.x)] + safeget(src.ax, 'dx', 0);
          app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.Range', range/10));
          app.UpdateScriptDisplay();

          Exp = app.ScriptFunctions(app.Sim{num}.Script, 'Exp');
          Vary = app.ScriptFunctions(app.Sim{num}.Script, 'Vary');
          [BestSys,BestSpc] = esfit('pepper',src.y,Sys,Vary,Exp);
          app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'GetSys', BestSys);
          app.UpdateScriptDisplay();
          app.Sim{num}.res = BestSpc';
          app.Sim{num}.x = linspace(min(Exp.Range), max(Exp.Range), length(src.y)) * 10;  % mT to G conversion
        case 'garlic'
          % Computes isotropic and fast-motional cw EPR spectra of radicals in solution.
          src = app.KazanViewer.GetData('source');
          F = safeget(src.ax, 'freq1', 0);
          if app.mSimLoadFreq.Checked && F ~= 0
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.mwFreq', F*1E-9));
            app.UpdateScriptDisplay();
          end
          if app.mSimLoadRange.Checked
            range = [min(src.ax.x), max(src.ax.x)];
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.Range', range/10));
            app.UpdateScriptDisplay();
          end
          Exp = app.ScriptFunctions(app.Sim{num}.Script, 'Exp');
          [app.Sim{num}.x,app.Sim{num}.res] = garlic(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
          app.Sim{num}.x = app.Sim{num}.x * 10;  % mT to G conversion
        case 'chili'
          % Simulation of field- and frequency-sweep cw EPR spectra in the slow-motional regime.
          src = app.KazanViewer.GetData('source');
          F = safeget(src.ax, 'freq1', 0);
          if app.mSimLoadFreq.Checked && F ~= 0
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.mwFreq', F*1E-9));
            app.UpdateScriptDisplay();
          end
          if app.mSimLoadRange.Checked
            range = [min(src.ax.x), max(src.ax.x)];
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.Range', range/10));
            app.UpdateScriptDisplay();
          end
          Exp = app.ScriptFunctions(app.Sim{num}.Script, 'Exp');
          [app.Sim{num}.x,app.Sim{num}.res] = chili(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
          app.Sim{num}.x = app.Sim{num}.x * 10;  % mT to G conversion
        case 'salt'
          % Calculation of powder and single-crystal ENDOR spectra.
          [app.Sim{num}.x,app.Sim{num}.res] = salt(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
        case 'saffron'
          % Calculation of pulse EPR spectra.
          [app.Sim{num}.x,app.Sim{num}.res] = saffron(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
          app.Sim{num}.x = app.Sim{num}.x * 10;  % mT to G conversion
        case 'curry'
          % Calculates magnetometry data: magnetic moment, static magnetic susceptibility.
          [app.Sim{num}.x,app.Sim{num}.res] = saffron(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
      end

      app.SimSum.x = app.Sim{1}.x;
      app.SimSum.res = app.Sim{1}.res;
      for ii=2:length(app.Sim)
        if isequal(size(app.SimSum.res), size(app.Sim{num}.res))
          app.SimSum.res = app.SimSum.res + app.Sim{num}.res;
        end
      end

      src = app.KazanViewer.GetData('source');
      fitmax = max(app.SimSum.res);
      srcmax = max(src.y);
      app.SimSum.Scale = srcmax / fitmax;
      app.SimSum.Shift = 0;

      for ii=1:length(app.Sim)
        app.Sim{num}.Scale = app.SimSum.Scale;
        app.Sim{num}.Shift = app.SimSum.Shift;
      end
      app.KazanViewer.Set('message', sprintf('ESFIT: calculation time %5.1fs.', toc));
      app.PlotThis();
    end

    %----------------------------------------------------------------------
    function x = UnitConversion(app, mode, ax, x)
      cc = 1.0;
      switch mode
        case {'field', 'rfield'}
          if app.mUnitsAutomatic.Checked
            switch safeget(ax, 'unit', 'G')
              case 'G', cc = 0.1; % G->mT
            end
          elseif app.mUnitsGauss.Checked
            cc = 0.1; % G->mT
          elseif app.mUnitsmT.Checked
            cc = 1;
          end
          if strcmp(mode, 'field')
            x = x * cc;
          else
            x = x / cc;
          end
      end
    end

    %----------------------------------------------------------------------
    function [it_name, it_val] = ScriptToID(app, script)
      [it_name, str1] = strtok(script, '=');
      it_val = [strtrim(str1(3:end)), ' '];
    end

  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
      app.Options.Steps = dictionary('value', 1);

      app.Color = [0.8500, 0.3250, 0.0980;...
        0.9290, 0.6940, 0.1250;...
        0.4940, 0.1840, 0.5560;...
        0.4660, 0.6740, 0.1880;...
        0.3010, 0.7450, 0.9330;...
        0, 0.4470, 0.7410;...
        0.6350, 0.0780, 0.1840];

      app.SumColor = [1,0,0];
      app.DiffColor = [0,1,0];

      app.Sim{1}.Script = {'i-Sys.S = 0.5', 'i-Sys.g(1) = 2', 'i-Sys.g(2) = 2', 'i-Sys.g(3) = 2', 'i-Sys.lw = 5', 'i-Exp.Range = [330,370]', 'i-Sys.gStrain(1) = 0', ...
        'i-Sys.gStrain(2) = 0', 'i-Sys.gStrain(3) = 0', 'i-Exp.mwFreq = 9.77', 'i-Exp.Harmonic = 1', 'i-Shift.x = 0', 'i-Shift.Scale = 1'};
      app.UpdateScriptList();
      app.UpdateScriptDisplay();

      set(app.ddStepSize, 'Items', {'1e-7', '1e-6', '1e-5', '1e-4', '1e-3', '0.01', '0.1', '1', '10', '100', '1000', '1e4', '1e5', '1e6', '1e7', '1e8'});
      set(app.ddStepSize, 'ItemsData', [1e-7, 1e-6, 1e-5, 1e-4, 1e-3, 0.01, 0.1, 1, 10, 100, 1000, 1e4, 1e5, 1e6, 1e7, 1e8]);

    end

    % Menu selected function: mFileBaseline
    function AddSim_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      handles = guidata(handles.figure1);

      switch hObject
        case handles.mFileBaseline
          stype = 'baseline';
          title = 'Open Baseline Script';
        otherwise
          stype = 'sim';
          title = 'Open Simulation Script';
      end

      full = get(handles.figure1, 'FileName');
      [fpath] = fileparts(full);
      handles.currdir = safeget(handles, 'currdir', fpath);
      [fname, pname] = uigetfile([handles.currdir, '\*.m'],title);
      handles.currdir = pname;
      if fname == 0, return; end


      [name, script] = LoadScript(app, [pname fname], stype);
      set(handles.list, 'String', script, 'Value', 1);

      if ischar(get(handles.popSel, 'String'))
        str = {get(handles.popSel, 'String')};
      else
        str = get(handles.popSel, 'String');
      end

      str = ChangeSimNumber(app, str); % changing numbers in popSel list

      str{handles.num + 1} = [num2str(handles.num + 1), ': ', name];
      set(handles.popSel, 'String', str, 'Value', handles.num + 1);

      hand = guidata(handles.hh);
      app.Sim{handles.num + 1}.Script = script;
      app.Sim{handles.num + 1}.x = hand.src.ax.x;
      app.Sim{handles.num + 1}.y = zeros(size(hand.src.ax.x));
      app.Sim{handles.num + 1}.x1 = 0;
      app.Sim{handles.num + 1}.stype = stype;
      handles.Color(handles.num + 1, :) = handles.Color(mod(handles.num,7)+1, :);
      handles.num = handles.num + 1;

      guidata(handles.figure1, handles);
      popSel_Callback(hObject, eventdata, handles);
    end

    % Button pushed function: bCalculate, pbReCalc
    function bCalculate_Callback(app, event)
      app.KazanViewer.Set('message', 'Calculating ...');
      tic
      num = app.ddScriptNumber.Value;
      Sys = app.ScriptFunctions(app.Sim{num}.Script, 'Sys');
      Opt = app.ScriptFunctions(app.Sim{num}.Script, 'Opt');
      Exp = app.ScriptFunctions(app.Sim{num}.Script, 'Exp');
      Shift = app.ScriptFunctions(app.Sim{num}.Script, 'Shift');

      if event.Source == app.pbReCalc
        disp('=== Sys =============================');
        disp(Sys);
        disp('=== Exp =============================');
        disp(Exp);
        disp('=== Opt =============================');
        disp(Opt);
        disp('=====================================');
        app.KazanViewer.Set('message', 'See Console output for Sys,Exp,Opt.');
        return;
      end

      switch safeget(Opt, 'Sim', 'pepper')
        case 'pepper'
          % Calculation of field-swept and frequency-swept solid-state cw EPR spectra for powders and crystals
          src = app.KazanViewer.GetData('source');
          F = safeget(src.ax, 'freq1', 0);
          if app.mSimLoadFreq.Checked && F ~= 0
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.mwFreq', F*1E-9));
            app.UpdateScriptDisplay();
          end
          if app.mSimLoadRange.Checked
            x = app.UnitConversion('field', src.ax, src.ax.x);
            range = [min(x), max(x)];
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.Range', range));
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.nPoints', length(src.y)));
            app.UpdateScriptDisplay();
          end
          if contains(app.ddOptMethod.Value, 'ESFIT')
            Vary = app.ScriptFunctions(app.Sim{num}.Script, 'Vary');
            [BestSys,BestSpc] = esfit('pepper',src.y,Sys,Vary,Exp);
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'GetSys', BestSys);
            app.UpdateScriptDisplay();
            app.Sim{num}.res = BestSpc';
            app.Sim{num}.x = app.UnitConversion('rfield', src.ax.x, linspace(min(Exp.Range), max(Exp.Range), length(src.y)));
          else
            [app.Sim{num}.x,app.Sim{num}.res] = pepper(Sys, Exp, Opt);
            app.Sim{num}.res = app.Sim{num}.res(:) * safeget(Shift, 'Scale', 1.0) ;
            app.Sim{num}.x = app.UnitConversion('rfield', src.ax.x, app.Sim{num}.x)  + safeget(Shift, 'dx', 0.0);  % mT to G conversion
          end
        case 'garlic'
          % Computes isotropic and fast-motional cw EPR spectra of radicals in solution.
          src = app.KazanViewer.GetData('source');
          F = safeget(src.ax, 'freq1', 0);
          if app.mSimLoadFreq.Checked && F ~= 0
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.mwFreq', F*1E-9));
            app.UpdateScriptDisplay();
          end
          if app.mSimLoadRange.Checked
            x = app.UnitConversion('field', src.ax);
            range = [min(x), max(x)];
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.Range', range));
            app.UpdateScriptDisplay();
          end
          [app.Sim{num}.x,app.Sim{num}.res] = garlic(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
          app.Sim{num}.x = app.UnitConversion('rfield', src.ax.x, app.Sim{num}.x)  + Shift.dx;  % mT to G conversion
        case 'chili'
          % Simulation of field- and frequency-sweep cw EPR spectra in the slow-motional regime.
          src = app.KazanViewer.GetData('source');
          F = safeget(src.ax, 'freq1', 0);
          if app.mSimLoadFreq.Checked && F ~= 0
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.mwFreq', F*1E-9));
            app.UpdateScriptDisplay();
          end
          if app.mSimLoadRange.Checked
            x = app.UnitConversion('field', src.ax);
            range = [min(x), max(x)];
            app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'field', app.FormField('Exp.Range', range));
            app.UpdateScriptDisplay();
          end
          [app.Sim{num}.x,app.Sim{num}.res] = chili(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
          app.Sim{num}.x = app.Sim{num}.x * 10;  % mT to G conversion
        case 'salt'
          % Calculation of powder and single-crystal ENDOR spectra.
          [app.Sim{num}.x,app.Sim{num}.res] = salt(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
        case 'saffron'
          % Calculation of pulse EPR spectra.
          [app.Sim{num}.x,app.Sim{num}.res] = saffron(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
          app.Sim{num}.x = app.Sim{num}.x * 10;  % mT to G conversion
        case 'curry'
          % Calculates magnetometry data: magnetic moment, static magnetic susceptibility.
          [app.Sim{num}.x,app.Sim{num}.res] = saffron(Sys, Exp, Opt);
          app.Sim{num}.res = app.Sim{num}.res(:);
      end

      app.SimSum.x = app.Sim{1}.x;
      app.SimSum.res = app.Sim{1}.res;
      for ii=2:length(app.Sim)
        if isequal(size(app.SimSum.res), size(app.Sim{num}.res))
          app.SimSum.res = app.SimSum.res + app.Sim{num}.res;
        end
      end

      switch app.ddOptMethod.Value
        case 'Fit Peak-To-Peak'
          src = app.KazanViewer.GetData('source');
          fitmax = max(app.SimSum.res);
          fitmin = min(app.SimSum.res);
          srcmax = max(src.y);
          srcmin = min(src.y);
          app.SimSum.Scale = (srcmax - srcmin) / (fitmax - fitmin);
          app.SimSum.Shift = srcmin + - fitmin * app.SimSum.Scale;
        case {'Fit Amplitude', 'ESFIT'}
          src = app.KazanViewer.GetData('source');
          fitmax = max(app.SimSum.res);
          srcmax = max(real(src.y));
          app.SimSum.Scale = srcmax / fitmax;
          app.SimSum.Shift = 0;
        otherwise
          app.SimSum.Scale = 1;
          app.SimSum.Shift = 0;
      end
      app.lbOpt.Items = {sprintf('Opm.Scale = %5.2f', app.SimSum.Scale), sprintf('Opm.Shift = %5.2f', app.SimSum.Shift)};

      for ii=1:length(app.Sim)
        app.Sim{num}.Scale = app.SimSum.Scale;
        app.Sim{num}.Shift = app.SimSum.Shift;
      end
      if app.mSimChi.Checked && isequal(size(src.y), size(app.Sim{num}.res))
        err = std(src.y - (app.Sim{num}.res*app.SimSum.Scale + app.SimSum.Shift));
        app.KazanViewer.Set('message', sprintf('Calculation time %5.1fs. Error %5.1f', toc, err));
      else
        app.KazanViewer.Set('message', sprintf('Calculation time %5.1fs.', toc));
      end

      % Fit Integral
      % Fit Double Integral
      % Fix Double Integral
      app.PlotThis();
    end

    % Menu selected function: mDeleteAllScripts
    function butDel_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      handles = guidata(handles.figure1);
      str = get(handles.popSel, 'String');
      if hObject==handles.mDeleteAll
        app.Sim = {app.Sim{1}};
        outstr = ChangeSimNumber(app, {str{1, :}}');
        handles.num = 1;
      else
        num = handles.num;
        val = get(handles.popSel, 'Value');
        if num == 1, return; end

        % ss = 1:num; ss = ss(ss~=val); % AlSi 07.12.04
        switch val
          case 1
            ss = 2:num;
          case num
            ss = 1:num-1;
          otherwise
            ss = [1:val-1, val+1:num];
        end

        app.Sim = {app.Sim{ss}};
        handles.num = handles.num - 1;
        outstr = ChangeSimNumber(app, {str{ss, :}}');
      end
      set(handles.popSel, 'String', outstr, 'Value', 1);
      guidata(handles.figure1, handles);
      popSel_Callback(hObject, eventdata, handles);
    end

    % Value changed function: eParsNumber, eParsString
    function EditParameterChanged(app, event)
      num = app.ddScriptNumber.Value;
      nline = app.lScript.Value;
      str = app.lScript.Items{nline};
      prefix = str(1:2);
      str = str(3:end);

      [lvalue, str1] = strtok(str, '=');
      str2 = [strtrim(str1(3:end)), ' '];

      switch app.EditorMode
        case 'function'
          rvalue = app.eParsString.Value;
          app.Sim{num}.Script{nline} = [prefix, strtrim(lvalue), ' = ', rvalue];
        case 'string'
          rvalue = app.eParsString.Value;
          app.Sim{num}.Script{nline} = [prefix, strtrim(lvalue), ' = ', rvalue];
        case 'array'
          rvalue = app.eParsString.Value;
          app.Sim{num}.Script{nline} = [prefix, strtrim(lvalue), ' = ', rvalue];
        case 'number'
          rvalue = app.eParsNumber.Value;
          app.Sim{num}.Script{nline} = [prefix, strtrim(lvalue), ' = ', num2str(rvalue)];
      end
      app.UpdateScriptDisplay();
      if app.UpdCheckBox.Value
        app.bCalculate_Callback(struct('Source',app.bCalculate));
      end
    end

    % Value changed function: lScript
    function lScript_Callback(app, event)
      num = app.lScript.Value;
      [app.ActiveParameter, str2] = app.ScriptToID(app.lScript.Items{num});

      if strcmp(app.ActiveParameter, 'f')
        app.eParsString.Value = str2;
        app.EditorMode = 'function';
        app.eParsString.Visible = true;
        app.eParsNumber.Visible = false;
      elseif contains(str2, '''')
        app.eParsString.Value = str2;
        app.EditorMode = 'string';
        app.eParsString.Visible = true;
        app.eParsNumber.Visible = false;
      elseif contains(str2, '[') || contains(str2, ']')
        app.eParsString.Value = str2;
        app.EditorMode = 'array';
        app.eParsString.Visible = true;
        app.eParsNumber.Visible = false;
      else
        app.eParsNumber.Value = str2double(str2);
        app.EditorMode = 'number';
        app.eParsNumber.Visible = true;
        app.eParsString.Visible = false;

        % select step
        if isKey(app.Options.Steps, app.ActiveParameter)
          app.ddStepSize.Value = app.Options.Steps(app.ActiveParameter);
          app.eParsNumber.Step = app.ddStepSize.Value;
        end
      end
    end

    % Menu selected function: mAbout, mAboutHelp
    function mAbout_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      handles = guidata(handles.figure1);

      if hObject==handles.mAboutHelp
        [fpath] = fileparts(which('kazan'));
        filename = ['file:///',fpath,'\help\simplugin_sims.html'];
        filename(filename=='\')='/';
        stat = web(filename);
      else
        msgbox({'simplugin';
          'EPR/ENDOR simulations interface';
          'by Boris Epel and Alexey Silakov, 2003-04';
          'EasySpin (www.esr.ethz.ch) syntax rules are used';'';
          'Syntax:';
          'Sys/Opt/Exp/Shift.fld_name = any_Matlab_expression';
          'i.e. Opt.ThetaRange([1,2])=[0,pi]';'';
          'Parameters additional to EasySpin:';
          'Opt.Sim - any sim of the type [x,y]=sim_name(Sys, Exp, Opt)';
          '  = pepper, salt, sugar, sugar1 <pepper>';
          '''2D'' in the name of simulation result in [x,x1,y]=sim_name(Sys, Exp, Opt)';
          '  = triplesugar2D';
          'Exp.gField - Field in g-factor units (salt, sugar)';'';
          'Shift parameters:';
          'x and y - simulation shift along corresponding axis <0>';
          'Scale - amplitude of simulation <1>';
          'wf - waterfall (shift of 2D slices) <1>';
          'line <''-''> and color [r g b]';'';
          'Some parameters (see line 314) could be arrays (COLUMNS!!!)';
          'i.e.: Sys.gField=[2.001;2.002;2.003] or Opt.ThetaRange(:,1) = [0:8]''*pi/15'
          }, ...
          'About', 'help')
      end
    end

    % Menu selected function: mContNotOpt, mContOpt
    function mContOptSet_Callback(app, event)
      num = app.ddScriptNumber.Value;
      nline = app.lScript.Value;
      switch event.Source
        case app.mContOpt
          app.Sim{num}.Script{nline}(2) = '+';
        case app.mContNotOpt
          app.Sim{num}.Script{nline}(2) = '-';
      end
      app.UpdateScriptDisplay();
    end

    % Menu selected function: SortMenu, mAddScript, mDeleteScript, 
    % ...and 6 other components
    function OnLoadSave(app, event)
      num = app.ddScriptNumber.Value;
      switch event.Source
        case app.mAddScript
          [fname,pname] = uigetfile(fullfile(app.CurrentDirectory, '\*.m'),'Open Script');
          if fname == 0, return; end
          app.CurrentDirectory = pname;
          [name, script] = app.LoadScript(fullfile(pname, fname));
          app.Sim{end+1}.Script = script;
          app.Sim{end}.ScriptFile = fullfile(pname, fname);
          app.Sim{end}.ScriptName = name;
          app.UpdateScriptList();
          app.UpdateScriptDisplay();
        case app.mLoadScript
          [fname,pname] = uigetfile(fullfile(app.CurrentDirectory, '\*.m'),'Open Script');
          if fname == 0, return; end
          app.CurrentDirectory = pname;
          [name, script] = app.LoadScript(fullfile(pname, fname));
          app.Sim{num}.Script = script;
          app.Sim{num}.ScriptFile = fullfile(pname, fname);
          app.Sim{num}.ScriptName = name;
          app.UpdateScriptDisplay();
        case app.mReloadScript
          [name, script] = app.LoadScript(app.Sim{num}.ScriptFile);
          app.Sim{num}.Script = script;
          app.Sim{num}.ScriptFile = app.Sim{num}.ScriptFile;
          app.Sim{num}.ScriptName = name;
          app.UpdateScriptDisplay();
        case app.mSaveScript
          [fname,pname] = uiputfile(fullfile(app.CurrentDirectory, '\*.m'),'Save Script');
          if fname == 0, return; end
          app.CurrentDirectory = pname;
          app.SaveScript(fullfile(pname, fname), app.Sim{num}.Script);
        case app.mScriptEditor
          % open(app.Sim{num}.ScriptFile);
          outscr = kv_scripteditor(app.Sim{num}.Script);
          if isempty(outscr), return; end
          app.Sim{num}.Script = outscr;
          app.UpdateScriptDisplay();
        case app.mDuplicateScript
          app.Sim{end+1} = app.Sim{num};
          app.Sim{end}.ScriptName = num2str(length(app.Sim));
          app.UpdateScriptList();
          app.UpdateScriptDisplay();
        case app.SortMenu
          app.Sim{num}.Script = app.ScriptFunctions(app.Sim{num}.Script, 'sort');
          app.UpdateScriptDisplay();
        case app.mFileChName
          answer  = inputdlg('Type name','Change simulation name',1,{safeget(app.Sim{num}, 'ScriptName', 'name')});
          if ~isempty(answer)
            app.Sim{num}.ScriptName = answer{1};
          end
      end
    end

    % Menu selected function: mRefresh
    function mRefresh_Callback(app, event)
      app.PlotThis();
    end

    % Callback function
    function mApplySourceProcessing_Callback(app, event)
      app.mApplySourceProcessing.Checked = ~app.mApplySourceProcessing.Checked;
      app.PlotThis();
    end

    % Callback function: butCol, mColDiff, mColSum
    function mSimCol_Callback(app, event)
      % Create GUIDE-style callback args - Added by Migration Tool
      [h, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

      handles = guidata(handles.figure1);
      switch h
        case handles.mColSum
          c = uisetcolor(handles.SumCol);
          if size(c, 2) > 1, handles.SumCol = c;
          else, return;
          end
        case handles.mColDiff
          c = uisetcolor(handles.DiffCol);
          if size(c, 2) > 1, handles.DiffCol = c;
          else, return;
          end
        case handles.butCol
          num = get(handles.popSel, 'Value');
          c = uisetcolor(handles.Color(num, :));
          if size(c, 2) > 1
            handles.Color(num, :) = c;
            set(handles.butCol, 'BackgroundColor', c, 'ForegroundColor', [1 1 1] - c);
          end
      end
      guidata(handles.figure1, handles);
      app.PlotThis();
    end

    % Callback function: UpdCheckBox, mShdiff, mShowSource, mShsim, 
    % ...and 6 other components
    function CheckBoxChanged(app, event)
      switch event.Source
        case {app.mShowSource, app.mShsim}
          event.Source.Checked = ~event.Source.Checked;
          app.PlotThis();
        case app.UpdCheckBox
          %           app.UpdCheckBox.Value = ~app.UpdCheckBox.Value;
        otherwise
          event.Source.Checked = ~event.Source.Checked;
      end
    end

    % Value changed function: ddScriptNumber
    function ddScriptNumber_Callback(app, event)
      app.UpdateScriptDisplay();
    end

    % Size changed function: figure1
    function figure1SizeChanged(app, event)
      standard_size = [70   669   265   365];
      fig_size = app.figure1.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.2;

      if ~isempty(app.Color)
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.figure1, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIStateButton'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UISpinner'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UILabel'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UICheckBox'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIEdit'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIDropDown'), 'FontSize', font_size);
        set(findall(app.figure1, 'Type', 'UIListBox'), 'FontSize', font_size);
      end
    end

    % Close request function: figure1
    function figure1CloseRequest(app, event)
      try app.KazanViewer.MakePlugins('close', app); catch; end
      delete(app);
    end

    % Value changed function: ddStepSize
    function ddStepSizeValueChanged(app, event)
      app.eParsNumber.Step = app.ddStepSize.Value;
      id = app.ScriptToID(app.lScript.Items{app.lScript.Value});
      app.Options.Steps(id) = app.ddStepSize.Value;
    end

    % Menu selected function: mUnitsAutomatic, mUnitsGauss, mUnitsHz, 
    % ...and 1 other component
    function UnitConversionChanged(app, event)
      units = [app.mUnitsHz, app.mUnitsAutomatic, app.mUnitsGauss, app.mUnitsmT];
      set(units, 'Checked', false);
      event.Source.Checked = true;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.figure1 = uifigure('Visible', 'off');
      colormap(app.figure1, 'parula');
      app.figure1.Name = 'simplugin';
      app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
      app.figure1.SizeChangedFcn = createCallbackFcn(app, @figure1SizeChanged, true);
      app.figure1.Tag = 'figure1';
      app.uimenu1 = uimenu(app.figure1);
      app.uimenu1.Text = 'Data';
      app.uimenu1.Tag = 'uimenu1';
      app.mSimLoadFreq = uimenu(app.uimenu1);
      app.mSimLoadFreq.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mSimLoadFreq.Text = 'Load Frequency';
      app.mSimLoadFreq.Tag = 'mSimLoadFreq';
      app.mSimLoadRange = uimenu(app.uimenu1);
      app.mSimLoadRange.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mSimLoadRange.Text = 'Load Field';
      app.mSimLoadRange.Tag = 'mSimLoadRange';
      app.UnitsMenu = uimenu(app.uimenu1);
      app.UnitsMenu.Text = 'Units';
      app.mUnitsAutomatic = uimenu(app.UnitsMenu);
      app.mUnitsAutomatic.MenuSelectedFcn = createCallbackFcn(app, @UnitConversionChanged, true);
      app.mUnitsAutomatic.Checked = 'on';
      app.mUnitsAutomatic.Text = 'Automatic unit conversion';
      app.mUnitsGauss = uimenu(app.UnitsMenu);
      app.mUnitsGauss.MenuSelectedFcn = createCallbackFcn(app, @UnitConversionChanged, true);
      app.mUnitsGauss.Text = 'Display in Gauss';
      app.mUnitsmT = uimenu(app.UnitsMenu);
      app.mUnitsmT.MenuSelectedFcn = createCallbackFcn(app, @UnitConversionChanged, true);
      app.mUnitsmT.Text = 'Display in mT';
      app.mUnitsHz = uimenu(app.UnitsMenu);
      app.mUnitsHz.MenuSelectedFcn = createCallbackFcn(app, @UnitConversionChanged, true);
      app.mUnitsHz.Text = 'Display in Hz';
      app.mSimLarm = uimenu(app.uimenu1);
      app.mSimLarm.Text = 'Shift to Larmor Frequency';
      app.mSimLarm.Tag = 'mSimLarm';
      app.mApplySourceProcessing = uimenu(app.uimenu1);
      app.mApplySourceProcessing.Separator = 'on';
      app.mApplySourceProcessing.Text = 'Source Processing';
      app.mApplySourceProcessing.Tag = 'mSimAgr';
      app.mSimAutoBaseline = uimenu(app.uimenu1);
      app.mSimAutoBaseline.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mSimAutoBaseline.Text = 'Auto baseline';
      app.mSimAutoBaseline.Tag = 'mSimAutoBaseline';
      app.mFileBaseline = uimenu(app.uimenu1);
      app.mFileBaseline.MenuSelectedFcn = createCallbackFcn(app, @AddSim_Callback, true);
      app.mFileBaseline.Text = 'Add baseline ...';
      app.mFileBaseline.Tag = 'mFileBaseline';
      app.mSimSubBl = uimenu(app.uimenu1);
      app.mSimSubBl.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mSimSubBl.Text = 'Subtract baseline';
      app.mSimSubBl.Tag = 'mSimSubBl';
      app.mEdit = uimenu(app.figure1);
      app.mEdit.Text = 'Script';
      app.mEdit.Tag = 'mEdit';
      app.mScriptEditor = uimenu(app.mEdit);
      app.mScriptEditor.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mScriptEditor.Text = 'Open in Editor';
      app.mScriptEditor.Tag = 'mFileOpenEditor';
      app.mCopy2Clipboard = uimenu(app.mEdit);
      app.mCopy2Clipboard.Text = 'Copy to Clipboard';
      app.mCopy2Clipboard.Tag = 'mFileCopyClipboard';
      app.SortMenu = uimenu(app.mEdit);
      app.SortMenu.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.SortMenu.Text = 'Sort';
      app.mAddScript = uimenu(app.mEdit);
      app.mAddScript.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mAddScript.Separator = 'on';
      app.mAddScript.Text = 'Add';
      app.mAddScript.Tag = 'AddSim';
      app.mLoadScript = uimenu(app.mEdit);
      app.mLoadScript.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mLoadScript.Text = 'Load';
      app.mLoadScript.Tag = 'mLoad';
      app.mReloadScript = uimenu(app.mEdit);
      app.mReloadScript.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mReloadScript.Text = 'Reload';
      app.mReloadScript.Tag = 'mFileReload';
      app.mSaveScript = uimenu(app.mEdit);
      app.mSaveScript.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mSaveScript.Text = 'Save';
      app.mSaveScript.Tag = 'mSave';
      app.mDuplicateScript = uimenu(app.mEdit);
      app.mDuplicateScript.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mDuplicateScript.Text = 'Duplicate';
      app.mDuplicateScript.Tag = 'mFileCopy';
      app.mDeleteScript = uimenu(app.mEdit);
      app.mDeleteScript.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mDeleteScript.Text = 'Delete';
      app.mDeleteScript.Tag = 'mFileDelete';
      app.mDeleteAllScripts = uimenu(app.mEdit);
      app.mDeleteAllScripts.MenuSelectedFcn = createCallbackFcn(app, @butDel_Callback, true);
      app.mDeleteAllScripts.Text = 'Delete All';
      app.mDeleteAllScripts.Tag = 'mDeleteAll';
      app.mLoadAll = uimenu(app.mEdit);
      app.mLoadAll.MenuSelectedFcn = createCallbackFcn(app, @OnLoadSave, true);
      app.mLoadAll.Text = 'Load All...';
      app.mLoadAll.Tag = 'mLoadAll';
      app.mSaveAll = uimenu(app.mEdit);
      app.mSaveAll.Text = 'Save All ...';
      app.mSaveAll.Tag = 'mSaveAll';
      app.mOptions = uimenu(app.figure1);
      app.mOptions.Text = 'Options';
      app.mOptions.Tag = 'mOptions';
      app.mShowSource = uimenu(app.mOptions);
      app.mShowSource.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mShowSource.Separator = 'on';
      app.mShowSource.Checked = 'on';
      app.mShowSource.Text = 'Show Source';
      app.mShsim = uimenu(app.mOptions);
      app.mShsim.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mShsim.Text = 'Show Each';
      app.mShsim.Tag = 'mShsim';
      app.mShsumm = uimenu(app.mOptions);
      app.mShsumm.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mShsumm.Checked = 'on';
      app.mShsumm.Text = 'Show Sum';
      app.mShsumm.Tag = 'mShsumm';
      app.mShdiff = uimenu(app.mOptions);
      app.mShdiff.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mShdiff.Text = 'Show Difference';
      app.mShdiff.Tag = 'mShdiff';
      app.mColSum = uimenu(app.mOptions);
      app.mColSum.MenuSelectedFcn = createCallbackFcn(app, @mSimCol_Callback, true);
      app.mColSum.Separator = 'on';
      app.mColSum.Text = '"Sum" color';
      app.mColSum.Tag = 'mColSum';
      app.mColDiff = uimenu(app.mOptions);
      app.mColDiff.MenuSelectedFcn = createCallbackFcn(app, @mSimCol_Callback, true);
      app.mColDiff.Text = '"Diff" color';
      app.mColDiff.Tag = 'mColDiff';
      app.mSimChi = uimenu(app.mOptions);
      app.mSimChi.MenuSelectedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.mSimChi.Text = 'Show Chi^2';
      app.mSimChi.Tag = 'mSimChi';
      app.mRefresh = uimenu(app.mOptions);
      app.mRefresh.MenuSelectedFcn = createCallbackFcn(app, @mRefresh_Callback, true);
      app.mRefresh.Separator = 'on';
      app.mRefresh.Text = 'Refresh';
      app.mRefresh.Tag = 'mRefresh';
      app.mAboutSimPlugin = uimenu(app.figure1);
      app.mAboutSimPlugin.Text = 'About...';
      app.mAboutSimPlugin.Tag = 'Untitled_1';
      app.mAbout = uimenu(app.mAboutSimPlugin);
      app.mAbout.MenuSelectedFcn = createCallbackFcn(app, @mAbout_Callback, true);
      app.mAbout.Text = 'About';
      app.mAbout.Tag = 'mAbout';
      app.mAboutHelp = uimenu(app.mAboutSimPlugin);
      app.mAboutHelp.MenuSelectedFcn = createCallbackFcn(app, @mAbout_Callback, true);
      app.mAboutHelp.Text = 'Help';
      app.mAboutHelp.Tag = 'mAboutHelp';
      app.GridLayout = uigridlayout(app.figure1);
      app.ddScriptNumber = uidropdown(app.GridLayout);
      app.ddScriptNumber.Items = {'1'};
      app.ddScriptNumber.ValueChangedFcn = createCallbackFcn(app, @ddScriptNumber_Callback, true);
      app.ddScriptNumber.Tag = 'popSel';
      app.ddScriptNumber.Value = '1';
      app.butCol = uibutton(app.GridLayout, 'push');
      app.butCol.ButtonPushedFcn = createCallbackFcn(app, @mSimCol_Callback, true);
      app.butCol.Tag = 'butCol';
      app.butCol.Tooltip = 'Change color';
      app.butCol.Text = '';
      app.lScript = uilistbox(app.GridLayout);
      app.lScript.Items = {'l-Sys.S = 0.5', 'l-Sys.g(1) = 2', 'l-Sys.g(2) = 2', 'l-Sys.g(3) = 2', 'l-Sys.lw = 5', 'l-Exp.Range = [330,370]', 'l-Sys.gStrain(1) = 0', 'l-Sys.gStrain(2) = 0', 'l-Sys.gStrain(3) = 0', 'l-Exp.mwFreq = 9.77', 'l-Exp.Harmonic = 1', 'l-Shift.x = 0', 'l-Shift.Scale = 1'};
      app.lScript.ValueChangedFcn = createCallbackFcn(app, @lScript_Callback, true);
      app.lScript.Tag = 'list';
      app.lScript.Value = 'l-Sys.S = 0.5';
      app.ddStepSize = uidropdown(app.GridLayout);
      app.ddStepSize.Items = {'1e-6', '1e-5', '1e-4', '1e-3', '1e-2', '0.1', '1', '10', '100', '1e+3', '1e+4', '1e+5', '1e+6'};
      app.ddStepSize.ItemsData = {'1e-6', '1e-5', '1e-4', '1e-3', '1e-2', '0.1', '1', '10', '100', '1e+3', '1e+4', '1e+5', '1e+6'};
      app.ddStepSize.ValueChangedFcn = createCallbackFcn(app, @ddStepSizeValueChanged, true);
      app.ddStepSize.Tag = 'popup';
      app.ddStepSize.Value = '0.1';
      app.ddOptMethod = uidropdown(app.GridLayout);
      app.ddOptMethod.Items = {'None', 'ESFIT', 'Fit Peak-To-Peak', 'Fit Amplitude', 'Fit Integral', 'Fit Double Integral'};
      app.ddOptMethod.Tag = 'pmOptMethod';
      app.ddOptMethod.Value = 'Fit Amplitude';
      app.lbOpt = uilistbox(app.GridLayout);
      app.lbOpt.Items = {' -Opm.Amp = 1', ' -Opm.wf = 0', ' -Opm.dys = 0'};
      app.lbOpt.Tag = 'lbOpt';
      app.lbOpt.Value = ' -Opm.Amp = 1';
      app.pbCalcAll = uibutton(app.GridLayout, 'push');
      app.pbCalcAll.Tag = 'pbCalcAll';
      app.pbCalcAll.Tooltip = 'Calculate All';
      app.pbCalcAll.Text = 'All';
      app.pbReCalc = uibutton(app.GridLayout, 'push');
      app.pbReCalc.ButtonPushedFcn = createCallbackFcn(app, @bCalculate_Callback, true);
      app.pbReCalc.Tag = 'pbReCalc';
      app.pbReCalc.Tooltip = 'Recalculate';
      app.pbReCalc.Text = 'Sys';
      app.bCalculate = uibutton(app.GridLayout, 'push');
      app.bCalculate.ButtonPushedFcn = createCallbackFcn(app, @bCalculate_Callback, true);
      app.bCalculate.Text = 'Calc';
      app.UpdCheckBox = uicheckbox(app.GridLayout);
      app.UpdCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxChanged, true);
      app.UpdCheckBox.Tooltip = {'Update Calculations'};
      app.UpdCheckBox.Text = 'Upd';
      app.eParsString = uieditfield(app.GridLayout, 'text');
      app.eParsString.ValueChangedFcn = createCallbackFcn(app, @EditParameterChanged, true);
      app.eParsString.Tag = 'ePars';
      app.eParsString.Visible = 'off';
      app.eParsString.Value = '0';
      app.eParsNumber = uispinner(app.GridLayout);
      app.eParsNumber.Step = 0.1;
      app.eParsNumber.ValueDisplayFormat = '%11.6g';
      app.eParsNumber.ValueChangedFcn = createCallbackFcn(app, @EditParameterChanged, true);
      app.mContext = uicontextmenu(app.figure1);
      app.mContext.Tag = 'mContext';
      app.mContOpt = uimenu(app.mContext);
      app.mContOpt.MenuSelectedFcn = createCallbackFcn(app, @mContOptSet_Callback, true);
      app.mContOpt.Text = 'Optimise';
      app.mContOpt.Tag = 'mContOpt';
      app.mContNotOpt = uimenu(app.mContext);
      app.mContNotOpt.MenuSelectedFcn = createCallbackFcn(app, @mContOptSet_Callback, true);
      app.mContNotOpt.Text = 'Do not optimise';
      app.mContNotOpt.Tag = 'mContNotOpt';
      % Assign app.mContext
      app.lScript.ContextMenu = app.mContext;
      % Show the figure after all components are created
      app.figure1.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = simplugin3(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.figure1)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.figure1)
    end
  end
end