classdef transient3 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    Transient       matlab.ui.Figure
    GridLayout      matlab.ui.container.GridLayout
    cbFileUpdate    matlab.ui.control.CheckBox
    cbShowSrc       matlab.ui.control.CheckBox
    pbCalculate     matlab.ui.control.Button
    eBLstart        matlab.ui.control.EditField
    eBLend          matlab.ui.control.EditField
    eSstart         matlab.ui.control.EditField
    eSend           matlab.ui.control.EditField
    text1           matlab.ui.control.Label
    text2           matlab.ui.control.Label
    text3           matlab.ui.control.Label
    text4           matlab.ui.control.Label
    text5           matlab.ui.control.Label
    text6           matlab.ui.control.Label
    pbTrans         matlab.ui.control.CheckBox
    cbInvert        matlab.ui.control.CheckBox
    tbF0            matlab.ui.control.StateButton
    tbF1            matlab.ui.control.StateButton
    tbF2            matlab.ui.control.StateButton
    tbFno           matlab.ui.control.StateButton
    pbAddRange      matlab.ui.control.Button
    pbRemoveRange   matlab.ui.control.Button
    text19          matlab.ui.control.Label
    text20          matlab.ui.control.Label
    text21          matlab.ui.control.Label
    pbTimeField     matlab.ui.control.StateButton
    text22          matlab.ui.control.Label
    tbS0            matlab.ui.control.StateButton
    tbS1            matlab.ui.control.StateButton
    tbS2            matlab.ui.control.StateButton
    tbS3            matlab.ui.control.StateButton
    text23          matlab.ui.control.Label
    tbF3            matlab.ui.control.StateButton
    eInfo           matlab.ui.control.TextArea
    cbShowFit       matlab.ui.control.CheckBox
    pbSelectSignal  matlab.ui.control.Button
    pbSelectDark    matlab.ui.control.Button
    pbMarkField     matlab.ui.control.Button
  end


  properties (Access = private)
    KazanViewer % Description
    AreaFit % Description
    TimeField % Description
    ShowFieldPosition % Description
  end

  properties (Access = public)
    guid % Description
  end

  methods (Access = public)
    %----------------------------------------------------------------------
    function FileUpdate(app)
      if app.cbFileUpdate.Value
        [~,y] = app.GetRawData();
        app.eInfo.Value = sprintf("field %i pts\n time %i pts", size(y,1), size(y,2));
        app.DrawAll();
      end
    end
  end

  methods (Access = private)
    %----------------------------------------------------------------------
    function DrawAll(app)
      [ax,y,areafit] = app.GetRawData();

      DARKstart = app.eBLstart.Value;
      DARKend = app.eBLend.Value;
      Sstart = app.eSstart.Value;
      Send = app.eSend.Value;
      % Lstart = app.eLstart.Value;
      % Lend = app.eLend.Value;

      SIG = str2double(Sstart):str2double(Send);
      DARK = str2double(DARKstart):str2double(DARKend);
      SIG = SIG(SIG > 0 & SIG <= size(y,2));
      DARK = DARK(DARK > 0 & DARK <= size(y,2));

      MEANDARK  = mean(y(:, DARK), 2);
      y = y - repmat(MEANDARK, 1, size(y,2));
      ysm = y;

      if app.tbS1.Value
        for jj=1:size(y,1)
          ysm(jj,:) = datasmooth(y(jj,:),5,'savgol',2); % smooth the data in the time dimension
        end
      elseif app.tbS2.Value
        for jj=1:size(y,1)
          ysm(jj,:) = datasmooth(y(jj,:),10,'savgol',2); % smooth the data in the time dimension
        end
      elseif app.tbS3.Value
        for jj=1:size(y,1)
          ysm(jj,:) = datasmooth(y(jj,:),20,'savgol',2); % smooth the data in the time dimension
        end
      end

      if app.cbInvert.Value == 1
        y = -y;
        ysm = -ysm;
      end

      if app.TimeField == 1

        if isempty(SIG)
          out = {};
          out{1}.ax = ax;
          out{1}.y = ysm;
          out{1}.title = 'original';
          app.KazanViewer.SetData('output', out);
          return;
        end

        yout = sum(y(:, SIG), 2)/length(SIG);
        ysmout = sum(ysm(:, SIG), 2)/length(SIG);

        if app.tbS1.Value
          ysmout = datasmooth(ysmout,1,'savgol',1);
        elseif app.tbS2.Value
          ysmout = datasmooth(ysmout,3,'savgol',1);
        elseif app.tbS3.Value
          ysmout = datasmooth(ysmout,5,'savgol',1);
        end

        BL = zeros(size(ax.x));
        areafit = areafit(1:min(length(ax.x), length(areafit)));
        if numel(areafit) > 3
          if app.tbF0.Value
            BL = mean(ysmout(areafit))*ones(size(ax.x));
          elseif app.tbF1.Value
            [P] = polyfit(ax.x(areafit), ysmout(areafit), 1);
            BL = polyval(P, ax.x);
          elseif app.tbF2.Value
            [P] = polyfit(ax.x(areafit), ysmout(areafit), 2);
            BL = polyval(P, ax.x);
          elseif app.tbF3.Value
            [P] = polyfit(ax.x(areafit), ysmout(areafit), 3);
            BL = polyval(P, ax.x);
          end
        end

        if app.cbShowFit.Value
          out = {};
          out{1}.ax = ax;
          out{1}.y = ysmout;
          out{1}.title = 'smoothed';

          if app.cbShowSrc.Value
            out{end+1}.ax = ax;
            out{end}.y = yout;
            out{end}.ax.Color = [1, 0, 0]; % always red
            out{end}.title = 'original';
          end

          % fitting area
          out{end+1}.ax = ax;
          out{end}.ax.x = ax.x(areafit);
          out{end}.y = ysmout(areafit);
          out{end}.ax.Color = [0, 0, 0]; % always green
          out{end}.ax.s = 0;
          out{end}.ax.Marker = 'o';
          out{end}.ax.LineStyle = 'none';
          out{end}.title = 'fit area';

          % baseline
          out{end+1}.ax = ax;
          out{end}.ax.x = ax.x;
          out{end}.y = BL;
          out{end}.ax.Color = [0, 1, 0]; % always green
          out{end}.ax.s = 0;
          out{end}.ax.LineStyle = '-';
          out{end}.title = 'baseline';

        else
          out = {};
          out{1}.ax = ax;
          out{1}.y = ysmout - BL;
          out{1}.title = 'baseline corrected';

          if app.cbShowSrc.Value
            out{end+1}.ax = ax;
            out{end}.y = yout - BL;
            out{end}.ax.Color = [1, 0, 0]; % always red
            out{end}.title = 'original';
          end

          % fitting area
          out{end+1}.ax = ax;
          out{end}.ax.x = ax.x(areafit);
          out{end}.y = ysmout(areafit) - BL(areafit);
          out{end}.ax.Color = [0, 0, 0]; % always green
          out{end}.ax.s = 0;
          out{end}.ax.Marker = 'o';
          out{end}.ax.LineStyle = 'none';
          out{end}.title = 'fit area';
        end
      else   % Time domain
        ysmout = ysm(app.ShowFieldPosition, :)';
        yout = y(app.ShowFieldPosition, :)';

        if ~isempty(areafit)
          BL = mean(ysmout(areafit,:),1)';
          ysmout = ysmout - BL;
          yout = yout - BL;
        end

        out = {};
        if app.cbShowSrc.Value
          out{end+1}.ax = ax;
          out{end}.ax.x = ax.y;
          out{end}.ax.y = ax.x(app.ShowFieldPosition);
          out{end}.ax.ylabel = ax.xlabel;
          out{end}.y = yout;
          out{end}.ax.Color = [1, 0, 0]; % always red
          out{end}.title = 'original';
        end

        out{end+1}.ax = ax;
        out{end}.ax.x = ax.y;
        out{end}.ax.y = ax.x(app.ShowFieldPosition);
        out{end}.ax.ylabel = ax.xlabel;
        out{end}.y = ysmout;
        out{end}.title = 'smoothed';

        out{end+1}.ax = ax;
        out{end}.ax.x = ax.y(SIG);
        out{end}.y = ysmout(SIG);
        out{end}.ax.xlabel = ax.ylabel;
        out{end}.ax.Color = [0, 1, 0]; % always green
        out{end}.title = 'signal';


        if numel(DARK) > 0
          out{end+1}.ax = ax;
          out{end}.ax.x = ax.y(DARK);
          out{end}.ax.xlabel = ax.ylabel;
          out{end}.y = ysmout(DARK);
          out{end}.ax.Color = [0, 0, 0];
          out{end}.title = 'dark';
        end
      end

      app.KazanViewer.SetData('output', out);
    end

    %----------------------------------------------------------------------
    function [ax, y, areafit] = GetRawData(app)
      src = app.KazanViewer.GetData('source');
      ax = src.ax;
      y = src.y;

      if app.pbTrans.Value == 1
        y = y.';
        yy = ax.y;
        ax.y = ax.x;
        ax.x = yy;
        ll = ax.xlabel;
        ax.xlabel = ax.ylabel;
        ax.ylabel = ll;
      end

      if isempty(app.AreaFit)
        app.AreaFit = false(size(y,1),1);
        app.AreaFit(1:10)=true;
        app.AreaFit(end-10:end)=true;
      end
      if size(app.AreaFit,1) > size(y,1)
        app.AreaFit = app.AreaFit(1:size(y,1));
      elseif size(app.AreaFit,1) < size(y,1)
        app.AreaFit(size(y,1)) = true;
      end
      areafit = app.AreaFit;
    end
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, caller, guid)
      app.KazanViewer = caller;
      app.guid = guid;
      app.TimeField = 1;
      app.ShowFieldPosition = 1;
    end

    % Size changed function: Transient
    function Transient_SizeChangedFcn(app, event)
      standard_size = [70   669   370   480];
      fig_size = app.Transient.Position;
      scaling_factor = min(fig_size(3:4) ./ standard_size(3:4)) * 1.5;

      if ~isempty(app.KazanViewer)
        Font = app.KazanViewer.Get('font');
        font_size = Font * scaling_factor;
        set(findall(app.Transient, 'Type', 'UIButton'), 'FontSize', font_size);
        set(findall(app.Transient, 'Type', 'UICheckBox'), 'FontSize', font_size);
        set(findall(app.Transient, 'Type', 'UITextArea'), 'FontSize', font_size);
        set(findall(app.Transient, 'Type', 'UIEdit'), 'FontSize', font_size);
        set(findall(app.Transient, 'Type', 'UIDropDown'), 'FontSize', font_size);
        set(findall(app.Transient, 'Type', 'UILabel'), 'FontSize', font_size);
        set(findall(app.Transient, 'Type', 'UIStateButton'), 'FontSize', font_size);
        set(findall(app.Transient, 'Type', 'UISpinner'), 'FontSize', font_size);
      end
    end

    % Value changed function: cbShowFit
    function cbShowFit_Callback(app, event)
      app.DrawAll();
    end

    % Button pushed function: pbAddRange
    function pbAddRange_Callback(app, event)
      disp('Select the beginning and the end of the range');
      pos = app.KazanViewer.Ginput(2);
      if isempty(pos), return; end

      pos1 = sort(pos(:,1));

      [ax,~,areafit] = app.GetRawData();
      areafit(ax.x > pos1(1) & ax.x < pos1(2)) = true;
      app.AreaFit = areafit;
      app.DrawAll();
    end

    % Button pushed function: pbMarkField
    function pbMarkField_Callback(app, event)
      pos = app.KazanViewer.Ginput(1);
      if isempty(pos), return; end

      [ax] = app.GetRawData();
      [~, minidx] = min(abs(ax.x - pos(1)));

      app.ShowFieldPosition = minidx;
    end

    % Button pushed function: pbRemoveRange
    function pbRemoveRange_Callback(app, event)
      disp('Select the beginning and the end of the range');
      pos = app.KazanViewer.Ginput(2);
      if isempty(pos), return; end

      pos1 = sort(pos(:,1));

      [ax,~,areafit] = app.GetRawData();
      areafit(ax.x > pos1(1) & ax.x < pos1(2)) = false;
      app.AreaFit = areafit;
      app.DrawAll();
    end

    % Button pushed function: pbSelectDark
    function pbSelectDark_Callback(app, event)

      if app.TimeField == 0
        disp('Select the beginning and the end of the range');
        pos = app.KazanViewer.Ginput(2);
        if isempty(pos), return; end

        pos1 = sort(pos(:,1));

        [ax,~] = app.GetRawData();
        [~,idx1] = min(abs(ax.y - pos1(1)));
        [~,idx2] = min(abs(ax.y - pos1(2)));

        app.eBLstart.Value = num2str(idx1);
        app.eBLend.Value = num2str(idx2);

        app.DrawAll();
      end
    end

    % Button pushed function: pbSelectSignal
    function pbSelectSignal_Callback(app, event)

      if app.TimeField == 0
        disp('Select the beginning and the end of the range');
        pos = app.KazanViewer.Ginput(2);
        if isempty(pos), return; end

        pos1 = sort(pos(:,1));

        [ax,~] = app.GetRawData();
        [~,idx1] = min(abs(ax.y - pos1(1)));
        [~,idx2] = min(abs(ax.y - pos1(2)));

        app.eSstart.Value = num2str(idx1);
        app.eSend.Value = num2str(idx2);

        app.DrawAll();
      end
    end

    % Value changed function: pbTimeField
    function pbTimeField_Callback(app, event)

      app.TimeField = app.TimeField+1;
      if app.TimeField > 1, app.TimeField = 0; end
      caps = {'Switch to Field', 'Switch to Time'};
      app.pbTimeField.Text = caps{app.TimeField+1};
      caps = {'Time Domain', 'Field Domain'};
      app.Transient.Name = ['TRANSIENT: ',caps{app.TimeField+1}];
      app.DrawAll();

      val = {'off','on'};
      app.pbMarkField.Enable = val{app.TimeField+1};
      app.pbRemoveRange.Enable = val{app.TimeField+1};
      app.pbAddRange.Enable = val{app.TimeField+1};
      val = {'on','off'};
      app.pbSelectSignal.Enable = val{app.TimeField+1};
      app.pbSelectDark.Enable = val{app.TimeField+1};

    end

    % Callback function: cbInvert, cbShowSrc, pbCalculate, pbTrans
    function Update(app, event)
      app.FileUpdate();
      app.DrawAll();
    end

    % Value changed function: tbF0, tbF1, tbF2, tbF3, tbFno
    function tbFno_Callback(app, event)

      allhh = [app.tbFno, app.tbF0, app.tbF1, app.tbF2, app.tbF3];
      set(allhh, 'Value', 0);
      event.Source.Value = 1;

      app.DrawAll();
    end

    % Value changed function: tbS0, tbS1, tbS2, tbS3
    function tbS0_Callback(app, event)

      allhh = [app.tbS0, app.tbS1, app.tbS2, app.tbS3];
      set(allhh, 'Value', 0);
      event.Source.Value = 1;

      app.DrawAll();
    end

    % Close request function: Transient
    function TransientCloseRequest(app, event)
      app.KazanViewer.MakePlugins('close', app);
      delete(app)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.Transient = uifigure('Visible', 'off');
      colormap(app.Transient, 'parula');
      app.Transient.Name = 'transient';
      app.Transient.CloseRequestFcn = createCallbackFcn(app, @TransientCloseRequest, true);
      app.Transient.SizeChangedFcn = createCallbackFcn(app, @Transient_SizeChangedFcn, true);
      app.Transient.Tag = 'Transient';
      app.GridLayout = uigridlayout(app.Transient);
      app.pbMarkField = uibutton(app.GridLayout, 'push');
      app.pbMarkField.ButtonPushedFcn = createCallbackFcn(app, @pbMarkField_Callback, true);
      app.pbMarkField.Tag = 'pbMarkField';
      app.pbMarkField.Icon = 'pbCross.png';
      app.pbMarkField.Tooltip = 'Mark Field for Trace';
      app.pbMarkField.Text = '';
      app.pbSelectDark = uibutton(app.GridLayout, 'push');
      app.pbSelectDark.ButtonPushedFcn = createCallbackFcn(app, @pbSelectDark_Callback, true);
      app.pbSelectDark.Tag = 'pbSelectDark';
      app.pbSelectDark.Icon = 'pbCross.png';
      app.pbSelectDark.Text = '';
      app.pbSelectSignal = uibutton(app.GridLayout, 'push');
      app.pbSelectSignal.ButtonPushedFcn = createCallbackFcn(app, @pbSelectSignal_Callback, true);
      app.pbSelectSignal.Tag = 'pbSelectSignal';
      app.pbSelectSignal.Icon = 'pbCross.png';
      app.pbSelectSignal.Text = '';
      app.cbShowFit = uicheckbox(app.GridLayout);
      app.cbShowFit.ValueChangedFcn = createCallbackFcn(app, @cbShowFit_Callback, true);
      app.cbShowFit.Tag = 'cbShowFit';
      app.cbShowFit.Text = 'Show Fit';
      app.eInfo = uitextarea(app.GridLayout);
      app.eInfo.Tag = 'eInfo';
      app.eInfo.Value = {'-'};
      app.tbF3 = uibutton(app.GridLayout, 'state');
      app.tbF3.ValueChangedFcn = createCallbackFcn(app, @tbFno_Callback, true);
      app.tbF3.Tag = 'tbF3';
      app.tbF3.Text = '3';
      app.text23 = uilabel(app.GridLayout);
      app.text23.Tag = 'text23';
      app.text23.Text = 'Smooth';
      app.tbS3 = uibutton(app.GridLayout, 'state');
      app.tbS3.ValueChangedFcn = createCallbackFcn(app, @tbS0_Callback, true);
      app.tbS3.Tag = 'tbS3';
      app.tbS3.Text = '10';
      app.tbS2 = uibutton(app.GridLayout, 'state');
      app.tbS2.ValueChangedFcn = createCallbackFcn(app, @tbS0_Callback, true);
      app.tbS2.Tag = 'tbS2';
      app.tbS2.Text = '5';
      app.tbS1 = uibutton(app.GridLayout, 'state');
      app.tbS1.ValueChangedFcn = createCallbackFcn(app, @tbS0_Callback, true);
      app.tbS1.Tag = 'tbS1';
      app.tbS1.Text = '3';
      app.tbS0 = uibutton(app.GridLayout, 'state');
      app.tbS0.ValueChangedFcn = createCallbackFcn(app, @tbS0_Callback, true);
      app.tbS0.Tag = 'tbS0';
      app.tbS0.Text = 'No';
      app.tbS0.Value = true;
      app.text22 = uilabel(app.GridLayout);
      app.text22.Tag = 'text22';
      app.text22.Text = 'BL';
      app.pbTimeField = uibutton(app.GridLayout, 'state');
      app.pbTimeField.ValueChangedFcn = createCallbackFcn(app, @pbTimeField_Callback, true);
      app.pbTimeField.Tag = 'pbTimeField';
      app.pbTimeField.Text = 'Switch to Time';
      app.text21 = uilabel(app.GridLayout);
      app.text21.Tag = 'text21';
      app.text21.Text = 'Range';
      app.text20 = uilabel(app.GridLayout);
      app.text20.Tag = 'text20';
      app.text20.Text = 'Remv';
      app.text19 = uilabel(app.GridLayout);
      app.text19.Tag = 'text19';
      app.text19.Text = 'Add';
      app.pbRemoveRange = uibutton(app.GridLayout, 'push');
      app.pbRemoveRange.ButtonPushedFcn = createCallbackFcn(app, @pbRemoveRange_Callback, true);
      app.pbRemoveRange.Tag = 'pbRemoveRange';
      app.pbRemoveRange.Icon = 'pbCross.png';
      app.pbRemoveRange.Text = '';
      app.pbAddRange = uibutton(app.GridLayout, 'push');
      app.pbAddRange.ButtonPushedFcn = createCallbackFcn(app, @pbAddRange_Callback, true);
      app.pbAddRange.Tag = 'pbAddRange';
      app.pbAddRange.Icon = 'pbCross.png';
      app.pbAddRange.Text = '';
      app.tbFno = uibutton(app.GridLayout, 'state');
      app.tbFno.ValueChangedFcn = createCallbackFcn(app, @tbFno_Callback, true);
      app.tbFno.Tag = 'tbFno';
      app.tbFno.Text = 'No';
      app.tbFno.Value = true;
      app.tbF2 = uibutton(app.GridLayout, 'state');
      app.tbF2.ValueChangedFcn = createCallbackFcn(app, @tbFno_Callback, true);
      app.tbF2.Tag = 'tbF2';
      app.tbF2.Text = '2';
      app.tbF1 = uibutton(app.GridLayout, 'state');
      app.tbF1.ValueChangedFcn = createCallbackFcn(app, @tbFno_Callback, true);
      app.tbF1.Tag = 'tbF1';
      app.tbF1.Text = '1';
      app.tbF0 = uibutton(app.GridLayout, 'state');
      app.tbF0.ValueChangedFcn = createCallbackFcn(app, @tbFno_Callback, true);
      app.tbF0.Tag = 'tbF0';
      app.tbF0.Text = '0';
      app.cbInvert = uicheckbox(app.GridLayout);
      app.cbInvert.ValueChangedFcn = createCallbackFcn(app, @Update, true);
      app.cbInvert.Tag = 'cbInvert';
      app.cbInvert.Text = 'Inv';
      app.pbTrans = uicheckbox(app.GridLayout);
      app.pbTrans.ValueChangedFcn = createCallbackFcn(app, @Update, true);
      app.pbTrans.Tag = 'pbTrans';
      app.pbTrans.Text = 'Transpose';
      app.pbTrans.Value = true;
      app.text6 = uilabel(app.GridLayout);
      app.text6.Tag = 'text6';
      app.text6.Text = 'End';
      app.text5 = uilabel(app.GridLayout);
      app.text5.Tag = 'text5';
      app.text5.Text = 'End';
      app.text4 = uilabel(app.GridLayout);
      app.text4.Tag = 'text4';
      app.text4.Text = 'Start';
      app.text3 = uilabel(app.GridLayout);
      app.text3.Tag = 'text3';
      app.text3.Text = 'Start';
      app.text2 = uilabel(app.GridLayout);
      app.text2.Tag = 'text2';
      app.text2.Text = 'Signal, pts';
      app.text1 = uilabel(app.GridLayout);
      app.text1.Tag = 'text1';
      app.text1.Text = 'Dark signal, pts';
      app.eSend = uieditfield(app.GridLayout, 'text');
      app.eSend.Tag = 'eSend';
      app.eSend.Value = '600';
      app.eSstart = uieditfield(app.GridLayout, 'text');
      app.eSstart.Tag = 'eSstart';
      app.eSstart.Value = '200';
      app.eBLend = uieditfield(app.GridLayout, 'text');
      app.eBLend.Tag = 'eBLend';
      app.eBLend.Value = '10';
      app.eBLstart = uieditfield(app.GridLayout, 'text');
      app.eBLstart.Tag = 'eBLstart';
      app.eBLstart.Value = '1';
      app.pbCalculate = uibutton(app.GridLayout, 'push');
      app.pbCalculate.ButtonPushedFcn = createCallbackFcn(app, @Update, true);
      app.pbCalculate.Tag = 'pbCalculate';
      app.pbCalculate.Text = 'Calculate';
      app.cbShowSrc = uicheckbox(app.GridLayout);
      app.cbShowSrc.ValueChangedFcn = createCallbackFcn(app, @Update, true);
      app.cbShowSrc.Text = {'Show src'; ''};
      app.cbFileUpdate = uicheckbox(app.GridLayout);
      app.cbFileUpdate.Text = 'Update when file changes';
      app.cbFileUpdate.Value = true;
      % Show the figure after all components are created
      app.Transient.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = transient3(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.Transient)

        % Execute the startup function
        runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.Transient)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.Transient)
    end
  end
end